classdef DSCSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSCMappingAdvancedSettingsUIFigure  matlab.ui.Figure
        ApplySettingsButton            matlab.ui.control.Button
        ResetToDefaultButton           matlab.ui.control.Button
        ConcentrationPanel             matlab.ui.container.Panel
        rEditField                     matlab.ui.control.NumericEditField
        rEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        ConcentrationCorrectionCheckBox  matlab.ui.control.CheckBox
        ProportionalityConstantsPanel  matlab.ui.container.Panel
        KhEditField                    matlab.ui.control.NumericEditField
        KhEditFieldLabel               matlab.ui.control.Label
        RhoEditField                   matlab.ui.control.NumericEditField
        RhoEditFieldLabel              matlab.ui.control.Label
        KvoiEditField                  matlab.ui.control.NumericEditField
        KvoiEditFieldLabel             matlab.ui.control.Label
        DeconvolutionPanel             matlab.ui.container.Panel
        oSVDCounterEditField           matlab.ui.control.NumericEditField
        oSVDCounterEditFieldLabel      matlab.ui.control.Label
        oSVDThresholdEditField         matlab.ui.control.NumericEditField
        oSVDThresholdEditFieldLabel    matlab.ui.control.Label
        cSVDThresholdEditField         matlab.ui.control.NumericEditField
        cSVDThresholdEditFieldLabel    matlab.ui.control.Label
        SVDThresholdEditField          matlab.ui.control.NumericEditField
        SVDThresholdEditFieldLabel     matlab.ui.control.Label
        SaveoSVDResidualsCheckBox      matlab.ui.control.CheckBox
        SavecSVDResidualsCheckBox      matlab.ui.control.CheckBox
        SaveSVDResidualsCheckBox       matlab.ui.control.CheckBox
        S0Panel                        matlab.ui.container.Panel
        S0MaxVoxNrEditField            matlab.ui.control.NumericEditField
        S0MaxVoxNrEditFieldLabel       matlab.ui.control.Label
        S0ThresholdEditField           matlab.ui.control.NumericEditField
        S0ThresholdEditFieldLabel      matlab.ui.control.Label
        S0MinVoxNrEditField            matlab.ui.control.NumericEditField
        S0MinVoxNrEditFieldLabel       matlab.ui.control.Label
        AIFPanel                       matlab.ui.container.Panel
        ClusterThresholdPeakTTPEditField  matlab.ui.control.NumericEditField
        ClusterThresholdPeakTTPEditFieldLabel  matlab.ui.control.Label
        VoxelDiscardFractionRegularityEditField  matlab.ui.control.NumericEditField
        VoxelDiscardFractionRegularityEditFieldLabel  matlab.ui.control.Label
        VoxelDiscardFractionTTPEditField  matlab.ui.control.NumericEditField
        VoxelDiscardFractionTTPEditFieldLabel  matlab.ui.control.Label
        VoxelDiscardFractionAUCEditField  matlab.ui.control.NumericEditField
        VoxelDiscardFractionAUCEditFieldLabel  matlab.ui.control.Label
        AIFMaxVoxNrEditField           matlab.ui.control.NumericEditField
        AIFMaxVoxNrEditFieldLabel      matlab.ui.control.Label
        AIFMinVoxNrEditField           matlab.ui.control.NumericEditField
        AIFMinVoxNrEditFieldLabel      matlab.ui.control.Label
        AIFROIMinorSemiaxisEditField   matlab.ui.control.NumericEditField
        AIFROIMinorSemiaxisEditFieldLabel  matlab.ui.control.Label
        AIFROIMajorSemiaxisEditField   matlab.ui.control.NumericEditField
        AIFROIMajorSemiaxisEditFieldLabel  matlab.ui.control.Label
        AIFRecirculationCorrectionCheckBox  matlab.ui.control.CheckBox
        EnableAIFCheckBox              matlab.ui.control.CheckBox
        MaskNrVoxelsEditField          matlab.ui.control.NumericEditField
        MaskNrVoxelsEditFieldLabel     matlab.ui.control.Label
        ImageValueButtonGroup          matlab.ui.container.ButtonGroup
        ConcentrationButton            matlab.ui.control.RadioButton
        SignalButton                   matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        suMRak % Main suMRak interface
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, loadedOptions)
            % Store suMRak
            app.suMRak = caller;

            % Older saved option structs used Italian field names. Translate
            % them to English before reading, so legacy .mat files still load.
            if isfield(loadedOptions.aif, 'semiasseMinore')
                loadedOptions.aif.minorSemiAxis = loadedOptions.aif.semiasseMinore;
                loadedOptions.aif = rmfield(loadedOptions.aif, 'semiasseMinore');
            end
            if isfield(loadedOptions.aif, 'semiasseMaggiore')
                loadedOptions.aif.majorSemiAxis = loadedOptions.aif.semiasseMaggiore;
                loadedOptions.aif = rmfield(loadedOptions.aif, 'semiasseMaggiore');
            end
            if isfield(loadedOptions.aif, 'ricircolo')
                loadedOptions.aif.recirculationCorrection = loadedOptions.aif.ricircolo;
                loadedOptions.aif = rmfield(loadedOptions.aif, 'ricircolo');
            end
            if isfield(loadedOptions.aif, 'diffPicco')
                loadedOptions.aif.peakTTPClusterThreshold = loadedOptions.aif.diffPicco;
                loadedOptions.aif = rmfield(loadedOptions.aif, 'diffPicco');
            end

            % Set label values to loaded option values
            % Image
            if loadedOptions.conc == 1
                app.ConcentrationButton.Value = 1;
            else
                app.ConcentrationButton.Value = 0;
            end
            % Mask
            app.MaskNrVoxelsEditField.Value = loadedOptions.mask.npixel;
            % S0
            app.S0ThresholdEditField.Value = loadedOptions.S0.thresh;
            app.S0MinVoxNrEditField.Value = loadedOptions.S0.nSamplesMin;
            app.S0MaxVoxNrEditField.Value = loadedOptions.S0.nSamplesMax;
            % AIF
            app.EnableAIFCheckBox.Value = loadedOptions.aif.enable;
            app.AIFROIMinorSemiaxisEditField.Value = loadedOptions.aif.minorSemiAxis;
            app.AIFROIMajorSemiaxisEditField.Value = loadedOptions.aif.majorSemiAxis;
            app.AIFMinVoxNrEditField.Value = loadedOptions.aif.nVoxelMin;
            app.AIFMaxVoxNrEditField.Value = loadedOptions.aif.nVoxelMax;
            app.AIFRecirculationCorrectionCheckBox.Value = loadedOptions.aif.recirculationCorrection;
            app.VoxelDiscardFractionAUCEditField.Value = loadedOptions.aif.pArea;
            app.VoxelDiscardFractionTTPEditField.Value = loadedOptions.aif.pTTP;
            app.VoxelDiscardFractionRegularityEditField.Value = loadedOptions.aif.pReg;
            app.ClusterThresholdPeakTTPEditField.Value = loadedOptions.aif.peakTTPClusterThreshold;
            % Proportionality constants
            app.KhEditField.Value = loadedOptions.par.kh;
            app.RhoEditField.Value = loadedOptions.par.rho;
            app.KvoiEditField.Value = loadedOptions.par.kvoi;
            % Concentration
            app.ConcentrationCorrectionCheckBox.Value = loadedOptions.qr.enable;
            app.aEditField.Value = loadedOptions.qr.a;
            app.bEditField.Value = loadedOptions.qr.b;
            app.rEditField.Value = loadedOptions.qr.r;
            % Deconvolution
            app.SVDThresholdEditField.Value = loadedOptions.deconv.SVD.threshold;
            app.cSVDThresholdEditField.Value = loadedOptions.deconv.cSVD.threshold;
            app.oSVDThresholdEditField.Value = loadedOptions.deconv.oSVD.OIthres;
            app.oSVDCounterEditField.Value = loadedOptions.deconv.oSVD.OIcounter;
            app.SaveSVDResidualsCheckBox.Value = loadedOptions.deconv.SVD.residual;
            app.SavecSVDResidualsCheckBox.Value = loadedOptions.deconv.cSVD.residual;
            app.SaveoSVDResidualsCheckBox.Value = loadedOptions.deconv.oSVD.residual;

            movegui(app.DSCMappingAdvancedSettingsUIFigure, 'center');
        end

        % Button pushed function: ResetToDefaultButton
        function ResetToDefaultButtonPushed(app, event)
            % Set label values to default option values
            % Image
            app.ConcentrationButton.Value = 0;
            % Mask
            app.MaskNrVoxelsEditField.Value = 300;
            % S0
            app.S0ThresholdEditField.Value = 0.05;
            app.S0MinVoxNrEditField.Value = 3;
            app.S0MaxVoxNrEditField.Value = 12;
            % AIF
            app.EnableAIFCheckBox.Value = 1;
            app.AIFROIMinorSemiaxisEditField.Value = 0.15;
            app.AIFROIMajorSemiaxisEditField.Value = 0.35;
            app.AIFMinVoxNrEditField.Value = 4;
            app.AIFMaxVoxNrEditField.Value = 6;
            app.AIFRecirculationCorrectionCheckBox.Value = 1;
            app.VoxelDiscardFractionAUCEditField.Value = 0.4;
            app.VoxelDiscardFractionTTPEditField.Value = 0.4;
            app.VoxelDiscardFractionRegularityEditField.Value = 0.05;
            app.ClusterThresholdPeakTTPEditField.Value = 0.04;
            % Proportionality constants
            app.KhEditField.Value = 1;
            app.RhoEditField.Value = 1;
            app.KvoiEditField.Value = 1;
            % Concentration
            app.ConcentrationCorrectionCheckBox.Value = 0;
            app.aEditField.Value = 0.0076;
            app.bEditField.Value = 0.00057;
            app.rEditField.Value = 0.044;
            % Deconvolution
            app.SVDThresholdEditField.Value = 0.2;
            app.cSVDThresholdEditField.Value = 0.1;
            app.oSVDThresholdEditField.Value = 0.035;
            app.oSVDCounterEditField.Value = 1;
            app.SaveSVDResidualsCheckBox.Value = 1;
            app.SavecSVDResidualsCheckBox.Value = 1;
            app.SaveoSVDResidualsCheckBox.Value = 1;
        end

        % Button pushed function: ApplySettingsButton
        function ApplySettingsButtonPushed(app, event)
            % Update suMRak options to new advanced options
            % Image
            if app.ConcentrationButton.Value == 1
                app.suMRak.DSCOptions.conc = 1; 
            else
                app.suMRak.DSCOptions.conc = 0;
            end
            % Mask
            app.suMRak.DSCOptions.mask.npixel = app.MaskNrVoxelsEditField.Value;
            % S0
            app.suMRak.DSCOptions.S0.thresh = app.S0ThresholdEditField.Value;
            app.suMRak.DSCOptions.S0.nSamplesMin = app.S0MinVoxNrEditField.Value;
            app.suMRak.DSCOptions.S0.nSamplesMax = app.S0MaxVoxNrEditField.Value;
            % AIF
            app.suMRak.DSCOptions.aif.enable = app.EnableAIFCheckBox.Value;
            app.suMRak.DSCOptions.aif.minorSemiAxis = app.AIFROIMinorSemiaxisEditField.Value;
            app.suMRak.DSCOptions.aif.majorSemiAxis = app.AIFROIMajorSemiaxisEditField.Value;
            app.suMRak.DSCOptions.aif.nVoxelMin = app.AIFMinVoxNrEditField.Value;
            app.suMRak.DSCOptions.aif.nVoxelMax = app.AIFMaxVoxNrEditField.Value;
            app.suMRak.DSCOptions.aif.recirculationCorrection = app.AIFRecirculationCorrectionCheckBox.Value;
            app.suMRak.DSCOptions.aif.pArea = app.VoxelDiscardFractionAUCEditField.Value;
            app.suMRak.DSCOptions.aif.pTTP = app.VoxelDiscardFractionTTPEditField.Value;
            app.suMRak.DSCOptions.aif.pReg = app.VoxelDiscardFractionRegularityEditField.Value;
            app.suMRak.DSCOptions.aif.peakTTPClusterThreshold = app.ClusterThresholdPeakTTPEditField.Value;
            % Mirror to the field names the DSC toolbox (DSC_mri_aif.m) actually reads
            app.suMRak.DSCOptions.aif.semiasseMinore   = app.suMRak.DSCOptions.aif.minorSemiAxis;
            app.suMRak.DSCOptions.aif.semiasseMaggiore = app.suMRak.DSCOptions.aif.majorSemiAxis;
            app.suMRak.DSCOptions.aif.ricircolo        = app.suMRak.DSCOptions.aif.recirculationCorrection;
            app.suMRak.DSCOptions.aif.diffPicco        = app.suMRak.DSCOptions.aif.peakTTPClusterThreshold;
            % Proportionality constants
            app.suMRak.DSCOptions.par.kh = app.KhEditField.Value;
            app.suMRak.DSCOptions.par.rho = app.RhoEditField.Value;
            app.suMRak.DSCOptions.par.kvoi = app.KvoiEditField.Value;
            % Concentration
            app.suMRak.DSCOptions.qr.enable = app.ConcentrationCorrectionCheckBox.Value;
            app.suMRak.DSCOptions.qr.a = app.aEditField.Value;
            app.suMRak.DSCOptions.qr.b = app.bEditField.Value;
            app.suMRak.DSCOptions.qr.r = app.rEditField.Value;
            % Deconvolution
            app.suMRak.DSCOptions.deconv.SVD.threshold = app.SVDThresholdEditField.Value;
            app.suMRak.DSCOptions.deconv.cSVD.threshold = app.cSVDThresholdEditField.Value;
            app.suMRak.DSCOptions.deconv.oSVD.OIthres = app.oSVDThresholdEditField.Value;
            app.suMRak.DSCOptions.deconv.oSVD.OIcounter = app.oSVDCounterEditField.Value;
            app.suMRak.DSCOptions.deconv.SVD.residual = app.SaveSVDResidualsCheckBox.Value;
            app.suMRak.DSCOptions.deconv.cSVD.residual = app.SavecSVDResidualsCheckBox.Value;
            app.suMRak.DSCOptions.deconv.oSVD.residual = app.SaveoSVDResidualsCheckBox.Value;
            
            % Turn on settings button, delete app
            app.suMRak.AdvancedSettingsButton.Enable = 'on';
            close(app.suMRak.ProgressBar)
            delete(app)
        end

        % Close request function: DSCMappingAdvancedSettingsUIFigure
        function DSCMappingAdvancedSettingsUIFigureCloseRequest(app, event)

            % Turn on settings button, delete app
            app.suMRak.AdvancedSettingsButton.Enable = 'on';
            close(app.suMRak.ProgressBar)
            delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DSCMappingAdvancedSettingsUIFigure = uifigure('Visible', 'off');
            app.DSCMappingAdvancedSettingsUIFigure.Name = 'DSC Mapping Advanced Settings';
            app.DSCMappingAdvancedSettingsUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'icon.png');
            app.DSCMappingAdvancedSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @DSCMappingAdvancedSettingsUIFigureCloseRequest, true);
            app.ImageValueButtonGroup = uibuttongroup(app.DSCMappingAdvancedSettingsUIFigure);
            app.ImageValueButtonGroup.Title = 'Image Value';
            app.SignalButton = uiradiobutton(app.ImageValueButtonGroup);
            app.SignalButton.Text = 'Signal';
            app.SignalButton.Value = true;
            app.ConcentrationButton = uiradiobutton(app.ImageValueButtonGroup);
            app.ConcentrationButton.Text = 'Concentration';
            app.MaskNrVoxelsEditFieldLabel = uilabel(app.DSCMappingAdvancedSettingsUIFigure);
            app.MaskNrVoxelsEditFieldLabel.Text = 'Mask Nr. Voxels';
            app.MaskNrVoxelsEditField = uieditfield(app.DSCMappingAdvancedSettingsUIFigure, 'numeric');
            app.AIFPanel = uipanel(app.DSCMappingAdvancedSettingsUIFigure);
            app.AIFPanel.Title = 'AIF';
            app.EnableAIFCheckBox = uicheckbox(app.AIFPanel);
            app.EnableAIFCheckBox.Text = 'Enable AIF';
            app.AIFRecirculationCorrectionCheckBox = uicheckbox(app.AIFPanel);
            app.AIFRecirculationCorrectionCheckBox.Text = 'AIF Recirculation Correction';
            app.AIFROIMajorSemiaxisEditFieldLabel = uilabel(app.AIFPanel);
            app.AIFROIMajorSemiaxisEditFieldLabel.Text = 'AIF ROI Major Semiaxis';
            app.AIFROIMajorSemiaxisEditField = uieditfield(app.AIFPanel, 'numeric');
            app.AIFROIMinorSemiaxisEditFieldLabel = uilabel(app.AIFPanel);
            app.AIFROIMinorSemiaxisEditFieldLabel.Text = 'AIF ROI Minor Semiaxis';
            app.AIFROIMinorSemiaxisEditField = uieditfield(app.AIFPanel, 'numeric');
            app.AIFMinVoxNrEditFieldLabel = uilabel(app.AIFPanel);
            app.AIFMinVoxNrEditFieldLabel.Text = 'AIF Min. Vox. Nr.';
            app.AIFMinVoxNrEditField = uieditfield(app.AIFPanel, 'numeric');
            app.AIFMaxVoxNrEditFieldLabel = uilabel(app.AIFPanel);
            app.AIFMaxVoxNrEditFieldLabel.Text = 'AIF Max. Vox. Nr.';
            app.AIFMaxVoxNrEditField = uieditfield(app.AIFPanel, 'numeric');
            app.VoxelDiscardFractionAUCEditFieldLabel = uilabel(app.AIFPanel);
            app.VoxelDiscardFractionAUCEditFieldLabel.Text = 'Voxel Discard Fraction (AUC)';
            app.VoxelDiscardFractionAUCEditField = uieditfield(app.AIFPanel, 'numeric');
            app.VoxelDiscardFractionTTPEditFieldLabel = uilabel(app.AIFPanel);
            app.VoxelDiscardFractionTTPEditFieldLabel.Text = 'Voxel Discard Fraction (TTP)';
            app.VoxelDiscardFractionTTPEditField = uieditfield(app.AIFPanel, 'numeric');
            app.VoxelDiscardFractionRegularityEditFieldLabel = uilabel(app.AIFPanel);
            app.VoxelDiscardFractionRegularityEditFieldLabel.Text = 'Voxel Discard Fraction (Regularity)';
            app.VoxelDiscardFractionRegularityEditField = uieditfield(app.AIFPanel, 'numeric');
            app.ClusterThresholdPeakTTPEditFieldLabel = uilabel(app.AIFPanel);
            app.ClusterThresholdPeakTTPEditFieldLabel.Text = 'Cluster Threshold (Peak/TTP)';
            app.ClusterThresholdPeakTTPEditField = uieditfield(app.AIFPanel, 'numeric');
            app.S0Panel = uipanel(app.DSCMappingAdvancedSettingsUIFigure);
            app.S0Panel.Title = 'S0';
            app.S0MinVoxNrEditFieldLabel = uilabel(app.S0Panel);
            app.S0MinVoxNrEditFieldLabel.Text = 'S0 Min. Vox. Nr.';
            app.S0MinVoxNrEditField = uieditfield(app.S0Panel, 'numeric');
            app.S0ThresholdEditFieldLabel = uilabel(app.S0Panel);
            app.S0ThresholdEditFieldLabel.Text = 'S0 Threshold';
            app.S0ThresholdEditField = uieditfield(app.S0Panel, 'numeric');
            app.S0MaxVoxNrEditFieldLabel = uilabel(app.S0Panel);
            app.S0MaxVoxNrEditFieldLabel.Text = 'S0 Max. Vox. Nr.';
            app.S0MaxVoxNrEditField = uieditfield(app.S0Panel, 'numeric');
            app.DeconvolutionPanel = uipanel(app.DSCMappingAdvancedSettingsUIFigure);
            app.DeconvolutionPanel.Title = 'Deconvolution';
            app.SaveSVDResidualsCheckBox = uicheckbox(app.DeconvolutionPanel);
            app.SaveSVDResidualsCheckBox.Text = 'Save SVD Residuals';
            app.SavecSVDResidualsCheckBox = uicheckbox(app.DeconvolutionPanel);
            app.SavecSVDResidualsCheckBox.Text = 'Save cSVD Residuals';
            app.SaveoSVDResidualsCheckBox = uicheckbox(app.DeconvolutionPanel);
            app.SaveoSVDResidualsCheckBox.Text = 'Save oSVD Residuals';
            app.SVDThresholdEditFieldLabel = uilabel(app.DeconvolutionPanel);
            app.SVDThresholdEditFieldLabel.Text = 'SVD Threshold';
            app.SVDThresholdEditField = uieditfield(app.DeconvolutionPanel, 'numeric');
            app.cSVDThresholdEditFieldLabel = uilabel(app.DeconvolutionPanel);
            app.cSVDThresholdEditFieldLabel.Text = 'cSVD Threshold';
            app.cSVDThresholdEditField = uieditfield(app.DeconvolutionPanel, 'numeric');
            app.oSVDThresholdEditFieldLabel = uilabel(app.DeconvolutionPanel);
            app.oSVDThresholdEditFieldLabel.Text = 'oSVD Threshold';
            app.oSVDThresholdEditField = uieditfield(app.DeconvolutionPanel, 'numeric');
            app.oSVDCounterEditFieldLabel = uilabel(app.DeconvolutionPanel);
            app.oSVDCounterEditFieldLabel.Text = 'oSVD Counter';
            app.oSVDCounterEditField = uieditfield(app.DeconvolutionPanel, 'numeric');
            app.ProportionalityConstantsPanel = uipanel(app.DSCMappingAdvancedSettingsUIFigure);
            app.ProportionalityConstantsPanel.Title = 'Proportionality Constants';
            app.KvoiEditFieldLabel = uilabel(app.ProportionalityConstantsPanel);
            app.KvoiEditFieldLabel.Text = 'Kvoi';
            app.KvoiEditField = uieditfield(app.ProportionalityConstantsPanel, 'numeric');
            app.RhoEditFieldLabel = uilabel(app.ProportionalityConstantsPanel);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoEditField = uieditfield(app.ProportionalityConstantsPanel, 'numeric');
            app.KhEditFieldLabel = uilabel(app.ProportionalityConstantsPanel);
            app.KhEditFieldLabel.Text = 'Kh';
            app.KhEditField = uieditfield(app.ProportionalityConstantsPanel, 'numeric');
            app.ConcentrationPanel = uipanel(app.DSCMappingAdvancedSettingsUIFigure);
            app.ConcentrationPanel.Title = 'Concentration';
            app.ConcentrationCorrectionCheckBox = uicheckbox(app.ConcentrationPanel);
            app.ConcentrationCorrectionCheckBox.Text = 'Concentration Correction';
            app.bEditFieldLabel = uilabel(app.ConcentrationPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ConcentrationPanel, 'numeric');
            app.aEditFieldLabel = uilabel(app.ConcentrationPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ConcentrationPanel, 'numeric');
            app.rEditFieldLabel = uilabel(app.ConcentrationPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.ConcentrationPanel, 'numeric');
            app.ResetToDefaultButton = uibutton(app.DSCMappingAdvancedSettingsUIFigure, 'push');
            app.ResetToDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetToDefaultButtonPushed, true);
            app.ResetToDefaultButton.Text = 'Reset To Default';
            app.ApplySettingsButton = uibutton(app.DSCMappingAdvancedSettingsUIFigure, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsButtonPushed, true);
            app.ApplySettingsButton.Text = 'Apply Settings';
            % Show the figure after all components are created
            app.DSCMappingAdvancedSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSCSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSCMappingAdvancedSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSCMappingAdvancedSettingsUIFigure)
        end
    end
end