classdef OverlayPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OverlayPickerUIFigure         matlab.ui.Figure
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel           matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel_Viewer  matlab.ui.control.Label
        Dim5Spinner                   matlab.ui.control.Spinner
        Dim5Label                     matlab.ui.control.Label
        Dim4Spinner                   matlab.ui.control.Spinner
        Dim4SpinnerLabel              matlab.ui.control.Label
        SelectROIListBox              matlab.ui.control.ListBox
        SelectROIListBoxLabel         matlab.ui.control.Label
        OverlayStyleDropDown          matlab.ui.control.DropDown
        OverlayStyleDropDownLabel     matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        ExperimentDropDown            matlab.ui.control.DropDown
        ExperimentDropDownLabel       matlab.ui.control.Label
        OverlaySwitch                 matlab.ui.control.Switch
        SelectOptionLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        suMRak % Main suMRak interface
        ExperimentPropertyTable % Table containing imported Bruker experiments inherited from suMRak
        SavedTable % Table containing inherited suMRak-modified experiments
        Volume % Volume to be passed into suMRak's 3D viewer as an overlay
        VolumeDims % Dimension sizes of the chosen volume
        ViewerDims % Dimension sizes inherited from suMRak's 3D viewer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, DropdownItemsCombined, ExperimentTable, SavedTable, DataDims)
            
            % Input validation 
            % SavedTable is [] until something has been saved (e.g. after loading
            % a single NIfTI), so an empty value is accepted.
            assert((iscell(DropdownItemsCombined) || isstring(DropdownItemsCombined)) ...
                && ~isempty(DropdownItemsCombined), ...
                'OverlayPicker: DropdownItemsCombined must be a non-empty list of names.');
            assert(istable(ExperimentTable) || isempty(ExperimentTable), ...
                'OverlayPicker: ExperimentTable must be a MATLAB table.');
            assert(istable(SavedTable) || isempty(SavedTable), ...
                'OverlayPicker: SavedTable must be a MATLAB table.');
            assert(isnumeric(DataDims) && ~isempty(DataDims), ...
                'OverlayPicker: DataDims must be a non-empty numeric vector.');

            % Center on screen
            movegui(app.OverlayPickerUIFigure, 'center');

            % Store suMRak
            app.suMRak = caller;

            % Populate dropdown
            app.ExperimentDropDown.Items = DropdownItemsCombined;

            % Save Experiment and Saved tables to their respective
            % properties
            app.ExperimentPropertyTable = ExperimentTable;
            app.SavedTable = SavedTable;

            % Save dimension sizes of the experiment loaded in suMRak's 3D viewer
            app.ViewerDims = DataDims;

        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            
            value = app.ExperimentDropDown.Value;
            
            if value == "None"
                app.Dim4Spinner.Enable = "off";
                app.Dim5Spinner.Enable = "off";
                app.Dim4Spinner.Value = 1;
                app.Dim5Spinner.Value = 1;
                app.SelectROIListBox.Enable = "off";
                app.OverlayStyleDropDown.Enable = "off";
                app.ColormapDropDown.Enable = "off";
                app.AlphaEditField.Enable = "off";
                app.ThresholdEditField.Enable = "off";
                app.ReturnButton.Enable = "off";
                app.SelectROIListBox.Items = {};
                return
            end

            % Get selected sequence image data. Use the SavedTable (registered/
            % processed data) when it holds this experiment; otherwise fall back
            % to the raw ExperimentPropertyTable. Real load errors are reported.
            savedT  = app.SavedTable;
            inSaved = istable(savedT) && ~isempty(savedT) ...
                && ismember('Image', savedT.Properties.VariableNames) ...
                && ismember(value, savedT.Properties.RowNames);

            if inSaved
                try
                    app.Volume = cell2mat(savedT.Image(value));
                catch ME
                    uialert(app.OverlayPickerUIFigure, ...
                        ['Unexpected error loading volume: ' ME.message], 'Volume Load Error');
                    return
                end
                if app.OverlaySwitch.Value == "ROI"
                    roiIDs = {};
                    if ismember('ROI', savedT.Properties.VariableNames)
                        try
                            roiIDs = savedT.ROI{value,1}.ID;
                        catch ME
                            uialert(app.OverlayPickerUIFigure, ...
                                ['Unexpected error reading ROI data: ' ME.message], 'ROI Load Error');
                        end
                    end
                    if isempty(roiIDs)
                        app.SelectROIListBox.Items = {};
                        app.SelectROIListBox.Enable = 'off';
                    elseif roiIDs{1,1} == "None"
                        app.SelectROIListBox.Items = {};
                        app.SelectROIListBox.Enable = 'off';
                        return
                    else
                        app.SelectROIListBox.Items = roiIDs;
                        app.SelectROIListBox.Enable = 'on';
                    end
                end
            else
                try
                    app.Volume = cell2mat(app.ExperimentPropertyTable.(2)(value));
                catch ME
                    uialert(app.OverlayPickerUIFigure, ...
                        ['Unexpected error loading volume: ' ME.message], 'Volume Load Error');
                    return
                end
                app.SelectROIListBox.Items = {};
                app.SelectROIListBox.Enable = 'off';
            end

            app.VolumeDims = size(app.Volume);
            app.OverlayStyleDropDown.Enable = "on";
            app.ColormapDropDown.Enable = "on";
            app.AlphaEditField.Enable = "on";
            app.ThresholdEditField.Enable = "on";
            app.ReturnButton.Enable = "on";

            switch numel(app.VolumeDims)
                case 3
                    app.Dim4Spinner.Value = 1;
                    app.Dim5Spinner.Value = 1;
                    app.Dim4Spinner.Enable = "off";
                    app.Dim5Spinner.Enable = "off";
                case 4
                    app.Dim4Spinner.Value = 1;
                    app.Dim5Spinner.Value = 1;
                    app.Dim4Spinner.Enable = "on";
                    app.Dim5Spinner.Enable = "off";
                    app.Dim4Spinner.Limits = [1 app.VolumeDims(4)];
                case 5
                    app.Dim4Spinner.Value = 1;
                    app.Dim5Spinner.Value = 1;
                    app.Dim4Spinner.Enable = "on";
                    app.Dim5Spinner.Enable = "on";
                    app.Dim4Spinner.Limits = [1 app.VolumeDims(4)];
                    app.Dim5Spinner.Limits = [1 app.VolumeDims(5)];
            end
        end

        % Value changed function: OverlaySwitch
        function OverlaySwitchValueChanged(app, event)
            
            value   = app.OverlaySwitch.Value;
            expName = app.ExperimentDropDown.Value;

            % Is the selected experiment in the SavedTable (registered/processed data)?
            savedT  = app.SavedTable;
            inSaved = istable(savedT) && ~isempty(savedT) ...
                && ismember('Image', savedT.Properties.VariableNames) ...
                && ismember(expName, savedT.Properties.RowNames);

            switch value
                case 'Experiment'
                    app.SelectROIListBox.Items = {};
                    app.SelectROIListBox.Enable = 'off';
                    try
                        if inSaved
                            app.Volume = cell2mat(savedT.Image(expName));
                        else
                            app.Volume = cell2mat(app.ExperimentPropertyTable.(2)(expName));
                        end
                    catch ME
                        uialert(app.OverlayPickerUIFigure, ...
                            ['Unexpected error switching overlay source: ' ME.message], ...
                            'Overlay Source Error');
                        return
                    end
                case 'ROI'
                    roiIDs = {};
                    if inSaved && ismember('ROI', savedT.Properties.VariableNames)
                        try
                            roiIDs = savedT.ROI{expName,1}.ID;
                        catch ME
                            uialert(app.OverlayPickerUIFigure, ...
                                ['Unexpected error loading ROI list: ' ME.message], ...
                                'ROI List Error');
                        end
                    end
                    if isempty(roiIDs) || roiIDs{1,1} == "None"
                        app.SelectROIListBox.Items = {};
                        app.SelectROIListBox.Enable = 'off';
                    else
                        app.SelectROIListBox.Items = roiIDs;
                        app.SelectROIListBox.Enable = 'on';
                    end
            end
        end

        % Value changed function: SelectROIListBox
        function SelectROIListBoxValueChanged(app, event)
            value = app.SelectROIListBox.Value;
            
            ROIindex = find(strcmp(value,app.SelectROIListBox.Items));
            app.Volume = app.SavedTable.ROI{app.ExperimentDropDown.Value,1}.Mask(:,:,:,ROIindex);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            
            if numel(app.VolumeDims) ~= numel(app.ViewerDims)
                uialert(app.OverlayPickerUIFigure, ...
                    'Overlay dimensions need to match dimensions of 3D volume. Register your overlay data first or choose another overlay data.', ...
                    'Invalid overlay image data')
                return
            else
                if app.VolumeDims ~= app.ViewerDims
                    uialert(app.OverlayPickerUIFigure, ...
                        'Overlay dimensions need to match dimensions of 3D volume. Register your overlay data first or choose another overlay data.', ...
                        'Invalid overlay image data')
                    return
                end
            end

            app.Volume = (app.Volume - min(app.Volume(:))) / (max(app.Volume(:)) - min(app.Volume(:)));
            app.suMRak.ViewerParentObject.Children.OverlayData = app.Volume;
            
            switch app.OverlayStyleDropDown.Value
                case 'Label'
                    app.suMRak.ViewerParentObject.Children.OverlayRenderingStyle = "LabelOverlay";
                case 'Volume'
                    app.suMRak.ViewerParentObject.Children.OverlayRenderingStyle = "VolumeOverlay";
                case 'Gradient'
                    app.suMRak.ViewerParentObject.Children.OverlayRenderingStyle = "GradientOverlay";
            end

            switch app.ColormapDropDown.Value
                case "Greyscale"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = gray;
                case "Turbo"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = turbo;
                case "Hot"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = hot;
                case "Jet"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = jet;
                case "Parula"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = parula;
                case "Sky"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = sky;
                case "Cool"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = cool;
                case "Copper"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = copper;
                case "Bone"
                    app.suMRak.ViewerParentObject.Children.OverlayColormap = bone;
            end

            app.suMRak.ViewerParentObject.Children.OverlayAlphamap = app.AlphaEditField.Value;
            app.suMRak.ViewerParentObject.Children.OverlayThreshold = app.ThresholdEditField.Value;

            % Close suMRak progress bar and delete app
            app.suMRak.OverlayButton.Enable = 'on';
            close(app.suMRak.ProgressBar);
            delete(app);

        end

        % Close request function: OverlayPickerUIFigure
        function OverlayPickerUIFigureCloseRequest(app, event)
            
            % Close suMRak progress bar and delete app
            app.suMRak.OverlayButton.Enable = 'on';
            app.suMRak.OverlayButton.Value = 0;
            close(app.suMRak.ProgressBar);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OverlayPickerUIFigure = uifigure('Visible', 'off');
            app.OverlayPickerUIFigure.Name = 'Overlay Picker';
            app.OverlayPickerUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'icon.png');
            app.OverlayPickerUIFigure.CloseRequestFcn = createCallbackFcn(app, @OverlayPickerUIFigureCloseRequest, true);
            app.SelectOptionLabel = uilabel(app.OverlayPickerUIFigure);
            app.SelectOptionLabel.Text = 'Select Option';
            app.OverlaySwitch = uiswitch(app.OverlayPickerUIFigure, 'slider');
            app.OverlaySwitch.Items = {'Experiment', 'ROI'};
            app.OverlaySwitch.ValueChangedFcn = createCallbackFcn(app, @OverlaySwitchValueChanged, true);
            app.OverlaySwitch.Value = 'Experiment';
            app.ExperimentDropDownLabel = uilabel(app.OverlayPickerUIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.OverlayPickerUIFigure);
            app.ExperimentDropDown.Items = {'None'};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.ExperimentDropDown.Value = 'None';
            app.ReturnButton = uibutton(app.OverlayPickerUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Enable = 'off';
            app.ReturnButton.Text = 'Use Selected Volume';
            app.OverlayStyleDropDownLabel = uilabel(app.OverlayPickerUIFigure);
            app.OverlayStyleDropDownLabel.Text = 'Overlay Style';
            app.OverlayStyleDropDown = uidropdown(app.OverlayPickerUIFigure);
            app.OverlayStyleDropDown.Items = {'Label', 'Volume', 'Gradient'};
            app.OverlayStyleDropDown.Enable = 'off';
            app.OverlayStyleDropDown.Value = 'Label';
            app.SelectROIListBoxLabel = uilabel(app.OverlayPickerUIFigure);
            app.SelectROIListBoxLabel.Text = 'Select ROI';
            app.SelectROIListBox = uilistbox(app.OverlayPickerUIFigure);
            app.SelectROIListBox.Items = {};
            app.SelectROIListBox.ValueChangedFcn = createCallbackFcn(app, @SelectROIListBoxValueChanged, true);
            app.SelectROIListBox.Value = {};
            app.Dim4SpinnerLabel = uilabel(app.OverlayPickerUIFigure);
            app.Dim4SpinnerLabel.Enable = 'off';
            app.Dim4SpinnerLabel.Text = 'Dim - 4';
            app.Dim4Spinner = uispinner(app.OverlayPickerUIFigure);
            app.Dim4Spinner.Enable = 'off';
            app.Dim4Spinner.Value = 1;
            app.Dim5Label = uilabel(app.OverlayPickerUIFigure);
            app.Dim5Label.Enable = 'off';
            app.Dim5Label.Text = 'Dim - 5';
            app.Dim5Spinner = uispinner(app.OverlayPickerUIFigure);
            app.Dim5Spinner.Enable = 'off';
            app.Dim5Spinner.Value = 1;
            app.ColormapDropDownLabel_Viewer = uilabel(app.OverlayPickerUIFigure);
            app.ColormapDropDownLabel_Viewer.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.OverlayPickerUIFigure);
            app.ColormapDropDown.Items = {'Greyscale', 'Turbo', 'Hot', 'Jet', 'Parula', 'Sky', 'Cool', 'Copper', 'Bone'};
            app.ColormapDropDown.Enable = 'off';
            app.ColormapDropDown.Tooltip = {''};
            app.ColormapDropDown.Value = 'Greyscale';
            app.AlphaEditFieldLabel = uilabel(app.OverlayPickerUIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.OverlayPickerUIFigure, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.Enable = 'off';
            app.AlphaEditField.Value = 0.2;
            app.ThresholdEditFieldLabel = uilabel(app.OverlayPickerUIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.OverlayPickerUIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Enable = 'off';
            app.ThresholdEditField.Value = 0.001;
            % Show the figure after all components are created
            app.OverlayPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OverlayPicker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OverlayPickerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OverlayPickerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OverlayPickerUIFigure)
        end
    end
end