classdef suMRak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        suMRakSimpleUtilityMRiAnalysisKitUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        LoadSingleNIfTIButton           matlab.ui.control.Button
        ExportEnvironmentButton         matlab.ui.control.Button
        LoadsuMRakFolderButton          matlab.ui.control.Button
        LoadBrukerStudyButton           matlab.ui.control.Button
        CreatesuMRakFolderButton        matlab.ui.control.Button
        SaveDataButton_Preview          matlab.ui.control.Button
        ExportDataButton_Preview        matlab.ui.control.Button
        ColormapButtonGroup_Preview     matlab.ui.container.ButtonGroup
        TurboButton_Preview             matlab.ui.control.RadioButton
        GreyscaleButton_Preview         matlab.ui.control.RadioButton
        ArchiveLabel                    matlab.ui.control.Label
        WeightEditFieldLabel_kg         matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.EditField
        WeightEditFieldLabel            matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        SubjectTypeEditField            matlab.ui.control.EditField
        SubjectTypeEditFieldLabel       matlab.ui.control.Label
        StudyStartDateEditField         matlab.ui.control.EditField
        StudyStartDateEditFieldLabel    matlab.ui.control.Label
        StudyStartTimeEditField         matlab.ui.control.EditField
        StudyStartTimeEditFieldLabel    matlab.ui.control.Label
        SubjectAgeEditFieldLabel_Days   matlab.ui.control.Label
        SubjectAgeEditField             matlab.ui.control.EditField
        SubjectAgeEditFieldLabel        matlab.ui.control.Label
        StudyCommentEditField           matlab.ui.control.EditField
        StudyCommentEditFieldLabel      matlab.ui.control.Label
        SubjectCommentEditField         matlab.ui.control.EditField
        SubjectCommentEditFieldLabel    matlab.ui.control.Label
        StudyIDEditField                matlab.ui.control.EditField
        StudyIDEditFieldLabel           matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        ResetEnvironmentButton          matlab.ui.control.Button
        UITable_Preview                 matlab.ui.control.Table
        ArchiveEditField                matlab.ui.control.EditField
        LoadPvDatasetsFileButton        matlab.ui.control.Button
        ContrastSlider_Preview          matlab.ui.control.Slider
        ContrastSliderLabel_Preview     matlab.ui.control.Label
        BrightnessSlider_Preview        matlab.ui.control.Slider
        BrightnessSliderLabel_Preview   matlab.ui.control.Label
        SliceSlider_Preview             matlab.ui.control.Slider
        SliceLabel_Preview              matlab.ui.control.Label
        SliceSpinner_Preview            matlab.ui.control.Spinner
        Dim4Slider_Preview              matlab.ui.control.Slider
        Dim4Slider_PreviewLabel         matlab.ui.control.Label
        Dim5Slider_Preview              matlab.ui.control.Slider
        Dim5Slider_PreviewLabel         matlab.ui.control.Label
        PreviewDropDown                 matlab.ui.control.DropDown
        PreviewLabel                    matlab.ui.control.Label
        UIAxes_Preview                  matlab.ui.control.UIAxes
        SegmenterTab                    matlab.ui.container.Tab
        PerspectiveViewButton           matlab.ui.control.StateButton
        BrainSegmentationToolsPanel     matlab.ui.container.Panel
        VolumeSwitch                    matlab.ui.control.Switch
        ApplyMaskButton                 matlab.ui.control.Button
        ResetSliceButton                matlab.ui.control.Button
        ImageshownSwitch_Brain          matlab.ui.control.Switch
        ImageshownSwitchLabel           matlab.ui.control.Label
        LoadExternalBrainMaskButton     matlab.ui.control.Button
        AutoClusterButton               matlab.ui.control.Button
        InitialSelectionButton          matlab.ui.control.Button
        MorphologyLabel                 matlab.ui.control.Label
        CloseMaskButton                 matlab.ui.control.Button
        OpenMaskButton                  matlab.ui.control.Button
        DiskradiusSpinner               matlab.ui.control.Spinner
        DiskradiusSpinnerLabel          matlab.ui.control.Label
        HemisphereSegmentationToolsPanel  matlab.ui.container.Panel
        AutoCompleteHemispheresCheckBox  matlab.ui.control.CheckBox
        LoadExternalHemisphereMaskButton  matlab.ui.control.Button
        HemisphereButtonGroup           matlab.ui.container.ButtonGroup
        RightredButton                  matlab.ui.control.RadioButton
        LeftblueButton                  matlab.ui.control.RadioButton
        ResetHemispheresButton          matlab.ui.control.Button
        ROISegmentationToolsPanel       matlab.ui.container.Panel
        VolROISegmentationToolsButton   matlab.ui.control.Button
        LoadExternalROIPackButton       matlab.ui.control.Button
        ImageshownSwitch_ROI            matlab.ui.control.Switch
        ImageshownSwitchLabel_ROI       matlab.ui.control.Label
        ResetROISliceButton             matlab.ui.control.Button
        DeleteROIButton                 matlab.ui.control.Button
        AddROIButton                    matlab.ui.control.Button
        ROIListListBox                  matlab.ui.control.ListBox
        ROIListListBoxLabel             matlab.ui.control.Label
        SelectionToolsPanel             matlab.ui.container.Panel
        DeleteButton                    matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        FreeButton_Remove               matlab.ui.control.Button
        PolyButton_Remove               matlab.ui.control.Button
        PolyButton_Add                  matlab.ui.control.Button
        FreeButton_Add                  matlab.ui.control.Button
        CurrentSegmentationDropDown     matlab.ui.control.DropDown
        CurrentsegmentationLabel        matlab.ui.control.Label
        ContrastSlider_Segmenter        matlab.ui.control.Slider
        ColormapButtonGroup_Segmenter   matlab.ui.container.ButtonGroup
        TurboButton_Segmenter           matlab.ui.control.RadioButton
        GreyscaleButton_Segmenter       matlab.ui.control.RadioButton
        ContrastSliderLabel_Segmenter   matlab.ui.control.Label
        BrightnessSlider_Segmenter      matlab.ui.control.Slider
        BrightnessSliderLabel_Segmenter  matlab.ui.control.Label
        SliceSlider_Segmenter           matlab.ui.control.Slider
        SliceSliderLabel                matlab.ui.control.Label
        Dim5Spinner_Segmenter           matlab.ui.control.Spinner
        Dim5Spinner_SegmenterLabel      matlab.ui.control.Label
        Dim4Spinner_Segmenter           matlab.ui.control.Spinner
        Dim4Spinner_SegmenterLabel      matlab.ui.control.Label
        ExportDataButton_Segmenter      matlab.ui.control.Button
        SaveSegmentedDataButton         matlab.ui.control.Button
        SliceSpinner_Segmenter          matlab.ui.control.Spinner
        SegmentDropDown                 matlab.ui.control.DropDown
        SelectExperimentToSegmentLabel  matlab.ui.control.Label
        UIAxes_SegmenterHelperDown      matlab.ui.control.UIAxes
        UIAxes_SegmenterHelperUp        matlab.ui.control.UIAxes
        UIAxes_Segmenter                matlab.ui.control.UIAxes
        RegistrationTab                 matlab.ui.container.Tab
        ReferenceFixedViewDropDown      matlab.ui.control.DropDown
        ShowReferenceFixedCheckBox      matlab.ui.control.CheckBox
        StandardAtlasRegistrationPanel  matlab.ui.container.Panel
        OutputInterpolatorDropDown      matlab.ui.control.DropDown
        OutputInterpolatorDropDownLabel  matlab.ui.control.Label
        TransformsListBox               matlab.ui.control.ListBox
        TransformsLabel                 matlab.ui.control.Label
        RegistrationSliceLimitsTextArea  matlab.ui.control.TextArea
        RegistrationSliceLimitsTextAreaLabel  matlab.ui.control.Label
        SelectAtlasDropDown             matlab.ui.control.DropDown
        ImportReferenceAtlasButton      matlab.ui.control.Button
        SelectparameterDropDown         matlab.ui.control.DropDown
        SelectparameterLabel            matlab.ui.control.Label
        UseDifferentParameterMapCheckBox  matlab.ui.control.CheckBox
        SelectfixedDropDown             matlab.ui.control.DropDown
        SelectfixedLabel                matlab.ui.control.Label
        SelectmovingDropDown            matlab.ui.control.DropDown
        SelectmovingLabel               matlab.ui.control.Label
        RegistrationViewerButton        matlab.ui.control.Button
        ManualInstructionInputCheckBox  matlab.ui.control.CheckBox
        RegisterButton                  matlab.ui.control.Button
        TimeSeriesAlignmentPanel        matlab.ui.container.Panel
        AdditionalDimensionControlsPanel  matlab.ui.container.Panel
        Dim5Slider_TimeAlignmentControl  matlab.ui.control.Slider
        Dim5SliderLabel_TimeAlignmentControl  matlab.ui.control.Label
        Dim5Spinner_TimeAlignmentControl  matlab.ui.control.Spinner
        Dim4Slider_TimeAlignmentControl  matlab.ui.control.Slider
        Dim4SliderLabel_TimeAlignmentControl  matlab.ui.control.Label
        Dim4Spinner_TimeAlignmentControl  matlab.ui.control.Spinner
        ReferenceDataPointLabel         matlab.ui.control.Label
        Dim5Spinner_TimeAlignmentReference  matlab.ui.control.Spinner
        Dim5Spinner_TimeAlignmentReferenceLabel  matlab.ui.control.Label
        Dim4Spinner_TimeAlignmentReference  matlab.ui.control.Spinner
        Dim4Spinner_TimeAlignmentReferenceLabel  matlab.ui.control.Label
        AlignDataButton                 matlab.ui.control.Button
        SelectTimeAlignmentDropDown     matlab.ui.control.DropDown
        SelectTimeAlignmentLabel        matlab.ui.control.Label
        ChooseRegistrationTypeDropDown  matlab.ui.control.DropDown
        ChooseRegistrationTypeDropDownLabel  matlab.ui.control.Label
        SliceSlider_Registration        matlab.ui.control.Slider
        SliceSliderLabel_Registration   matlab.ui.control.Label
        SliceSpinner_Registration       matlab.ui.control.Spinner
        ColormapButtonGroup_Registration  matlab.ui.container.ButtonGroup
        TurboButton_Registration        matlab.ui.control.RadioButton
        GreyscaleButton_Registration    matlab.ui.control.RadioButton
        ExportDataButton_Registration   matlab.ui.control.Button
        SaveRegisteredDataButton        matlab.ui.control.Button
        UIAxes_Registration             matlab.ui.control.UIAxes
        ParameterMapsTab                matlab.ui.container.Tab
        FAIRpASLMappingoptionsPanel     matlab.ui.container.Panel
        CalculatepASLMapButton          matlab.ui.control.Button
        ExperimentorderButtonGroup      matlab.ui.container.ButtonGroup
        NonSelectiveFirstButton         matlab.ui.control.RadioButton
        SliceSelectiveFirstButton       matlab.ui.control.RadioButton
        BloodT1sEditField               matlab.ui.control.NumericEditField
        BloodT1sEditFieldLabel          matlab.ui.control.Label
        T1OptionsPanel                  matlab.ui.container.Panel
        TIvaluesText                    matlab.ui.control.TextArea
        TIvaluesLabel                   matlab.ui.control.Label
        CalculateT1MapButton            matlab.ui.control.Button
        TRvaluesText                    matlab.ui.control.TextArea
        TRvaluesLabel                   matlab.ui.control.Label
        T2OptionsPanel                  matlab.ui.container.Panel
        CalculateT2MapButton            matlab.ui.control.Button
        TEvaluesText                    matlab.ui.control.TextArea
        TEvaluesLabel                   matlab.ui.control.Label
        OptimizationOptionsPanel        matlab.ui.container.Panel
        ResettodefaultsButton           matlab.ui.control.Button
        MaxNrofIterationsEditField      matlab.ui.control.NumericEditField
        MaxNrofIterationsEditFieldLabel  matlab.ui.control.Label
        MaxNrofEvaluationsEditField     matlab.ui.control.NumericEditField
        MaxNrofEvaluationsEditFieldLabel  matlab.ui.control.Label
        dfStepSizeEditField             matlab.ui.control.NumericEditField
        dfStepSizeEditFieldLabel        matlab.ui.control.Label
        StepToleranceEditField          matlab.ui.control.NumericEditField
        StepToleranceEditFieldLabel     matlab.ui.control.Label
        OptimalityToleranceEditField    matlab.ui.control.NumericEditField
        OptimalityToleranceEditFieldLabel  matlab.ui.control.Label
        fxToleranceEditField            matlab.ui.control.NumericEditField
        fxToleranceEditFieldLabel       matlab.ui.control.Label
        ExportDataButton_Map            matlab.ui.control.Button
        SaveDataButton_Map              matlab.ui.control.Button
        SliceSlider_PostMap             matlab.ui.control.Slider
        SliceLabel_ParameterMaps_PostMap  matlab.ui.control.Label
        SliceSpinner_PostMap            matlab.ui.control.Spinner
        DSCMappingOptionsPanel          matlab.ui.container.Panel
        AIFExtractionSliceSpinner       matlab.ui.control.Spinner
        AIFExtractionSliceSpinnerLabel  matlab.ui.control.Label
        DSCMapDropDown                  matlab.ui.control.DropDown
        DSCMapDropDownLabel             matlab.ui.control.Label
        AdvancedSettingsButton          matlab.ui.control.Button
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        oSVDButton                      matlab.ui.control.RadioButton
        cSVDButton                      matlab.ui.control.RadioButton
        SVDButton                       matlab.ui.control.RadioButton
        CalculateDSCmapsButton          matlab.ui.control.Button
        ColormapButtonGroup_PostMap     matlab.ui.container.ButtonGroup
        TurboButton_PostMap             matlab.ui.control.RadioButton
        GreyscaleButton_PostMap         matlab.ui.control.RadioButton
        ContrastSlider_PostMap          matlab.ui.control.Slider
        ContrastSliderLabel_ParameterMaps  matlab.ui.control.Label
        BrightnessSlider_PostMap        matlab.ui.control.Slider
        BrightnessSliderLabel_ParameterMaps  matlab.ui.control.Label
        ChooseMapTypeDropDown           matlab.ui.control.DropDown
        ChooseMapTypeDropDownLabel      matlab.ui.control.Label
        Dim5Spinner_PreMap              matlab.ui.control.Spinner
        Dim5Spinner_Label_ParameterMaps  matlab.ui.control.Label
        Dim4Spinner_PreMap              matlab.ui.control.Spinner
        Dim4Spinner_Label_ParameterMaps  matlab.ui.control.Label
        SliceSlider_PreMap              matlab.ui.control.Slider
        SliceLabel_ParameterMaps_PreMap  matlab.ui.control.Label
        SelectPreMapDropDown            matlab.ui.control.DropDown
        SelectDSCvolumetricdataformapcalculationLabel  matlab.ui.control.Label
        SliceSpinner_PreMap             matlab.ui.control.Spinner
        UIAxes_PostMap                  matlab.ui.control.UIAxes
        UIAxes_PreMap                   matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        UIAxes_Results_Container        matlab.ui.container.Panel
        UIAxes_Results                  matlab.ui.control.UIAxes
        ExportDataButton_Results        matlab.ui.control.Button
        VolumeUnitLabel                 matlab.ui.control.Label
        AreaUnitLabel                   matlab.ui.control.Label
        VolumeLabel                     matlab.ui.control.Label
        AreaLabel                       matlab.ui.control.Label
        UnitsLabel                      matlab.ui.control.Label
        SelectResultsDropDown           matlab.ui.control.DropDown
        SelectExperimentForVolumetryLabel  matlab.ui.control.Label
        ROIPanel_Results                matlab.ui.container.Panel
        ApplyEdemaCorrectionCheckBox    matlab.ui.control.CheckBox
        IQRUpperEditField_ROI           matlab.ui.control.NumericEditField
        IQRLowerEditField_ROI           matlab.ui.control.NumericEditField
        IQREditFieldLabel_ROI           matlab.ui.control.Label
        MedianEditField_ROI             matlab.ui.control.NumericEditField
        MedianEditFieldLabel_ROI        matlab.ui.control.Label
        SelectROIDropDown               matlab.ui.control.DropDown
        SelectROIDropDownLabel          matlab.ui.control.Label
        MinEditField_ROI                matlab.ui.control.NumericEditField
        MinEditFieldLabel_ROI           matlab.ui.control.Label
        MaxEditField_ROI                matlab.ui.control.NumericEditField
        MaxEditFieldLabel_ROI           matlab.ui.control.Label
        MeanEditField_ROI               matlab.ui.control.NumericEditField
        MeanEditFieldLabel_ROI          matlab.ui.control.Label
        SDEditField_ROI                 matlab.ui.control.NumericEditField
        SDEditFieldLabel_ROI            matlab.ui.control.Label
        LabelPlusMinus_ROI              matlab.ui.control.Label
        VolumeEditField_ROI             matlab.ui.control.NumericEditField
        VolumeEditFieldLabel_ROI        matlab.ui.control.Label
        UITable_ResultsROI              matlab.ui.control.Table
        HemispherePanel                 matlab.ui.container.Panel
        IQRUpperEditField_Hemisphere    matlab.ui.control.NumericEditField
        IQRLowerEditField_Hemisphere    matlab.ui.control.NumericEditField
        IQREditFieldLabel_Hemisphere    matlab.ui.control.Label
        MedianEditField_Hemisphere      matlab.ui.control.NumericEditField
        MedianEditFieldLabel_Hemisphere  matlab.ui.control.Label
        SelectHemisphereDropDown        matlab.ui.control.DropDown
        SelectHemisphereDropDownLabel   matlab.ui.control.Label
        MinEditField_Hemisphere         matlab.ui.control.NumericEditField
        MinEditFieldLabel_Hemisphere    matlab.ui.control.Label
        MaxEditField_Hemisphere         matlab.ui.control.NumericEditField
        MaxEditFieldLabel_Hemisphere    matlab.ui.control.Label
        MeanEditField_Hemisphere        matlab.ui.control.NumericEditField
        MeanEditFieldLabel_Hemisphere   matlab.ui.control.Label
        SDEditField_Hemisphere          matlab.ui.control.NumericEditField
        SDEditFieldLabel_Hemisphere     matlab.ui.control.Label
        LabelPlusMinus_Hemisphere       matlab.ui.control.Label
        VolumeEditField_Hemisphere      matlab.ui.control.NumericEditField
        VolumeEditFieldLabel_Hemisphere  matlab.ui.control.Label
        UITable_ResultsHemisphere       matlab.ui.control.Table
        BrainPanel                      matlab.ui.container.Panel
        IQRUpperEditField_Brain         matlab.ui.control.NumericEditField
        MinEditField_Brain              matlab.ui.control.NumericEditField
        MinEditFieldLabel_Brain         matlab.ui.control.Label
        MaxEditField_Brain              matlab.ui.control.NumericEditField
        MaxEditFieldLabel_Brain         matlab.ui.control.Label
        MedianEditField_Brain           matlab.ui.control.NumericEditField
        MedianEditFieldLabel_Brain      matlab.ui.control.Label
        IQRLowerEditField_Brain         matlab.ui.control.NumericEditField
        IQREditFieldLabel_Brain         matlab.ui.control.Label
        LabelPlusMinus_Brain            matlab.ui.control.Label
        MeanEditField_Brain             matlab.ui.control.NumericEditField
        MeanEditFieldLabel_Brain        matlab.ui.control.Label
        SDEditField_Brain               matlab.ui.control.NumericEditField
        SDEditFieldLabel_Brain          matlab.ui.control.Label
        VolumeEditField_Brain           matlab.ui.control.NumericEditField
        VolumeEditFieldLabel_Brain      matlab.ui.control.Label
        UITable_ResultsBrain            matlab.ui.control.Table
        DViewerTab                      matlab.ui.container.Tab
        PlaneIntersectionSwitch         matlab.ui.control.Switch
        PlaneIntersectionSwitchLabel    matlab.ui.control.Label
        ExportSceneButton               matlab.ui.control.Button
        DataDimensionsPanel             matlab.ui.container.Panel
        ZEditField_Viewer               matlab.ui.control.NumericEditField
        ZEditFieldLabel_Viewer          matlab.ui.control.Label
        XEditField_Viewer               matlab.ui.control.NumericEditField
        XEditFieldLabel_Viewer          matlab.ui.control.Label
        YEditField_Viewer               matlab.ui.control.NumericEditField
        YEditFieldLabel_Viewer          matlab.ui.control.Label
        OverlayButton                   matlab.ui.control.StateButton
        DashLabel                       matlab.ui.control.Label
        SliceRangeHighSpinner_Viewer    matlab.ui.control.Spinner
        SliceRangeLowSpinner_Viewer     matlab.ui.control.Spinner
        DisplayedSliceRangeLabel        matlab.ui.control.Label
        Dim4Spinner_Viewer              matlab.ui.control.Spinner
        Dim4Spinner_ViewerLabel         matlab.ui.control.Label
        Dim5Spinner_Viewer              matlab.ui.control.Spinner
        Dim5Spinner_ViewerLabel         matlab.ui.control.Label
        ColormapImage_Viewer            matlab.ui.control.Image
        AlphamapDropDown_Viewer         matlab.ui.control.DropDown
        AlphamapDropDownLabel_Viewer    matlab.ui.control.Label
        ColormapDropDown_Viewer         matlab.ui.control.DropDown
        ColormapDropDownLabel_Viewer    matlab.ui.control.Label
        RenderingStyleDropDown          matlab.ui.control.DropDown
        RenderingStyleDropDownLabel     matlab.ui.control.Label
        Select3DViewerDropDown          matlab.ui.control.DropDown
        Select3DViewerLabel             matlab.ui.control.Label
        ViewerPanel                     matlab.ui.container.Panel
        UIAxes_AlphaMap                 matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        AboutLabel                      matlab.ui.control.Label
        AuthorsILabel                   matlab.ui.control.Label
        VersionLabel                    matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
        MATLAB                          matlab.ui.control.Image
        Bruker                          matlab.ui.control.Image
        Splash                          matlab.ui.control.Image
        HRZZ                            matlab.ui.control.Image
        GlowLab                         matlab.ui.control.Image
        UNIZG                           matlab.ui.control.Image
        ContextMenu_Preview             matlab.ui.container.ContextMenu
        RotateMenu_Preview              matlab.ui.container.Menu
        FlipVerticallyMenu_Preview      matlab.ui.container.Menu
        FlipHorizontallyMenu_Preview    matlab.ui.container.Menu
        ResetViewMenu_Preview           matlab.ui.container.Menu
        ContextMenu_Segmenter           matlab.ui.container.ContextMenu
        RotateMenu_Segmenter            matlab.ui.container.Menu
        FlipVerticallyMenu_Segmenter    matlab.ui.container.Menu
        FlipHorizontallyMenu_Segmenter  matlab.ui.container.Menu
        ResetViewMenu_Segmenter         matlab.ui.container.Menu
        PermuteDimensionsMenu           matlab.ui.container.Menu
        PermuteMenu_3_4                 matlab.ui.container.Menu
        PermuteMenu_3_5                 matlab.ui.container.Menu
        PermuteMenu_4_5                 matlab.ui.container.Menu
        ContextMenu_PreMap              matlab.ui.container.ContextMenu
        RotateMenu_PreMap               matlab.ui.container.Menu
        FlipVerticallyMenu_PreMap       matlab.ui.container.Menu
        FlipHorizontallyMenu_PreMap     matlab.ui.container.Menu
        ResetViewMenu_PreMap            matlab.ui.container.Menu
        PermuteDimensionsMenu_PreMap    matlab.ui.container.Menu
        PermuteMenu_3_4_PreMap          matlab.ui.container.Menu
        PermuteMenu_3_5_PreMap          matlab.ui.container.Menu
        PermuteMenu_4_5_PreMap          matlab.ui.container.Menu
        ContextMenuEdema                matlab.ui.container.ContextMenu
        HemisphereScalingFactorMenu     matlab.ui.container.Menu
        BelayevScalingFactorMenu        matlab.ui.container.Menu
        GerrietsCompressionFactorMenu   matlab.ui.container.Menu
        ContextMenu_PostMap             matlab.ui.container.ContextMenu
        RotateMenu_PostMap              matlab.ui.container.Menu
        FlipVerticallyMenu_PostMap      matlab.ui.container.Menu
        FlipHorizontallyMenu_PostMap    matlab.ui.container.Menu
        ResetViewMenu_PostMap           matlab.ui.container.Menu
        ContextMenu_Registration        matlab.ui.container.ContextMenu
        ResetViewMenu_Registration      matlab.ui.container.Menu
        ContextMenu_RegistrationInstructions  matlab.ui.container.ContextMenu
        ResetInstructionsMenu           matlab.ui.container.Menu
        TransformsContextMenu           matlab.ui.container.ContextMenu
        EditTransformMenu               matlab.ui.container.Menu
        AddTransformMenu                matlab.ui.container.Menu
        AddTranslationMenu              matlab.ui.container.Menu
        AddEuler3DMenu                  matlab.ui.container.Menu
        AddSimilarity3DMenu             matlab.ui.container.Menu
        AddAffineMenu                   matlab.ui.container.Menu
        AddBSplineMenu                  matlab.ui.container.Menu
        DuplicateTransformMenu          matlab.ui.container.Menu
        RemoveTransformMenu             matlab.ui.container.Menu
        MoveUpTransformMenu             matlab.ui.container.Menu
        MoveDownTransformMenu           matlab.ui.container.Menu
        ResetTransformMenu              matlab.ui.container.Menu
    end

    
    properties (Access = private)

        % Loading and preview tab properties
        PvDatasetsFile % Filepath of selected archive file
        StudyPath % Filepath of selected study directory
        TEvalues = zeros(1000, 1000); % 1000x1000 table storing TE values
        TRvalues = zeros(1000, 1000); % 1000x1000 table storing TR values
        TIvalues = zeros(1000, 1000); % 1000x1000 table storing TI values
        ExperimentPropertyTable = table(); % Table of loaded experiment properties
        SavedTable % Table for storing all saved segmented/registered data.

        PreviewImageData % Preview experiment image data matrix
        PreviewImage % Property for storing imshow of PreviewImageData
        ExpDimsPreview % Dimensions of preview experiment 

        % Segmenter tab
        DropDownItemsCombined = {'None'}; % Stored items featuring loaded and saved experiments
        % Brain segmentation
        CurrentSlice % Points to current slice matrix
        OriginalSegmenterImageData % Original experiment image data matrix
        WorkingSegmenterImageData % Working experiment image data matrix
        ExpDimsSegmenter % Dimensions of selected experiment for segmentation
        SegmenterImage % Property for storing imshow of segmenter image without a mask overlay or MaskedImage
        SegmenterOverlayGreen  % Image graphics handle for the green (brain-mask) overlay
        SegmenterOverlayBlue   % Image graphics handle for the blue (left-hemisphere) overlay
        SegmenterOverlayRed    % Image graphics handle for the red (right-hemisphere) overlay
        SegmenterOverlayYellow % Image graphics handle for the yellow (ROI) overlay
        FreeROI % Property for storing current manual ROI object
        ROI_OperationID = ""; % Manually set ROI operation identifier
        MaskedImage % Masked image of current slice
        BrainMask % Binary mask of current slice brain
        GreenScreen % Mask green screen of current slice
        SegmenterImageZX % Property for storing unwarped ZX image based on chosen Y
        SegmenterImageZY % Property for storing unwarped ZY image based in chosen X
        SegmenterDimTriplet % Voxel dimensions of the loaded image data matrix
        SegmenterHelperVolume % Stores 3D permuted volume for use with helper UIaxes
        SegmenterPosX % Position X of the current helperUp perspective
        SegmenterPosY % Position Y of the current helperDown perspective
        SegmenterHelperImageUp     % Image graphics handle for UIAxes_SegmenterHelperUp
        SegmenterHelperImageDown   % Image graphics handle for UIAxes_SegmenterHelperDown
        SegmenterHelperRectZ_Up    % Yellow rectangle (Z slice) on HelperUp axes
        SegmenterHelperRectX_Up    % Yellow rectangle (X position) on HelperUp axes
        SegmenterHelperRectZ_Down  % Yellow rectangle (Z slice) on HelperDown axes
        SegmenterHelperRectY_Down  % Yellow rectangle (Y position) on HelperDown axes
         
        % Hemisphere segmentation
        HemisphereMask % 4D Matrix with right and left hemisphere masks stored in 4th dimension for each slice - 1 = left, 2 = right
        BlueScreen % Hemisphere mask blue screen for left hemi
        RedScreen % Hemisphere mask red screen for right hemi

        % ROI segmentation
        YellowScreen % ROI mask yellow screen
        ROIVolumeSegmenterWindow % Volume segmenter window
        
        % Saved segmenter data
        SavedBrainMask % Saved brain mask data of current experiment
        DropDownItemsSavedOnly = {'None'}; % Stored items featuring only saved experiments

        % Volumetry tab
        VolumetryImageData % Property for storing image data of currently selected experiment in volumetry tab
        VolumetryBrainMask % Property for storing brain mask of currently selected experiment in volumetry tab
        VolumetryHemiMask % Property for storing hemisphere mask of currently selected experiment in volumetry tab
        VolumetryROI % Property for storing roi data of currently selected experiment in volumetry tab
        VolumetryDimX % Property for storing voxel dimension x of currently selected experiment in volumetry tab
        VolumetryDimY % Property for storing voxel dimension y of currently selected experiment in volumetry tab
        VolumetryThickness % Property for storing slice thickness of currently selected experiment in volumetry tab
        VolumetryGap  % Property for storing slice gap of currently selected experiment in volumetry tab

        % Registration tab
        ExpDimsRegistration % Dimensions of currently displayed registered data
        RegistrationViewerWindow % Registration data viewer window
        MovingNDims % Number of moving image data dimensions
        FixedNDims % Number of fixed image data dimensions
        ParameterNDims % Number of parameter image data dimensions
        AtlasImporterWindow % Atlas importer window
        ChosenAtlas % Property for storing selected reference atlas
        ResizedAtlasProperties % Stored atlas properties after resizing operations
        RegisteredImageData % Property for storing registered image data
        PreRegistrationFixedImage % Property for storing before-registration fixed image data
        RegisteredMask % Property for storing mask of fixed image data used in registration
        RegistrationImage % Image graphics handle on UIAxes_Registration (kept across refreshes for CData updates)
        
        % Parameter Maps tab
        DSCSettingsWindow % DSC Settings dialogue window
        MTTData % MTT data of a DSC sequence
        CBFData % CBF data of a DSC sequence
        CBVData % CBV data of a DSC sequence
        CBVLCData % CBV_LC data of a DSC sequence
        PreMapImage % Property for storing imshow of currently displayed image without mask overlay
        PreMapImageData % Image data of currently displayed sequence for mapping
        PostMapWorkMask % Working mask of currently displayed ASL sequence
        PostMapImageData % Image data of currently displayed parameter map
        PostMapImage % Property for storing imshow of currently displayed parameter map without mask overlay
        ExpDimsPreMap % Dimensions of currently displayed sequence for mapping
        ExpDimsPostMap % Dimensions of currently displayed parameter map

        % 3D Viewer Tab
        ViewerImageData % Property for storing currently matrix of currently selected experiment in 3D Viewer
        CurrentVolume % Property for storing chosen 3D volume for viewer
        ViewerImageDataDims % Dimension sizes of the currently loaded experiment
        ViewerDimTriplet % Property for storing dimension triplet for 
        OverlayPickerWindow % Overlay picker window
        AlphamapPoly % Interactive PolyLine for Viewer alphamap control

    end
    
    properties (Access = public)
        % General properties
        WorkingFolder = strcat(tempdir, 'suMRak'); % Filepath to working folder
        ExportFolderPath % Filepath of created export folder
        ProgressBar % Property for storing progress dialogues when opening new windows

        % ROI segmentation properties 
        ROIIdentifiers = {}; % Matrix containing added ROI Names
        ROIMask = [];% 4D Matrix containing added ROI masks for each slice

        % Registration properties
        StageSettings  % 1xN struct array of SimpleITK stage settings
        AtlasCollection = struct(); % Struct containing all imported reference atlases

        % DSC Parameter map properties
        DSCOptions = DSC_mri_getOptions; % DSC map calculation options

        % 3D Viewer Tab
        ViewerParentObject % Property for storing viewer3d object
    end
    
    properties (Constant)
        Version = '1.1.0'  % Application version — bump on release

        % Canonical column names for ExperimentPropertyTable.
        ExpTableVars = ["Experiment ID", "Image data", "TE1", "TR1", ...
                        "Voxel dimension X", "Voxel dimension Y", ...
                        "Slice Thickness", "Slice Gap", ...
                        "Dimension Units", "Rotation Matrix"];
    end
    
    methods (Static)

        % Returns an onCleanup handle that closes `dlg` when it goes out of scope.
        function guard = guardDialog(dlg)
            guard = onCleanup(@() closeIfValid(dlg));
            function closeIfValid(d)
                try
                    if isvalid(d), close(d); end
                catch
                    % Dialog already gone; nothing to do.
                end
            end
        end

        % Image-update helper using CData. Any ambiguity
        % falls through to the safe imshow-rebuild path.
        function imHandle = showImage(imHandle, ax, data, useTurbo, displayRange)
            arguments
                imHandle
                ax {mustBeA(ax, ["matlab.ui.control.UIAxes", "matlab.graphics.axis.Axes"])}
                data
                useTurbo (1,1) logical
                displayRange (1,2) double = [0 1]
            end

            canReuse = false;
            if ~isempty(imHandle) && isa(imHandle, 'matlab.graphics.primitive.Image')
                try
                    canReuse = isvalid(imHandle) && isequal(imHandle.Parent, ax) ...
                    && isequal(size(imHandle.CData), size(data)); 
                catch
                    canReuse = false;
                end
            end

            if canReuse
                try
                    imHandle.CData = data;
                    if useTurbo
                        ax.Colormap = turbo;
                    else
                        ax.Colormap = gray;
                    end
                    return
                catch
                    % Fall through to rebuild.
                end
            end

            imHandle = imshow(data, displayRange, 'Parent', ax);
            if useTurbo
                ax.Colormap = turbo;
            else
                ax.Colormap = gray;
            end
        end

        % Overlay-layer helper used by RefreshImageSegmenter.
        function imHandle = showOverlay(imHandle, ax, screenData, alphaData)
            arguments
                imHandle
                ax {mustBeA(ax, ["matlab.ui.control.UIAxes", "matlab.graphics.axis.Axes"])}
                screenData
                alphaData
            end

            if isempty(imHandle) || ~isgraphics(imHandle) || ~isvalid(imHandle) ...
                    || imHandle.Parent ~= ax
                hold(ax, "on");
                imHandle = imshow(screenData, 'Parent', ax);
                hold(ax, "off");
            end
            imHandle.AlphaData = alphaData;
        end
    
        function stages = defaultStageSettings(regType)
            arguments
                regType (1,1) string = "Standard"
            end
            if regType == "Reference Atlas", preset = "Atlas"; else, preset = "Standard"; end
            stages = [ ...
                suMRak.defaultStageOfType("Euler3D", preset), ...
                suMRak.defaultStageOfType("Affine",  preset), ...
                suMRak.defaultStageOfType("BSpline", preset)];
        end

        function s = defaultStageOfType(type, preset)
        %DEFAULTSTAGEOFTYPE Default settings for a single SimpleITK registration stage.
            arguments
                type (1,1) string {mustBeMember(type, ...
                    ["Translation","Euler3D","Similarity3D","Affine","BSpline"])}
                preset (1,1) string {mustBeMember(preset, ["Standard","Atlas"])} = "Standard"
            end
        
            s = struct( ...
                'Type',                char(type), ...
                'Enabled',             true, ...
                'Metric',              'MattesMI', ...
                'MetricBins',          50, ...
                'SamplingPct',         0.10, ...
                'SamplingStrategy',    'Random', ...
                'Optimizer',           'GradientDescent', ...
                'LearningRate',        1.0, ...
                'NumIterations',       100, ...
                'ConvergenceTol',      1e-6, ...
                'ShrinkFactors',       [4 2 1], ...
                'SmoothingSigmas',     [2 1 0], ...
                'Interpolator',        'Linear', ...
                'BSplineMeshSize',     [8 8 8], ...
                'BSplineOrder',        3, ...
                'BSplineScaleFactors', [1 1 1], ...   % coarse->fine grid; numel must == numel(ShrinkFactors)
                'MinJacobian',         0.1, ...          % reject BSpline if min Jacobian <= this
                'InPlaneOnly',         true);   % freeze out-of-plane rot/shear for co-planar acquisitions
        
            switch char(type)
                case 'Translation'
                    s.NumIterations = 50;
                case 'BSpline'
                    s.LearningRate  = 0.1;
                    s.NumIterations = 50;
            end
        
            switch preset
                case "Standard"     % intra-subject: near-aligned, only light/local warp needed
                    switch char(type)
                        case {'Euler3D','Affine'}
                            s.SamplingPct = 0.15;  s.NumIterations = 200;
                            s.Optimizer       = 'GradientDescentLineSearch';
                            s.ShrinkFactors   = [2 1];
                            s.SmoothingSigmas = [1 0];
                        case 'BSpline'
                            s.Enabled = false;   % intra-subject is near-aligned; enable only to correct real distortion
                            s.SamplingPct = 0.25;  s.NumIterations = 100;
                            s.ShrinkFactors = [2 1];  s.SmoothingSigmas = [1 0];
                            s.BSplineMeshSize = [6 6 4];  s.BSplineScaleFactors = [1 2];
                            s.MinJacobian = 0.1;
                    end
                case "Atlas"        % subject<->atlas: large scale/shape/contrast gap, partial FOV
                    switch char(type)
                        case {'Euler3D','Affine'}
                            s.SamplingPct = 0.25;  s.NumIterations = 400;  s.SmoothingSigmas = [3 1.5 0];
                            s.InPlaneOnly = false;   % subject<->atlas needs full 3D orientation
                        case 'BSpline'
                            s.SamplingPct = 0.30;  s.NumIterations = 150;
                            s.ShrinkFactors = [4 2 1];  s.SmoothingSigmas = [2 1 0];
                            s.BSplineMeshSize = [4 4 3];  s.BSplineScaleFactors = [1 2 2];
                            s.MinJacobian = 0.2;
                    end
            end
        end

        function types = availableStageTypes()
            types = ["Translation","Euler3D","Similarity3D","Affine","BSpline"];
        end

        function code = buildRegistrationScript()
        %BUILDREGISTRATIONSCRIPT 3-stage SimpleITK pipeline as a Python source array.
            code = [ ...
                "import SimpleITK as sitk", ...
                "import numpy as np", ...
                "", ...
                "stage_warnings = []", ...
                "fixIm = np.ascontiguousarray(np.transpose(fixIm, (2, 0, 1)).astype(np.float32))", ...
                "movIm = np.ascontiguousarray(np.transpose(movIm, (2, 0, 1)).astype(np.float32))", ...
                "fixed  = sitk.GetImageFromArray(fixIm)", ...
                "moving = sitk.GetImageFromArray(movIm)", ...
                "fixed.SetSpacing([float(s) for s in fixSpacing])", ...
                "moving.SetSpacing([float(s) for s in movSpacing])", ...
                "def center_at_origin(img):", ...
                "    img.SetOrigin([-(sz - 1) * sp / 2.0 for sz, sp in zip(img.GetSize(), img.GetSpacing())])", ...
                "center_at_origin(fixed)", ...
                "center_at_origin(moving)", ...
                "", ...
                "def make_metric(R, cfg):", ...
                "    m = cfg['Metric']", ...
                "    if m == 'MattesMI':", ...
                "        R.SetMetricAsMattesMutualInformation(int(cfg['MetricBins']))", ...
                "    elif m == 'MeanSquares':", ...
                "        R.SetMetricAsMeanSquares()", ...
                "    elif m == 'Correlation':", ...
                "        R.SetMetricAsCorrelation()", ...
                "    strat = {'Random': R.RANDOM, 'Regular': R.REGULAR, 'None': R.NONE}[cfg['SamplingStrategy']]", ...
                "    R.SetMetricSamplingStrategy(strat)", ...
                "    if cfg['SamplingPct'] > 0:", ...
                "        R.SetMetricSamplingPercentage(float(cfg['SamplingPct']), seed=42)", ...
                "", ...
                "FOREGROUND_THRESHOLD = 0.0            # intensity > this counts as foreground", ...
                "BSPLINE_MASK_DILATION = [5, 5, 1]     # dilation radius in voxels per [x, y, z(slice)] axis", ...
                "def foreground_mask(img):", ...
                "    arr = sitk.GetArrayFromImage(img)", ...
                "    mask = sitk.GetImageFromArray((arr > FOREGROUND_THRESHOLD).astype(np.uint8))", ...
                "    mask.CopyInformation(img)", ...
                "    return mask", ...
                "def dilate_mask(mask):", ...
                "    return sitk.BinaryDilate(mask, [int(r) for r in BSPLINE_MASK_DILATION], sitk.sitkBall)", ...
                "fixedMask         = foreground_mask(fixed)", ...
                "movingMask        = foreground_mask(moving)", ...
                "fixedMaskDilated  = dilate_mask(fixedMask)", ...
                "movingMaskDilated = dilate_mask(movingMask)", ...
                "", ...
                "def make_optimizer(R, cfg):", ...
                "    o = cfg['Optimizer']", ...
                "    if o == 'GradientDescent':", ...
                "        R.SetOptimizerAsGradientDescent(", ...
                "            learningRate=float(cfg['LearningRate']),", ...
                "            numberOfIterations=int(cfg['NumIterations']),", ...
                "            convergenceMinimumValue=float(cfg['ConvergenceTol']),", ...
                "            convergenceWindowSize=10)", ...
                "    elif o == 'GradientDescentLineSearch':", ...
                "        R.SetOptimizerAsGradientDescentLineSearch(", ...
                "            learningRate=float(cfg['LearningRate']),", ...
                "            numberOfIterations=int(cfg['NumIterations']),", ...
                "            convergenceMinimumValue=float(cfg['ConvergenceTol']),", ...
                "            convergenceWindowSize=10)", ...
                "    elif o == 'LBFGSB':", ...
                "        R.SetOptimizerAsLBFGSB(", ...
                "            gradientConvergenceTolerance=float(cfg['ConvergenceTol']),", ...
                "            numberOfIterations=int(cfg['NumIterations']))", ...
                "    elif o == 'Powell':", ...
                "        R.SetOptimizerAsPowell(numberOfIterations=int(cfg['NumIterations']))", ...
                "    R.SetOptimizerScalesFromPhysicalShift()", ...
                "", ...
                "def make_interpolator(R, cfg):", ...
                "    R.SetInterpolator({", ...
                "        'Linear':          sitk.sitkLinear,", ...
                "        'NearestNeighbor': sitk.sitkNearestNeighbor,", ...
                "        'BSpline':         sitk.sitkBSpline}[cfg['Interpolator']])", ...
                "", ...
                "def init_transform(stage_type, fixed, moving):", ...
                "    if stage_type == 'Translation':", ...
                "        return sitk.TranslationTransform(fixed.GetDimension())", ...
                "    if stage_type == 'Euler3D':", ...
                "        return sitk.CenteredTransformInitializer(", ...
                "            fixed, moving, sitk.Euler3DTransform(),", ...
                "            sitk.CenteredTransformInitializerFilter.MOMENTS)", ...
                "    if stage_type == 'Similarity3D':", ...
                "        return sitk.CenteredTransformInitializer(", ...
                "            fixed, moving, sitk.Similarity3DTransform(),", ...
                "            sitk.CenteredTransformInitializerFilter.MOMENTS)", ...
                "    if stage_type == 'Affine':", ...
                "        return sitk.AffineTransform(fixed.GetDimension())", ...
                "    if stage_type == 'BSpline':", ...
                "        return None", ...
                "    raise ValueError(stage_type)", ...
                "", ...
                "def min_jacobian(reference, transform):", ...
                "    # smallest Jacobian determinant of the deformation; <= 0 means folding", ...
                "    f = sitk.TransformToDisplacementFieldFilter()", ...
                "    f.SetReferenceImage(reference)", ...
                "    jac = sitk.DisplacementFieldJacobianDeterminant(f.Execute(transform))", ...
                "    return float(sitk.GetArrayViewFromImage(jac).min())", ...
                "", ...
                "def run_stage(cfg, fixed, moving, prior):", ...
                "    if not bool(cfg['Enabled']):", ...
                "        return prior", ...
                "    R = sitk.ImageRegistrationMethod()", ...
                "    make_metric(R, cfg)", ...
                "    make_optimizer(R, cfg)", ...
                "    make_interpolator(R, cfg)", ...
                "    if cfg['Type'] == 'BSpline':", ...
                "        R.SetMetricFixedMask(fixedMaskDilated)", ...
                "        R.SetMetricMovingMask(movingMaskDilated)", ...
                "    R.SetShrinkFactorsPerLevel([int(x) for x in cfg['ShrinkFactors']])", ...
                "    R.SetSmoothingSigmasPerLevel([float(x) for x in cfg['SmoothingSigmas']])", ...
                "    R.SmoothingSigmasAreSpecifiedInPhysicalUnitsOn()", ...
                "    if cfg['Type'] == 'BSpline':", ...
                "        mesh = [int(x) for x in cfg['BSplineMeshSize']]", ...
                "        tx = sitk.BSplineTransformInitializer(fixed, mesh, int(cfg['BSplineOrder']))", ...
                "        scale = [int(x) for x in cfg['BSplineScaleFactors']]", ...
                "        if len(scale) != len(cfg['ShrinkFactors']):", ...
                "            scale = [1] * len(cfg['ShrinkFactors'])", ...
                "        R.SetInitialTransformAsBSpline(tx, inPlace=True, scaleFactors=scale)", ...
                "    else:", ...
                "        tx = init_transform(cfg['Type'], fixed, moving)", ...
                "        R.SetInitialTransform(tx, inPlace=False)", ...
                "        if bool(cfg['InPlaneOnly']):", ...
                "            w = {'Euler3D': [0, 0, 1, 1, 1, 1],", ...
                "                 'Similarity3D': [0, 0, 1, 1, 1, 1, 1],", ...
                "                 'Affine':  [1, 1, 0, 1, 1, 0, 0, 0, 0, 1, 1, 1]}.get(cfg['Type'])", ...
                "            if w is not None:", ...
                "                R.SetOptimizerWeights(w)", ...
                "    if prior is not None:", ...
                "        R.SetMovingInitialTransform(prior)", ...
                "    learned = R.Execute(fixed, moving)", ...
                "    if cfg['Type'] == 'BSpline':", ...
                "        mj = min_jacobian(fixed, learned)", ...
                "        if mj <= float(cfg['MinJacobian']):", ...
                "            stage_warnings.append('BSpline stage skipped: deformation folded (min Jacobian %.3f <= %.2f).' % (mj, float(cfg['MinJacobian'])))", ...
                "            return prior", ...
                "    composite = sitk.CompositeTransform(fixed.GetDimension())", ...
                "    if prior is not None:", ...
                "        composite.AddTransform(prior)", ...
                "    composite.AddTransform(learned)", ...
                "    return composite", ...
                "", ...
                "prior = None", ...
                "for cfg in stagesList:", ...
                "    prior = run_stage(cfg, fixed, moving, prior)", ...
                "", ...
                "if prior is None:", ...
                "    final = sitk.Transform(fixed.GetDimension(), sitk.sitkIdentity)", ...
                "else:", ...
                "    final = prior", ...
                "OUT_INTERP = {'Linear':          sitk.sitkLinear,", ...
                "              'NearestNeighbor': sitk.sitkNearestNeighbor,", ...
                "              'BSpline':         sitk.sitkBSpline}[str(outInterp)]", ...
                "resampled = sitk.Resample(moving, fixed, final, OUT_INTERP, 0.0, moving.GetPixelID())", ...
                "resultArray = sitk.GetArrayFromImage(resampled)", ...
                "resultArray = np.ascontiguousarray(np.transpose(resultArray, (1, 2, 0)))" ...
                ];
        end

        function v = getfield_or(s, name, default)
            if isstruct(s) && isfield(s, name)
                v = s.(name);
            elseif istable(s) && ismember(name, s.Properties.VariableNames)
                v = s.(name);
                if iscell(v), v = v{1}; end
            else
                v = default;
            end
        end

        function spacing = voxelSpacing(props)
        %   Convert MR volume metadata to a SimpleITK spacing vector.
        
        %   z-spacing = SliceThickness + SliceGap 
        
            sx = double(suMRak.getfield_or(props, 'VoxDimX',        1.0));
            sy = double(suMRak.getfield_or(props, 'VoxDimY',        1.0));
            th = double(suMRak.getfield_or(props, 'SliceThickness', 1.0));
            gp = double(suMRak.getfield_or(props, 'SliceGap',       0.0));
        
            if any(~isfinite([sx sy th gp])) || sx <= 0 || sy <= 0 || th <= 0
                warning('suMRak:voxelSpacing', ...
                    'Invalid spacing metadata (sx=%g sy=%g th=%g gp=%g); falling back to [1 1 1].', ...
                    sx, sy, th, gp);
                spacing = [1 1 1];
                return
            end
            spacing = [sx, sy, th + max(gp, 0)];
        end

        % Convert a StageSettings struct array into the Python list of dicts
        % that buildRegistrationScript expects as stagesList.
        function stages_py = stagesToPy(stages)
            stages_py = py.list();
            for k = 1:numel(stages)
                s = stages(k);
                d = py.dict(pyargs( ...
                    'Type',                s.Type, ...
                    'Enabled',             s.Enabled, ...
                    'Metric',              s.Metric, ...
                    'MetricBins',          s.MetricBins, ...
                    'SamplingPct',         s.SamplingPct, ...
                    'SamplingStrategy',    s.SamplingStrategy, ...
                    'Optimizer',           s.Optimizer, ...
                    'LearningRate',        s.LearningRate, ...
                    'NumIterations',       s.NumIterations, ...
                    'ConvergenceTol',      s.ConvergenceTol, ...
                    'ShrinkFactors',       py.list(num2cell(int32(s.ShrinkFactors))), ...
                    'SmoothingSigmas',     py.list(num2cell(double(s.SmoothingSigmas))), ...
                    'Interpolator',        s.Interpolator, ...
                    'BSplineMeshSize',     py.list(num2cell(int32(s.BSplineMeshSize))), ...
                    'BSplineOrder',        s.BSplineOrder, ...
                    'BSplineScaleFactors', py.list(num2cell(int32(s.BSplineScaleFactors))), ...
                    'MinJacobian',         s.MinJacobian, ...
                    'InPlaneOnly',         suMRak.getfield_or(s, 'InPlaneOnly', true)));
                stages_py.append(d);
            end
        end

        % Parse a single registration notation segment
        % Returns dim3 as a double (the slice index); dim4 and dim5 as char tokens
        function [dim3, dim4, dim5] = parseSegment(instr, prefix)
            pat = [prefix '\((\d+),([^,)]+),([^,)]+)\)'];
            tok = regexp(instr, pat, 'tokens', 'once');
            if isempty(tok)
                dim3 = []; dim4 = ''; dim5 = '';
                return
            end
            dim3 = str2double(tok{1});
            dim4 = tok{2};
            dim5 = tok{3};
        end

    end
    
    methods (Access = private)

        % Enables/disables all six "Export Data" buttons based on
        % whether the relevant tab actually has something exportable.
        % Replaces two duplicated 18-line blocks elsewhere in the code.
        function refreshExportButtonState(app)
            setEnable = @(btn, cond) set(btn, 'Enable', ...
                matlab.lang.OnOffSwitchState(logical(cond)));

            setEnable(app.ExportDataButton_Preview,      app.PreviewDropDown.Value        ~= "None");
            setEnable(app.ExportDataButton_Segmenter,    app.SegmentDropDown.Value        ~= "None");
            setEnable(app.ExportDataButton_Results,      app.SelectResultsDropDown.Value  ~= "None");
            setEnable(app.ExportDataButton_Registration, ~isempty(app.RegisteredImageData));
            setEnable(app.ExportDataButton_Map,          ~isempty(app.PostMapImageData));
            setEnable(app.ExportSceneButton,             app.Select3DViewerDropDown.Value ~= "None");
        end

        % Look up sequence parameters (TE, TR, voxel sizes, slice
        % thickness/gap, units, rotation matrix) for an experiment by
        % dropdown value. Prefers SavedTable, falls back to
        % ExperimentPropertyTable. Returns a struct with safe defaults
        % if the experiment isn't in either table.
        function params = LookupExperimentParams(app, expName)
            params = struct('OrigIndex', [], 'TE', 0, 'TR', 0, ...
                            'VoxDimX', 0, 'VoxDimY', 0, ...
                            'SliceThickness', 0, 'SliceGap', 0, ...
                            'Units', "", 'RotMat', {{eye(3)}}, ...
                            'Source', "none");

            if ~isempty(app.SavedTable) && ...
               ismember(expName, app.SavedTable.Properties.RowNames)
                S = app.SavedTable;
                params.OrigIndex      = S.OrigIndex(expName);
                params.TE             = S.TE(expName);
                params.TR             = S.TR(expName);
                params.VoxDimX        = S.VoxDimX(expName);
                params.VoxDimY        = S.VoxDimY(expName);
                params.SliceThickness = S.SliceThickness(expName);
                params.SliceGap       = S.SliceGap(expName);
                params.Units          = S.Units(expName);
                params.RotMat         = S.RotMat(expName);
                params.Source         = "saved";
                return
            end

            T = app.ExperimentPropertyTable;
            if ~isempty(T) && ismember(expName, T.Properties.RowNames)
                params.OrigIndex      = find(strcmp(T{:, "Experiment ID"}, expName));
                params.TE             = T.("TE1")(expName);
                params.TR             = T.("TR1")(expName);
                params.VoxDimX        = T.("Voxel dimension X")(expName);
                params.VoxDimY        = T.("Voxel dimension Y")(expName);
                params.SliceThickness = T.("Slice Thickness")(expName);
                params.SliceGap       = T.("Slice Gap")(expName);
                params.Units          = T.("Dimension Units")(expName);
                params.RotMat         = T.("Rotation Matrix")(expName);
                params.Source         = "original";
                return
            end

            uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                sprintf(['Experiment "%s" not found in SavedTable or ', ...
                         'ExperimentPropertyTable. Saved row will carry ', ...
                         'zero-valued sequence parameters.'], expName), ...
                'Experiment Parameters Not Found');
        end

        % Deletes any of the four segmenter overlay image handles whose
        % property name is NOT in keepFields. Called at the top of each
        % branch of RefreshImageSegmenter so stale overlays from a
        % previous branch don't linger on the axes.
        function deleteSegmenterOverlaysExcept(app, keepFields)
            allFields = ["SegmenterOverlayGreen", "SegmenterOverlayBlue", ...
                         "SegmenterOverlayRed", "SegmenterOverlayYellow"];
            keepFields = string(keepFields);
            for f = allFields
                if any(keepFields == f), continue, end
                h = app.(f);
                if ~isempty(h) && isgraphics(h)
                    delete(h);
                end
                app.(f) = [];
            end
        end

        % Preview UIAxes image updating
        function RefreshImagePreview(app)
            app.ExpDimsPreview = size(app.PreviewImageData);
            switch numel(app.ExpDimsPreview)
                case 3
                    app.CurrentSlice = app.PreviewImageData(:,:, round(app.SliceSlider_Preview.Value));
                case 4
                    app.CurrentSlice = app.PreviewImageData(:,:, round(app.SliceSlider_Preview.Value), round(app.Dim4Slider_Preview.Value));
                case 5
                    app.CurrentSlice = app.PreviewImageData(:,:, round(app.SliceSlider_Preview.Value), round(app.Dim4Slider_Preview.Value), round(app.Dim5Slider_Preview.Value));
                otherwise
                    return
            end
            app.CurrentSlice = RefreshImageBC_mex(app.CurrentSlice, ...
                app.ContrastSlider_Preview.Value, app.BrightnessSlider_Preview.Value);
            app.PreviewImage = suMRak.showImage(app.PreviewImage, ...
                app.UIAxes_Preview, app.CurrentSlice, ...
                app.TurboButton_Preview.Value);
            app.PreviewImage.ContextMenu = app.ContextMenu_Preview;
        end

        % Registration UIAxes image updating
        function RefreshImageRegistration(app)

            % Get chosen image based on image shown switch
            app.ExpDimsRegistration = size(app.RegisteredImageData);
            chosen_Image = app.RegisteredImageData;
            chosen_Fixed = app.PreRegistrationFixedImage;

            switch app.ShowReferenceFixedCheckBox.Value
                case true
                    switch numel(app.ExpDimsRegistration)
                        case 2
                            app.CurrentSlice = chosen_Image(:,:);
                            CurrentFixed = chosen_Fixed(:,:);
                        case 3
                            app.CurrentSlice = chosen_Image(:,:, app.SliceSpinner_Registration.Value);
                            CurrentFixed = chosen_Fixed(:,:, app.SliceSpinner_Registration.Value);
                        case 4
                            app.CurrentSlice = chosen_Image(:,:, app.SliceSpinner_Registration.Value, app.Dim4Spinner_TimeAlignmentControl.Value);
                            CurrentFixed = chosen_Fixed(:,:, app.SliceSpinner_Registration.Value, app.Dim4Spinner_TimeAlignmentControl.Value);
                        case 5
                            app.CurrentSlice = chosen_Image(:,:, app.SliceSpinner_Registration.Value, app.Dim4Spinner_TimeAlignmentControl.Value, app.Dim5Spinner_TimeAlignmentControl.Value);
                            CurrentFixed = chosen_Fixed(:,:, app.SliceSpinner_Registration.Value, app.Dim4Spinner_TimeAlignmentControl.Value, app.Dim5Spinner_TimeAlignmentControl.Value);
                    end
                    switch app.ReferenceFixedViewDropDown.Value
                        case 'multiply'
                            CurrentFixed = double(CurrentFixed);
                            CurrentFixed = (CurrentFixed - min(CurrentFixed(:))) / (max(CurrentFixed(:)) - min(CurrentFixed(:)) + eps);
                            app.CurrentSlice = (app.CurrentSlice - min(app.CurrentSlice(:))) / (max(app.CurrentSlice(:)) - min(app.CurrentSlice(:)) + eps);
                            app.CurrentSlice = CurrentFixed .* app.CurrentSlice;
                            app.RegistrationImage = suMRak.showImage(app.RegistrationImage, ...
                                app.UIAxes_Registration, app.CurrentSlice, ...
                                app.TurboButton_Registration.Value);
                            app.RegistrationImage.ContextMenu = app.ContextMenu_Registration;
                        case 'sidebyside'
                        % Registered moving image beside the fixed reference. Each half is
                        % normalised on its own so both stay visible regardless of contrast.
                        CurrentFixed = double(CurrentFixed);
                        CurrentFixed = (CurrentFixed - min(CurrentFixed(:))) / (max(CurrentFixed(:)) - min(CurrentFixed(:)) + eps);
                        app.CurrentSlice = double(app.CurrentSlice);
                        app.CurrentSlice = (app.CurrentSlice - min(app.CurrentSlice(:))) / (max(app.CurrentSlice(:)) - min(app.CurrentSlice(:)) + eps);
                        app.RegistrationImage = suMRak.showImage(app.RegistrationImage, ...
                            app.UIAxes_Registration, [app.CurrentSlice, CurrentFixed], ...
                            app.TurboButton_Registration.Value);
                        app.RegistrationImage.ContextMenu = app.ContextMenu_Registration;
                        case 'falsecolor'
                        CurrentFixed = double(CurrentFixed);
                        CurrentFixed = (CurrentFixed - min(CurrentFixed(:))) / (max(CurrentFixed(:)) - min(CurrentFixed(:)) + eps);
                        app.CurrentSlice = (app.CurrentSlice - min(app.CurrentSlice(:))) / (max(app.CurrentSlice(:)) - min(app.CurrentSlice(:)) + eps);
                        switch app.TurboButton_Registration.Value
                            case true
                                app.CurrentSlice = ind2rgb(gray2ind(app.CurrentSlice), turbo);
                                CurrentFixed = ind2rgb(gray2ind(CurrentFixed), turbo);
                        end
                        % imshowpair creates a different graphics object; invalidate
                        % any previous handle so it gets reconstructed cleanly.
                        if ~isempty(app.RegistrationImage) && isgraphics(app.RegistrationImage)
                            delete(app.RegistrationImage);
                            app.RegistrationImage = [];
                        end
                        app.RegistrationImage = imshowpair(app.CurrentSlice, CurrentFixed, "falsecolor", 'Parent', app.UIAxes_Registration);
                        app.RegistrationImage.ContextMenu = app.ContextMenu_Registration;
                    end
                case false
                    switch numel(app.ExpDimsRegistration)
                        case 2
                            app.CurrentSlice = chosen_Image(:,:);
                        case 3
                            app.CurrentSlice = chosen_Image(:,:, app.SliceSpinner_Registration.Value);
                        case 4
                            app.CurrentSlice = chosen_Image(:,:, app.SliceSpinner_Registration.Value, app.Dim4Spinner_TimeAlignmentControl.Value);
                        case 5
                            app.CurrentSlice = chosen_Image(:,:, app.SliceSpinner_Registration.Value, app.Dim4Spinner_TimeAlignmentControl.Value, app.Dim5Spinner_TimeAlignmentControl.Value);
                    end
                    app.CurrentSlice = (app.CurrentSlice - min(app.CurrentSlice(:))) / (max(app.CurrentSlice(:)) - min(app.CurrentSlice(:)));

                    app.RegistrationImage = suMRak.showImage(app.RegistrationImage, ...
                        app.UIAxes_Registration, app.CurrentSlice, ...
                        app.TurboButton_Registration.Value);
                    app.RegistrationImage.ContextMenu = app.ContextMenu_Registration;
            end
        end

        % Redraw after a reference-fixed display mode change. Before any
        % registration has run there is nothing to draw; anything else that
        % fails is a real fault and gets reported instead of silently dropped.
        function refreshReferenceFixedView(app)
            if isempty(app.RegisteredImageData) || isempty(app.PreRegistrationFixedImage)
                return
            end
            try
                RefreshImageRegistration(app);
            catch ME
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    ME.message, 'Display error');
            end
        end

        % Parameter Maps UIAxes input image updating
        function RefreshImagePreMap(app)
            app.ExpDimsPreMap = size(app.PreMapImageData);
            switch numel(app.ExpDimsPreMap)
                case 2
                    app.CurrentSlice = app.PreMapImageData(:,:);
                case 3
                    app.CurrentSlice = app.PreMapImageData(:,:,app.SliceSpinner_PreMap.Value);
                case 4
                    app.CurrentSlice = app.PreMapImageData(:,:,app.SliceSpinner_PreMap.Value, app.Dim4Spinner_PreMap.Value);
                case 5
                    app.CurrentSlice = app.PreMapImageData(:,:,app.SliceSpinner_PreMap.Value, app.Dim4Spinner_PreMap.Value, app.Dim5Spinner_PreMap.Value);
                otherwise
                    return
            end
            app.CurrentSlice = (app.CurrentSlice - min(app.CurrentSlice(:))) / (max(app.CurrentSlice(:)) - min(app.CurrentSlice(:)));
            app.PreMapImage = suMRak.showImage(app.PreMapImage, ...
                app.UIAxes_PreMap, app.CurrentSlice, false);
            app.PreMapImage.ContextMenu = app.ContextMenu_PreMap;
        end

        % Parameter Maps UIAxes output image updating
        function RefreshImagePostMap(app)
            app.ExpDimsPostMap = size(app.PostMapImageData);
            switch numel(app.ExpDimsPostMap)
                case 2
                    app.CurrentSlice = app.PostMapImageData(:,:);
                case 3
                    app.CurrentSlice = app.PostMapImageData(:,:,app.SliceSpinner_PostMap.Value);
                otherwise
                    return
            end
            app.CurrentSlice = RefreshImageBC_mex(app.CurrentSlice, ...
                app.ContrastSlider_PostMap.Value, app.BrightnessSlider_PostMap.Value);
            app.PostMapImage = suMRak.showImage(app.PostMapImage, ...
                app.UIAxes_PostMap, app.CurrentSlice, ...
                app.TurboButton_PostMap.Value);
            app.PostMapImage.ContextMenu = app.ContextMenu_PostMap;
        end
        
        % Function returns edema adjusted slice area
        function adj_Area = AdjustSliceForEdema(app, slice_mask, hemi_mask)
            switch numel(size(hemi_mask))
                case 3
                    left_hemi = hemi_mask(:,:,1);
                    right_hemi = hemi_mask(:,:,2);
                case 4
                    left_hemi = hemi_mask(:,:,:,1);
                    right_hemi = hemi_mask(:,:,:,2);
            end
            dice_left = dice(logical(slice_mask), logical(left_hemi));
            dice_right = dice(logical(slice_mask), logical(right_hemi));
            left_area = nnz(left_hemi);
            right_area = nnz(right_hemi);

            if dice_left > dice_right
                % Get area to adjust and remaining ROI area
                area_to_adjust = nnz(slice_mask & left_hemi);
                remaining_area = slice_mask;
                remaining_area(slice_mask & left_hemi) = 0;
                remaining_area = nnz(remaining_area);
                % Use simple hemisphere scaling where area is adjusted for
                % a factor of (contralateral area/ipsilateral area)
                if app.HemisphereScalingFactorMenu.Checked == "on"
                    adj_Area = area_to_adjust*(right_area/left_area);

                % Use hemisphere scaling factor described in Belayev et al.
                % 2003, 1-(ipsilateral-contralateral/contralateral area)
                elseif app.BelayevScalingFactorMenu.Checked == "on"
                    adj_Area = area_to_adjust*(1-((left_area-right_area)/right_area));

                % Use compression factor described in Gerriets et al. 2004.
                elseif app.GerrietsCompressionFactorMenu.Checked == "on"
                    adj_Area = right_area+left_area-((right_area+left_area-area_to_adjust)*((right_area+left_area)/(2*right_area)));
                end
                % Add remaining area to edema corrected area
                adj_Area = adj_Area + remaining_area;

            elseif dice_right > dice_left
                % Get area to adjust and remaining ROI area
                area_to_adjust = nnz(slice_mask & right_hemi);
                remaining_area = slice_mask;
                remaining_area(slice_mask & right_hemi) = 0;
                remaining_area = nnz(remaining_area);
                % Use simple hemisphere scaling where area is adjusted for
                % a factor of (contralateral area/ipsilateral area)
                if app.HemisphereScalingFactorMenu.Checked == "on"
                    adj_Area = area_to_adjust*(left_area/right_area);

                % Use hemisphere scaling factor described in Belayev et al.
                % 2003, 1-(ipsilateral-contralateral/contralateral area)
                elseif app.BelayevScalingFactorMenu.Checked == "on"
                    adj_Area = area_to_adjust*(1-((right_area-left_area)/left_area));

                % Use compression factor described in Gerriets et al. 2004.
                elseif app.GerrietsCompressionFactorMenu.Checked == "on"
                    adj_Area = left_area+right_area-((left_area+right_area-area_to_adjust)*((left_area+right_area)/(2*left_area)));
                end
                % Add remaining area to edema corrected area
                adj_Area = adj_Area + remaining_area;

            elseif dice_right == dice_left
                adj_Area = nnz(slice_mask);
            end
        end

        % Function returns total volume of input matrix, corrected for
        % slice gaps and volume descriptives
        function [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, image_data, mask_data, voxel_area, slice_thickness, slice_gap, correction_hemi)   
            sliceTable=table();
            dims = size(image_data);
            % Mask image using mask data. MATLAB's implicit singleton
            % expansion (R2016b+) broadcasts mask_data [:,:,nz] across
            % any trailing dim4/dim5, collapsing the old nested loops
            % to a single elementwise multiply.
            if isa(image_data, 'double') || isa(image_data, 'single')
                image_data = image_data .* mask_data;
            else
                image_data = double(image_data) .* mask_data;
            end
            
            % Get single voxel volume
            voxel_V = voxel_area*slice_thickness;
            switch nargin
                case 6
                    % Calculate nonzero total volume
                    Volume = nnz(mask_data)*voxel_V;
                    % If mask is 3D go through mask and check for possible
                    % volume corrections
                    if numel(dims)>2
                        for i=1:(dims(3))
                            % Go through individual slices, if slice contains nonzero elements add the slice to nonzero slice table 
                            if ~isequal(mask_data(:,:,i), false(dims(1:2))) 
                                temp_table = table(i, nnz(mask_data(:,:,i))*voxel_area, 'VariableNames', {'Slice Number' 'Slice Area'});
                                sliceTable = cat(1, sliceTable, temp_table);
                                % If the next slice also contains nonzero
                                % elements calculate gap volume and add the
                                % correction to total volume
                                if i ~= dims(3) && ~isequal(mask_data(:,:,i+1), false(dims(1:2)))
                                    gap_voxelN = (nnz(mask_data(:,:,i))+nnz(mask_data(:,:,i+1)))/2;
                                    correction = (voxel_area*slice_gap)*gap_voxelN;
                                    Volume = Volume + correction;
                                end
                            end
                        end
                    else
                    % If slice contains nonzero elements add the slice to nonzero slice table 
                        if ~isequal(mask_data, false(dims(1:2))) 
                            temp_table = table(1, nnz(mask_data)*voxel_area, 'VariableNames', {'Slice Number' 'Slice Area'});
                            sliceTable = cat(1, sliceTable, temp_table);
                        end
                    end
                case 7
                    % If mask is 3D go through mask and add corrected
                    % volumes and gap corrections
                    Volume = 0;
                    if numel(dims)>2
                        for i=1:(dims(3))
                            % Go through individual slices, if slice contains nonzero elements add slice to
                            % nonzero slice table, and add edema corrected slice volume to total if hemispheres are segmented
                            if ~isequal(mask_data(:,:,i), false(dims(1:2)))
                                if ~isequal(correction_hemi(:,:,i,1), false(dims(1:2)))
                                    adj_Slice = AdjustSliceForEdema(app, mask_data(:,:,i), correction_hemi(:,:,i,:));
                                    temp_table = table(i, adj_Slice*voxel_area, 'VariableNames', {'Slice Number' 'Slice Area'});
                                    sliceTable = cat(1, sliceTable, temp_table);
                                    Volume = Volume + (adj_Slice*voxel_area*slice_thickness);
                                else
                                    adj_Slice = nnz(mask_data(:,:,i));
                                    temp_table = table(i, nnz(mask_data(:,:,i))*voxel_area, 'VariableNames', {'Slice Number' 'Slice Area'});
                                    sliceTable = cat(1, sliceTable, temp_table);
                                    Volume = Volume + (adj_Slice*voxel_area*slice_thickness);
                                end
                                % If the next slice also contains nonzero
                                % elements calculate gap volume and add the
                                % correction to total volume
                                if i ~= dims(3) && ~isequal(mask_data(:,:,i+1), false(dims(1:2)))
                                    if ~isequal(correction_hemi(:,:,i+1,1), false(dims(1:2)))
                                        adj_SliceNext = AdjustSliceForEdema(app, mask_data(:,:,i+1), correction_hemi(:,:,i+1,:));
                                        gap_voxelN = (adj_Slice+adj_SliceNext)/2;
                                        correction = (voxel_area*slice_gap)*gap_voxelN;
                                        Volume = Volume + correction;
                                    else
                                        adj_SliceNext = nnz(mask_data(:,:,i+1));
                                        gap_voxelN = (adj_Slice+adj_SliceNext)/2;
                                        correction = (voxel_area*slice_gap)*gap_voxelN;
                                        Volume = Volume + correction;
                                    end
                                end
                            end
                        end
                    else
                        if ~isequal(mask_data, false(dims(1:2))) 
                            adj_Slice = AdjustSliceForEdema(app, mask_data, correction_hemi);
                            temp_table = table(1, adj_Slice*voxel_area, 'VariableNames', {'Slice Number' 'Slice Area'});
                            sliceTable = cat(1, sliceTable, temp_table);
                            Volume = adj_Slice*voxel_area*slice_thickness;
                        end
                    end
            end
            
            mean_val = mean(nonzeros(image_data), "all");
            std_val = std(nonzeros(image_data), 1, "all");
            median_val = median(nonzeros(image_data), "all");
            IQRlow = quantile(nonzeros(image_data), 0.25, "all");
            IQRup = quantile(nonzeros(image_data), 0.75, "all");
            min_val = min(nonzeros(image_data), [], "all");
            max_val = max(nonzeros(image_data), [], "all");
        end

        % Function updates volumetry ROI section
        function UpdateVolumetryROI(app)
            voxel_Area = app.VolumetryDimY*app.VolumetryDimX;
            % Find index of selected ROI
            index = find(contains(app.VolumetryROI.ID, app.SelectROIDropDown.Value));
            switch app.ApplyEdemaCorrectionCheckBox.Value
                case 1
                    switch numel(size(app.VolumetryImageData))
                        case 2
                            [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap, app.VolumetryHemiMask);
                        otherwise
                            [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap, app.VolumetryHemiMask);
                    end
                case 0
                    switch numel(size(app.VolumetryImageData))
                        case 2
                            [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                        otherwise
                            [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    end
            end
            app.UITable_ResultsROI.Data = sliceTable;
            app.VolumeEditField_ROI.Value = Volume;

            % Populate ROI descriptive edit fields
            try
                app.MeanEditField_ROI.Value = mean_val;
                app.SDEditField_ROI.Value = std_val;
                app.MedianEditField_ROI.Value = median_val;
                app.IQRLowerEditField_ROI.Value = IQRlow;
                app.IQRUpperEditField_ROI.Value = IQRup;
                app.MinEditField_ROI.Value = min_val;
                app.MaxEditField_ROI.Value = max_val;
            catch
                app.MeanEditField_ROI.Value = 0;
                app.SDEditField_ROI.Value = 0;
                app.MedianEditField_ROI.Value = 0;
                app.IQRLowerEditField_ROI.Value = 0;
                app.IQRUpperEditField_ROI.Value = 0;
                app.MinEditField_ROI.Value = 0;
                app.MaxEditField_ROI.Value = 0;
            end
        end

        % Saving temporary experiment data to permanent tables
        function SaveData(app, tab)
            switch tab
                case 'Preview'
                    OrigIndex = find(strcmp(app.ExperimentPropertyTable{:,'Experiment ID'}, app.PreviewDropDown.Value));
                    exp_ID = append(app.PreviewDropDown.Value, '_Preview');
                    image_Data = app.PreviewImageData;
                    saved_BrainMask = false(size(image_Data));
                    hemi_Mask = false(1);
                    roi.Mask = false(1);
                    roi.ID = {'None'};
                    p = LookupExperimentParams(app, app.PreviewDropDown.Value);
                    TE              = p.TE;
                    TR              = p.TR;
                    vox_dim_X       = p.VoxDimX;
                    vox_dim_Y       = p.VoxDimY;
                    slice_Thickness = p.SliceThickness;
                    slice_Gap       = p.SliceGap;
                    units           = p.Units;
                    RotMat          = p.RotMat;
                case 'Segmenter'
                    exp_ID = append(app.SegmentDropDown.Value, '_Segmented');
                    image_Data = app.WorkingSegmenterImageData;
                    saved_BrainMask = app.SavedBrainMask;
                    switch numel(app.ExpDimsSegmenter)
                        case 2
                            if ~isequal(app.HemisphereMask, false(cat(2, app.ExpDimsSegmenter, 2)))
                                hemi_Mask = app.HemisphereMask;
                            else
                                hemi_Mask = false(1);
                            end
                        otherwise
                            if ~isequal(app.HemisphereMask, false(cat(2, app.ExpDimsSegmenter(1:3), 2)))
                                hemi_Mask = app.HemisphereMask;
                            else
                                hemi_Mask = false(1);
                            end
                    end
                    if ~isequal(app.ROIMask, [])
                        roi.Mask = app.ROIMask;
                    else
                        roi.Mask = false(1);
                    end
                    if ~isequal(app.ROIIdentifiers, {})
                        roi.ID = app.ROIIdentifiers;
                    else
                        roi.ID = {'None'};
                    end
                    try
                        p = LookupExperimentParams(app, app.SegmentDropDown.Value);
                        OrigIndex       = p.OrigIndex;
                        TE              = p.TE;
                        TR              = p.TR;
                        vox_dim_X       = p.VoxDimX;
                        vox_dim_Y       = p.VoxDimY;
                        slice_Thickness = p.SliceThickness;
                        slice_Gap       = p.SliceGap;
                        units           = p.Units;
                        RotMat          = p.RotMat;
                    catch ME
                        uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                            sprintf('Could not look up parameters for "%s": %s', ...
                                    app.SegmentDropDown.Value, ME.message), ...
                            'Parameter Lookup Failed');
                        return
                    end
                case 'Registration'
                    image_Data = app.RegisteredImageData;
                    % Remove negative values
                    image_Data(image_Data<0) = 0;
                    switch app.ChooseRegistrationTypeDropDown.Value
                        case "Standard"
                            OrigIndex = app.SavedTable.OrigIndex(app.SelectmovingDropDown.Value);
                            exp_ID = append(app.SelectmovingDropDown.Value, '_Registered');
                            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,['Save the fixed data mask along with the registered image data? If the fixed data mask is not saved, registration image data will' ...
                            ' need to be segmented again.'],'Save Fixed Data Mask?', 'Icon','question', 'Options', {'Save Mask','Save without Mask'}, 'DefaultOption', 1);
                            switch selection
                                case 'Save Mask'
                                    saved_BrainMask = app.RegisteredMask;
                                    image_Data = image_Data.*app.RegisteredMask;
                                case 'Save without Mask'
                                    saved_BrainMask = false(size(image_Data));
                            end
                            hemi_Mask = false(1);
                            roi.Mask = false(1);
                            roi.ID = {'None'};
                            TE = app.SavedTable.TE(app.SelectmovingDropDown.Value);
                            TR = app.SavedTable.TR(app.SelectmovingDropDown.Value);
                            vox_dim_X = app.SavedTable.VoxDimX(app.SelectfixedDropDown.Value); 
                            vox_dim_Y = app.SavedTable.VoxDimY(app.SelectfixedDropDown.Value);
                            slice_Thickness = app.SavedTable.SliceThickness(app.SelectfixedDropDown.Value);
                            slice_Gap = app.SavedTable.SliceGap(app.SelectfixedDropDown.Value);
                            units = app.SavedTable.Units(app.SelectfixedDropDown.Value);
                            RotMat = app.SavedTable.RotMat(app.SelectfixedDropDown.Value);
                        case "Reference Atlas"
                            OrigIndex = app.SavedTable.OrigIndex(app.SelectmovingDropDown.Value);
                            exp_ID = append(app.SelectmovingDropDown.Value, '_Atlas_Registered');
                            saved_BrainMask = false(size(image_Data));
                            hemi_Mask = false(1);
                            roi.Mask = false(1);
                            roi.ID = {'None'};
                            TE = app.SavedTable.TE(app.SelectmovingDropDown.Value);
                            TR = app.SavedTable.TR(app.SelectmovingDropDown.Value);
                            vox_dim_X = app.ResizedAtlasProperties.VoxDimX; 
                            vox_dim_Y = app.ResizedAtlasProperties.VoxDimY;
                            slice_Thickness = app.ResizedAtlasProperties.SliceThickness;
                            slice_Gap = app.ResizedAtlasProperties.SliceGap;
                            units = app.ChosenAtlas.Units;
                            RotMat = {app.ResizedAtlasProperties.RotMat};
                        case "Time-Series Alignment"
                            exp_ID = append(app.SelectTimeAlignmentDropDown.Value, '_Aligned');
                            saved_BrainMask = false(size(image_Data));
                            hemi_Mask = false(1);
                            roi.Mask = false(1);
                            roi.ID = {'None'};                 
                            try
                                OrigIndex = app.SavedTable.OrigIndex(app.SelectTimeAlignmentDropDown.Value);
                                TE = app.SavedTable.TE(app.SelectTimeAlignmentDropDown.Value);
                                TR = app.SavedTable.TR(app.SelectTimeAlignmentDropDown.Value);
                                vox_dim_X = app.SavedTable.VoxDimX(app.SelectTimeAlignmentDropDown.Value); 
                                vox_dim_Y = app.SavedTable.VoxDimY(app.SelectTimeAlignmentDropDown.Value);
                                slice_Thickness = app.SavedTable.SliceThickness(app.SelectTimeAlignmentDropDown.Value);
                                slice_Gap = app.SavedTable.SliceGap(app.SelectTimeAlignmentDropDown.Value);
                                units = app.SavedTable.Units(app.SelectTimeAlignmentDropDown.Value);
                                RotMat = app.SavedTable.RotMat(app.SelectTimeAlignmentDropDown.Value);
                            catch
                                try
                                    p = LookupExperimentParams(app, app.SelectTimeAlignmentDropDown.Value);
                                    OrigIndex       = p.OrigIndex;
                                    TE              = p.TE;
                                    TR              = p.TR;
                                    vox_dim_X       = p.VoxDimX;
                                    vox_dim_Y       = p.VoxDimY;
                                    slice_Thickness = p.SliceThickness;
                                    slice_Gap       = p.SliceGap;
                                    units           = p.Units;
                                    RotMat          = p.RotMat;
                                catch ME
                                    uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                                        sprintf('Could not look up parameters for "%s": %s', ...
                                                app.SelectTimeAlignmentDropDown.Value, ME.message), ...
                                        'Parameter Lookup Failed');
                                    return
                                end
                            end
                    end                    
                case 'Map'
                    exp_ID = append(app.SelectPreMapDropDown.Value, '_Map');
                    image_Data = app.PostMapImageData;
                    try
                        saved_BrainMask = cell2mat(app.SavedTable.BrainMask(app.SelectPreMapDropDown.Value));
                    catch
                        saved_BrainMask = false(size(image_Data));
                    end
                    hemi_Mask = false(1);
                    roi.Mask = false(1);
                    roi.ID = {'None'};                 
                    try
                        OrigIndex = app.SavedTable.OrigIndex(app.SelectPreMapDropDown.Value);
                        TE = app.SavedTable.TE(app.SelectPreMapDropDown.Value);
                        TR = app.SavedTable.TR(app.SelectPreMapDropDown.Value);
                        vox_dim_X = app.SavedTable.VoxDimX(app.SelectPreMapDropDown.Value); 
                        vox_dim_Y = app.SavedTable.VoxDimY(app.SelectPreMapDropDown.Value);
                        slice_Thickness = app.SavedTable.SliceThickness(app.SelectPreMapDropDown.Value);
                        slice_Gap = app.SavedTable.SliceGap(app.SelectPreMapDropDown.Value);
                        units = app.SavedTable.Units(app.SelectPreMapDropDown.Value);
                        RotMat = app.SavedTable.RotMat(app.SelectPreMapDropDown.Value);
                    catch
                        try
                            p = LookupExperimentParams(app, app.SelectPreMapDropDown.Value);
                            OrigIndex       = p.OrigIndex;
                            TE              = p.TE;
                            TR              = p.TR;
                            vox_dim_X       = p.VoxDimX;
                            vox_dim_Y       = p.VoxDimY;
                            slice_Thickness = p.SliceThickness;
                            slice_Gap       = p.SliceGap;
                            units           = p.Units;
                            RotMat          = p.RotMat;
                        catch ME
                            uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                                sprintf('Could not look up parameters for "%s": %s', ...
                                        app.SelectPreMapDropDown.Value, ME.message), ...
                                'Parameter Lookup Failed');
                            return
                        end
                    end
            end

            % Construct temporary table of saved data
            temp_Table = table(OrigIndex, {image_Data}, {saved_BrainMask}, {hemi_Mask}, {roi}, TE, TR, vox_dim_X, vox_dim_Y, slice_Thickness, slice_Gap, units, RotMat,'RowNames', {exp_ID}, 'VariableNames', {'OrigIndex' 'Image' 'BrainMask' 'HemiMask' 'ROI' 'TE' 'TR' 'VoxDimX' 'VoxDimY' 'SliceThickness' 'SliceGap' 'Units' 'RotMat'});
            try
                % Move temporarily saved data to permanent app table
                app.SavedTable = [app.SavedTable; temp_Table];
                
                % Update Combined drop down
                app.DropDownItemsCombined = cat(1, app.DropDownItemsCombined, exp_ID);
                app.SegmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectTimeAlignmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectPreMapDropDown.Items = app.DropDownItemsCombined;
                app.Select3DViewerDropDown.Items = app.DropDownItemsCombined;

                % Update DSC and Registration tab drop down menus
                app.DropDownItemsSavedOnly = cat(1, app.DropDownItemsSavedOnly, exp_ID);
                app.SelectfixedDropDown.Items = app.DropDownItemsSavedOnly;
                app.SelectmovingDropDown.Items = app.DropDownItemsSavedOnly;
                app.SelectparameterDropDown.Items = app.DropDownItemsSavedOnly;
                app.SelectResultsDropDown.Items = app.DropDownItemsSavedOnly;

                % Display confirmation figure
                uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Sequence saved to permanent data.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
            catch ME
                switch ME.identifier
                    case 'MATLAB:table:DuplicateRowNames'
                        selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Saved data already contains an experiment under the same name, do you want to overwrite the data?','Overwrite data', 'Icon','question');
                        switch selection
                            case 'OK'
                                % Overwrite data of currently saved experiment under same identifier
                                app.SavedTable.Image(exp_ID) = {image_Data};
                                app.SavedTable.BrainMask(exp_ID) = {saved_BrainMask};
                                app.SavedTable.HemiMask(exp_ID) = {hemi_Mask};
                                app.SavedTable.ROI(exp_ID) = {roi};
                                app.SavedTable.TE(exp_ID) = TE;
                                app.SavedTable.TR(exp_ID) = TR;
                                app.SavedTable.VoxDimX(exp_ID) = vox_dim_X;
                                app.SavedTable.VoxDimY(exp_ID) = vox_dim_Y;
                                app.SavedTable.SliceThickness(exp_ID) = slice_Thickness;
                                app.SavedTable.SliceGap(exp_ID) = slice_Gap;
                                app.SavedTable.Units(exp_ID) = units;
                                app.SavedTable.RotMat(exp_ID) = RotMat;
                                
                                % Display confirmation figure
                                uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Current sequence saved to permanent data.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
                            case 'Cancel'
                                return
                        end
                    otherwise
                end
            end 
        end
        

        function ExportImageDataGeneral(app, tab)
            % Draw progress box
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Retrieving data for export");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow    
            switch tab
                % Get image data, dropdown value and suffix based on tab
                case 'Preview'
                    ImageData = app.PreviewImageData;
                    DropDownValue = app.PreviewDropDown.Value;
                    Suffix = ".nii";
                case 'Segmenter'
                    ImageData = app.WorkingSegmenterImageData;
                    DropDownValue = app.SegmentDropDown.Value;
                    Suffix = "_segmented.nii";
                case 'Map'
                    ImageData = app.PostMapImageData;
                    DropDownValue = app.SelectPreMapDropDown.Value;
                    switch app.ChooseMapTypeDropDown.Value
                        case 'DSC'
                            Suffix = "_DSC_" + convertCharsToStrings(app.DSCMapDropDown.Value) + ".nii";
                        otherwise
                            Suffix = "_" + convertCharsToStrings(app.ChooseMapTypeDropDown.Value) + ".nii";
                    end
            progress.Value = 0.2;
            progress.Message = "Writing NIfTI data information...";

            end
            % Write initial nifti file for header updating
            niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + DropDownValue + Suffix);
            info = niftiinfo(app.ExportFolderPath + filesep + DropDownValue + Suffix);
            info.Description = 'Image file generated in suMRak';
            % Get dimension padding and slice thickness/gap
            switch numel(size(ImageData))
                case 2
                    DimPadding = [];
                    SliceThickness = [];
                    SliceGap = [];
                case 3
                    DimPadding = [];
                    try
                        SliceThickness = app.SavedTable.SliceThickness(DropDownValue);
                        SliceGap = app.SavedTable.SliceGap(DropDownValue);
                    catch
                        SliceThickness = table2array(app.ExperimentPropertyTable(DropDownValue, "Slice Thickness"));
                        SliceGap = table2array(app.ExperimentPropertyTable(DropDownValue, "Slice Gap"));
                    end
                case 4
                    DimPadding = 1;
                    try
                        SliceThickness = app.SavedTable.SliceThickness(DropDownValue);
                        SliceGap = app.SavedTable.SliceGap(DropDownValue);
                    catch
                        SliceThickness = table2array(app.ExperimentPropertyTable(DropDownValue, "Slice Thickness"));
                        SliceGap = table2array(app.ExperimentPropertyTable(DropDownValue, "Slice Gap"));
                    end
                case 5
                    DimPadding = [1,1];
                    try
                        SliceThickness = app.SavedTable.SliceThickness(DropDownValue);
                        SliceGap = app.SavedTable.SliceGap(DropDownValue);
                    catch
                        SliceThickness = table2array(app.ExperimentPropertyTable(DropDownValue, "Slice Thickness"));
                        SliceGap = table2array(app.ExperimentPropertyTable(DropDownValue, "Slice Gap"));
                    end
            end
            % Get other voxel dimensions, update header
            try
                info.PixelDimensions = [app.SavedTable.VoxDimX(DropDownValue), ...
                    app.SavedTable.VoxDimY(DropDownValue), ...
                    SliceThickness + SliceGap, DimPadding];
                temp = split(app.SavedTable.Units(DropDownValue));
                rotm = cell2mat(app.SavedTable.RotMat(DropDownValue));
            catch
                info.PixelDimensions = [table2array(app.ExperimentPropertyTable(DropDownValue, "Voxel dimension X")), ...
                    table2array(app.ExperimentPropertyTable(DropDownValue, "Voxel dimension Y")), ...
                    SliceThickness + SliceGap, DimPadding];
                temp = split(table2array(app.ExperimentPropertyTable(DropDownValue,'Dimension Units')));
                rotm = cell2mat(table2array(app.ExperimentPropertyTable(DropDownValue,"Rotation Matrix")));
            end
            % Update header units
            switch temp(1)
                case "mm"
                    info.SpaceUnits = 'Millimeter';
                case "um"
                    info.SpaceUnits = 'Micron';
                case "m"
                    info.SpaceUnits = 'Meter';
            end
            progress.Value = 0.6;
            progress.Message = "Exporting image data";

            % Update header transformation
            info.Transform.T(1:3,1:3) = rotm;
            info.TransformName = 'Qform';
            % Write final nifti
            niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + DropDownValue + Suffix, info);
            if  strcmp(tab, 'Segmenter')
                try
                progress.Value = 0.8;
                progress.Message = "Exporting segmenter mask data";

                % Write nifti files for segmentation masks
                niftiwrite(pagetranspose(double(app.SavedBrainMask)),app.ExportFolderPath + filesep + app.SegmentDropDown.Value+"_mask_brain.nii");
                niftiwrite(pagetranspose(double(app.HemisphereMask)),app.ExportFolderPath + filesep + app.SegmentDropDown.Value+"_mask_hemisphere.nii");
                niftiwrite(pagetranspose(double(app.ROIMask)),app.ExportFolderPath + filesep + app.SegmentDropDown.Value+"_mask_ROI.nii");
                roi_id = app.ROIIdentifiers;
                % Save ROI identifiers
                save(app.ExportFolderPath + filesep + app.SegmentDropDown.Value+"_identifiers_ROI.mat", "roi_id")
                catch
                end
            end

            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";

            
        end

        function ExportImageDataRegistration(app, registration)
            % Draw progress box
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Retrieving data for export");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow
            ImageData = app.RegisteredImageData;
            ImageData(ImageData<0) = 0;
            switch registration
                % Standard registration (moving/fixed)
                case 'Standard Registration'
                    Suffix = "_registered.nii";

                    progress.Value = 0.2;
                    progress.Message = "Writing NIfTI data information...";

                    % Write initial nifti file for header updating
                    niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + app.SelectmovingDropDown.Value + Suffix);
                    info = niftiinfo(app.ExportFolderPath + filesep + app.SelectmovingDropDown.Value + Suffix);
                    info.Description = 'Image file generated in suMRak';
                    % Get voxel dimensions, update header
                    switch numel(size(ImageData))
                        case 2
                            SliceThickness = [];
                            SliceGap = [];
                        case 3
                            SliceThickness = app.SavedTable.SliceThickness(app.SelectfixedDropDown.Value);
                            SliceGap = app.SavedTable.SliceGap(app.SelectfixedDropDown.Value);
                    end
                    DimPadding = [];
                    info.PixelDimensions = [app.SavedTable.VoxDimX(app.SelectfixedDropDown.Value), ...
                        app.SavedTable.VoxDimY(app.SelectfixedDropDown.Value), ...
                        SliceThickness + SliceGap, DimPadding];
                    % Update header units
                    temp = split(app.SavedTable.Units(app.SelectfixedDropDown.Value));
                    switch temp(1)
                        case "mm"
                            info.SpaceUnits = 'Millimeter';
                        case "um"
                            info.SpaceUnits = 'Micron';
                        case "m"
                            info.SpaceUnits = 'Meter';
                    end
                    progress.Value = 0.6;
                    progress.Message = "Exporting image data";

                    % Update header transform
                    rotm = cell2mat(app.SavedTable.RotMat(app.SelectfixedDropDown.Value));
                    info.Transform.T(1:3,1:3) = rotm;
                    info.TransformName = 'Qform';
                    % Write final nifti
                    niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + app.SelectmovingDropDown.Value + Suffix, info);
                
                % Reference Atlas registration
                case 'Atlas Registration'
                    Suffix = "_atlas_registered.nii";

                    progress.Value = 0.2;
                    progress.Message = "Writing NIfTI data information...";

                    % Write initial nifti file for header updating
                    niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + app.SelectmovingDropDown.Value + Suffix);
                    info = niftiinfo(app.ExportFolderPath + filesep + app.SelectmovingDropDown.Value + Suffix);
                    info.Description = 'Image file generated in suMRak';
                    % Get voxel dimensions, update header
                    switch numel(size(ImageData))
                        case 2
                            SliceThickness = [];
                            SliceGap = [];
                        case 3
                            SliceThickness = app.ResizedAtlasProperties.SliceThickness;
                            SliceGap = app.ResizedAtlasProperties.SliceGap;
                    end
                    DimPadding = [];
                    info.PixelDimensions = [app.ResizedAtlasProperties.VoxDimX, ...
                        app.ResizedAtlasProperties.VoxDimY, ...
                        SliceThickness + SliceGap, DimPadding];
                    % Update header units
                    temp = split(app.ChosenAtlas.Units);
                    switch temp(1)
                        case "mm"
                            info.SpaceUnits = 'Millimeter';
                        case "um"
                            info.SpaceUnits = 'Micron';
                        case "m"
                            info.SpaceUnits = 'Meter';
                    end
                    progress.Value = 0.6;
                    progress.Message = "Exporting image data";

                    % Update header transform
                    rotm = app.ResizedAtlasProperties.RotMat;
                    info.Transform.T(1:3,1:3) = rotm;
                    info.TransformName = 'Qform';
                    % Write final nifti
                    niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + app.SelectmovingDropDown.Value + Suffix, info);

                % Time Series alignment
                case 'Time Series Alignment'
                    Suffix = "_aligned.nii";

                    progress.Value = 0.2;
                    progress.Message = "Writing NIfTI data information...";

                    % Write initial nifti file for header updating
                    niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + app.SelectTimeAlignmentDropDown.Value + Suffix);
                    info = niftiinfo(app.ExportFolderPath + filesep + app.SelectTimeAlignmentDropDown.Value + Suffix);
                    info.Description = 'Image file generated in suMRak';
                    % Get voxel dimensions, update header
                    switch numel(size(ImageData))
                        case 4
                            DimPadding = 1;
                        case 5
                            DimPadding = [1,1];
                    end
                    try
                        SliceThickness = app.SavedTable.SliceThickness(app.SelectTimeAlignmentDropDown.Value);
                        SliceGap = app.SavedTable.SliceGap(app.SelectTimeAlignmentDropDown.Value);
                    catch
                        SliceThickness = table2array(app.ExperimentPropertyTable(app.SelectTimeAlignmentDropDown.Value, "Slice Thickness"));
                        SliceGap = table2array(app.ExperimentPropertyTable(app.SelectTimeAlignmentDropDown.Value, "Slice Gap"));
                    end
                    % Get other voxel dimensions, update header
                    try
                        info.PixelDimensions = [app.SavedTable.VoxDimX(app.SelectTimeAlignmentDropDown.Value), ...
                            app.SavedTable.VoxDimY(app.SelectTimeAlignmentDropDown.Value), ...
                            SliceThickness + SliceGap, DimPadding];
                        temp = split(app.SavedTable.Units(app.SelectTimeAlignmentDropDown.Value));
                        rotm = cell2mat(app.SavedTable.RotMat(app.SelectTimeAlignmentDropDown.Value));
                    catch
                        info.PixelDimensions = [table2array(app.ExperimentPropertyTable(app.SelectTimeAlignmentDropDown.Value, "Voxel dimension X")), ...
                            table2array(app.ExperimentPropertyTable(app.SelectTimeAlignmentDropDown.Value, "Voxel dimension Y")), ...
                            SliceThickness + SliceGap, DimPadding];
                        temp = split(table2array(app.ExperimentPropertyTable(app.SelectTimeAlignmentDropDown.Value,'Dimension Units')));
                        rotm = cell2mat(table2array(app.ExperimentPropertyTable(app.SelectTimeAlignmentDropDown.Value,"Rotation Matrix")));
                    end
                    % Update header units
                    switch temp(1)
                        case "mm"
                            info.SpaceUnits = 'Millimeter';
                        case "um"
                            info.SpaceUnits = 'Micron';
                        case "m"
                            info.SpaceUnits = 'Meter';
                    end
                    progress.Value = 0.6;
                    progress.Message = "Exporting image data";

                    % Update header transform
                    info.Transform.T(1:3,1:3) = rotm;
                    info.TransformName = 'Qform';
                    % Write final nifti
                    niftiwrite(pagetranspose(ImageData),app.ExportFolderPath + filesep + app.SelectTimeAlignmentDropDown.Value + Suffix, info);
            end
            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";

            
        end

        function ResetEnvironment(app)
            % Reset tables
            app.ExperimentPropertyTable = table();
            app.SavedTable = table();

            % Reset time values
            app.TEvalues = zeros(1000, 1000); % 1000x1000 table storing TE values
            app.TRvalues = zeros(1000, 1000); % 1000x1000 table storing TR values
            app.TIvalues = zeros(1000, 1000); % 1000x1000 table storing TI values
    
            % Reset drop downs and text fields
            app.PreviewDropDown.Items = {'None'};
            app.SegmentDropDown.Items = {'None'};
            app.DropDownItemsSavedOnly = {'None'};
            app.DropDownItemsCombined = {'None'};
            app.SelectResultsDropDown.Items = {'None'};
            app.SelectROIDropDown.Items = {'None'};
            app.SelectfixedDropDown.Items = {'None'};
            app.SelectmovingDropDown.Items = {'None'};
            app.SelectparameterDropDown.Items = {'None'};
            app.SelectTimeAlignmentDropDown.Items = {'None'};
            app.SelectPreMapDropDown.Items = {'None'};
            app.Select3DViewerDropDown.Items = {'None'};

            % Reset export folder path and components, save buttons
            app.CreatesuMRakFolderButton.Enable = 'off';
            app.ExportEnvironmentButton.Enable = 'off';
            app.ExportDataButton_Preview.Enable = 'off';
            app.ExportDataButton_Segmenter.Enable = 'off';
            app.ExportDataButton_Results.Enable = 'off';
            app.ExportDataButton_Registration.Enable = 'off';
            app.ExportDataButton_Map.Enable = 'off';
            app.ExportSceneButton.Enable = 'off';

            app.SaveDataButton_Preview.Enable = 'off';
            app.SaveSegmentedDataButton.Enable = 'off';
            app.SaveRegisteredDataButton.Enable = 'off';
            app.SaveDataButton_Map.Enable = 'off';
    
            % Disable and reset components in different tabs
            
            % Main
            % Reset UIAxes
            cla(app.UIAxes_Preview);
            app.UITable_Preview.Data=table();
            app.BrightnessSlider_Preview.Value = 0;
            app.BrightnessSlider_Preview.Enable = 'off';
            app.ContrastSlider_Preview.Value = 0;
            app.ContrastSlider_Preview.Enable = 'off';
            app.SliceSpinner_Preview.Value = 1;
            app.SliceSpinner_Preview.Enable = 'off';
            app.SliceSlider_Preview.Value = 1;
            app.SliceSlider_Preview.Enable = 'off';
            app.Dim4Slider_Preview.Value = 1;
            app.Dim5Slider_Preview.Value = 1;
            app.TurboButton_Preview.Enable = 'off';
            app.GreyscaleButton_Preview.Enable = 'off';
             % Reset text fields Preview
            app.ArchiveEditField.Value = "";
            app.SubjectIDEditField.Value = "";
            app.StudyIDEditField.Value = "";
            app.SubjectCommentEditField.Value = "";
            app.StudyCommentEditField.Value = "";
            app.SubjectTypeEditField.Value = "";
            app.SexEditField.Value = "";
            app.WeightEditField.Value = "";
            app.StudyStartDateEditField.Value = "";
            app.StudyStartTimeEditField.Value = "";
            app.SubjectAgeEditField.Value = "";
            
            % Segmenter
            % Reset UIAxes
            cla(app.UIAxes_Segmenter);
            cla(app.UIAxes_SegmenterHelperUp);
            cla(app.UIAxes_SegmenterHelperDown);
            app.UIAxes_SegmenterHelperUp.Visible = 'off';
            app.UIAxes_SegmenterHelperDown.Visible = 'off';
            app.SliceSlider_Segmenter.Enable = 'off';
            app.SliceSpinner_Segmenter.Enable = 'off';
            app.Dim4Spinner_Segmenter.Enable = 'off';
            app.Dim5Spinner_Segmenter.Enable = 'off';
            app.BrightnessSlider_Segmenter.Enable = 'off';
            app.ContrastSlider_Segmenter.Enable = 'off';
            app.SliceSlider_Segmenter.Value = 1;
            app.SliceSpinner_Segmenter.Value = 1;
            app.Dim4Spinner_Segmenter.Value = 1;
            app.Dim5Spinner_Segmenter.Value = 1;
            app.ContrastSlider_Segmenter.Value = 0;
            app.BrightnessSlider_Segmenter.Value = 0;
            app.TurboButton_Segmenter.Enable = 'off';
            app.GreyscaleButton_Segmenter.Enable = 'off';
            app.CurrentSegmentationDropDown.Enable = 'off';
            app.CurrentSegmentationDropDown.Value = 'Brain';
            app.BrainSegmentationToolsPanel.Visible = 'off';
            app.HemisphereSegmentationToolsPanel.Visible = 'off';
            app.ROISegmentationToolsPanel.Visible = 'off';
            app.SelectionToolsPanel.Visible = 'off';
            app.PerspectiveViewButton.Enable = 'off';
    
            % Registration
            % Reset UIAxes
            cla(app.UIAxes_Registration);
            app.ChooseRegistrationTypeDropDown.Value = "Standard";
            app.SliceSlider_Registration.Enable = 'off';
            app.SliceSlider_Registration.Value = 1;
            app.SliceSpinner_Registration.Enable = 'off';
            app.SliceSpinner_Registration.Value = 1;
            app.TurboButton_Registration.Enable = 'off';
            app.GreyscaleButton_Registration.Enable = 'off';
            app.ShowReferenceFixedCheckBox.Enable = 'off';
            app.ShowReferenceFixedCheckBox.Value = 0;
            app.ReferenceFixedViewDropDown.Enable = 'off';
            app.ReferenceFixedViewDropDown.Value = 'sidebyside';
            app.UseDifferentParameterMapCheckBox.Value = 0;
            app.SelectparameterDropDown.Enable = 'off';
            app.RegistrationSliceLimitsTextArea.Value = '';
            app.StandardAtlasRegistrationPanel.Visible = 'on';
            app.TimeSeriesAlignmentPanel.Visible = 'off';
            app.SelectfixedDropDown.Visible = 'on';
            app.SelectfixedLabel.Visible = 'on';
            app.ImportReferenceAtlasButton.Visible = 'off';
            app.SelectAtlasDropDown.Visible = 'off';
            app.AtlasCollection = struct();
            app.SelectAtlasDropDown.Items = {'None'};
            app.Dim4Spinner_TimeAlignmentReference.Enable = 'off';
            app.Dim4Spinner_TimeAlignmentReference.Value = 1;
            app.Dim5Spinner_TimeAlignmentReference.Enable = 'off';
            app.Dim5Spinner_TimeAlignmentReference.Value = 1;
            app.AlignDataButton.Enable = 'off';
            app.ChooseRegistrationTypeDropDown.Value = "Standard";
            app.OutputInterpolatorDropDown.Value = 'NearestNeighbor';
            app.Dim4Slider_TimeAlignmentControl.Enable = 'off';
            app.Dim4Slider_TimeAlignmentControl.Value = 1;
            app.Dim4Spinner_TimeAlignmentControl.Enable = 'off';
            app.Dim4Spinner_TimeAlignmentControl.Value = 1;
            app.Dim5Slider_TimeAlignmentControl.Enable = 'off';
            app.Dim5Slider_TimeAlignmentControl.Value = 1;
            app.Dim5Spinner_TimeAlignmentControl.Enable = 'off';
            app.Dim5Spinner_TimeAlignmentControl.Value = 1;
    
            % Volumetry 
            cla(app.UIAxes_Results);
            app.UIAxes_Results.Visible = 'off';
            % Reset brain fields and table
            app.UITable_ResultsBrain.Data = table();
            app.VolumeEditField_Brain.Value = 0;
            app.MeanEditField_Brain.Value = 0;
            app.SDEditField_Brain.Value = 0;
            app.MedianEditField_Brain.Value = 0;
            app.IQRLowerEditField_Brain.Value = 0;
            app.IQRUpperEditField_Brain.Value = 0;
            app.MinEditField_Brain.Value = 0;
            app.MaxEditField_Brain.Value = 0;
            % Reset hemisphere fields and table
            app.SelectHemisphereDropDown.Enable = 'off';
            app.UITable_ResultsHemisphere.Data = table();
            app.VolumeEditField_Hemisphere.Value = 0;
            app.MeanEditField_Hemisphere.Value = 0;
            app.SDEditField_Hemisphere.Value = 0;
            app.MedianEditField_Hemisphere.Value = 0;
            app.IQRLowerEditField_Hemisphere.Value = 0;
            app.IQRUpperEditField_Hemisphere.Value = 0;
            app.MinEditField_Hemisphere.Value = 0;
            app.MaxEditField_Hemisphere.Value = 0;
            % Reset ROI fields and table
            app.SelectROIDropDown.Enable = 'off';
            app.SelectROIDropDown.Items = "None";
            app.UITable_ResultsROI.Data = table();
            app.ApplyEdemaCorrectionCheckBox.Enable = 'off';
            app.ApplyEdemaCorrectionCheckBox.Value = 0;
            app.VolumeEditField_ROI.Value = 0;
            app.MeanEditField_ROI.Value = 0;
            app.SDEditField_ROI.Value = 0;
            app.MedianEditField_ROI.Value = 0;
            app.IQRLowerEditField_ROI.Value = 0;
            app.IQRUpperEditField_ROI.Value = 0;
            app.MinEditField_ROI.Value = 0;
            app.MaxEditField_ROI.Value = 0;
            app.AreaUnitLabel.Text = "";
            app.VolumeUnitLabel.Text = "";
    
            % Parameter Maps
            % Reset UIAxes
            cla(app.UIAxes_PreMap);
            cla(app.UIAxes_PostMap);
            app.DSCMapDropDown.Value = 'CBF';
            app.DSCMapDropDown.Enable = 'off';
            app.SliceSlider_PreMap.Enable = 'off';
            app.SliceSlider_PreMap.Value = 1;
            app.SliceSpinner_PreMap.Enable = 'off';
            app.SliceSpinner_PreMap.Value = 1;
            app.Dim5Spinner_PreMap.Enable = 'off';
            app.Dim5Spinner_PreMap.Value = 1;
            app.Dim4Spinner_PreMap.Enable = 'off';
            app.Dim4Spinner_PreMap.Value = 1;
            app.SliceSlider_PostMap.Enable = 'off';
            app.SliceSlider_PostMap.Value = 1;
            app.SliceSpinner_PostMap.Enable = 'off';
            app.SliceSpinner_PostMap.Value = 1;
            app.ContrastSlider_PostMap.Enable = 'off';
            app.ContrastSlider_PostMap.Value = 0;
            app.BrightnessSlider_PostMap.Enable = 'off';
            app.BrightnessSlider_PostMap.Value = 0;
            app.AIFExtractionSliceSpinner.Value = 1;
            app.TurboButton_PostMap.Enable = 'off';
            app.GreyscaleButton_PostMap.Enable = 'off';
            app.TRvaluesText.Value = "";
            app.TIvaluesText.Value = "";
            app.TEvaluesText.Value = "";
            app.ResettodefaultsButtonPushed;

            % 3D Viewer
            delete(app.ViewerParentObject);
            cla(app.UIAxes_AlphaMap);
            app.RenderingStyleDropDown.Enable = 'off';
            app.RenderingStyleDropDown.Value = "Volume Rendering";
            app.PlaneIntersectionSwitch.Enable = 'off';
            app.ColormapDropDown_Viewer.Enable = 'off';
            app.ColormapDropDown_Viewer.Value = "Greyscale";
            app.ColormapImage_Viewer.Visible = 'off';
            app.AlphamapDropDown_Viewer.Enable = 'off';
            app.AlphamapDropDown_Viewer.Value = "Linear";
            app.XEditField_Viewer.Value = 0;
            app.YEditField_Viewer.Value = 0;
            app.ZEditField_Viewer.Value = 0;
            app.SliceRangeLowSpinner_Viewer.Enable = 'off';
            app.SliceRangeLowSpinner_Viewer.Value = 1;
            app.SliceRangeHighSpinner_Viewer.Enable = 'off';
            app.SliceRangeHighSpinner_Viewer.Limits = [1, 2];
            app.SliceRangeHighSpinner_Viewer.Value = 1;
            app.Dim4Spinner_Viewer.Enable = 'off';
            app.Dim4Spinner_Viewer.Value = 1;
            app.Dim5Spinner_Viewer.Enable = 'off';
            app.Dim5Spinner_Viewer.Value = 1;
        end
        
        function ClickHandlerHelperUp(app, ~, e)
            coords = e.IntersectionPoint;

            app.SegmenterPosX = ceil(coords(1)*app.SegmenterDimTriplet(3));
            app.SegmenterImageZY = squeeze(app.SegmenterHelperVolume(:,app.SegmenterPosX,:));
            
            z = ceil(coords(2)*app.SegmenterDimTriplet(2));
            app.SliceSlider_Segmenter.Value = z;
            app.SliceSpinner_Segmenter.Value = z;

            RefreshImageSegmenterHelperUp(app);
            RefreshImageSegmenterHelperDown(app);
            RefreshImageSegmenter(app);
        end
        
        function ClickHandlerHelperDown(app, ~, e)
            coords = e.IntersectionPoint;

            app.SegmenterPosY = ceil(coords(1)*app.SegmenterDimTriplet(3));
            app.SegmenterImageZX = squeeze(app.SegmenterHelperVolume(:,:,app.SegmenterPosY));

            z = ceil(coords(2)*app.SegmenterDimTriplet(2));
            app.SliceSlider_Segmenter.Value = z;
            app.SliceSpinner_Segmenter.Value = z;

            RefreshImageSegmenterHelperUp(app);
            RefreshImageSegmenterHelperDown(app);
            RefreshImageSegmenter(app);
        end
        
        function RefreshImageSegmenterHelperUp(app)
            switch app.PerspectiveViewButton.Value
                case 1
                    Tzx = [1/app.SegmenterDimTriplet(3) 0 0; 0 1/app.SegmenterDimTriplet(1) 0; 0 0 1];
                    tformZX = affinetform2d(Tzx);
                    imageZXwarped = imwarp(app.SegmenterImageZX,tformZX);
                    imageZXwarped = RefreshImageBC_mex(imageZXwarped,app.ContrastSlider_Segmenter.Value,app.BrightnessSlider_Segmenter.Value);

                    ax = app.UIAxes_SegmenterHelperUp;
                    useTurbo = app.TurboButton_Segmenter.Value;

                    % Image layer: reuse handle, update CData only
                    canReuseImg = ~isempty(app.SegmenterHelperImageUp) ...
                        && isa(app.SegmenterHelperImageUp, 'matlab.graphics.primitive.Image');
                    if canReuseImg
                        try
                            canReuseImg = isvalid(app.SegmenterHelperImageUp) ...
                                && isequal(app.SegmenterHelperImageUp.Parent, ax);
                        catch
                            canReuseImg = false;
                        end
                    end

                    if canReuseImg
                        try
                            app.SegmenterHelperImageUp.CData = imageZXwarped;
                        catch
                            % Size/type mismatch -> rebuild from scratch.
                            canReuseImg = false;
                        end
                    end

                    if ~canReuseImg
                        app.SegmenterHelperImageUp = imshow(imageZXwarped, ...
                            'DisplayRange', [0 1], 'Parent', ax);
                        app.SegmenterHelperImageUp.ButtonDownFcn = ...
                            @(o, e) ClickHandlerHelperUp(app, o, e);
                        app.SegmenterHelperRectZ_Up = [];
                        app.SegmenterHelperRectX_Up = [];
                    end

                    if useTurbo
                        ax.Colormap = turbo;
                    else
                        ax.Colormap = gray;
                    end

                    % Rectangle overlays: reuse handles, update Position only
                    posZ = [0 (app.SliceSpinner_Segmenter.Value-1)/app.SegmenterDimTriplet(2) ...
                        size(imageZXwarped, 2) 1/app.SegmenterDimTriplet(2)];
                    posX = [app.SegmenterPosX/app.SegmenterDimTriplet(3) 0 ...
                        1/app.SegmenterDimTriplet(3) size(imageZXwarped, 1)];

                    if isempty(app.SegmenterHelperRectZ_Up) ...
                            || ~isgraphics(app.SegmenterHelperRectZ_Up) ...
                            || ~isvalid(app.SegmenterHelperRectZ_Up) ...
                            || ~isequal(app.SegmenterHelperRectZ_Up.Parent, ax)
                        app.SegmenterHelperRectZ_Up = rectangle( ...
                            'Position', posZ, 'EdgeColor', [1 1 0], 'Parent', ax);
                        app.SegmenterHelperRectZ_Up.ButtonDownFcn = ...
                            @(o, e) ClickHandlerHelperUp(app, o, e);
                    else
                        app.SegmenterHelperRectZ_Up.Position = posZ;
                    end

                    if isempty(app.SegmenterHelperRectX_Up) ...
                            || ~isgraphics(app.SegmenterHelperRectX_Up) ...
                            || ~isvalid(app.SegmenterHelperRectX_Up) ...
                            || ~isequal(app.SegmenterHelperRectX_Up.Parent, ax)
                        app.SegmenterHelperRectX_Up = rectangle( ...
                            'Position', posX, 'EdgeColor', [1 1 0], 'Parent', ax);
                        app.SegmenterHelperRectX_Up.ButtonDownFcn = ...
                            @(o, e) ClickHandlerHelperUp(app, o, e);
                    else
                        app.SegmenterHelperRectX_Up.Position = posX;
                    end
                case 0
                    return
            end
        end
        
        function RefreshImageSegmenterHelperDown(app)
            switch app.PerspectiveViewButton.Value
                case 1
                    Tzy = [1/app.SegmenterDimTriplet(3) 0 0; 0 1/app.SegmenterDimTriplet(2) 0; 0 0 1];
                    tformZY = affinetform2d(Tzy);
                    imageZYwarped = imwarp(app.SegmenterImageZY,tformZY);
                    imageZYwarped = RefreshImageBC_mex(imageZYwarped,app.ContrastSlider_Segmenter.Value,app.BrightnessSlider_Segmenter.Value);

                    ax = app.UIAxes_SegmenterHelperDown;
                    useTurbo = app.TurboButton_Segmenter.Value;

                    % Image layer: reuse handle, update CData only
                    canReuseImg = ~isempty(app.SegmenterHelperImageDown) ...
                        && isa(app.SegmenterHelperImageDown, 'matlab.graphics.primitive.Image');
                    if canReuseImg
                        try
                            canReuseImg = isvalid(app.SegmenterHelperImageDown) ...
                                && isequal(app.SegmenterHelperImageDown.Parent, ax);
                        catch
                            canReuseImg = false;
                        end
                    end

                    if canReuseImg
                        try
                            app.SegmenterHelperImageDown.CData = imageZYwarped;
                        catch
                            canReuseImg = false;
                        end
                    end

                    if ~canReuseImg
                        app.SegmenterHelperImageDown = imshow(imageZYwarped, ...
                            'DisplayRange', [0 1], 'Parent', ax);
                        app.SegmenterHelperImageDown.ButtonDownFcn = ...
                            @(o, e) ClickHandlerHelperDown(app, o, e);
                        app.SegmenterHelperRectZ_Down = [];
                        app.SegmenterHelperRectY_Down = [];
                    end

                    if useTurbo
                        ax.Colormap = turbo;
                    else
                        ax.Colormap = gray;
                    end

                    % Rectangle overlays: reuse handles, update Position only 
                    posZ = [0 (app.SliceSpinner_Segmenter.Value-1)/app.SegmenterDimTriplet(2) ...
                        size(imageZYwarped, 2) 1/app.SegmenterDimTriplet(2)];
                    posY = [app.SegmenterPosY/app.SegmenterDimTriplet(3) 0 ...
                        1/app.SegmenterDimTriplet(3) size(imageZYwarped, 1)];

                    if isempty(app.SegmenterHelperRectZ_Down) ...
                            || ~isgraphics(app.SegmenterHelperRectZ_Down) ...
                            || ~isvalid(app.SegmenterHelperRectZ_Down) ...
                            || ~isequal(app.SegmenterHelperRectZ_Down.Parent, ax)
                        app.SegmenterHelperRectZ_Down = rectangle( ...
                            'Position', posZ, 'EdgeColor', [1 1 0], 'Parent', ax);
                        app.SegmenterHelperRectZ_Down.ButtonDownFcn = ...
                            @(o, e) ClickHandlerHelperDown(app, o, e);
                    else
                        app.SegmenterHelperRectZ_Down.Position = posZ;
                    end

                    if isempty(app.SegmenterHelperRectY_Down) ...
                            || ~isgraphics(app.SegmenterHelperRectY_Down) ...
                            || ~isvalid(app.SegmenterHelperRectY_Down) ...
                            || ~isequal(app.SegmenterHelperRectY_Down.Parent, ax)
                        app.SegmenterHelperRectY_Down = rectangle( ...
                            'Position', posY, 'EdgeColor', [1 1 0], 'Parent', ax);
                        app.SegmenterHelperRectY_Down.ButtonDownFcn = ...
                            @(o, e) ClickHandlerHelperDown(app, o, e);
                    else
                        app.SegmenterHelperRectY_Down.Position = posY;
                    end
                case 0
                    return
            end
        end

        function ApplyAlphaMap(app, ~, ~)
            for i = 1:size(app.AlphamapPoly.Position,1) + 1
                if i == 1
                    alphamap = linspace(0,app.AlphamapPoly.Position(i,2)/app.UIAxes_AlphaMap.YLim(2),app.AlphamapPoly.Position(i)+1);
                elseif i > 1 && i < size(app.AlphamapPoly.Position,1) + 1
                    alphamap = cat(2,alphamap,linspace(app.AlphamapPoly.Position(i-1,2)/app.UIAxes_AlphaMap.YLim(2), ...
                        app.AlphamapPoly.Position(i,2)/app.UIAxes_AlphaMap.YLim(2),app.AlphamapPoly.Position(i)-app.AlphamapPoly.Position(i-1)));
                elseif i == size(app.AlphamapPoly.Position,1) + 1
                    alphamap = cat(2,alphamap,linspace(app.AlphamapPoly.Position(i-1,2)/app.UIAxes_AlphaMap.YLim(2), ...
                        1,255-app.AlphamapPoly.Position(i-1)));
                end
            end
            app.ViewerParentObject.Children.Alphamap = alphamap;
        end
    
        function idx = currentStageIndex(app, requireSelection)
            if nargin < 2, requireSelection = true; end
            idx = app.TransformsListBox.Value;
            if isempty(idx) && requireSelection
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    'Select a transform first.', 'No selection');
            end
        end
    
        function selectStage(app, k)
            n = numel(app.StageSettings);
            if n == 0
                app.TransformsListBox.Value = {};
            else
                app.TransformsListBox.Value = max(1, min(k, n));
            end
            updateContextMenuState(app);
        end
    
        function insertStageOfType(app, typeName)
            if strcmp(app.ChooseRegistrationTypeDropDown.Value, 'Reference Atlas')
                preset = "Atlas";
            else
                preset = "Standard";
            end
            newStage = suMRak.defaultStageOfType(typeName, preset);
            sel = currentStageIndex(app, false);
            if isempty(sel)
                insertAt = numel(app.StageSettings) + 1;
            else
                insertAt = sel + 1;     % insert after current selection
            end
            app.StageSettings = [ ...
                app.StageSettings(1:insertAt-1), ...
                newStage, ...
                app.StageSettings(insertAt:end)];
            refreshTransformsListBox(app);
            selectStage(app, insertAt);
        end
        
        function updateContextMenuState(app)
            n = numel(app.StageSettings);
            idx = app.TransformsListBox.Value;
            hasSel = ~isempty(idx);
            isTimeSeries = strcmp(app.ChooseRegistrationTypeDropDown.Value, 'Time-Series Alignment');
        
            canEdit = hasSel && ~isTimeSeries;
            canMod  = ~isTimeSeries;
        
            app.EditTransformMenu.Enable      = canEdit;
            app.AddTransformMenu.Enable       = canMod;
            app.DuplicateTransformMenu.Enable = canEdit;
            app.RemoveTransformMenu.Enable    = canEdit && n > 1;
            app.MoveUpTransformMenu.Enable    = canEdit && idx > 1;
            app.MoveDownTransformMenu.Enable  = canEdit && idx < n;
            app.ResetTransformMenu.Enable     = canMod;
        end

        % Handle errors raised by SimpleITK during registration
        function handleRegistrationError(app, ME)
            msgText = ME.message;
            % Walk the cause chain so we also see the underlying Python message
            c = ME;
            while ~isempty(c.cause)
                c = c.cause{1};
                msgText = sprintf('%s\n%s', msgText, c.message);
            end

            % Signature of the "images do not overlap" failure
            overlapSignatures = [ ...
                "All samples map outside moving image buffer", ...
                "images do not sufficiently overlap", ...
                "align the image centers by translation"];
            isOverlapError = any(arrayfun(@(s) contains(msgText, s, 'IgnoreCase', true), ...
                overlapSignatures));

            if isOverlapError
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    ['Registration failed because the moving and fixed images do not sufficiently overlap. ' ...
                     newline newline ...
                     'Add a Translation stage at the start of the registration pipeline ' ...
                     '(Add Transform -> Translation) and run the pipeline again. ' ...
                     'The translation will align the image centers so the later stages ' ...
                     '(Euler3D / Affine / BSpline) have enough overlap to converge.'], ...
                    'Insufficient image overlap', ...
                    'Icon', 'warning');
            else
                % Any other Python / registration failure
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    sprintf('Registration failed:\n\n%s', msgText), ...
                    'Registration Error', ...
                    'Icon', 'error');
            end
        end

    end


    methods (Access = public)

        function refreshTransformsListBox(app)
            n = numel(app.StageSettings);
            if n == 0
                app.TransformsListBox.Items = {};
                app.TransformsListBox.ItemsData = [];
                removeStyle(app.TransformsListBox);
                updateContextMenuState(app);
                return
            end
            items = cell(1, n);
            disabledRows = false(1, n);
            for k = 1:n
                s = app.StageSettings(k);
                plain = sprintf('%d: %s', k-1, s.Type);
                if s.Enabled
                    items{k} = plain;
                else
                    items{k} = sprintf('%s  (disabled)', plain);
                    disabledRows(k) = true;
                end
            end
            app.TransformsListBox.Items = items;
            app.TransformsListBox.ItemsData = 1:n;
            
            % Style disabled rows.
            removeStyle(app.TransformsListBox);
            if any(disabledRows)
                addStyle(app.TransformsListBox, ...
                    uistyle('FontColor', [0.53 0.53 0.53], 'FontAngle', 'italic'), ...
                    'item', find(disabledRows));
            end
            updateContextMenuState(app);
        end

        % Segmenter UIAxes image updating
        function RefreshImageSegmenter(app)
            app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
            switch numel(app.ExpDimsSegmenter)
                case 2
                    app.CurrentSlice = app.WorkingSegmenterImageData(:,:);
                case 3
                    app.CurrentSlice = app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value);
                case 4
                    app.CurrentSlice = app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value, app.Dim4Spinner_Segmenter.Value);
                case 5
                    app.CurrentSlice = app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value, app.Dim4Spinner_Segmenter.Value, app.Dim5Spinner_Segmenter.Value);
                otherwise
                    return
            end
            app.CurrentSlice = RefreshImageBC_mex(app.CurrentSlice, ...
                app.ContrastSlider_Segmenter.Value, app.BrightnessSlider_Segmenter.Value);

            useTurbo = app.TurboButton_Segmenter.Value;
            slice = app.SliceSpinner_Segmenter.Value;
            isVolumetric = numel(app.ExpDimsSegmenter) ~= 2;

            switch app.CurrentSegmentationDropDown.Value
                case 'Brain'
                    switch app.ImageshownSwitch_Brain.Value
                        case "Overlay"
                            deleteSegmenterOverlaysExcept(app, "SegmenterOverlayGreen");
                            app.SegmenterImage = suMRak.showImage(app.SegmenterImage, ...
                                app.UIAxes_Segmenter, app.CurrentSlice, useTurbo);
                            try
                                alphaMap = app.BrainMask(:,:,slice) - 0.8;
                                app.SegmenterOverlayGreen = suMRak.showOverlay( ...
                                    app.SegmenterOverlayGreen, app.UIAxes_Segmenter, ...
                                    app.GreenScreen, alphaMap);
                            catch
                                return
                            end
                            app.SegmenterOverlayGreen.ContextMenu = app.ContextMenu_Segmenter;
                        case "Masked"
                            deleteSegmenterOverlaysExcept(app, "");
                            app.MaskedImage = double(app.CurrentSlice) .* app.BrainMask(:,:,slice);
                            app.SegmenterImage = suMRak.showImage(app.SegmenterImage, ...
                                app.UIAxes_Segmenter, app.MaskedImage, useTurbo);
                            app.SegmenterImage.ContextMenu = app.ContextMenu_Segmenter;
                    end

                case 'Hemisphere'
                    deleteSegmenterOverlaysExcept(app, ["SegmenterOverlayBlue", "SegmenterOverlayRed"]);
                    app.SegmenterImage = suMRak.showImage(app.SegmenterImage, ...
                        app.UIAxes_Segmenter, app.CurrentSlice, useTurbo);
                    try
                        if isVolumetric
                            alphaBlue = app.HemisphereMask(:,:,slice,1) - 0.8;
                            alphaRed  = app.HemisphereMask(:,:,slice,2) - 0.8;
                        else
                            alphaBlue = app.HemisphereMask(:,:,1) - 0.8;
                            alphaRed  = app.HemisphereMask(:,:,2) - 0.8;
                        end
                        app.SegmenterOverlayBlue = suMRak.showOverlay( ...
                            app.SegmenterOverlayBlue, app.UIAxes_Segmenter, ...
                            app.BlueScreen, alphaBlue);
                        app.SegmenterOverlayRed = suMRak.showOverlay( ...
                            app.SegmenterOverlayRed, app.UIAxes_Segmenter, ...
                            app.RedScreen, alphaRed);
                    catch
                        return
                    end
                    app.SegmenterOverlayRed.ContextMenu = app.ContextMenu_Segmenter;

                case 'ROI'
                    switch app.ImageshownSwitch_ROI.Value
                        case "Overlay"
                            deleteSegmenterOverlaysExcept(app, "SegmenterOverlayYellow");
                            app.SegmenterImage = suMRak.showImage(app.SegmenterImage, ...
                                app.UIAxes_Segmenter, app.CurrentSlice, useTurbo);
                            try
                                index = find(strcmp(app.ROIIdentifiers, app.ROIListListBox.Value));
                                if ~isempty(index)
                                    if isVolumetric
                                        alphaYellow = app.ROIMask(:,:,slice,index) - 0.8;
                                    else
                                        alphaYellow = app.ROIMask(:,:,index) - 0.8;
                                    end
                                else
                                    alphaYellow = zeros(app.ExpDimsSegmenter(1:2));
                                end
                            catch
                                alphaYellow = zeros(app.ExpDimsSegmenter(1:2));
                            end
                            app.SegmenterOverlayYellow = suMRak.showOverlay( ...
                                app.SegmenterOverlayYellow, app.UIAxes_Segmenter, ...
                                app.YellowScreen, alphaYellow);
                            app.SegmenterOverlayYellow.ContextMenu = app.ContextMenu_Segmenter;
                        case "Masked"
                            deleteSegmenterOverlaysExcept(app, "");
                            try
                                index = find(strcmp(app.ROIIdentifiers, app.ROIListListBox.Value));
                                if isVolumetric
                                    app.MaskedImage = double(app.CurrentSlice) .* app.ROIMask(:,:,slice,index);
                                else
                                    app.MaskedImage = double(app.CurrentSlice) .* app.ROIMask(:,:,index);
                                end
                            catch
                                app.MaskedImage = double(app.CurrentSlice) .* (false(app.ExpDimsSegmenter(1:2)));
                            end
                            app.SegmenterImage = suMRak.showImage(app.SegmenterImage, ...
                                app.UIAxes_Segmenter, app.MaskedImage, useTurbo);
                            app.SegmenterImage.ContextMenu = app.ContextMenu_Segmenter;
                    end
                otherwise
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFcn(app)
            
            % Check for existence of suMRak working folder in temp,
            % otherwise create new
            if exist(app.WorkingFolder, 'dir')
                rmdir(app.WorkingFolder, "s");
                mkdir(app.WorkingFolder);
            else
                mkdir(app.WorkingFolder);
            end
            movegui(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'center');
            
             % Get app installation directory
            if isdeployed % Stand-alone mode.
                [~, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = 1;
            end

            %  3D Viewer support-file check 
            if isdeployed
                runtimeRoot = matlabroot;
                volumeDir   = fullfile(runtimeRoot, 'toolbox', 'images', 'volume');
                if ~exist(volumeDir, 'dir') && ~isequal(currentDir, 1)
                    fallbackVolume = fullfile(currentDir, 'volume');
                    if exist(fallbackVolume, 'dir')
                        selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                            sprintf(['Your MATLAB Runtime (%s) is missing files needed ', ...
                                     'by the 3D Viewer. Replace them now? You may need ', ...
                                     'to restart as an administrator on Windows.'], runtimeRoot), ...
                            'MATLAB Runtime Missing Files', 'Icon', 'warning');
                        if strcmp(selection, 'OK')
                            [status, msg] = copyfile(fallbackVolume, volumeDir);
                            if status
                                uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                                    "Missing MATLAB Runtime files successfully replaced.", ...
                                    "", "Options", {'OK'}, "DefaultOption", 1, "Icon", "success");
                            else
                                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                                    sprintf(['Error replacing missing files: %s\n\n', ...
                                             'Please restart the application as ', ...
                                             'administrator and try again.'], msg), ...
                                    'Error Replacing Missing MATLAB Runtime Files');
                            end
                        end
                    end
                end
            end

            % Populate transforms list box on Registration tab
            app.StageSettings = suMRak.defaultStageSettings();
            refreshTransformsListBox(app);
            updateContextMenuState(app);
        end

        % Key press function: suMRakSimpleUtilityMRiAnalysisKitUIFigure
        function suMRakSimpleUtilityMRiAnalysisKitUIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'rightarrow'
                    key = 1;
                case 'leftarrow'
                    key = -1;
                case 'return'
                    if ~isempty(app.FreeROI)
                        ConfirmButtonPushed(app);
                    end
                case 'delete'
                    if ~isempty(app.FreeROI)
                        DeleteButtonPushed(app);
                    end
            end

            switch app.TabGroup.SelectedTab.Title
                case 'Main'
                    try
                        app.SliceSpinner_Preview.Value = app.SliceSpinner_Preview.Value+key;
                        app.SliceSlider_Preview.Value = app.SliceSpinner_Preview.Value;
                        RefreshImagePreview(app);
                    catch
                    end
                case 'Segmentation'
                    try
                        app.SliceSpinner_Segmenter.Value = app.SliceSpinner_Segmenter.Value+key;
                        app.SliceSlider_Segmenter.Value = app.SliceSpinner_Segmenter.Value;
                        RefreshImageSegmenter(app);
                        if app.PerspectiveViewButton.Value == true
                            RefreshImageSegmenterHelperUp(app);
                            RefreshImageSegmenterHelperDown(app);
                        end

                        % Check if there is a saved non-zero brain mask to enable hemisphere segmentation
                        if ~isequal(app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value), false(app.ExpDimsSegmenter(1:2))) 
                            app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
                        else
                            app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
                        end
                    catch
                    end
            end
        end

        % Button pushed function: LoadPvDatasetsFileButton
        function LoadPvDatasetsFileButtonPushed(app, event)
                        
            % Draw progress box 
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Purging old temporary data");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            % Create new folder for loading operations inside working temp
            % folder
            loading_folder = strcat(app.WorkingFolder, filesep, 'Loading');
            % Purge old temporary data
            try
                rmdir(loading_folder, "s");
            catch
            end
            mkdir(loading_folder);       
            
            % Select .PvDatasets file, check for cancel and update the edit field text
            progress.Value = 0.2;
            progress.Message = "Selecting an archive file";
            [file, folder] = uigetfile('*.PvDatasets', 'Select a Bruker archive file');
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            if isequal(file, 0)
                
                return;
            end
            ResetEnvironment(app);
            app.PvDatasetsFile = fullfile(folder, file);
            app.ArchiveEditField.Value = app.PvDatasetsFile;
            
            % Extract the archive file to temp loading folder
            progress.Value = 0.4;
            progress.Message = "Unzipping selected archive";
            unzip(app.PvDatasetsFile, loading_folder);
            temp = struct2cell(dir(fullfile(loading_folder, '*')));
            
            % Check if selected archive is a study or a subject file
            progress.Value = 0.6;
            progress.Message = "Selecting a study to load";
            if regexp(temp{1,end}, '.*\.study$') == 1
                app.StudyPath = fullfile(loading_folder, temp{1,end-1});
            elseif regexp(temp{1,end}, '.*\.subject$') == 1
                app.StudyPath = uigetdir(loading_folder, 'Select a study folder to use');
                figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            else
                uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Unkown archive type.", "Error Loading PvDatasets","Options",{'OK'},"DefaultOption",1, "Icon","error");
                return;
            end

            
            % Create property arrays of sequences in selected study
            progress.Value = 0.8;
            progress.Message = "Importing individual experiments";
            visu_AcqProt = {'None'};
            exp_ImageData = {[]};
            voxel_Dims_X = 0;
            voxel_Dims_Y = 0;
            slice_Thickness = 0;
            slice_Gap = 0;
            dim_Units = "Unspecifed";
            rot_Matrix = {[]};

            filelist_studyPath = dir(app.StudyPath);
            filelist_studyPath = sort(rmmissing(str2double({filelist_studyPath.name})));
            
            for i=filelist_studyPath % List through experiments 
                experiment_dir = fullfile(app.StudyPath, filesep, num2str(i), filesep, 'pdata');
                filelist_expDir = dir(experiment_dir);
                filelist_expDir = rmmissing(str2double({filelist_expDir.name}));

                for j=filelist_expDir % List through processings
                    progress.Message = "Importing experiment nr." + num2str(i) + " processing nr." + num2str(j);
                    try
                        % Create image object
                        processing_dir = fullfile(experiment_dir, filesep, num2str(j));
                        imageObj = ImageDataObject(processing_dir);
                        try
                            attempt_AcqProt = imageObj.Visu.VisuAcquisitionProtocol;
                        catch
                            attempt_AcqProt = imageObj.Visu.VisuSeriesTypeId;
                        end
                        
                        % Store properties into respective arrays if a
                        % minimum of 3 dimensions is sastisfied
                        n_dims = numel(size(squeeze(imageObj.data)));
                        if n_dims >= 3 
                            exp_ImageData = cat(1, exp_ImageData, {squeeze(pagetranspose(imageObj.data))});
                            visu_AcqProt = cat(1, visu_AcqProt, append(num2str(i), '-', num2str(j), '. ', attempt_AcqProt));
                            try
                                voxel_Dims = imageObj.Visu.VisuCoreExtent./imageObj.Visu.VisuCoreSize;
                                voxel_Dims_X = cat(1, voxel_Dims_X, voxel_Dims(1)); 
                                voxel_Dims_Y = cat(1, voxel_Dims_Y, voxel_Dims(2));
                            catch
                                voxel_Dims_X = cat(1, voxel_Dims_X, 0); 
                                voxel_Dims_Y = cat(1, voxel_Dims_Y, 0);
                            end
                            try
                                app.TEvalues(size(visu_AcqProt, 1),1:size(imageObj.Visu.VisuAcqEchoTime, 2)) = imageObj.Visu.VisuAcqEchoTime*10^-3;
                                app.TRvalues(size(visu_AcqProt, 1),1:size(imageObj.Visu.VisuAcqRepetitionTime, 2)) = imageObj.Visu.VisuAcqRepetitionTime*10^-3;
                            catch
                            end
                            try
                                app.TIvalues(size(visu_AcqProt, 1),1:size(imageObj.Visu.VisuAcqInversionTime, 2)) = imageObj.Visu.VisuAcqInversionTime*10^-3;
                            catch
                            end
                            try
                                if numel(voxel_Dims) == 3
                                    slice_Thickness = cat(1, slice_Thickness, voxel_Dims(3));
                                else
                                    slice_Thickness = cat(1, slice_Thickness, imageObj.Visu.VisuCoreFrameThickness);
                                end
                            catch
                                slice_Thickness = cat(1, slice_Thickness, 0);
                            end
                            try
                                slice_Gap = cat(1, slice_Gap, imageObj.Visu.VisuCoreSlicePacksSliceDist-imageObj.Visu.VisuCoreFrameThickness);
                            catch
                                slice_Gap = cat(1, slice_Gap, 0);
                            end
                            try
                                dim_Units = cat(1, dim_Units, strjoin(string(imageObj.Visu.VisuCoreUnits)));
                            catch
                                dim_Units = cat(1, dim_Units, "Unspecifed");
                            end
                            try
                                rot_Matrix = cat(1, rot_Matrix, {reshape(round(imageObj.Visu.VisuCoreOrientation(1,:)), 3, 3)});
                            catch
                                rot_Matrix = cat(1, rot_Matrix, {zeros(3,3)});
                            end
                        else
                        end
                    catch
                    end
                end
            end

            % Construct experiment property table
            progress.Value = 0.9;
            progress.Message = "Constructing property table";
            exp_ID = visu_AcqProt;
            variable_Names = app.ExpTableVars;
            app.ExperimentPropertyTable = table(exp_ID, exp_ImageData, ...
                app.TEvalues(1:size(visu_AcqProt, 1),1), app.TRvalues(1:size(visu_AcqProt, 1),1), ...
                voxel_Dims_X, voxel_Dims_Y, slice_Thickness, ...
                slice_Gap, dim_Units, rot_Matrix, 'RowNames', visu_AcqProt, 'VariableNames', variable_Names);
            app.UITable_Preview.Data=app.ExperimentPropertyTable(2:end,:);
            app.UITable_Preview.ColumnName = variable_Names;

            % Populate text fields from last loaded imageObj for study info
            progress.Message = "Populating information fields";
            try
                app.SubjectIDEditField.Value = imageObj.Visu.VisuSubjectId;
            catch
                app.SubjectIDEditField.Value = "No valid ID found.";
            end
            try
                app.StudyIDEditField.Value = imageObj.Visu.VisuStudyId;
            catch
                app.StudyIDEditField.Value = "No valid ID found.";
            end
            try
                app.SubjectCommentEditField.Value = regexprep(imageObj.Visu.VisuSubjectComment,'(\\t|\\n|\\r)','');
            catch
                app.SubjectCommentEditField.Value = "No valid comment found.";
            end
            try
                app.StudyCommentEditField.Value = regexprep(imageObj.Visu.VisuStudyDescription,'(\\t|\\n|\\r)','');
            catch
                app.StudyCommentEditField.Value = "No valid comment found.";
            end
            try
                app.SubjectTypeEditField.Value = imageObj.Visu.VisuSubjectType;
            catch
                app.SubjectTypeEditField.Value = "Subject type not specified.";
            end
            try
                app.SexEditField.Value = imageObj.Visu.VisuSubjectSex;
            catch
                app.SexEditField.Value = "Subject sex not specified.";
            end
            try
                app.WeightEditField.Value = num2str(imageObj.Visu.VisuSubjectWeight);
            catch
                app.WeightEditField.Value = "Subject weight not specified.";
            end
            try
                app.StudyStartDateEditField.Value = string(datetime(str2double(imageObj.Visu.VisuStudyDate(2:5)), ...
                    str2double(imageObj.Visu.VisuStudyDate(7:8)),str2double(imageObj.Visu.VisuStudyDate(10:11)), ...
                    str2double(imageObj.Visu.VisuStudyDate(13:14)),str2double(imageObj.Visu.VisuStudyDate(16:17)), ...
                    str2double(imageObj.Visu.VisuStudyDate(19:20)), 'Format', 'dd.MM.yyyy'));
            catch
                app.StudyStartDateEditField.Value = "Study start date not specified.";
            end
            try
                app.StudyStartTimeEditField.Value = string(datetime(str2double(imageObj.Visu.VisuStudyDate(2:5)), ...
                    str2double(imageObj.Visu.VisuStudyDate(7:8)),str2double(imageObj.Visu.VisuStudyDate(10:11)), ...
                    str2double(imageObj.Visu.VisuStudyDate(13:14)),str2double(imageObj.Visu.VisuStudyDate(16:17)), ...
                    str2double(imageObj.Visu.VisuStudyDate(19:20)), 'Format', 'HH:mm:ss'));
            catch
                app.StudyStartTimeEditField.Value = "Study start time not specified.";
            end
            try
                BirthDate = datetime(str2double(imageObj.Visu.VisuSubjectBirthDate(1:4)), ...
                    str2double(imageObj.Visu.VisuSubjectBirthDate(5:6)),str2double(imageObj.Visu.VisuSubjectBirthDate(7:8)));
                StudyDate = datetime(str2double(imageObj.Visu.VisuStudyDate(2:5)),str2double(imageObj.Visu.VisuStudyDate(7:8)), ...
                    str2double(imageObj.Visu.VisuStudyDate(10:11)),str2double(imageObj.Visu.VisuStudyDate(13:14)), ...
                    str2double(imageObj.Visu.VisuStudyDate(16:17)),str2double(imageObj.Visu.VisuStudyDate(19:20)));
                app.SubjectAgeEditField.Value = num2str(floor(days(StudyDate - BirthDate)));
            catch
                app.SubjectAgeEditField.Value = "Subject age not specified.";
            end

            % Update drop down items
            app.PreviewDropDown.Items = exp_ID;
            app.DropDownItemsCombined = exp_ID;
            app.SegmentDropDown.Items = app.DropDownItemsCombined;
            app.SelectTimeAlignmentDropDown.Items = app.DropDownItemsCombined;
            app.SelectPreMapDropDown.Items = app.DropDownItemsCombined;
            app.Select3DViewerDropDown.Items = app.DropDownItemsCombined;
            app.CreatesuMRakFolderButton.Enable = 'on';
            
            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";

            
        end

        % Button pushed function: LoadsuMRakFolderButton
        function LoadsuMRakFolderButtonPushed(app, event)
            % Draw progress box
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Selecting suMRak folder");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow    
            
            % Select suMRak folder, check for cancel and update the edit field text
            progress.Value = 0.2;
            folder_path = uigetdir;
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            env_Path = string(folder_path) + filesep + "exported_environment" + filesep + "main_properties.mat";
            if exist(env_Path, 'file')
                app.ArchiveEditField.Value = folder_path;
                progress.Value = 0.6;
                progress.Message = "Importing saved data";

                % Load properties from saved environment
                LoadedProps = load(env_Path);
                
                % Set app properties 
                app.TEvalues = LoadedProps.TEValues;
                app.TRvalues = LoadedProps.TRValues;
                app.TIvalues = LoadedProps.TIValues;
                app.ExperimentPropertyTable = LoadedProps.ExperimentPropertyTable; 
                app.SavedTable = LoadedProps.SavedTable; 
                app.SubjectIDEditField.Value = LoadedProps.SubjectID;
                app.StudyIDEditField.Value = LoadedProps.StudyID;
                app.SubjectCommentEditField.Value = LoadedProps.SubjectComment;
                app.StudyCommentEditField.Value = LoadedProps.StudyComment;
                app.SubjectTypeEditField.Value = LoadedProps.SubjectType;
                app.SexEditField.Value = LoadedProps.Sex;
                app.WeightEditField.Value = LoadedProps.Weight;
                app.StudyStartDateEditField.Value = LoadedProps.StudyStartDate;
                app.StudyStartTimeEditField.Value = LoadedProps.StudyStartTime;
                app.SubjectAgeEditField.Value = LoadedProps.SubjectAge;
                app.DropDownItemsSavedOnly = LoadedProps.DropDownItemsSavedOnly;
                app.DropDownItemsCombined = LoadedProps.DropDownItemsCombined;
                
                % Update app drop down menus
                app.PreviewDropDown.Items = LoadedProps.PreviewDropDownItems;
                app.SegmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectTimeAlignmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectPreMapDropDown.Items = app.DropDownItemsCombined;
                app.Select3DViewerDropDown.Items = app.DropDownItemsCombined;
                app.SelectResultsDropDown.Items = app.DropDownItemsSavedOnly;
                app.SelectfixedDropDown.Items = app.DropDownItemsSavedOnly;
                app.SelectmovingDropDown.Items = app.DropDownItemsSavedOnly;
                app.SelectparameterDropDown.Items = app.DropDownItemsSavedOnly;

                % Set Preview Table
                app.UITable_Preview.Data=app.ExperimentPropertyTable(2:end,:);
                variable_Names = app.ExpTableVars;
                app.UITable_Preview.ColumnName = variable_Names;
                
                % Set export folder to loaded suMRak folder and cd
                app.ExportFolderPath = string(folder_path);
                cd(app.ExportFolderPath);

                % Enable export Environment
                app.ExportEnvironmentButton.Enable = 'on';

                % Refresh all six "Export Data" buttons based on current state.
                refreshExportButtonState(app);

                % close the dialog box
                progress.Value = 1;
                progress.Message = "Done!";

                
            else
                
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'No saved environment was found in selected directory.', 'No Environment Found')
            end
        end

        % Button pushed function: LoadBrukerStudyButton
        function LoadBrukerStudyButtonPushed(app, event)
            
            % Draw progress box
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Selecting Bruker study");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow
            
            % Select Bruker study folder, check for cancel and update the edit field text
            progress.Value = 0.2;
            app.StudyPath = uigetdir; 
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            if isequal(app.StudyPath, 0)
                
                return;
            end
            ResetEnvironment(app);
            app.ArchiveEditField.Value = app.StudyPath;
           
             % Create property arrays of sequences in selected study
            progress.Value = 0.6;
            progress.Message = "Importing individual experiments";
            visu_AcqProt = {'None'};
            exp_ImageData = {[]};
            voxel_Dims_X = 0;
            voxel_Dims_Y = 0;
            slice_Thickness = 0;
            slice_Gap = 0;
            dim_Units = "Unspecifed";
            rot_Matrix = {[]};

            filelist_studyPath = dir(app.StudyPath);
            filelist_studyPath = sort(rmmissing(str2double({filelist_studyPath.name})));
            
            for i=filelist_studyPath % List through experiments 
                experiment_dir = fullfile(app.StudyPath, filesep, num2str(i), filesep, 'pdata');
                filelist_expDir = dir(experiment_dir);
                filelist_expDir = rmmissing(str2double({filelist_expDir.name}));

                for j=filelist_expDir % List through processings
                    progress.Message = "Importing experiment nr." + num2str(i) + " processing nr." + num2str(j);
                    try
                        % Create image object
                        processing_dir = fullfile(experiment_dir, filesep, num2str(j));
                        imageObj = ImageDataObject(processing_dir);
                        try
                            attempt_AcqProt = imageObj.Visu.VisuAcquisitionProtocol;
                        catch
                            attempt_AcqProt = imageObj.Visu.VisuSeriesTypeId;
                        end
                        
                        % Store properties into respective arrays if a
                        % minimum of 3 dimensions is sastisfied
                        n_dims = numel(size(squeeze(imageObj.data)));
                        if n_dims >= 3 
                            exp_ImageData = cat(1, exp_ImageData, {squeeze(pagetranspose(imageObj.data))});
                            visu_AcqProt = cat(1, visu_AcqProt, append(num2str(i), '-', num2str(j), '. ', attempt_AcqProt));
                            try
                                voxel_Dims = imageObj.Visu.VisuCoreExtent./imageObj.Visu.VisuCoreSize;
                                voxel_Dims_X = cat(1, voxel_Dims_X, voxel_Dims(1)); 
                                voxel_Dims_Y = cat(1, voxel_Dims_Y, voxel_Dims(2));
                            catch
                                voxel_Dims_X = cat(1, voxel_Dims_X, 0); 
                                voxel_Dims_Y = cat(1, voxel_Dims_Y, 0);
                            end
                            try
                                app.TEvalues(size(visu_AcqProt, 1),1:size(imageObj.Visu.VisuAcqEchoTime, 2)) = imageObj.Visu.VisuAcqEchoTime*10^-3;
                                app.TRvalues(size(visu_AcqProt, 1),1:size(imageObj.Visu.VisuAcqRepetitionTime, 2)) = imageObj.Visu.VisuAcqRepetitionTime*10^-3;
                            catch
                            end
                            try
                                app.TIvalues(size(visu_AcqProt, 1),1:size(imageObj.Visu.VisuAcqInversionTime, 2)) = imageObj.Visu.VisuAcqInversionTime*10^-3;
                            catch
                            end
                            try
                                if numel(voxel_Dims) == 3
                                    slice_Thickness = cat(1, slice_Thickness, voxel_Dims(3));
                                else
                                    slice_Thickness = cat(1, slice_Thickness, imageObj.Visu.VisuCoreFrameThickness);
                                end
                            catch
                                slice_Thickness = cat(1, slice_Thickness, 0);
                            end
                            try
                                slice_Gap = cat(1, slice_Gap, imageObj.Visu.VisuCoreSlicePacksSliceDist-imageObj.Visu.VisuCoreFrameThickness);
                            catch
                                slice_Gap = cat(1, slice_Gap, 0);
                            end
                            try
                                dim_Units = cat(1, dim_Units, strjoin(string(imageObj.Visu.VisuCoreUnits)));
                            catch
                                dim_Units = cat(1, dim_Units, "Unspecifed");
                            end
                            try
                                rot_Matrix = cat(1, rot_Matrix, {reshape(round(imageObj.Visu.VisuCoreOrientation(1,:)), 3, 3)});
                            catch
                                rot_Matrix = cat(1, rot_Matrix, {zeros(3,3)});
                            end
                        else
                        end
                    catch
                    end
                end
            end

            % Construct experiment property table
            progress.Value = 0.9;
            progress.Message = "Constructing property table";
            exp_ID = visu_AcqProt;
            variable_Names = app.ExpTableVars;
            app.ExperimentPropertyTable = table(exp_ID, exp_ImageData, ...
                app.TEvalues(1:size(visu_AcqProt, 1),1), app.TRvalues(1:size(visu_AcqProt, 1),1), ...
                voxel_Dims_X, voxel_Dims_Y, slice_Thickness, ...
                slice_Gap, dim_Units, rot_Matrix, 'RowNames', visu_AcqProt, 'VariableNames', variable_Names);
            app.UITable_Preview.Data=app.ExperimentPropertyTable(2:end,:);
            app.UITable_Preview.ColumnName = variable_Names;

            % Populate text fields from last loaded imageObj for study info
            progress.Message = "Populating information fields";
            try
                app.SubjectIDEditField.Value = imageObj.Visu.VisuSubjectId;
            catch
                app.SubjectIDEditField.Value = "No valid ID found.";
            end
            try
                app.StudyIDEditField.Value = imageObj.Visu.VisuStudyId;
            catch
                app.StudyIDEditField.Value = "No valid ID found.";
            end
            try
                app.SubjectCommentEditField.Value = regexprep(imageObj.Visu.VisuSubjectComment,'(\\t|\\n|\\r)','');
            catch
                app.SubjectCommentEditField.Value = "No valid comment found.";
            end
            try
                app.StudyCommentEditField.Value = regexprep(imageObj.Visu.VisuStudyDescription,'(\\t|\\n|\\r)','');
            catch
                app.StudyCommentEditField.Value = "No valid comment found.";
            end
            try
                app.SubjectTypeEditField.Value = imageObj.Visu.VisuSubjectType;
            catch
                app.SubjectTypeEditField.Value = "Subject type not specified.";
            end
            try
                app.SexEditField.Value = imageObj.Visu.VisuSubjectSex;
            catch
                app.SexEditField.Value = "Subject sex not specified.";
            end
            try
                app.WeightEditField.Value = num2str(imageObj.Visu.VisuSubjectWeight);
            catch
                app.WeightEditField.Value = "Subject weight not specified.";
            end
            try
                app.StudyStartDateEditField.Value = string(datetime(str2double(imageObj.Visu.VisuStudyDate(2:5)), ...
                    str2double(imageObj.Visu.VisuStudyDate(7:8)),str2double(imageObj.Visu.VisuStudyDate(10:11)), ...
                    str2double(imageObj.Visu.VisuStudyDate(13:14)),str2double(imageObj.Visu.VisuStudyDate(16:17)), ...
                    str2double(imageObj.Visu.VisuStudyDate(19:20)), 'Format', 'dd.MM.yyyy'));
            catch
                app.StudyStartDateEditField.Value = "Study start date not specified.";
            end
            try
                app.StudyStartTimeEditField.Value = string(datetime(str2double(imageObj.Visu.VisuStudyDate(2:5)), ...
                    str2double(imageObj.Visu.VisuStudyDate(7:8)),str2double(imageObj.Visu.VisuStudyDate(10:11)), ...
                    str2double(imageObj.Visu.VisuStudyDate(13:14)),str2double(imageObj.Visu.VisuStudyDate(16:17)), ...
                    str2double(imageObj.Visu.VisuStudyDate(19:20)), 'Format', 'HH:mm:ss'));
            catch
                app.StudyStartTimeEditField.Value = "Study start time not specified.";
            end
            try
                BirthDate = datetime(str2double(imageObj.Visu.VisuSubjectBirthDate(1:4)), ...
                    str2double(imageObj.Visu.VisuSubjectBirthDate(5:6)),str2double(imageObj.Visu.VisuSubjectBirthDate(7:8)));
                StudyDate = datetime(str2double(imageObj.Visu.VisuStudyDate(2:5)),str2double(imageObj.Visu.VisuStudyDate(7:8)), ...
                    str2double(imageObj.Visu.VisuStudyDate(10:11)),str2double(imageObj.Visu.VisuStudyDate(13:14)), ...
                    str2double(imageObj.Visu.VisuStudyDate(16:17)),str2double(imageObj.Visu.VisuStudyDate(19:20)));
                app.SubjectAgeEditField.Value = num2str(floor(days(StudyDate - BirthDate)));
            catch
                app.SubjectAgeEditField.Value = "Subject age not specified.";
            end

            % Update drop down items
            app.PreviewDropDown.Items = exp_ID;
            app.DropDownItemsCombined = exp_ID;
            app.SegmentDropDown.Items = app.DropDownItemsCombined;
            app.SelectTimeAlignmentDropDown.Items = app.DropDownItemsCombined;
            app.SelectPreMapDropDown.Items = app.DropDownItemsCombined;
            app.Select3DViewerDropDown.Items = app.DropDownItemsCombined;
            app.CreatesuMRakFolderButton.Enable = 'on';
            
            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";

            
        end

        % Button pushed function: LoadSingleNIfTIButton
        function LoadSingleNIfTIButtonPushed(app, event)
            
            % Get nifti image data and info
            [temp_file, temp_dir] = uigetfile('.nii');
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            nifti_info = niftiinfo(cat(2, temp_dir, temp_file));
            try
                exp_ImageData = {pagetranspose(double(niftiread(cat(2, temp_dir, temp_file))))};
            catch ME
                if strcmp(ME.identifier, "MATLAB:nomem")
                    uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'SuMRak ran out of memory; the selected NIfTI may be too large.', 'Memory Error', 'Icon','error');
                    return
                else
                    uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Error loading selected NIfTI; please check file validity.', 'NIfTI Loading Error', 'Icon','error');
                    return
                end
            end
            
            % Extract data from niftinfo
            nifti_ID = {temp_file};
            voxel_Dims_X = nifti_info.PixelDimensions(1);
            voxel_Dims_Y = nifti_info.PixelDimensions(2);
            try
                slice_Thickness = nifti_info.PixelDimensions(3);
            catch
                slice_Thickness = 0;
            end
            slice_Gap = 0;
            switch nifti_info.SpaceUnits
                case 'Millimeter'
                    dim_Units = join(repmat("mm",1,numel(size(cell2mat(exp_ImageData)))),' ');
                case 'Micron'
                    dim_Units = join(repmat("um",1,numel(size(cell2mat(exp_ImageData)))),' ');
                case 'Meter'
                    dim_Units = join(repmat("m",1,numel(size(cell2mat(exp_ImageData)))),' ');
                case 'Unknown'
                    dim_Units = "Unspecified";
            end
            rot_Matrix = {nifti_info.Transform.T(1:3,1:3)};
            
            variable_Names = app.ExpTableVars;
            % Load nifti data
            if isequal(app.ExperimentPropertyTable, table())
                % No other data loaded
                nifti_ID = cat(1, {'None'}, nifti_ID);
                exp_ImageData = cat(1, {[]}, exp_ImageData);
                voxel_Dims_X = cat(1, 0, voxel_Dims_X);
                voxel_Dims_Y = cat(1, 0, voxel_Dims_Y);
                slice_Thickness = cat(1, 0, slice_Thickness);
                slice_Gap = cat(1, 0, slice_Gap);
                dim_Units = cat(1, "Unspecifed", dim_Units);
                rot_Matrix = cat(1, {[]}, rot_Matrix);
                
                % Update experiment property table
                app.ExperimentPropertyTable = table(nifti_ID, exp_ImageData, ...
                [0;0], [0;0], voxel_Dims_X, voxel_Dims_Y, slice_Thickness, ...
                slice_Gap, dim_Units, rot_Matrix, 'RowNames', nifti_ID, 'VariableNames', variable_Names);
                app.UITable_Preview.Data=app.ExperimentPropertyTable(2:end,:);
                app.UITable_Preview.ColumnName = variable_Names;

                % Populate ID fields
                app.SubjectIDEditField.Value = temp_file;
                app.StudyIDEditField.Value = "Nifti_Single_Experiment";

                % Update drop down items
                app.PreviewDropDown.Items = nifti_ID;
                app.DropDownItemsCombined = nifti_ID;
                app.SegmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectTimeAlignmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectPreMapDropDown.Items = app.DropDownItemsCombined;
                app.Select3DViewerDropDown.Items = app.DropDownItemsCombined;
                app.CreatesuMRakFolderButton.Enable = 'on';
            else
                % Other data already loaded
                % Create temp table
                temp_table = table(nifti_ID, exp_ImageData, ...
                0, 0, voxel_Dims_X, voxel_Dims_Y, slice_Thickness, ...
                slice_Gap, dim_Units, rot_Matrix, 'RowNames', nifti_ID, 'VariableNames', variable_Names);
                % Update experiment property table
                app.ExperimentPropertyTable = cat(1, app.ExperimentPropertyTable, temp_table);
                app.UITable_Preview.Data=app.ExperimentPropertyTable(2:end,:);
                app.UITable_Preview.ColumnName = variable_Names;
                % Update drop down items
                app.PreviewDropDown.Items = cat(2, app.PreviewDropDown.Items, nifti_ID);
                app.DropDownItemsCombined = cat(1, app.DropDownItemsCombined, nifti_ID);
                app.SegmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectTimeAlignmentDropDown.Items = app.DropDownItemsCombined;
                app.SelectPreMapDropDown.Items = app.DropDownItemsCombined;
                app.Select3DViewerDropDown.Items = app.DropDownItemsCombined;
            end            
        end

        % Button pushed function: CreatesuMRakFolderButton
        function CreatesuMRakFolderButtonPushed(app, event)
            selected_path = uigetdir;
            app.ExportFolderPath = string(selected_path) + filesep + app.SubjectIDEditField.Value + "_" + app.StudyIDEditField.Value;
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            if exist(app.ExportFolderPath, 'dir')
                selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'suMRak Study Folder already existes in chosen directory. Are you sure you want to overwrite?', 'Confirm Overwrite', ...
                    'Icon', 'warning');
                switch selection
                    case 'OK'
                        cd(app.ExportFolderPath);
                    case 'Cancel'
                        return
                end  
            else
                mkdir(app.ExportFolderPath);
                cd(app.ExportFolderPath);
            end
            
            infofile = fopen('info.txt', 'w+');
            if infofile == -1
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    'Unable to create info.txt in the study folder.', 'File Error');
                return
            end
            cleanupFile = onCleanup(@() fclose(infofile));  
            fprintf(infofile, "########## suMRak study info %s\r\n\r\n", app.Version);
            fprintf(infofile, "Subject ID: %s\r\n", app.SubjectIDEditField.Value);
            fprintf(infofile, "Subject Comment: %s\r\n", app.SubjectCommentEditField.Value);
            fprintf(infofile, "Subject Age: %s\r\n", app.SubjectAgeEditField.Value);
            fprintf(infofile, "Subject Type: %s\r\n", app.SubjectTypeEditField.Value);
            fprintf(infofile, "Subject Sex: %s\r\n", app.SexEditField.Value);
            fprintf(infofile, "Subject Weight: %s\r\n", app.WeightEditField.Value);
            fprintf(infofile, "\r\n");
            fprintf(infofile, "Study ID: %s\r\n", app.StudyIDEditField.Value);
            fprintf(infofile, "Study Comment: %s\r\n", app.StudyCommentEditField.Value);
            fprintf(infofile, "Study Start Time: %s\r\n", app.StudyStartTimeEditField.Value);
            fprintf(infofile, "Study Start Date: %s\r\n", app.StudyStartDateEditField.Value);
            % cleanupFile closes the handle automatically — on normal return,
            % on error, or if the user interrupts with Ctrl-C.
            
            imagedata_Column = table2array(app.ExperimentPropertyTable(:,2));
            imagedata_String = "";
            for i = 1:numel(imagedata_Column)
                current_Data = cell2mat(imagedata_Column(i));
                imagedata_Name = strjoin(string(size(current_Data)), 'x') + " " + string(class(current_Data));
                imagedata_String = cat(1, imagedata_String, imagedata_Name);
            end
            imagedata_String = imagedata_String(2:end);
            writing_Table = cat(2, app.ExperimentPropertyTable(:,1), table(imagedata_String, 'VariableNames', {'Image Data'}), app.ExperimentPropertyTable(:,3:end-1));
            writetable(writing_Table(2:end,:), 'data_info.xlsx');
            
            % Enable export Environment
            app.ExportEnvironmentButton.Enable = 'on';

            % Refresh all six "Export Data" buttons based on current state.
            refreshExportButtonState(app);
        end

        % Button pushed function: ExportEnvironmentButton
        function ExportEnvironmentButtonPushed(app, event)
            
            % Draw progress box
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Retrieving environment properties");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow    

            % Get main environment properties
            progress.Value = 0.3;

            ExperimentPropertyTable = app.ExperimentPropertyTable; %#ok<ADPROPLC> 
            SavedTable = app.SavedTable; %#ok<ADPROPLC> 
            SubjectID = app.SubjectIDEditField.Value;
            StudyID = app.StudyIDEditField.Value;
            SubjectComment = app.SubjectCommentEditField.Value;
            StudyComment = app.StudyCommentEditField.Value;
            SubjectType = app.SubjectTypeEditField.Value;
            Sex = app.SexEditField.Value;
            Weight = app.WeightEditField.Value;
            StudyStartDate = app.StudyStartDateEditField.Value;
            StudyStartTime = app.StudyStartTimeEditField.Value;
            SubjectAge = app.SubjectAgeEditField.Value;
            DropDownItemsSavedOnly = app.DropDownItemsSavedOnly; %#ok<ADPROPLC>
            PreviewDropDownItems = app.PreviewDropDown.Items; 
            DropDownItemsCombined = app.DropDownItemsCombined; %#ok<ADPROPLC>
            TEValues = app.TEvalues;
            TRValues = app.TRvalues;
            TIValues = app.TIvalues;
            
            % Save environment inside export folder
            progress.Value = 0.6;
            progress.Message = "Exporting environment";
            env_Path = app.ExportFolderPath + filesep + "exported_environment";
            if exist(env_Path, 'dir')
                selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Overwrite currently saved environment instance in export folder?', 'Confirm Overwrite', ...
                    'Icon', 'warning');
                switch selection
                    case 'OK'
                        env_Path = env_Path + filesep + "main_properties.mat";
                        save(env_Path, "ExperimentPropertyTable", "SavedTable", "SubjectID", "StudyID", ...
                            "SubjectComment", "StudyComment", "SubjectType", "Sex", "Weight", "StudyStartDate", ...
                            "StudyStartTime", "SubjectAge", "DropDownItemsSavedOnly", "DropDownItemsCombined", "PreviewDropDownItems", ...
                            "TEValues", "TRValues", "TIValues")
                    case 'Cancel'
                        return
                end  
            else
                mkdir(env_Path);
                env_Path = env_Path + filesep + "main_properties.mat";
                save(env_Path, "ExperimentPropertyTable", "SavedTable", "SubjectID", "StudyID", ...
                            "SubjectComment", "StudyComment", "SubjectType", "Sex", "Weight", "StudyStartDate", ...
                            "StudyStartTime", "SubjectAge", "DropDownItemsSavedOnly", "DropDownItemsCombined", "PreviewDropDownItems", ...
                            "TEValues", "TRValues", "TIValues")
            end

            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";


            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Environment data sucessfully exported.", "","Options",{'OK'},"DefaultOption",1, "Icon","success");
        end

        % Button pushed function: ResetEnvironmentButton
        function ResetEnvironmentButtonButtonPushed(app, event)
            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Reset environment variables and saved data?','Confirm Reset',...
                        'Icon','warning');
            switch selection 
                case 'OK'  
                    ResetEnvironment(app);
                case 'Cancel'
                    return
            end
        end

        % Cell edit callback: UITable_Preview
        function UITable_PreviewCellEdit(app, event)
 
            % Update Experiment property table
            app.ExperimentPropertyTable(2:end,:) = app.UITable_Preview.Data;         
        end

        % Value changed function: PreviewDropDown
        function PreviewDropDownValueChanged(app, event)
            value = app.PreviewDropDown.Value;  

            % If 'None' is selected, clear UIaxes, disable controls and return
            if value == "None"
                cla(app.UIAxes_Preview);
                app.SliceSlider_Preview.Enable = 'off';
                app.SliceSpinner_Preview.Enable = 'off';
                app.BrightnessSlider_Preview.Enable = 'off';
                app.ContrastSlider_Preview.Enable = 'off';
                app.Dim4Slider_Preview.Enable = 'off';
                app.Dim5Slider_Preview.Enable = 'off';
                app.TurboButton_Preview.Enable = 'off';
                app.GreyscaleButton_Preview.Enable = 'off';
                app.SaveDataButton_Preview.Enable = 'off';
                app.ExportDataButton_Preview.Enable = 'off';
                return
            end
            
            % Get selected sequence image data
            app.PreviewImageData = cell2mat(app.ExperimentPropertyTable.("Image data")(value));
            
            % Initialize default slider values
            app.Dim5Slider_Preview.Value = 1;
            app.Dim4Slider_Preview.Value = 1;
            app.SliceSlider_Preview.Value = 1;
            app.SliceSpinner_Preview.Value = 1;
            app.BrightnessSlider_Preview.Value = 0;
            app.ContrastSlider_Preview.Value = 0;

            % Get data dimension sizes, set slider limits
            app.ExpDimsPreview = size(app.PreviewImageData);
            dim3_size = app.ExpDimsPreview(3);
            app.SliceSlider_Preview.Limits = [1, dim3_size];
            app.SliceSpinner_Preview.Limits = [1, dim3_size];
            
            switch numel(app.ExpDimsPreview)
                case 4
                    dim4_size = app.ExpDimsPreview(4);
                    app.Dim4Slider_Preview.Enable = 'on';
                    app.Dim5Slider_Preview.Enable = 'off';
                    app.Dim4Slider_Preview.Limits = [1, dim4_size];
                case 5
                    dim4_size = app.ExpDimsPreview(4);
                    app.Dim4Slider_Preview.Enable = 'on';
                    app.Dim4Slider_Preview.Limits = [1, dim4_size];
                    dim5_size = app.ExpDimsPreview(5);
                    app.Dim5Slider_Preview.Enable = 'on';
                    app.Dim5Slider_Preview.Limits = [1, dim5_size];
                case 3
                    app.Dim4Slider_Preview.Enable = 'off';
                    app.Dim5Slider_Preview.Enable = 'off';
            end

            % Enable slice, contrast and brightness controls
            app.SliceSlider_Preview.Enable = 'on';
            app.SliceSpinner_Preview.Enable = 'on';
            app.BrightnessSlider_Preview.Enable = 'on';
            app.ContrastSlider_Preview.Enable = 'on';
            app.TurboButton_Preview.Enable = 'on';
            app.GreyscaleButton_Preview.Enable = 'on';
            app.SaveDataButton_Preview.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Preview.Enable = 'on';
            end
            
            % Display sequence
            RefreshImagePreview(app);
            % disableDefaultInteractivity(app.UIAxes_Preview);
            
            % Set interactions of preview uiaxes
            app.UIAxes_Preview.Interactions = [regionZoomInteraction zoomInteraction];
            
        end

        % Button pushed function: ExportDataButton_Preview
        function ExportDataButton_PreviewPushed(app, event)
            ExportImageDataGeneral(app, 'Preview');

            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Image data exported in NIfTI format.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
        end

        % Value changing function: SliceSlider_Preview
        function SliceSlider_PreviewValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSpinner_Preview.Value = event.Source.Value;

            RefreshImagePreview(app);
        end

        % Value changed function: SliceSpinner_Preview
        function SliceSpinner_PreviewValueChanged(app, event)
            changingValue = round(event.Value);
            app.SliceSlider_Preview.Value = changingValue;

            RefreshImagePreview(app);
        end

        % Value changing function: Dim4Slider_Preview
        function Dim4Slider_PreviewValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.Dim4Slider_Preview.Value = event.Source.Value;

            RefreshImagePreview(app);
        end

        % Value changing function: Dim5Slider_Preview
        function Dim5Slider_PreviewValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.Dim5Slider_Preview.Value = event.Source.Value;
            
            RefreshImagePreview(app);
        end

        % Value changing function: ContrastSlider_Preview
        function ContrastSlider_PreviewValueChanging(app, event)
            app.ContrastSlider_Preview.Value = event.Value;
            RefreshImagePreview(app);
        end

        % Value changing function: BrightnessSlider_Preview
        function BrightnessSlider_PreviewValueChanging(app, event)
            app.BrightnessSlider_Preview.Value = event.Value;
            RefreshImagePreview(app);
        end

        % Menu selected function: RotateMenu_Preview
        function RotateMenu_PreviewSelected(app, event)
            
            % Rotate image data, show image
            app.PreviewImageData = rot90(app.PreviewImageData, -1);
            
            RefreshImagePreview(app);
        end

        % Menu selected function: FlipVerticallyMenu_Preview
        function FlipVerticallyMenu_PreviewSelected(app, event)
            
            % Flip image data, show image
            app.PreviewImageData = flipud(app.PreviewImageData);
            
            RefreshImagePreview(app);
        end

        % Menu selected function: FlipHorizontallyMenu_Preview
        function FlipHorizontallyMenu_PreviewSelected(app, event)
            
            % Flip image data, show image
            app.PreviewImageData = fliplr(app.PreviewImageData);
            
            RefreshImagePreview(app);
        end

        % Menu selected function: ResetViewMenu_Preview
        function ResetViewMenu_PreviewSelected(app, event)
            % Reset zoom
            app.UIAxes_Preview.XLim = [-inf inf];
            app.UIAxes_Preview.YLim = [-inf inf];

            % Reset contrast and brightness then refresh
            app.BrightnessSlider_Preview.Value = 0;
            app.ContrastSlider_Preview.Value = 0;
            RefreshImagePreview(app);
        end

        % Selection changed function: ColormapButtonGroup_Preview
        function ColormapButtonGroup_PreviewSelectionChanged(app, event)
            RefreshImagePreview(app);
        end

        % Button pushed function: SaveDataButton_Preview
        function SaveDataButton_PreviewPushed(app, event)
            SaveData(app, 'Preview')
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            value = app.SegmentDropDown.Value;
            
            % If 'None' is selected, clear UIaxes, disable controls and return
            if value == "None"
                cla(app.UIAxes_Segmenter);
                % Sliders/spinners
                app.SliceSlider_Segmenter.Enable = 'off';
                app.SliceSpinner_Segmenter.Enable = 'off';
                app.Dim4Spinner_Segmenter.Enable = 'off';
                app.Dim5Spinner_Segmenter.Enable = 'off';
                app.TurboButton_Segmenter.Enable = 'off';
                app.GreyscaleButton_Segmenter.Enable = 'off';
                app.BrightnessSlider_Segmenter.Enable = 'off';
                app.ContrastSlider_Segmenter.Enable = 'off';
                app.SliceSlider_Segmenter.Value = 1;
                app.SliceSpinner_Segmenter.Value = 1;
                app.Dim4Spinner_Segmenter.Value = 1;
                app.Dim5Spinner_Segmenter.Value = 1;
                app.ContrastSlider_Segmenter.Value = 0;
                app.BrightnessSlider_Segmenter.Value = 0;
                % Buttons/drop downs/panels
                app.CurrentSegmentationDropDown.Enable = 'off';
                app.CurrentSegmentationDropDown.Value = 'Brain';
                app.BrainSegmentationToolsPanel.Visible = 'off';
                app.HemisphereSegmentationToolsPanel.Visible = 'off';
                app.ROISegmentationToolsPanel.Visible = 'off';
                app.SelectionToolsPanel.Visible = 'off';
                app.SaveSegmentedDataButton.Enable = 'off';
                app.ExportDataButton_Segmenter.Enable = 'off';
                app.PerspectiveViewButton.Value = 0;
                return
            end
            
            % Get selected sequence image data from loaded experiments or saved registration experiments
            try
                app.OriginalSegmenterImageData = cell2mat(app.SavedTable.Image(value));
                app.SavedBrainMask = cell2mat(app.SavedTable.BrainMask(value));
                reset_indicator = 0;         
            catch
                try
                    app.OriginalSegmenterImageData = cell2mat(app.ExperimentPropertyTable.("Image data")(value));
                    reset_indicator = 1;
                catch
                end
            end
            app.WorkingSegmenterImageData = app.OriginalSegmenterImageData;

            % Initialize default slider values
            app.Dim5Spinner_Segmenter.Value = 1;
            app.Dim4Spinner_Segmenter.Value = 1;
            app.SliceSlider_Segmenter.Value = 1;
            app.SliceSpinner_Segmenter.Value = 1;
            app.BrightnessSlider_Segmenter.Value = 0;
            app.ContrastSlider_Segmenter.Value = 0;

            % Delete ROI masks
            app.ROIIdentifiers = {};
            app.ROIListListBox.Items = app.ROIIdentifiers;
            app.ROIMask = [];
            
            % Get data dimension sizes
            app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);

            % Fetch voxel dimensions for use with helper UIaxes,
            % enable/disable Perspective View button and Volume ROI segmenter button accordingly
            app.PerspectiveViewButton.Value = 0;
            app.PerspectiveViewButtonValueChanged(app);
            app.PerspectiveViewButton.Enable = "off";
            app.VolROISegmentationToolsButton.Enable = "off";
            if numel(app.ExpDimsSegmenter) >= 3 
                try
                    app.SegmenterDimTriplet = [app.SavedTable.VoxDimX(value) app.SavedTable.VoxDimY(value) app.SavedTable.SliceThickness(value)+app.SavedTable.SliceGap(value)];
                    app.PerspectiveViewButton.Enable = "on";
                    app.VolROISegmentationToolsButton.Enable = "on";
                catch 
                    app.SegmenterDimTriplet = [app.ExperimentPropertyTable.("Voxel dimension X")(value) app.ExperimentPropertyTable.("Voxel dimension Y")(value) app.ExperimentPropertyTable.("Slice Thickness")(value)+app.ExperimentPropertyTable.("Slice Gap")(value)];
                    app.PerspectiveViewButton.Enable = "on";
                    app.VolROISegmentationToolsButton.Enable = "on";
                end
            end

            % Create mask screens
            app.GreenScreen = cat(3, zeros(app.ExpDimsSegmenter(1:2)), ones(app.ExpDimsSegmenter(1:2)), zeros(app.ExpDimsSegmenter(1:2)));
            app.BlueScreen = cat(3, zeros(app.ExpDimsSegmenter(1:2)), zeros(app.ExpDimsSegmenter(1:2)), ones(app.ExpDimsSegmenter(1:2)));
            app.RedScreen = cat(3, ones(app.ExpDimsSegmenter(1:2)), zeros(app.ExpDimsSegmenter(1:2)), zeros(app.ExpDimsSegmenter(1:2)));
            app.YellowScreen = cat(3, ones(app.ExpDimsSegmenter(1:2)), ones(app.ExpDimsSegmenter(1:2)), zeros(app.ExpDimsSegmenter(1:2)));

            try
                dim3_size = app.ExpDimsSegmenter(3);
                app.SliceSpinner_Segmenter.Limits = [1, dim3_size];
                app.SliceSlider_Segmenter.Limits = [1, dim3_size];
            catch
            end
            
            % Disable/Enable UI items based on number of dimensions
            switch numel(app.ExpDimsSegmenter)
                case 5
                    app.SliceSlider_Segmenter.Enable = 'on';
                    app.SliceSpinner_Segmenter.Enable = 'on';
                    dim4_size = app.ExpDimsSegmenter(4);
                    app.Dim4Spinner_Segmenter.Enable = 'on';
                    app.Dim4Spinner_Segmenter.Limits = [1, dim4_size];
                    dim5_size = app.ExpDimsSegmenter(5);
                    app.Dim5Spinner_Segmenter.Enable = 'on';
                    app.Dim5Spinner_Segmenter.Limits = [1, dim5_size];
                    app.PermuteDimensionsMenu.Enable = 'on';
                    app.PermuteMenu_3_4.Enable ='on';
                    app.PermuteMenu_3_5.Enable ='on';
                    app.PermuteMenu_4_5.Enable ='on';

                    % Define Mask matrices
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
                    if reset_indicator == 1
                        app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    end
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter(1:3), 2));

                case 4
                    app.SliceSlider_Segmenter.Enable = 'on';
                    app.SliceSpinner_Segmenter.Enable = 'on';
                    dim4_size = app.ExpDimsSegmenter(4);
                    app.Dim4Spinner_Segmenter.Enable = 'on';
                    app.Dim4Spinner_Segmenter.Limits = [1, dim4_size];
                    app.Dim5Spinner_Segmenter.Enable = 'off';
                    app.PermuteDimensionsMenu.Enable = 'on';
                    app.PermuteMenu_3_4.Enable ='on';
                    app.PermuteMenu_3_5.Enable ='off';
                    app.PermuteMenu_4_5.Enable ='off';

                    % Define Mask matrices
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
                    if reset_indicator == 1
                        app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    end
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter(1:3), 2));

                case 3
                    app.SliceSlider_Segmenter.Enable = 'on';
                    app.SliceSpinner_Segmenter.Enable = 'on';
                    app.Dim4Spinner_Segmenter.Enable = 'off';
                    app.Dim5Spinner_Segmenter.Enable = 'off';
                    app.PermuteDimensionsMenu.Enable = 'off';
                    

                    % Define Mask matrices
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
                    if reset_indicator == 1
                        app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    end
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter(1:3), 2));

                case 2
                    app.SliceSlider_Segmenter.Enable = 'off';
                    app.SliceSpinner_Segmenter.Enable = 'off';
                    app.Dim4Spinner_Segmenter.Enable = 'off';
                    app.Dim5Spinner_Segmenter.Enable = 'off';

                    % Define Mask matrices
                    app.BrainMask = false(app.ExpDimsSegmenter());
                    if reset_indicator == 1
                        app.SavedBrainMask = false(app.ExpDimsSegmenter);
                    end
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter, 2));
            end

            % Enable remaining components
            app.TurboButton_Segmenter.Enable = 'on';
            app.GreyscaleButton_Segmenter.Enable = 'on';
            app.CurrentSegmentationDropDown.Enable = 'on';
            app.CurrentSegmentationDropDown.Value = 'Brain';
            app.BrainSegmentationToolsPanel.Visible = 'on';
            app.HemisphereSegmentationToolsPanel.Visible = 'off';
            app.ROISegmentationToolsPanel.Visible = 'off';
            app.SelectionToolsPanel.Visible = 'on';
            brainPosition = app.BrainSegmentationToolsPanel.Position;
            selectionPosition = app.SelectionToolsPanel.Position;
            selectionPosition(2) = brainPosition(2)-selectionPosition(4);
            app.SelectionToolsPanel.Position = selectionPosition;
            app.SaveSegmentedDataButton.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Segmenter.Enable = 'on';
            end

            % Check if there is a saved non-zero brain mask to enable hemisphere segmentation
            if ~isequal(app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value), false(app.ExpDimsSegmenter(1:2))) 
                app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
            else
                app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
            end

            % Display sequence
            RefreshImageSegmenter(app);

            % Enable contrast and brightness controls
            app.BrightnessSlider_Segmenter.Enable = 'on';
            app.ContrastSlider_Segmenter.Enable = 'on';
            
            % Set interactions of segmenter uiaxes
            disableDefaultInteractivity(app.UIAxes_SegmenterHelperUp);
            app.UIAxes_Segmenter.Interactions = [regionZoomInteraction zoomInteraction];
            
            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];
        end

        % Value changing function: SliceSlider_Segmenter
        function SliceSlider_SegmenterValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSpinner_Segmenter.Value = event.Source.Value;
            
            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];
            
            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            app.AutoClusterButton.Enable = 'off'; % TUrn off auto cluster button

            RefreshImageSegmenter(app);
            if app.PerspectiveViewButton.Value == true
                RefreshImageSegmenterHelperUp(app);
                RefreshImageSegmenterHelperDown(app);
            end

            % Check if there is a saved non-zero brain mask to enable hemisphere segmentation
            if ~isequal(app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value), false(app.ExpDimsSegmenter(1:2))) 
                app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
            else
                if app.CurrentSegmentationDropDown.Value == "Hemisphere"
                    app.BrainSegmentationToolsPanel.Visible = 'on';
                    app.HemisphereSegmentationToolsPanel.Visible ='off';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    brainPosition = app.BrainSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = brainPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;
                end
                app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
            end
        end

        % Value changed function: SliceSpinner_Segmenter
        function SliceSpinner_SegmenterValueChanged(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSlider_Segmenter.Value = event.Source.Value;

            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];
            
            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            app.AutoClusterButton.Enable = 'off'; % TUrn off auto cluster button

            RefreshImageSegmenter(app);
            if app.PerspectiveViewButton.Value == true
                RefreshImageSegmenterHelperUp(app);
                RefreshImageSegmenterHelperDown(app);
            end

            % Check if there is a saved non-zero brain mask to enable hemisphere segmentation
            if ~isequal(app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value), false(app.ExpDimsSegmenter(1:2))) 
                app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
            else
                if app.CurrentSegmentationDropDown.Value == "Hemisphere"
                    app.BrainSegmentationToolsPanel.Visible = 'on';
                    app.HemisphereSegmentationToolsPanel.Visible ='off';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    brainPosition = app.BrainSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = brainPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;
                end
                app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'}; 
            end
        end

        % Value changed function: Dim4Spinner_Segmenter
        function Dim4Spinner_SegmenterValueChanged(app, event)

            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];
            
            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            app.AutoClusterButton.Enable = 'off'; % TUrn off auto cluster button

            RefreshImageSegmenter(app);
        end

        % Value changed function: Dim5Spinner_Segmenter
        function Dim5Spinner_SegmenterValueChanged(app, event)

            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];
            
            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            app.AutoClusterButton.Enable = 'off'; % TUrn off auto cluster button

            RefreshImageSegmenter(app);
        end

        % Value changing function: ContrastSlider_Segmenter
        function ContrastSlider_SegmenterValueChanging(app, event)
            app.ContrastSlider_Segmenter.Value = event.Value;      
            RefreshImageSegmenter(app);
            if app.PerspectiveViewButton.Value == true
                RefreshImageSegmenterHelperUp(app);
                RefreshImageSegmenterHelperDown(app);
            end
        end

        % Value changing function: BrightnessSlider_Segmenter
        function BrightnessSlider_SegmenterValueChanging(app, event)
            app.BrightnessSlider_Segmenter.Value = event.Value;
            RefreshImageSegmenter(app);
            if app.PerspectiveViewButton.Value == true
                RefreshImageSegmenterHelperUp(app);
                RefreshImageSegmenterHelperDown(app);
            end
        end

        % Menu selected function: RotateMenu_Segmenter
        function RotateMenu_SegmenterSelected(app, event)
            
            % Rotate image data, update dimensions, show image
            app.OriginalSegmenterImageData = rot90(app.OriginalSegmenterImageData, -1);
            app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
            app.WorkingSegmenterImageData = rot90(app.WorkingSegmenterImageData, -1);
            app.BrainMask = rot90(app.BrainMask, -1);
            app.SavedBrainMask = rot90(app.SavedBrainMask, -1);
            app.GreenScreen = rot90(app.GreenScreen, -1);
            app.HemisphereMask = rot90(app.HemisphereMask, -1);
            app.RedScreen = rot90(app.RedScreen, -1);
            app.BlueScreen = rot90(app.BlueScreen, -1);
            app.ROIMask = rot90(app.ROIMask, -1);
            app.YellowScreen = rot90(app.YellowScreen, -1);
            RefreshImageSegmenter(app);
        end

        % Menu selected function: FlipVerticallyMenu_Segmenter
        function FlipVerticallyMenu_SegmenterSelected(app, event)
            
            % Flip image data, update dimensions, show image
            app.OriginalSegmenterImageData = flipud(app.OriginalSegmenterImageData);
            app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
            app.WorkingSegmenterImageData = flipud(app.WorkingSegmenterImageData);
            app.BrainMask = flipud(app.BrainMask);
            app.SavedBrainMask = flipud(app.SavedBrainMask);
            app.GreenScreen = flipud(app.GreenScreen);
            app.HemisphereMask = flipud(app.HemisphereMask);
            app.RedScreen = flipud(app.RedScreen);
            app.BlueScreen = flipud(app.BlueScreen);
            app.ROIMask = flipud(app.ROIMask);
            app.YellowScreen = flipud(app.YellowScreen);
            RefreshImageSegmenter(app);
        end

        % Menu selected function: FlipHorizontallyMenu_Segmenter
        function FlipHorizontallyMenu_SegmenterSelected(app, event)
            
            % Flip image data, update dimensions, show image
            app.OriginalSegmenterImageData = fliplr(app.OriginalSegmenterImageData);
            app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
            app.WorkingSegmenterImageData = fliplr(app.WorkingSegmenterImageData);
            app.BrainMask = fliplr(app.BrainMask);
            app.SavedBrainMask = fliplr(app.SavedBrainMask);
            app.GreenScreen = fliplr(app.GreenScreen);
            app.HemisphereMask = fliplr(app.HemisphereMask);
            app.RedScreen = fliplr(app.RedScreen);
            app.BlueScreen = fliplr(app.BlueScreen);
            app.ROIMask = fliplr(app.ROIMask);
            app.YellowScreen = fliplr(app.YellowScreen);
            RefreshImageSegmenter(app);
        end

        % Menu selected function: ResetViewMenu_Segmenter
        function ResetViewMenu_SegmenterSelected(app, event)
            
            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];

            % Reset contrast and brightness then refresh
            app.BrightnessSlider_Segmenter.Value = 0;
            app.ContrastSlider_Segmenter.Value = 0;
            RefreshImageSegmenter(app);
        end

        % Menu selected function: PermuteMenu_3_4
        function PermuteMenu_3_4Selected(app, event)
                
            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Permute experiment 3rd and 4th dimensions? This will erase all segmentation progress.','Permute Dimensions', 'Icon','question');
            switch selection
                case 'OK'
                    switch numel(app.ExpDimsSegmenter)
                        case 4
                            app.OriginalSegmenterImageData = permute(app.OriginalSegmenterImageData, [1,2,4,3]);
                        case 5
                            app.OriginalSegmenterImageData = permute(app.OriginalSegmenterImageData, [1,2,4,3,5]);
                    end
                    app.WorkingSegmenterImageData = app.OriginalSegmenterImageData;
                    app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter(1:3), 2));
                    
                    % Delete ROI masks
                    app.ROIIdentifiers = {};
                    app.ROIListListBox.Items = app.ROIIdentifiers;
                    app.ROIMask = [];
                    
                    % Modify UI items
                    app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
                    app.CurrentSegmentationDropDown.Value = 'Brain';
                    app.BrainSegmentationToolsPanel.Visible = 'on';
                    app.HemisphereSegmentationToolsPanel.Visible = 'off';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    brainPosition = app.BrainSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = brainPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;

                    temp_limits = app.SliceSpinner_Segmenter.Limits;
                    temp_value = app.SliceSpinner_Segmenter.Value;

                    app.SliceSpinner_Segmenter.Limits = app.Dim4Spinner_Segmenter.Limits;
                    app.SliceSpinner_Segmenter.Value = app.Dim4Spinner_Segmenter.Value;
                    app.SliceSlider_Segmenter.Limits = app.Dim4Spinner_Segmenter.Limits;
                    app.SliceSlider_Segmenter.Value = app.Dim4Spinner_Segmenter.Value;
                    app.Dim4Spinner_Segmenter.Limits = temp_limits;
                    app.Dim4Spinner_Segmenter.Value = temp_value;

                    RefreshImageSegmenter(app);

                case 'Cancel'
                    return
            end
        end

        % Menu selected function: PermuteMenu_3_5
        function PermuteMenu_3_5Selected(app, event)
            
            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Permute experiment 3rd and 5th dimensions? This will erase all segmentation progress.','Permute Dimensions', 'Icon','question');
            switch selection
                case 'OK'
                    app.OriginalSegmenterImageData = permute(app.OriginalSegmenterImageData, [1,2,5,4,3]);
                    app.WorkingSegmenterImageData = app.OriginalSegmenterImageData;
                    app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter(1:3), 2));

                    % Delete ROI masks
                    app.ROIIdentifiers = {};
                    app.ROIListListBox.Items = app.ROIIdentifiers;
                    app.ROIMask = [];

                    % Modify UI items
                    app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
                    app.CurrentSegmentationDropDown.Value = 'Brain';
                    app.BrainSegmentationToolsPanel.Visible = 'on';
                    app.HemisphereSegmentationToolsPanel.Visible = 'off';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    brainPosition = app.BrainSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = brainPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;

                    temp_value_slice = app.SliceSpinner_Segmenter.Value;
                    temp_limits_slice = app.SliceSpinner_Segmenter.Limits;

                    app.SliceSpinner_Segmenter.Limits = app.Dim5Spinner_Segmenter.Limits;
                    app.SliceSpinner_Segmenter.Value = app.Dim5Spinner_Segmenter.Value;
                    app.SliceSlider_Segmenter.Limits = app.Dim5Spinner_Segmenter.Limits;
                    app.SliceSlider_Segmenter.Value = app.Dim5Spinner_Segmenter.Value;
                    app.Dim5Spinner_Segmenter.Limits = temp_limits_slice;
                    app.Dim5Spinner_Segmenter.Value = temp_value_slice;

                    RefreshImageSegmenter(app);

                case 'Cancel'
                    return
            end
        end

        % Menu selected function: PermuteMenu_4_5
        function PermuteMenu_4_5Selected(app, event)
            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Permute experiment 4th and 5th dimensions? This will erase all segmentation progress.','Permute Dimensions', 'Icon','question');
            switch selection
                case 'OK'
                    app.OriginalSegmenterImageData = permute(app.OriginalSegmenterImageData, [1,2,3,5,4]);
                    app.WorkingSegmenterImageData = app.OriginalSegmenterImageData;
                    app.ExpDimsSegmenter = size(app.OriginalSegmenterImageData);
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.HemisphereMask = false(cat(2, app.ExpDimsSegmenter(1:3), 2));
                    
                    % Delete ROI masks
                    app.ROIIdentifiers = {};
                    app.ROIListListBox.Items = app.ROIIdentifiers;
                    app.ROIMask = [];

                    % Modify UI items
                    app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
                    app.CurrentSegmentationDropDown.Value = 'Brain';
                    app.BrainSegmentationToolsPanel.Visible = 'on';
                    app.HemisphereSegmentationToolsPanel.Visible = 'off';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    brainPosition = app.BrainSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = brainPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;

                    temp_limits = app.Dim5Spinner_Segmenter.Limits;
                    temp_value = app.Dim5Spinner_Segmenter.Value;

                    app.Dim5Spinner_Segmenter.Limits = app.Dim4Spinner_Segmenter.Limits;
                    app.Dim5Spinner_Segmenter.Value = app.Dim4Spinner_Segmenter.Value;
                    app.Dim4Spinner_Segmenter.Limits = temp_limits;
                    app.Dim4Spinner_Segmenter.Value = temp_value;

                    RefreshImageSegmenter(app);

                case 'Cancel'
                    return
            end
        end

        % Selection changed function: ColormapButtonGroup_Segmenter
        function ColormapButtonGroup_SegmenterSelectionChanged(app, event)
            RefreshImageSegmenter(app);
            if app.PerspectiveViewButton.Value == true
                RefreshImageSegmenterHelperUp(app);
                RefreshImageSegmenterHelperDown(app);
            end
        end

        % Value changed function: PerspectiveViewButton
        function PerspectiveViewButtonValueChanged(app, event)
            value = app.PerspectiveViewButton.Value;
            
            switch value
                case 1
                    app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.HandleVisibility = 'callback';
                    [y,x] = ginput(1);
                    switch numel(app.ExpDimsSegmenter)
                        case 3
                            app.SegmenterHelperVolume = permute(app.WorkingSegmenterImageData, [3 1 2]);
                        case 4
                            app.SegmenterHelperVolume = permute(squeeze(app.WorkingSegmenterImageData(:,:,:,app.Dim4Spinner_Segmenter.Value)), [3 1 2]);
                        case 5
                            app.SegmenterHelperVolume = permute(squeeze(app.WorkingSegmenterImageData(:,:,:,app.Dim4Spinner_Segmenter.Value, ...
                                app.Dim4Spinner_Segmenter.Value)), [3 1 2]);
                    end
                    try
                        app.SegmenterPosX = round(x);
                        app.SegmenterPosY = round(y);
                        app.SegmenterImageZX = squeeze(app.SegmenterHelperVolume(:,:,app.SegmenterPosY));
                        app.SegmenterImageZY = squeeze(app.SegmenterHelperVolume(:,app.SegmenterPosX,:));
                    catch ME
                        if strcmp(ME.identifier,'MATLAB:badsubscript')
                            uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Selection out of bounds. Please select a point on the image.', 'Out Of Bounds Error', 'Icon','error');
                        end
                        app.PerspectiveViewButton.Value = 0;
                        return
                    end
                    app.UIAxes_Segmenter.Position = [239,66,669,627];
                    reset(app.UIAxes_SegmenterHelperUp);
                    disableDefaultInteractivity(app.UIAxes_SegmenterHelperUp);
                    reset(app.UIAxes_SegmenterHelperDown);
                    disableDefaultInteractivity(app.UIAxes_SegmenterHelperDown);
                    app.UIAxes_SegmenterHelperUp.Visible = 'on';
                    app.UIAxes_SegmenterHelperDown.Visible = 'on';
                    RefreshImageSegmenterHelperUp(app);
                    RefreshImageSegmenterHelperDown(app);
                case 0
                    app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.HandleVisibility = 'off';
                    cla(app.UIAxes_SegmenterHelperUp);
                    cla(app.UIAxes_SegmenterHelperDown);
                    app.UIAxes_SegmenterHelperUp.Visible = 'off';
                    app.UIAxes_SegmenterHelperDown.Visible = 'off';
                    app.UIAxes_Segmenter.Position = [5,66,903,627];
            end
        end

        % Value changed function: CurrentSegmentationDropDown
        function CurrentSegmentationDropDownValueChanged(app, event)

            switch app.CurrentSegmentationDropDown.Value
                case 'Brain'
                    app.BrainSegmentationToolsPanel.Visible = 'on';
                    app.HemisphereSegmentationToolsPanel.Visible ='off';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    brainPosition = app.BrainSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = brainPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;
                case 'Hemisphere'
                    app.BrainSegmentationToolsPanel.Visible = 'off';
                    app.HemisphereSegmentationToolsPanel.Visible ='on';
                    app.ROISegmentationToolsPanel.Visible = 'off';
                    hemiPosition = app.HemisphereSegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = hemiPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;
                case 'ROI'
                    app.BrainSegmentationToolsPanel.Visible = 'off';
                    app.HemisphereSegmentationToolsPanel.Visible ='off';
                    app.ROISegmentationToolsPanel.Visible = 'on';
                    roitoolsPosition = app.ROISegmentationToolsPanel.Position;
                    selectionPosition = app.SelectionToolsPanel.Position;
                    selectionPosition(2) = roitoolsPosition(2)-selectionPosition(4);
                    app.SelectionToolsPanel.Position = selectionPosition;
                otherwise
            end
            RefreshImageSegmenter(app);
        end

        % Value changed function: ImageshownSwitch_Brain
        function ImageshownSwitch_BrainValueChanged(app, event)
            RefreshImageSegmenter(app);
        end

        % Button pushed function: LoadExternalBrainMaskButton
        function LoadExternalBrainMaskButtonPushed(app, event)
            % Get external brain mask data
            [temp_file, temp_dir] = uigetfile('.nii');
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure)
            temp_Mask = pagetranspose(niftiread(cat(2, temp_dir, temp_file)));
            dims_loaded = size(temp_Mask);
            if ~isequal(dims_loaded(1:2), app.ExpDimsSegmenter(1:2))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Loaded brain mask x and y dimensions must be equal to those of data being segmented.', 'Loaded Mask Dimension Error')
                return
            elseif numel(dims_loaded)>=3 && numel(app.ExpDimsSegmenter)>=3 && ~isequal(dims_loaded(3), app.ExpDimsSegmenter(3))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Loaded brain mask must have the same amount of slices as data being segmented', 'Loaded Mask Dimension Error')
                return
            end
            app.BrainMask = temp_Mask;
            
            RefreshImageSegmenter(app);
            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "External brain mask loaded successfully.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
        end

        % Value changed function: VolumeSwitch
        function VolumeSwitchValueChanged(app, event)
            value = app.VolumeSwitch.Value;
            switch value
                case '2D'
                    app.AutoClusterButton.Text = "Auto Cluster";
                    app.OpenMaskButton.Text = "Open Mask";
                    app.CloseMaskButton.Text = "Close Mask";
                    app.ResetSliceButton.Text = "Reset Slice";
                    app.ApplyMaskButton.Text = "Apply Slice";
                case '3D'
                    app.AutoClusterButton.Text = "3D Auto Cluster";
                    app.OpenMaskButton.Text = "3D Open Mask";
                    app.CloseMaskButton.Text = "3D Close Mask";
                    app.ResetSliceButton.Text = "Reset All";
                    app.ApplyMaskButton.Text = "Apply All";
            end
        end

        % Button pushed function: InitialSelectionButton
        function InitialSelectionButtonPushed(app, event)
            app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = false(app.ExpDimsSegmenter(1:2));
            RefreshImageSegmenter(app);

            % Draw initial ROI, create mask and masked image
            init_Region = drawfreehand(app.UIAxes_Segmenter);
            app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = createMask(init_Region, app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value));
            delete(init_Region);
            
            % Show image with mask overlaid on top
            RefreshImageSegmenter(app);
            
            app.AutoClusterButton.Enable = 'on'; % Turn on auto cluster button
        end

        % Button pushed function: AutoClusterButton
        function AutoClusterButtonPushed(app, event)
            switch app.VolumeSwitch.Value
                case '2D'
                    % Get clusters on masked image, calculate best overlap using sorensen dice coefficient and
                    % select best cluster
                    clusters = imsegkmeans(single(app.CurrentSlice).*app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value),2,'NumAttempts',2);
                    cluster_1_dice = dice(clusters==1, logical(app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value)));
                    cluster_2_dice = dice(clusters==2, logical(app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value)));
                    if cluster_1_dice > cluster_2_dice
                        app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = clusters == 1;
                    else 
                        app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = clusters == 2;
                    end  
                case '3D'
                    % Prompt for slice limits
                    limits = inputdlg({'Enter lower slice limit:','Enter upper slice limit:'}, 'Input Slice Limits', [1 40], {'1', num2str(app.ExpDimsSegmenter(3))});
                    min = str2double(limits{1});
                    max = str2double(limits{2});
                    % Expand selection to chosen slices
                    app.BrainMask(:,:,min:max) = repmat(app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value),[1 1 max-min+1]);
                    % Get clusters on masked image, calculate best overlap using sorensen dice coefficient and
                    % select best cluster
                    switch numel(app.ExpDimsSegmenter)
                        case 2
                            uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                                'Data needs to have at least 3 dimensions to be eligible for 3D clustering.', ...
                                'Auto Cluster Dimension Mismatch')
                        case 3
                            CurrentROIVolume = app.OriginalSegmenterImageData;
                        case 4
                            CurrentROIVolume = app.OriginalSegmenterImageData(:,:,:,app.Dim4Spinner_Segmenter.Value);
                        case 5
                            CurrentROIVolume = app.OriginalSegmenterImageData(:,:,:,app.Dim4Spinner_Segmenter.Value,app.Dim5Spinner_Segmenter.Value);
                    end
                    clusters = imsegkmeans3(single(CurrentROIVolume(:,:,min:max)).*app.BrainMask(:,:,min:max),2,'NumAttempts',2);
                    cluster_1_dice = dice(clusters==1, logical(app.BrainMask(:,:,min:max)));
                    cluster_2_dice = dice(clusters==2, logical(app.BrainMask(:,:,min:max)));
                    if cluster_1_dice > cluster_2_dice
                        app.BrainMask(:,:,min:max) = clusters == 1;
                    else 
                        app.BrainMask(:,:,min:max) = clusters == 2;
                    end 
            end
            
            app.AutoClusterButton.Enable = 'off'; % Turn off auto cluster button
            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            
            RefreshImageSegmenter(app); 
        end

        % Button pushed function: OpenMaskButton
        function OpenMaskButtonPushed(app, event)
            
            % Construct structuring element, open image mask
            disk_Radius = app.DiskradiusSpinner.Value;
            SE = strel('disk', disk_Radius, 0);

            switch app.VolumeSwitch.Value
                case '2D'
                    app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = imopen(app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value), SE);
                case '3D'
                    app.BrainMask = imopen(app.BrainMask, SE);
            end
            
            % Show image with mask overlaid on top
            RefreshImageSegmenter(app);
        end

        % Button pushed function: CloseMaskButton
        function CloseMaskButtonPushed(app, event)
            
            % Construct structuring element, close image mask
            disk_Radius = app.DiskradiusSpinner.Value;
            SE = strel('disk', disk_Radius, 0);

            switch app.VolumeSwitch.Value
                case '2D'
                    app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = imclose(app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value), SE);
                case '3D'
                    app.BrainMask = imclose(app.BrainMask, SE);
            end
            
            % Show image with mask overlaid on top
            RefreshImageSegmenter(app);
        end

        % Button pushed function: ApplyMaskButton
        function ApplyMaskButtonPushed(app, event)

            switch app.VolumeSwitch.Value
                case '2D'
                    % Save slice image based on number of dimensions in original matrix
                    switch numel(app.ExpDimsSegmenter)
                        case 5
                            for i=1:app.ExpDimsSegmenter(4)
                                for j=1:app.ExpDimsSegmenter(5)
                                    app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i,j) = app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value).*app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i,j);
                                end
                            end         
                        case 4
                            for i=1:app.ExpDimsSegmenter(4)
                                app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i) = app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value).*app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i);
                            end
                        otherwise
                            app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value) = app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value).*app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value);
                    end
        
                    % Save slice mask
                    app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value) = app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value);
                    app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = false(app.ExpDimsSegmenter(1:2));
                case '3D'
                    % Save slice image based on number of dimensions in original matrix
                    switch numel(app.ExpDimsSegmenter)
                        case 5
                            for i=1:app.ExpDimsSegmenter(4)
                                for j=1:app.ExpDimsSegmenter(5)
                                    app.WorkingSegmenterImageData(:,:,:,i,j) = app.BrainMask.*app.OriginalSegmenterImageData(:,:,:,i,j);
                                end
                            end         
                        case 4
                            for i=1:app.ExpDimsSegmenter(4)
                                app.WorkingSegmenterImageData(:,:,:,i) = app.BrainMask.*app.OriginalSegmenterImageData(:,:,:,i);
                            end
                        otherwise
                            app.WorkingSegmenterImageData = app.BrainMask.*app.OriginalSegmenterImageData;
                    end
        
                    % Save slice mask
                    app.SavedBrainMask = app.BrainMask;
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
            end

            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            app.AutoClusterButton.Enable = 'off'; % TUrn off auto cluster button

            RefreshImageSegmenter(app);

            % Check if there is a saved non-zero brain mask to enable hemisphere segmentation
            if ~isequal(app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value), false(app.ExpDimsSegmenter(1:2))) 
                app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
            else
                app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
            end
        end

        % Button pushed function: ResetSliceButton
        function ResetSliceButtonPushed(app, event)
 
            % Reset zoom
            app.UIAxes_Segmenter.XLim = [-inf inf];
            app.UIAxes_Segmenter.YLim = [-inf inf];
            
            switch app.VolumeSwitch.Value
                case '2D'
                    % Reset slice image based on number of dimensions in original
                    % matrix
                    switch numel(app.ExpDimsSegmenter)
                        case 5
                            for j=1:app.ExpDimsSegmenter(5)
                                for i=1:app.ExpDimsSegmenter(4)
                                    app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i,j) = app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i,j);
                                end
                            end         
                        case 4
                            for i=1:app.ExpDimsSegmenter(4)
                                app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i) = app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value,i);
                            end
                        otherwise
                            app.WorkingSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value) = app.OriginalSegmenterImageData(:,:,app.SliceSpinner_Segmenter.Value);
                    end
        
                    % Reset slice mask
                    app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value) = false(app.ExpDimsSegmenter(1:2));
                    app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = false(app.ExpDimsSegmenter(1:2));
                case '3D'
                    % Reset slice image based on number of dimensions in original
                    % matrix
                    switch numel(app.ExpDimsSegmenter)
                        case 5
                            for j=1:app.ExpDimsSegmenter(5)
                                for i=1:app.ExpDimsSegmenter(4)
                                    app.WorkingSegmenterImageData(:,:,:,i,j) = app.OriginalSegmenterImageData(:,:,:,i,j);
                                end
                            end         
                        case 4
                            for i=1:app.ExpDimsSegmenter(4)
                                app.WorkingSegmenterImageData(:,:,:,i) = app.OriginalSegmenterImageData(:,:,:,i);
                            end
                        otherwise
                            app.WorkingSegmenterImageData = app.OriginalSegmenterImageData;
                    end
        
                    % Reset slice mask
                    app.SavedBrainMask = false(app.ExpDimsSegmenter(1:3));
                    app.BrainMask = false(app.ExpDimsSegmenter(1:3));
            end
            
            app.ImageshownSwitch_Brain.Value = "Overlay"; % Set image shown value to overlay
            app.AutoClusterButton.Enable = 'off'; % Turn off auto cluster button

            RefreshImageSegmenter(app);

            % Check if there is a saved non-zero brain mask to enable hemisphere segmentation
            if ~isequal(app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value), false(app.ExpDimsSegmenter(1:2))) 
                app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
            else
                app.CurrentSegmentationDropDown.Items = {'Brain', 'ROI'};
            end
        end

        % Button pushed function: LoadExternalHemisphereMaskButton
        function LoadExternalHemisphereMaskButtonPushed(app, event)
            
             % Get external hemisphere mask data
            [temp_file, temp_dir] = uigetfile('.nii');
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure)
            temp_Mask = pagetranspose(niftiread(cat(2, temp_dir, temp_file)));
            dims_loaded = size(temp_Mask);
            if ~isequal(dims_loaded(1:2), app.ExpDimsSegmenter(1:2))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Loaded hemisphere mask x and y dimensions must be equal to those of data being segmented.', 'Loaded Mask Dimension Error')
                return
            elseif numel(dims_loaded)>=4 && numel(app.ExpDimsSegmenter)>=3 && ~isequal(dims_loaded(3), app.ExpDimsSegmenter(3))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Loaded hemisphere mask must have the same amount of slices as data being segmented', 'Loaded Mask Dimension Error')
                return
            end
            app.HemisphereMask = temp_Mask;
            
            RefreshImageSegmenter(app);
            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "External hemisphere mask loaded successfully.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
        end

        % Button pushed function: ResetHemispheresButton
        function ResetHemispheresButtonPushed(app, event)
            if numel(app.ExpDimsSegmenter) ~= 2
                app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = false(app.ExpDimsSegmenter(1:2));
                app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = false(app.ExpDimsSegmenter(1:2));
            else
                app.HemisphereMask(:,:,1) = false(app.ExpDimsSegmenter(1:2));
                app.HemisphereMask(:,:,2) = false(app.ExpDimsSegmenter(1:2));
            end
            RefreshImageSegmenter(app);
        end

        % Button pushed function: LoadExternalROIPackButton
        function LoadExternalROIPackButtonPushed(app, event)
            
            % Get external ROI pack data
            [temp_file, temp_dir] = uigetfile('.nii');
            figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure)
            temp_Mask = pagetranspose(niftiread(cat(2, temp_dir, temp_file)));
            id_path = string(temp_dir)+string(temp_file(1:end-13))+"_identifiers_ROI.mat";   
            dims_loaded = size(temp_Mask);
            if ~exist(id_path, 'file')
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'No valid ROI identifier file found on selected ROI pack path.', 'Loaded ROI Pack ID Error')
                return
            elseif ~isequal(dims_loaded(1:2), app.ExpDimsSegmenter(1:2))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Loaded ROI pack x and y dimensions must be equal to those of data being segmented.', 'Loaded ROI Pack Dimension Error')
                return
            elseif numel(dims_loaded)>=4 && numel(app.ExpDimsSegmenter)>=3 && ~isequal(dims_loaded(3), app.ExpDimsSegmenter(3))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Loaded ROI pack must have the same amount of slices as data being segmented', 'Loaded ROI Pack Dimension Error')
                return
            end
            temp_identifiers = load(id_path);
            app.ROIIdentifiers = temp_identifiers.roi_id;
            app.ROIListListBox.Items = app.ROIIdentifiers;
            app.ROIMask = temp_Mask;
            
            RefreshImageSegmenter(app);
            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "External ROI pack loaded successfully.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
        end

        % Value changed function: ROIListListBox
        function ROIListListBoxValueChanged(app, event)
            RefreshImageSegmenter(app);
        end

        % Value changed function: ImageshownSwitch_ROI
        function ImageshownSwitch_ROIValueChanged(app, event)
            RefreshImageSegmenter(app);
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            ROI_name = inputdlg('Enter ROI name', 'Add New ROI', [1 40]);

            % Check for empty/duplicate name input
            if ~isequal(ROI_name, {''}) && ~any(strcmp(app.ROIIdentifiers,ROI_name))  
                app.ROIIdentifiers = cat(2, app.ROIIdentifiers, ROI_name);
                app.ROIListListBox.Items = app.ROIIdentifiers;
                app.ROIListListBox.Value = ROI_name;

                % Concatenate ROI mask to 4D matrix with other ROI masks
                if numel(app.ExpDimsSegmenter) ~= 2
                    app.ROIMask = cat(4, app.ROIMask, false(app.ExpDimsSegmenter(1:3)));
                else
                    app.ROIMask = cat(3, app.ROIMask, false(app.ExpDimsSegmenter));
                end
            else
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'ROI name must be non-empty and not a duplicate.', 'ROI Naming Error')
            end
            RefreshImageSegmenter(app);
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            try
                % Find ROI index
                index = find(strcmp(app.ROIIdentifiers,app.ROIListListBox.Value));
                % Delete ROI identifier
                app.ROIIdentifiers(index) = [];
                app.ROIListListBox.Items = app.ROIIdentifiers;
                % Delete ROI Mask
                if numel(app.ExpDimsSegmenter) ~= 2
                    app.ROIMask(:,:,:,index) = [];
                else
                    app.ROIMask(:,:,index) = [];
                end
            catch
            end
            RefreshImageSegmenter(app);
        end

        % Button pushed function: ResetROISliceButton
        function ResetROISliceButtonPushed(app, event)
            try
                 % Find ROI index
                index = find(strcmp(app.ROIIdentifiers,app.ROIListListBox.Value));
                % Reset ROI slice
                if numel(app.ExpDimsSegmenter) ~= 2
                    app.ROIMask(:,:,app.SliceSpinner_Segmenter.Value,index) = false(app.ExpDimsSegmenter(1:2));
                else
                    app.ROIMask(:,:,index) = false(app.ExpDimsSegmenter(1:2));
                end
            catch
            end
            RefreshImageSegmenter(app);
        end

        % Button pushed function: VolROISegmentationToolsButton
        function VolROISegmentationToolsButtonPushed(app, event)
            app.ProgressBar = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Segmenting ROI Volumes...", 'Indeterminate','on');
            drawnow
            
            app.VolROISegmentationToolsButton.Enable = 'off';

            switch numel(app.ExpDimsSegmenter)
                case 3
                    volume = app.WorkingSegmenterImageData;
                case 4
                    volume = app.WorkingSegmenterImageData(:,:,:,app.Dim4Spinner_Segmenter.Value);
                case 5
                    volume = app.WorkingSegmenterImageData(:,:,:,app.Dim4Spinner_Segmenter.Value,app.Dim5Spinner_Segmenter.Value);
            end
            app.ROIVolumeSegmenterWindow = ROIVolumeSegmenter(app, volume, app.ROIMask, ...
                app.ROIIdentifiers, app.SegmenterDimTriplet(1), app.SegmenterDimTriplet(2), app.SegmenterDimTriplet(3));
        end

        % Button pushed function: FreeButton_Add
        function FreeButton_AddPushed(app, event)
            
            % Draw freehand ROI
            app.FreeROI = drawfreehand(app.UIAxes_Segmenter, 'InteractionsAllowed', "reshape", 'FaceAlpha', 0.1, 'Color', [0.4660 0.6740 0.1880]);
            app.ROI_OperationID = "add";
        end

        % Button pushed function: FreeButton_Remove
        function FreeButton_RemovePushed(app, event)
            
            % Draw freehand ROI
            app.FreeROI = drawfreehand(app.UIAxes_Segmenter, 'InteractionsAllowed', "reshape", 'FaceAlpha', 0.1, 'Color', [0.6350 0.0780 0.1840]);
            app.ROI_OperationID = "remove";
        end

        % Button pushed function: PolyButton_Add
        function PolyButton_AddPushed(app, event)
            
            % Draw polygon ROI
            app.FreeROI = drawpolygon(app.UIAxes_Segmenter, 'InteractionsAllowed', "reshape", 'FaceAlpha', 0.1, 'Color', [0.4660 0.6740 0.1880]);
            app.ROI_OperationID = "add";
        end

        % Button pushed function: PolyButton_Remove
        function PolyButton_RemovePushed(app, event)
            
            % Draw polygon ROI
            app.FreeROI = drawpolygon(app.UIAxes_Segmenter, 'InteractionsAllowed', "reshape", 'FaceAlpha', 0.1, 'Color', [0.6350 0.0780 0.1840]);
            app.ROI_OperationID = "remove";
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
                        
            % Update mask using new ROI based on current image displayed -
            % masked or original with overlaid transparent mask
            try
                switch app.CurrentSegmentationDropDown.Value
                    case 'Brain'
                        switch app.ROI_OperationID
                            case 'add'
                                try
                                    added_Mask = app.FreeROI.createMask(app.SegmenterImage);
                                    app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value)|added_Mask;
                                catch
                                end
                                RefreshImageSegmenter(app);
                            case 'remove'
                                try
                                    removed_Mask = app.FreeROI.createMask(app.SegmenterImage);
                                    temp = app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value);
                                    temp(removed_Mask) = 0;
                                    app.BrainMask(:,:,app.SliceSpinner_Segmenter.Value) = temp;
                                catch
                                end
                                RefreshImageSegmenter(app);
                        end
                    case 'Hemisphere'
                        switch app.ROI_OperationID
                            case 'add'
                                added_Mask = app.FreeROI.createMask(app.SegmenterImage);
                                if app.RightredButton.Value == true
                                    % Check for 2D image data
                                    if numel(app.ExpDimsSegmenter) ~= 2
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = added_Mask|app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2);
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) == 0;
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1);
                                        end
                                    else
                                        app.HemisphereMask(:,:,2) = added_Mask|app.HemisphereMask(:,:,2);
                                        app.HemisphereMask(:,:,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,2);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,1) = app.HemisphereMask(:,:,2) == 0;
                                            app.HemisphereMask(:,:,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,1);
                                        end
                                    end
    
                                else
                                    %Check for 2D image data
                                    if numel(app.ExpDimsSegmenter) ~= 2
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = added_Mask|app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1);
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) == 0;
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2);
                                        end
                                    else
                                        app.HemisphereMask(:,:,1) = added_Mask|app.HemisphereMask(:,:,1);
                                        app.HemisphereMask(:,:,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,1);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,2) = app.HemisphereMask(:,:,1) == 0;
                                            app.HemisphereMask(:,:,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,2);
                                        end
                                    end
                                end
                                RefreshImageSegmenter(app);
                            case 'remove'
                                removed_Mask = app.FreeROI.createMask(app.SegmenterImage);
                                if app.RightredButton.Value == true
                                    % Check for 2D image data
                                    if numel(app.ExpDimsSegmenter) ~= 2
                                        temp_Hemi = app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2);
                                        temp_Hemi(removed_Mask)=0;
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = temp_Hemi;
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) == 0;
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1);
                                        end
                                    else
                                        temp_Hemi = app.HemisphereMask(:,:,2);
                                        temp_Hemi(removed_Mask)=0;
                                        app.HemisphereMask(:,:,2) = temp_Hemi;
                                        app.HemisphereMask(:,:,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,2);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,1) = app.HemisphereMask(:,:,2) == 0;
                                            app.HemisphereMask(:,:,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,1);
                                        end
                                    end
    
                                else
                                    % Check for 2D image data
                                    if numel(app.ExpDimsSegmenter) ~= 2
                                        temp_Hemi = app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1);
                                        temp_Hemi(removed_Mask)=0;
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = temp_Hemi;
                                        app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,1) == 0;
                                            app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,app.SliceSpinner_Segmenter.Value,2);
                                        end
                                    else
                                        temp_Hemi = app.HemisphereMask(:,:,1);
                                        temp_Hemi(removed_Mask)=0;
                                        app.HemisphereMask(:,:,1) = temp_Hemi;
                                        app.HemisphereMask(:,:,1) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,1);
                                        % Check for auto completion of second hemisphere
                                        if app.AutoCompleteHemispheresCheckBox.Value == 1
                                            app.HemisphereMask(:,:,2) = app.HemisphereMask(:,:,1) == 0;
                                            app.HemisphereMask(:,:,2) = app.SavedBrainMask(:,:,app.SliceSpinner_Segmenter.Value)&app.HemisphereMask(:,:,2);
                                        end
                                    end
                                end
                                RefreshImageSegmenter(app);
                        end
                    case 'ROI'
                        % Find selected ROI index
                        index = find(strcmp(app.ROIIdentifiers,app.ROIListListBox.Value));
                        switch app.ROI_OperationID
                            case 'add'
                                added_Mask = app.FreeROI.createMask(app.SegmenterImage);
                                if numel(app.ExpDimsSegmenter) ~= 2
                                    app.ROIMask(:,:,app.SliceSpinner_Segmenter.Value,index) = added_Mask|app.ROIMask(:,:,app.SliceSpinner_Segmenter.Value,index);
                                else
                                    app.ROIMask(:,:,index) = added_Mask|app.ROIMask(:,:,app.SliceSpinner_Segmenter.Value,index);
                                end
                                RefreshImageSegmenter(app);
                            case 'remove'
                                removed_Mask = app.FreeROI.createMask(app.SegmenterImage);
                                if numel(app.ExpDimsSegmenter) ~= 2
                                    temp_ROI = app.ROIMask(:,:,app.SliceSpinner_Segmenter.Value,index);
                                    temp_ROI(removed_Mask)=0;
                                    app.ROIMask(:,:,app.SliceSpinner_Segmenter.Value,index) = temp_ROI;
                                else
                                    temp_ROI = app.ROIMask(:,:,index);
                                    temp_ROI(removed_Mask)=0;
                                    app.ROIMask(:,:,index) = temp_ROI;
                                end
                                RefreshImageSegmenter(app);
                        end
                end
            catch
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % Delete current ROI
            try
                delete(app.FreeROI)
            catch
            end
        end

        % Button pushed function: SaveSegmentedDataButton
        function SaveSegmentedDataButtonPushed(app, event)
            SaveData(app, 'Segmenter')
        end

        % Button pushed function: ExportDataButton_Segmenter
        function ExportDataButton_SegmenterPushed(app, event)
            
            ExportImageDataGeneral(app, 'Segmenter');
            
            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Segmented sequence mask and image data exported in NIfTI format.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
        end

        % Value changed function: SelectResultsDropDown
        function SelectResultsDropDownValueChanged(app, event)
            if app.SelectResultsDropDown.Value == "None"
                % Disable Export
                app.ExportDataButton_Results.Enable = 'off';
                % Reset brain fields and table
                app.UITable_ResultsBrain.Data = table();
                app.VolumeEditField_Brain.Value = 0;
                app.MeanEditField_Brain.Value = 0;
                app.SDEditField_Brain.Value = 0;
                app.MedianEditField_Brain.Value = 0;
                app.IQRLowerEditField_Brain.Value = 0;
                app.IQRUpperEditField_Brain.Value = 0;
                app.MinEditField_Brain.Value = 0;
                app.MaxEditField_Brain.Value = 0;
                % Reset hemisphere fields and table
                app.SelectHemisphereDropDown.Enable = 'off';
                app.UITable_ResultsHemisphere.Data = table();
                app.VolumeEditField_Hemisphere.Value = 0;
                app.MeanEditField_Hemisphere.Value = 0;
                app.SDEditField_Hemisphere.Value = 0;
                app.MedianEditField_Hemisphere.Value = 0;
                app.IQRLowerEditField_Hemisphere.Value = 0;
                app.IQRUpperEditField_Hemisphere.Value = 0;
                app.MinEditField_Hemisphere.Value = 0;
                app.MaxEditField_Hemisphere.Value = 0;
                % Reset ROI fields and table
                app.SelectROIDropDown.Enable = 'off';
                app.SelectROIDropDown.Items = "None";
                app.UITable_ResultsROI.Data = table();
                app.ApplyEdemaCorrectionCheckBox.Enable = 'off';
                app.ApplyEdemaCorrectionCheckBox.Value = 0;
                app.VolumeEditField_ROI.Value = 0;
                app.MeanEditField_ROI.Value = 0;
                app.SDEditField_ROI.Value = 0;
                app.MedianEditField_ROI.Value = 0;
                app.IQRLowerEditField_ROI.Value = 0;
                app.IQRUpperEditField_ROI.Value = 0;
                app.MinEditField_ROI.Value = 0;
                app.MaxEditField_ROI.Value = 0;
                app.AreaUnitLabel.Text = "";
                app.VolumeUnitLabel.Text = "";
                return
            end
            
            % Draw a progress box 
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Retrieving saved data.");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow
            
            % Get data from saved table
            progress.Value = 0.1;

            app.VolumetryImageData = cell2mat(app.SavedTable.Image(app.SelectResultsDropDown.Value));
            app.VolumetryBrainMask = cell2mat(app.SavedTable.BrainMask(app.SelectResultsDropDown.Value));
            app.VolumetryHemiMask = cell2mat(app.SavedTable.HemiMask(app.SelectResultsDropDown.Value));
            app.VolumetryROI = cell2mat(app.SavedTable.ROI(app.SelectResultsDropDown.Value));
            app.VolumetryDimX = app.SavedTable.VoxDimX(app.SelectResultsDropDown.Value);
            app.VolumetryDimY = app.SavedTable.VoxDimY(app.SelectResultsDropDown.Value);
            voxel_Area = app.VolumetryDimY*app.VolumetryDimX;
            app.VolumetryThickness = app.SavedTable.SliceThickness(app.SelectResultsDropDown.Value);
            app.VolumetryGap = app.SavedTable.SliceGap(app.SelectResultsDropDown.Value);
            dim_units = split(app.SavedTable.Units(app.SelectResultsDropDown.Value));
            
            % Set unit edit fields
            if dim_units == "Unspecified"
                app.AreaUnitLabel.Text = "Unspecified";
                app.VolumeUnitLabel.Text = "Unspecified";
            elseif dim_units(1) == "mm" && dim_units(2) == "mm"
                app.AreaUnitLabel.Text = "mm^2";
                app.VolumeUnitLabel.Text = "mm^3";
            end

            % Enable Export
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Results.Enable = 'on';
            end

            % Get volume for segmented brain and area for separate slices
            progress.Value = 0.3;
            progress.Message = "Calculating brain volume and descriptive statistics.";

            [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryBrainMask, voxel_Area, app.VolumetryThickness, app.VolumetryGap);
            app.UITable_ResultsBrain.Data = sliceTable;
            app.VolumeEditField_Brain.Value = Volume;

            % Populate brain descriptive edit fields
            try
                app.MeanEditField_Brain.Value = mean_val;
                app.SDEditField_Brain.Value = std_val;
                app.MedianEditField_Brain.Value = median_val;
                app.IQRLowerEditField_Brain.Value = IQRlow;
                app.IQRUpperEditField_Brain.Value = IQRup;
                app.MinEditField_Brain.Value = min_val;
                app.MaxEditField_Brain.Value = max_val;
            catch
                app.MeanEditField_Brain.Value = 0;
                app.SDEditField_Brain.Value = 0;
                app.MedianEditField_Brain.Value = 0;
                app.IQRLowerEditField_Brain.Value = 0;
                app.IQRUpperEditField_Brain.Value = 0;
                app.MinEditField_Brain.Value = 0;
                app.MaxEditField_Brain.Value = 0;
            end
            
            % Check for hemisphere segmentation
            if ~isequal(app.VolumetryHemiMask, false(1))
                % Get volume for segmented hemispheres and area for separate slices
                progress.Value = 0.5;
                progress.Message = "Calculating hemisphere volume and descriptive statistics.";

                app.SelectHemisphereDropDown.Enable = 'on';
                app.SelectHemisphereDropDown.Value = 'Left';
                switch numel(size(app.VolumetryHemiMask))
                    case 4
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    case 3
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                end
                app.UITable_ResultsHemisphere.Data = sliceTable;
                app.VolumeEditField_Hemisphere.Value = Volume;

                % Populate hemisphere descriptive edit fields
                app.MeanEditField_Hemisphere.Value = mean_val;
                app.SDEditField_Hemisphere.Value = std_val;
                app.MedianEditField_Hemisphere.Value = median_val;
                app.IQRLowerEditField_Hemisphere.Value = IQRlow;
                app.IQRUpperEditField_Hemisphere.Value = IQRup;
                app.MinEditField_Hemisphere.Value = min_val;
                app.MaxEditField_Hemisphere.Value = max_val;
            else
                app.SelectHemisphereDropDown.Enable = 'off';
                app.UITable_ResultsHemisphere.Data = table();
                app.VolumeEditField_Hemisphere.Value = 0;
                app.MeanEditField_Hemisphere.Value = 0;
                app.SDEditField_Hemisphere.Value = 0;
                app.MedianEditField_Hemisphere.Value = 0;
                app.IQRLowerEditField_Hemisphere.Value = 0;
                app.IQRUpperEditField_Hemisphere.Value = 0;
                app.MinEditField_Hemisphere.Value = 0;
                app.MaxEditField_Hemisphere.Value = 0;
            end

            % Check for ROI segmentation
            if ~isequal(app.VolumetryROI.Mask, false(1))
                % Get volume for first segmented ROI and area for separate slices
                progress.Value = 0.7;
                progress.Message = "Calculating ROI volumes and descriptive statistics.";

                app.SelectROIDropDown.Enable = 'on';
                app.SelectROIDropDown.Items = app.VolumetryROI.ID;
                app.SelectROIDropDown.Value = app.VolumetryROI.ID(1);
                if ~isequal(app.VolumetryHemiMask, false(1))
                    app.ApplyEdemaCorrectionCheckBox.Enable = 'on';
                else
                    app.ApplyEdemaCorrectionCheckBox.Enable = 'off';
                end
                app.ApplyEdemaCorrectionCheckBox.Value = 0;
                switch numel(size(app.VolumetryImageData))
                    case 2
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    otherwise
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                end
                app.UITable_ResultsROI.Data = sliceTable;
                app.VolumeEditField_ROI.Value = Volume;

                % Populate ROI descriptive edit fields
                app.MeanEditField_ROI.Value = mean_val;
                app.SDEditField_ROI.Value = std_val;
                app.MedianEditField_ROI.Value = median_val;
                app.IQRLowerEditField_ROI.Value = IQRlow;
                app.IQRUpperEditField_ROI.Value = IQRup;
                app.MinEditField_ROI.Value = min_val;
                app.MaxEditField_ROI.Value = max_val;
            else
                app.SelectROIDropDown.Enable = 'off';
                app.SelectROIDropDown.Items = "None";
                app.ApplyEdemaCorrectionCheckBox.Enable = 'off';
                app.ApplyEdemaCorrectionCheckBox.Value = 0;
                app.UITable_ResultsROI.Data = table();
                app.VolumeEditField_ROI.Value = 0;
                app.MeanEditField_ROI.Value = 0;
                app.SDEditField_ROI.Value = 0;
                app.MedianEditField_ROI.Value = 0;
                app.IQRLowerEditField_ROI.Value = 0;
                app.IQRUpperEditField_ROI.Value = 0;
                app.MinEditField_ROI.Value = 0;
                app.MaxEditField_ROI.Value = 0;
            end
            
            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";

            
        end

        % Value changed function: SelectHemisphereDropDown
        function SelectHemisphereDropDownValueChanged(app, event)
            voxel_Area = app.VolumetryDimY*app.VolumetryDimX;
            if app.SelectHemisphereDropDown.Value == "Left"
                switch numel(size(app.VolumetryHemiMask))
                    case 4
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    case 3
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                end
                app.UITable_ResultsHemisphere.Data = sliceTable;
                app.VolumeEditField_Hemisphere.Value = Volume;

                % Populate hemisphere descriptive edit fields
                app.MeanEditField_Hemisphere.Value = mean_val;
                app.SDEditField_Hemisphere.Value = std_val;
                app.MedianEditField_Hemisphere.Value = median_val;
                app.IQRLowerEditField_Hemisphere.Value = IQRlow;
                app.IQRUpperEditField_Hemisphere.Value = IQRup;
                app.MinEditField_Hemisphere.Value = min_val;
                app.MaxEditField_Hemisphere.Value = max_val;
            else
                switch numel(size(app.VolumetryHemiMask))
                    case 4
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,:,2), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    case 3
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,2), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                end
                app.UITable_ResultsHemisphere.Data = sliceTable;
                app.VolumeEditField_Hemisphere.Value = Volume;

                % Populate hemisphere descriptive edit fields
                app.MeanEditField_Hemisphere.Value = mean_val;
                app.SDEditField_Hemisphere.Value = std_val;
                app.MedianEditField_Hemisphere.Value = median_val;
                app.IQRLowerEditField_Hemisphere.Value = IQRlow;
                app.IQRUpperEditField_Hemisphere.Value = IQRup;
                app.MinEditField_Hemisphere.Value = min_val;
                app.MaxEditField_Hemisphere.Value = max_val;
            end          
        end

        % Value changed function: SelectROIDropDown
        function SelectROIDropDownValueChanged(app, event)
            UpdateVolumetryROI(app);
        end

        % Value changed function: ApplyEdemaCorrectionCheckBox
        function ApplyEdemaCorrectionCheckBoxValueChanged(app, event)
            UpdateVolumetryROI(app);
        end

        % Menu selected function: HemisphereScalingFactorMenu
        function HemisphereScalingFactorMenuSelected(app, event)
            app.HemisphereScalingFactorMenu.Checked = 'on';
            app.BelayevScalingFactorMenu.Checked = 'off';
            app.GerrietsCompressionFactorMenu.Checked = 'off';
            if app.SelectResultsDropDown.Value ~= "None" && app.ApplyEdemaCorrectionCheckBox.Enable == "on" 
                UpdateVolumetryROI(app);
            end
        end

        % Menu selected function: BelayevScalingFactorMenu
        function BelayevScalingFactorMenuSelected(app, event)
            app.HemisphereScalingFactorMenu.Checked = 'off';
            app.BelayevScalingFactorMenu.Checked = 'on';
            app.GerrietsCompressionFactorMenu.Checked = 'off';
            if app.SelectResultsDropDown.Value ~= "None" && app.ApplyEdemaCorrectionCheckBox.Enable == "on" 
                UpdateVolumetryROI(app);
            end
        end

        % Menu selected function: GerrietsCompressionFactorMenu
        function GerrietsCompressionFactorMenuSelected(app, event)
            app.HemisphereScalingFactorMenu.Checked = 'off';
            app.BelayevScalingFactorMenu.Checked = 'off';
            app.GerrietsCompressionFactorMenu.Checked = 'on';
            if app.SelectResultsDropDown.Value ~= "None" && app.ApplyEdemaCorrectionCheckBox.Enable == "on" 
                UpdateVolumetryROI(app);
            end
        end

        % Button pushed function: ExportDataButton_Results
        function ExportDataButton_ResultsPushed(app, event)
            voxel_Area = app.VolumetryDimY*app.VolumetryDimX;
            target_Path = app.ExportFolderPath + filesep + app.SelectResultsDropDown.Value + "_Results.xlsx";
            table_Vars = ["Volume", "Mean", "SD", "Median", "IQR Low", "IQR High", "Min", "Max"];

            % Write Brain Volumetry to excel
            writing_table = table(app.VolumeEditField_Brain.Value, app.MeanEditField_Brain.Value, app.SDEditField_Brain.Value, app.MedianEditField_Brain.Value, app.IQRLowerEditField_Brain.Value, ...
                app.IQRUpperEditField_Brain.Value, app.MinEditField_Brain.Value, app.MaxEditField_Brain.Value, 'VariableNames', table_Vars);
            writetable(writing_table, target_Path, 'Sheet', "Brain")
            writetable(app.UITable_ResultsBrain.Data, target_Path, 'Sheet', "Brain", 'Range', 'A4')

            % Write Hemisphere Volumetry if applicable
            if app.SelectHemisphereDropDown.Enable == "on"
                % Write Left Hemi Data
                switch numel(size(app.VolumetryHemiMask))
                    case 4
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    case 3
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,1), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                end
                writing_table = table(Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val, 'VariableNames', table_Vars);
                writetable(writing_table, target_Path, 'Sheet', "Left Hemisphere")
                writetable(sliceTable, target_Path, 'Sheet', "Left Hemisphere", 'Range', 'A4')
                % Write Right Hemi Data
                switch numel(size(app.VolumetryHemiMask))
                    case 4
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,:,2), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                    case 3
                        [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryHemiMask(:,:,2), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                end
                writing_table = table(Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val, 'VariableNames', table_Vars);
                writetable(writing_table, target_Path, 'Sheet', "Right Hemisphere")
                writetable(sliceTable, target_Path, 'Sheet', "Right Hemisphere", 'Range', 'A4')
            end

            % Write ROI Volumetry if applicable
            if app.SelectROIDropDown.Enable == "on"
                for i = app.SelectROIDropDown.Items
                     % Find index of selected ROI
                    index = find(contains(app.VolumetryROI.ID, i));
                    switch app.ApplyEdemaCorrectionCheckBox.Value
                        case 1
                            switch numel(size(app.VolumetryImageData))
                                case 2
                                    [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap, app.VolumetryHemiMask);
                                otherwise
                                    [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap, app.VolumetryHemiMask);
                            end
                        case 0
                            switch numel(size(app.VolumetryImageData))
                                case 2
                                    [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                                otherwise
                                    [sliceTable, Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val] = GetVolumetricData(app, app.VolumetryImageData, app.VolumetryROI.Mask(:,:,:,index), voxel_Area, app.VolumetryThickness, app.VolumetryGap);
                            end
                    end
                    try
                        writing_table = table(Volume, mean_val, std_val, median_val, IQRlow, IQRup, min_val, max_val, 'VariableNames', table_Vars);
                    catch
                        writing_table = table(0, 0, 0, 0, 0, 0, 0, 0, 'VariableNames', table_Vars);
                    end
                    writetable(writing_table, target_Path, 'Sheet', string(i))
                        writetable(sliceTable, target_Path, 'Sheet', string(i), 'Range', 'A4')
                end
            end

            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Results successfully exported to an Excel file.", "","Options",{'OK'},"DefaultOption",1, "Icon","success")
        end

        % Cell selection callback: UITable_ResultsBrain
        function UITable_ResultsBrainCellSelection(app, event)
            indices = event.Indices;
            try
                selectedSlice = table2array(app.UITable_ResultsBrain.Data(indices(1),1));
                brainPreview = app.VolumetryBrainMask(:,:,selectedSlice).*app.VolumetryImageData(:,:,selectedSlice);
                brainPreview = (brainPreview - min(brainPreview)) / (max(brainPreview(:)) - min(brainPreview(:)));
                app.UIAxes_Results_Container.Visible = "on";
                imshow(brainPreview, 'DisplayRange', [0 1], 'Parent', app.UIAxes_Results);
                
                app.UIAxes_Results.Interactions = [regionZoomInteraction zoomInteraction];
                table_pos = app.UITable_ResultsBrain.Position;
                app.UIAxes_Results_Container.Position(3) = 300;
                app.UIAxes_Results_Container.Position(4) = 185;
                app.UIAxes_Results_Container.Position(1) = table_pos(1)+table_pos(3)+app.BrainPanel.Position(1);
                app.UIAxes_Results_Container.Position(2) = (app.BrainPanel.Position(4)-app.UIAxes_Results_Container.Position(4))/2;
            catch
            end
        end

        % Cell selection callback: UITable_ResultsHemisphere
        function UITable_ResultsHemisphereCellSelection(app, event)
            indices = event.Indices;
            data_dims = size(app.VolumetryBrainMask);
            try
                selectedSlice = table2array(app.UITable_ResultsHemisphere.Data(indices(1),1));
                switch app.SelectHemisphereDropDown.Value
                    case 'Left'
                        selectedHemisphere = 1;
                        color_Overlay = cat(3, zeros(data_dims(1:2)), zeros(data_dims(1:2)), ones(data_dims(1:2)));
                    case 'Right'
                        selectedHemisphere = 2;
                        color_Overlay = cat(3, ones(data_dims(1:2)), zeros(data_dims(1:2)), zeros(data_dims(1:2)));
                end
                maskPreview = app.VolumetryBrainMask(:,:,selectedSlice);
                app.UIAxes_Results_Container.Visible = "on";
                imshow(maskPreview, 'DisplayRange', [0 1], 'Parent', app.UIAxes_Results);
                hold(app.UIAxes_Results, "on")
                shown_hemi = imshow(color_Overlay, 'Parent', app.UIAxes_Results);
                hold(app.UIAxes_Results, "off")
                shown_hemi.AlphaData = app.VolumetryHemiMask(:,:,selectedSlice,selectedHemisphere)-0.3;
                
                app.UIAxes_Results.Interactions = [regionZoomInteraction zoomInteraction];
                table_pos = app.UITable_ResultsHemisphere.Position;
                app.UIAxes_Results_Container.Position(3) = 300;
                app.UIAxes_Results_Container.Position(4) = 185;
                app.UIAxes_Results_Container.Position(1) = table_pos(1)+table_pos(3)+app.HemispherePanel.Position(1);
                app.UIAxes_Results_Container.Position(2) = (app.HemispherePanel.Position(4)-app.UIAxes_Results_Container.Position(4))/2;
            catch
            end
        end

        % Cell selection callback: UITable_ResultsROI
        function UITable_ResultsROICellSelection(app, event)
            indices = event.Indices;
            data_dims = size(app.VolumetryBrainMask);
            color_Overlay = cat(3, ones(data_dims(1:2)), ones(data_dims(1:2)), zeros(data_dims(1:2)));
            color_Overlay(:,:,1) = 0.9290;
            color_Overlay(:,:,2) = 0.6940;
            color_Overlay(:,:,3) = 0.1250;
            try
                selectedSlice = table2array(app.UITable_ResultsROI.Data(indices(1),1));
                selectedROI = find(contains(app.VolumetryROI.ID, app.SelectROIDropDown.Value));
                maskPreview = app.VolumetryBrainMask(:,:,selectedSlice);
                app.UIAxes_Results_Container.Visible = "on";
                imshow(maskPreview, 'DisplayRange', [0 1], 'Parent', app.UIAxes_Results);
                hold(app.UIAxes_Results, "on")
                shown_roi = imshow(color_Overlay, 'Parent', app.UIAxes_Results);
                hold(app.UIAxes_Results, "off")
                shown_roi.AlphaData = app.VolumetryROI.Mask(:,:,selectedSlice,selectedROI)-0.3;

                app.UIAxes_Results.Interactions = [regionZoomInteraction zoomInteraction];
                table_pos = app.UITable_ResultsROI.Position;
                app.UIAxes_Results_Container.Position(3) = 300;
                app.UIAxes_Results_Container.Position(4) = 185;
                app.UIAxes_Results_Container.Position(1) = table_pos(1)-app.UIAxes_Results_Container.Position(3)+app.ROIPanel_Results.Position(1);
                app.UIAxes_Results_Container.Position(2) = (app.ROIPanel_Results.Position(4)-app.UIAxes_Results_Container.Position(4))/2;
            catch
            end
        end

        % Button down function: ResultsTab
        function ResultsTabButtonDown(app, event)
            app.UIAxes_Results_Container.Visible = "off";
        end

        % Button down function: BrainPanel
        function BrainPanelButtonDown(app, event)
            app.UIAxes_Results_Container.Visible = "off";
        end

        % Button down function: HemispherePanel
        function HemispherePanelButtonDown(app, event)
            app.UIAxes_Results_Container.Visible = "off";
        end

        % Button down function: ROIPanel_Results
        function ROIPanel_ResultsButtonDown(app, event)
            app.UIAxes_Results_Container.Visible = "off";
        end

        % Clicked callback: SelectResultsDropDown
        function SelectResultsDropDownClicked(app, event)
            app.UIAxes_Results_Container.Visible = "off";
        end

        % Clicked callback: SelectHemisphereDropDown
        function SelectHemisphereDropDownClicked(app, event)
            app.UIAxes_Results_Container.Visible = "off";  
        end

        % Clicked callback: SelectROIDropDown
        function SelectROIDropDownClicked(app, event)
            app.UIAxes_Results_Container.Visible = "off";
        end

        % Value changed function: ChooseRegistrationTypeDropDown
        function ChooseRegistrationTypeDropDownValueChanged(app, event)
            
            switch app.ChooseRegistrationTypeDropDown.Value
                case "Standard"
                    app.StandardAtlasRegistrationPanel.Visible = 'on';
                    app.TimeSeriesAlignmentPanel.Visible = 'off';
                    app.SelectfixedDropDown.Visible = 'on';
                    app.SelectfixedLabel.Visible = 'on';
                    app.ImportReferenceAtlasButton.Visible = 'off';
                    app.SelectAtlasDropDown.Visible = 'off';
                case "Reference Atlas"
                    app.StandardAtlasRegistrationPanel.Visible = 'on';
                    app.TimeSeriesAlignmentPanel.Visible = 'off';
                    app.SelectfixedDropDown.Visible = 'off';
                    app.SelectfixedLabel.Visible = 'off';
                    app.ImportReferenceAtlasButton.Visible = 'on';
                    app.SelectAtlasDropDown.Visible = 'on';
                case "Time-Series Alignment"
                    app.StandardAtlasRegistrationPanel.Visible = 'off';
                    app.TimeSeriesAlignmentPanel.Visible = 'on';
            end
            app.SelectfixedDropDown.Value = "None";
            app.RegistrationSliceLimitsTextArea.Value = '';

            switch app.ChooseRegistrationTypeDropDown.Value
                case "Time-Series Alignment"
                    app.StageSettings = suMRak.defaultStageOfType("Euler3D");
                case "Reference Atlas"
                    app.StageSettings = suMRak.defaultStageSettings("Reference Atlas");
                    app.OutputInterpolatorDropDown.Value = 'Linear';           % anatomical subject<->atlas
                otherwise
                    app.StageSettings = suMRak.defaultStageSettings("Standard");
                    app.OutputInterpolatorDropDown.Value = 'NearestNeighbor';  % preserve quantitative values
            end
            refreshTransformsListBox(app);
        end

        % Value changed function: SelectfixedDropDown
        function SelectfixedDropDownValueChanged(app, event)
            if app.SelectfixedDropDown.Value == "None"
                app.RegistrationSliceLimitsTextArea.Value = '';
                return
            end

            app.RegistrationSliceLimitsTextArea.Value = '';
        end

        % Value changed function: SelectmovingDropDown
        function SelectmovingDropDownValueChanged(app, event)
            if app.SelectmovingDropDown.Value == "None"
                app.RegistrationSliceLimitsTextArea.Value = '';
                return
            end

            app.RegistrationSliceLimitsTextArea.Value = '';
        end

        % Value changed function: UseDifferentParameterMapCheckBox
        function UseDifferentParameterMapCheckBoxValueChanged(app, event)

            if app.UseDifferentParameterMapCheckBox.Value == 1
                app.SelectparameterDropDown.Enable = 'on';
            else
                app.SelectparameterDropDown.Enable = 'off';
            end
            app.SelectparameterDropDown.Value = "None";
            app.RegistrationSliceLimitsTextArea.Value = '';
        end

        % Value changed function: SelectparameterDropDown
        function SelectparameterDropDownValueChanged(app, event)
            if app.SelectparameterDropDown.Value == "None"
                app.RegistrationSliceLimitsTextArea.Value = '';
                return
            end

            app.RegistrationSliceLimitsTextArea.Value = '';
        end

        % Button pushed function: ImportReferenceAtlasButton
        function ImportReferenceAtlasButtonPushed(app, event)
            
            % Open import options
            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Select import option:", "Reference Atlas Import Options", ...
                "Options", ["Download New","Load From Directory","Cancel"], "DefaultOption", 1, "CancelOption", 3);
            switch selection
                case "Download New"
                    % Open atlas importer
                    app.ProgressBar = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                    'Message', "Importing Reference Atlases...", 'Indeterminate','on');
                    drawnow
                    app.ImportReferenceAtlasButton.Enable = 'off';
                    app.AtlasImporterWindow = ReferenceAtlasImporter(app);
                case "Load From Directory"
                    progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                    'Message', "Selecting reference atlas folder");
                    cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
                    drawnow
                    progress.Value = 0;
                    % Get directory
                    selected_directory = uigetdir;
                    figure(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);

                    loadedCollection = struct();
                    dropdown_items = {'None'};

                    % Check for atlases and import found
                    MICe_atlas_Path = strcat(selected_directory, filesep, 'MICe Neuroanatomy Atlas - C57BL6J Mouse');
                    if exist(MICe_atlas_Path, 'dir')
                        progress.Value = 0.2;
                        progress.Message = 'Found MICe Neuroanatomy Atlas - C57BL6J Mouse: Importing...';

                        % Load atlas and labels using minc_read, apply
                        % labels as mask
                        MICe_labels_Path = strcat(MICe_atlas_Path, filesep, 'MICeAtlasLabels.mnc');
                        MICe_atlas_Path  = strcat(MICe_atlas_Path, filesep, 'MICeAtlas.mnc');
                        [MICe_info, MICe_atlas.ImageData] = minc_read(char(MICe_atlas_Path));
                        [~, MICe_labels.ImageData]        = minc_read(char(MICe_labels_Path));
                        MICe_labels.ImageData = cast(MICe_labels.ImageData, 'logical');
                        MICe_atlas.ImageData = MICe_atlas.ImageData .* cast(MICe_labels.ImageData, 'like', MICe_atlas.ImageData);

                        % Permute dims, pagetranspose and flip volume
                        MICe_atlas.ImageData = permute(MICe_atlas.ImageData, [1,3,2]);
                        MICe_atlas.ImageData = pagetranspose(MICe_atlas.ImageData);
                        MICe_atlas.ImageData = flipud(MICe_atlas.ImageData);
                        MICe_atlas.ImageData = flip(MICe_atlas.ImageData, 3);

                        % Get atlas info, update dimensions and rotation
                        % matrix
                        MICe_atlas.VoxDimX = MICe_info.info.voxel_size(1);
                        MICe_atlas.VoxDimY = MICe_info.info.voxel_size(2);
                        MICe_atlas.SliceThickness = MICe_info.info.voxel_size(3);
                        MICe_atlas.SliceGap = 0;
                        MICe_atlas.Units = "mm mm mm";
                        MICe_atlas.RotMat = MICe_info.info.mat(1:3,1:3);

                        % Save to loaded atlas collection struct, update
                        % drop down items
                        loadedCollection.MICeAtlas = MICe_atlas;
                        dropdown_items = cat(1, dropdown_items, 'MICe Neuroanatomy Atlas - C57BL6J Mouse');
                    end

                    waxholm_t2_atlas_Path = strcat(selected_directory, filesep, 'T2w Waxholm Space Atlas - C57BL6J Mouse');
                    if exist(waxholm_t2_atlas_Path, 'dir')
                        progress.Value = 0.4;
                        progress.Message = 'Found T2w Waxholm Space Atlas - C57BL6J Mouse: Importing...';

                        % Update atlas and labels path
                        waxholm_t2_labels_Path = strcat(waxholm_t2_atlas_Path, filesep, 'T2WaxholmMouseLabels.nii.gz');
                        waxholm_t2_atlas_Path  = strcat(waxholm_t2_atlas_Path, filesep, 'T2WaxholmMouse.nii.gz');
                
                        % Load atlas and labels using niftiread, apply labels as
                        % mask
                        waxholm_t2_atlas.ImageData = niftiread(waxholm_t2_atlas_Path);
                        waxholm_t2_labels.ImageData = niftiread(waxholm_t2_labels_Path);
                        
                        % Remove nerves and inner ear and apply labels as mask
                        waxholm_t2_labels.ImageData(ismember(waxholm_t2_labels.ImageData, [18 32 33 34 35 36 37 38 39])) = 0;
                        waxholm_t2_labels.ImageData = cast(waxholm_t2_labels.ImageData, 'logical');
                        waxholm_t2_atlas.ImageData = waxholm_t2_atlas.ImageData .* cast(waxholm_t2_labels.ImageData, 'like', waxholm_t2_atlas.ImageData);
                    
                        % permute dims, pagetranspose and flipud
                        waxholm_t2_atlas.ImageData = permute(waxholm_t2_atlas.ImageData, [1,3,2]);
                        waxholm_t2_atlas.ImageData = pagetranspose(waxholm_t2_atlas.ImageData);
                        waxholm_t2_atlas.ImageData = flipud(waxholm_t2_atlas.ImageData);
                        waxholm_t2_atlas.ImageData = flip(waxholm_t2_atlas.ImageData, 3);

                        % Get atlas info, update dimensions and rotation
                        % matrix
                        waxholm_info = niftiinfo(waxholm_t2_atlas_Path);
                        waxholm_t2_atlas.VoxDimX = waxholm_info.PixelDimensions(1);
                        waxholm_t2_atlas.VoxDimY = waxholm_info.PixelDimensions(1);
                        waxholm_t2_atlas.SliceThickness = waxholm_info.PixelDimensions(1);
                        waxholm_t2_atlas.SliceGap = 0;
                        waxholm_t2_atlas.Units = "mm mm mm";
                        waxholm_t2_atlas.RotMat = waxholm_info.Transform.T(1:3,1:3);

                        % Save to loaded atlas collection struct, update
                        % drop down items
                        loadedCollection.T2WaxholmMouse = waxholm_t2_atlas;
                        dropdown_items = cat(1, dropdown_items, 'T2w Waxholm Space Atlas - C57BL6J Mouse');
                    end
                    
                    waxholm_t1_atlas_Path = strcat(selected_directory, filesep, 'T1w Waxholm Space Atlas - C57BL6J Mouse');
                    if exist(waxholm_t1_atlas_Path, 'dir')
                        progress.Value = 0.6;
                        progress.Message = 'Found T1w Waxholm Space Atlas - C57BL6J Mouse: Importing...';

                        % Update atlas and labels path
                        waxholm_t1_labels_Path = strcat(waxholm_t1_atlas_Path, filesep, 'T1WaxholmMouseLabels.nii.gz');
                        waxholm_t1_atlas_Path  = strcat(waxholm_t1_atlas_Path, filesep, 'T1WaxholmMouse.nii.gz');
                
                        % Load atlas and labels using niftiread, apply labels as
                        % mask
                        waxholm_t1_atlas.ImageData = niftiread(waxholm_t1_atlas_Path);
                        waxholm_t1_labels.ImageData = niftiread(waxholm_t1_labels_Path);
                        
                        % Remove nerves and inner ear and apply labels as mask
                        waxholm_t1_labels.ImageData(ismember(waxholm_t1_labels.ImageData, [18 32 33 34 35 36 37 38 39])) = 0;
                        waxholm_t1_labels.ImageData = cast(waxholm_t1_labels.ImageData, 'logical');
                        waxholm_t1_atlas.ImageData = waxholm_t1_atlas.ImageData .* cast(waxholm_t1_labels.ImageData, 'like', waxholm_t1_atlas.ImageData);
                        
        
                        % permute dims, pagetranspose and flipud
                        waxholm_t1_atlas.ImageData = permute(waxholm_t1_atlas.ImageData, [1,3,2]);
                        waxholm_t1_atlas.ImageData = pagetranspose(waxholm_t1_atlas.ImageData);
                        waxholm_t1_atlas.ImageData = flipud(waxholm_t1_atlas.ImageData);
                        waxholm_t1_atlas.ImageData = flip(waxholm_t1_atlas.ImageData, 3);

                        % Get atlas info, update dimensions and rotation
                        % matrix
                        waxholm_info = niftiinfo(waxholm_t1_atlas_Path);
                        waxholm_t1_atlas.VoxDimX = waxholm_info.PixelDimensions(1);
                        waxholm_t1_atlas.VoxDimY = waxholm_info.PixelDimensions(1);
                        waxholm_t1_atlas.SliceThickness = waxholm_info.PixelDimensions(1);
                        waxholm_t1_atlas.SliceGap = 0;
                        waxholm_t1_atlas.Units = "mm mm mm";
                        waxholm_t1_atlas.RotMat = waxholm_info.Transform.T(1:3,1:3);

                        % Save to loaded atlas collection struct, update
                        % drop down items
                        loadedCollection.T1WaxholmMouse = waxholm_t1_atlas;
                        dropdown_items = cat(1, dropdown_items, 'T1w Waxholm Space Atlas - C57BL6J Mouse');
                    end

                    allen_atlas_Path = strcat(selected_directory, filesep, 'Allen Brain Atlas - Adult Mouse Nissl Grayscale'); 
                    if exist(allen_atlas_Path, 'dir')
                        progress.Value = 0.8;
                        progress.Message = 'Found Allen Brain Atlas - Adult Mouse Nissl Grayscale; Importing...';

                        % Update atlas path
                        allen_atlas_Path = strcat(allen_atlas_Path, filesep, 'atlasVolume.raw');
                        % Load atlas per Allen Brain Atlas instructions
                        fid = fopen(allen_atlas_Path, 'r', 'l' );
                        allen_atlas.ImageData = fread( fid, prod([528 320 456]), 'uint8');
                        fclose( fid );
                        allen_atlas.ImageData = reshape(allen_atlas.ImageData, [528 320 456]);
                        % Permute and transpose image
                        allen_atlas.ImageData = permute(allen_atlas.ImageData, [3 2 1]);
                        allen_atlas.ImageData = pagetranspose(allen_atlas.ImageData);
                        
                        % Set atlas dimensions, rotation matrix
                        allen_atlas.VoxDimX = 0.025;
                        allen_atlas.VoxDimY = 0.025;
                        allen_atlas.SliceThickness = 0.025;
                        allen_atlas.SliceGap = 0;
                        allen_atlas.Units = "mm mm mm";
                        allen_atlas.RotMat = diag([1,1,1]);

                        % Save to loaded atlas collection struct, update
                        % drop down items
                        loadedCollection.AllenAdultMouseNissl = allen_atlas;
                        dropdown_items = cat(1, dropdown_items, 'Allen Brain Atlas - Adult Mouse Nissl Grayscale');
                    end

                    if isequal(dropdown_items, {'None'})
                        
                        uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'No reference atlases were found in selected directory.', 'No Reference Atlas Found')
                    else
                        % Update loaded atlas collection, update drop down
                        app.AtlasCollection = loadedCollection;
                        app.SelectAtlasDropDown.Items = dropdown_items;
                        
                        progress.Value = 1;
                        progress.Message = 'Done!';


                        % Display confirmation
                        uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Reference atlases sucessfully imported.", "","Options",{'OK'},"DefaultOption",1, "Icon","success");
                    end
                case "Cancel"
                    return
            end
        end

        % Value changed function: SelectAtlasDropDown
        function SelectAtlasDropDownValueChanged(app, event)
            
            value = app.SelectAtlasDropDown.Value;
            switch value
                case "MICe Neuroanatomy Atlas - C57BL6J Mouse"
                    app.ChosenAtlas = app.AtlasCollection.MICeAtlas;
                case "T2w Waxholm Space Atlas - C57BL6J Mouse"
                    app.ChosenAtlas = app.AtlasCollection.T2WaxholmMouse;
                case "T1w Waxholm Space Atlas - C57BL6J Mouse"
                    app.ChosenAtlas = app.AtlasCollection.T1WaxholmMouse;
                case "Allen Brain Atlas - Adult Mouse Nissl Grayscale"
                    app.ChosenAtlas = app.AtlasCollection.AllenAdultMouseNissl;
            end
        end

        % Button pushed function: RegistrationViewerButton
        function RegistrationViewerButtonPushed(app, event)
            
            % Check for valid selections
            if app.SelectmovingDropDown.Value == "None" || ...
                    (app.ChooseRegistrationTypeDropDown.Value == "Standard" && app.SelectfixedDropDown.Value == "None") || ...
                    (app.SelectparameterDropDown.Value == "None" && app.UseDifferentParameterMapCheckBox.Value == 1)
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Cannot open Registration Viewer; please select valid registration data.', 'Registration Viewer Error.')
                return
            elseif (app.ChooseRegistrationTypeDropDown.Value == "Reference Atlas" && app.SelectAtlasDropDown.Value == "None")
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Cannot open Registration Viewer; please import and select valid reference atlas.', 'Registration Viewer Error.')
                return
            end
            
            % Get registration image data based on registration type
            moving_Image = cell2mat(app.SavedTable.Image(app.SelectmovingDropDown.Value));
            switch app.ChooseRegistrationTypeDropDown.Value
                case "Standard"
                    fixed_Image = cell2mat(app.SavedTable.Image(app.SelectfixedDropDown.Value));
                case "Reference Atlas"
                    fixed_Image = app.ChosenAtlas.ImageData;
            end
            
            % Open Registration Viewer 
            if app.UseDifferentParameterMapCheckBox.Value == 0
                app.ProgressBar = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                     'Message', "Viewing Registration Data...", 'Indeterminate','on');
                drawnow
                app.RegistrationViewerButton.Enable = 'off';
                app.RegistrationViewerWindow = RegistrationViewer_Basic(app, moving_Image, fixed_Image);
                if app.ChooseRegistrationTypeDropDown.Value == "Reference Atlas"
                    app.RegistrationViewerWindow.FixedImageLabel.Text = "Reference Atlas";
                end
            elseif app.UseDifferentParameterMapCheckBox.Value == 1
                parameter_Image = cell2mat(app.SavedTable.Image(app.SelectparameterDropDown.Value));
                app.ProgressBar = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                     'Message', "Viewing Registration Data...", 'Indeterminate','on');
                drawnow
                app.RegistrationViewerButton.Enable = 'off';
                app.RegistrationViewerWindow = RegistrationViewer_Parameter(app, moving_Image, fixed_Image, parameter_Image);
                if app.ChooseRegistrationTypeDropDown.Value == "Reference Atlas"
                    app.RegistrationViewerWindow.FixedImageLabel.Text = "Reference Atlas";
                end
            end
        end

        % Value changed function: ManualInstructionInputCheckBox
        function ManualInstructionInputCheckBoxValueChanged(app, event)
            value = app.ManualInstructionInputCheckBox.Value;
               
            if value == 1
                app.RegistrationSliceLimitsTextArea.Editable = 'on';
            else
                app.RegistrationSliceLimitsTextArea.Editable = 'off';
            end
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            % Check for valid selections, registration instructions
            if app.SelectmovingDropDown.Value == "None"|(app.ChooseRegistrationTypeDropDown.Value == "Standard" & app.SelectfixedDropDown.Value == "None")|(app.SelectparameterDropDown.Value == "None" & app.UseDifferentParameterMapCheckBox.Value ==1) %#ok<OR2,AND2> 
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Registration not possible; please select valid registration data.', 'Registration Error.')
                return
            elseif (app.ChooseRegistrationTypeDropDown.Value == "Reference Atlas" && app.SelectAtlasDropDown.Value == "None")
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Registration not possible; please import and select valid reference atlas.', 'Registration Error.')
                return
            elseif app.RegistrationSliceLimitsTextArea.Value == ""
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Registration not possible; no instructions specified.', 'Registration Error.')
                return
            end
            
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title','Please wait', 'Indeterminate','on', 'Message', 'Registering images');
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            % Get total registration instructions, remove 0x0 char arrays
            split_instr = split(app.RegistrationSliceLimitsTextArea.Value, ' -> ');
            split_instr =  split_instr(~cellfun('isempty',split_instr));

            app.RegisteredImageData = [];
            % app.PreRegistrationFixedImage = cell2mat(app.SavedTable.Image(app.SelectfixedDropDown.Value));
            app.RegisteredMask = [];
            
            % Get moving, fixed/atlas and parameter image data and spacing
            moving_Image = cell2mat(app.SavedTable.Image(app.SelectmovingDropDown.Value));
            movingProps = app.SavedTable(app.SelectmovingDropDown.Value, :);
            movingSpacing = suMRak.voxelSpacing(movingProps);
            app.MovingNDims = numel(size(moving_Image));
            switch app.ChooseRegistrationTypeDropDown.Value
                case "Standard"
                    fixed_Image = cell2mat(app.SavedTable.Image(app.SelectfixedDropDown.Value));
                    fixed_Mask = cell2mat(app.SavedTable.BrainMask(app.SelectfixedDropDown.Value));
                    fixedProps = app.SavedTable(app.SelectfixedDropDown.Value, :);
                    fixedSpacing = suMRak.voxelSpacing(fixedProps);
                    app.FixedNDims = numel(size(fixed_Image));
                case "Reference Atlas"
                    fixed_Image = app.ChosenAtlas.ImageData;
                    % Resize Atlas image data using moving/atlas larger dimension ratio
                    moving_dims = size(moving_Image);
                    moving_dims = moving_dims(1:2);
                    atlas_dims = size(fixed_Image);
                    atlas_dims = atlas_dims(1:2);
                    resizing_factor = max(moving_dims)/max(atlas_dims);
                    fixed_Image = imresize(fixed_Image, resizing_factor, 'Method', 'bilinear');
                
                    app.ResizedAtlasProperties.VoxDimX        = app.ChosenAtlas.VoxDimX/resizing_factor;
                    app.ResizedAtlasProperties.VoxDimY        = app.ChosenAtlas.VoxDimY/resizing_factor;
                    app.ResizedAtlasProperties.SliceThickness = app.ChosenAtlas.SliceThickness;
                    app.ResizedAtlasProperties.SliceGap       = app.ChosenAtlas.SliceGap;
                    app.ResizedAtlasProperties.RotMat         = app.ChosenAtlas.RotMat;
                
                    fixedSpacing = suMRak.voxelSpacing(app.ResizedAtlasProperties);
                    app.FixedNDims = numel(size(fixed_Image));
                                case "Time-Series Alignment"
                    fixedSpacing = movingSpacing;
                    app.FixedNDims = app.MovingNDims;
            end
            if app.UseDifferentParameterMapCheckBox.Value == 1
                parameter_Image = cell2mat(app.SavedTable.Image(app.SelectparameterDropDown.Value));
                paramProps = app.SavedTable(app.SelectparameterDropDown.Value, :);
                paramSpacing = suMRak.voxelSpacing(paramProps);
                paramSpacing_py = py.list(num2cell(paramSpacing));
                app.ParameterNDims = numel(size(parameter_Image));
            end

            fixedSpacing_py  = py.list(num2cell(fixedSpacing));
            movingSpacing_py = py.list(num2cell(movingSpacing));

            % Convert StageSettings struct array -> Python list of dicts
            stages_py = py.list();
            for k = 1:numel(app.StageSettings)
                s = app.StageSettings(k);
                d = py.dict(pyargs( ...
                    'Type',                s.Type, ...
                    'Enabled',             s.Enabled, ...
                    'Metric',              s.Metric, ...
                    'MetricBins',          s.MetricBins, ...
                    'SamplingPct',         s.SamplingPct, ...
                    'SamplingStrategy',    s.SamplingStrategy, ...
                    'Optimizer',           s.Optimizer, ...
                    'LearningRate',        s.LearningRate, ...
                    'NumIterations',       s.NumIterations, ...
                    'ConvergenceTol',      s.ConvergenceTol, ...
                    'ShrinkFactors',       py.list(num2cell(int32(s.ShrinkFactors))), ...
                    'SmoothingSigmas',     py.list(num2cell(double(s.SmoothingSigmas))), ...
                    'Interpolator',        s.Interpolator, ...
                    'BSplineMeshSize',     py.list(num2cell(int32(s.BSplineMeshSize))), ...
                    'BSplineOrder',        s.BSplineOrder, ...
                    'BSplineScaleFactors', py.list(num2cell(int32(s.BSplineScaleFactors))), ...
                    'MinJacobian',         s.MinJacobian, ...
                    'InPlaneOnly',         suMRak.getfield_or(s, 'InPlaneOnly', true)));
                stages_py.append(d);
            end
            
            basic = suMRak.buildRegistrationScript();

            % Check for pipeline order sanity
            types = {app.StageSettings.Type};
            enabled = [app.StageSettings.Enabled];
            bspl = strcmp(types,'BSpline') & enabled;
            lastBspl = find(bspl, 1, 'last');
            if ~isempty(lastBspl) && any(enabled(lastBspl+1:end))
                answer = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    'A BSpline stage is followed by an enabled non-BSpline stage. This rarely converges well. Continue?', ...
                    'Pipeline order warning', 'Options', {'Continue','Cancel'}, 'DefaultOption', 2);
                if strcmp(answer,'Cancel'), return, end
            end

            % Get start and end instructions
            start_instr = cell2mat(split_instr(1));
            end_instr = cell2mat(split_instr(2));
            
            % Get moving volume dimension indexes
            [dim3_start, dim4, dim5] = suMRak.parseSegment(start_instr, 'm');
            dim3_end                 = suMRak.parseSegment(end_instr,   'm');
            switch app.MovingNDims
                case 5
                    moving_Image = squeeze(moving_Image(:,:,:,str2double(dim4),str2double(dim5)));
                case 4
                    moving_Image = squeeze(moving_Image(:,:,:,str2double(dim4)));
            end

            % Create moving numpy array from selected subvolume
            moving_Image_py = py.numpy.array(subvolume(moving_Image,[nan,nan,nan,nan,dim3_start,dim3_end]));

            % Get fixed slice dimension indexes, create numpy array
            [dim3_start, dim4, dim5] = suMRak.parseSegment(start_instr, 'f');
            dim3_end                 = suMRak.parseSegment(end_instr,   'f');
            switch app.FixedNDims
                case 5
                    fixed_Image = squeeze(fixed_Image(:,:,:,str2double(dim4),str2double(dim5)));
                case 4
                    fixed_Image = squeeze(fixed_Image(:,:,:,str2double(dim4)));
            end

            % Create fixed numpy array from selected subvolume
            fixed_Image = subvolume(fixed_Image,[nan,nan,nan,nan,dim3_start,dim3_end]);
            app.PreRegistrationFixedImage = fixed_Image;
            fixed_Image_py = py.numpy.array(fixed_Image);

            if contains(start_instr, 'p') == 1 % If there is parameter image instruction set

                % Get parameter slice dimension indexes, create numpy array
                [dim3_start, dim4, dim5] = suMRak.parseSegment(start_instr, 'p');
                dim3_end                 = suMRak.parseSegment(end_instr,   'p');
                switch app.ParameterNDims
                    case 5
                        parameter_Image = squeeze(parameter_Image(:,:,:,str2double(dim4),str2double(dim5)));
                    case 4
                        parameter_Image = squeeze(parameter_Image(:,:,:,str2double(dim4)));
                end

                parameter_Image_py = py.numpy.array(subvolume(parameter_Image,[nan,nan,nan,nan,dim3_start,dim3_end]));
                
                % Register on the parameter contrast, then resample the moving image
                apply_addon = [ ...
                    "applyIm  = np.ascontiguousarray(np.transpose(applyIm,  (2, 0, 1)))", ...
                    "applied = sitk.GetImageFromArray(applyIm.astype(np.float32))", ...
                    "applied.SetSpacing([float(s) for s in applySpacing])", ...
                    "center_at_origin(applied)", ...
                    "resampled2 = sitk.Resample(applied, fixed, final, OUT_INTERP, 0.0, applied.GetPixelID())", ...
                    "resultArray = sitk.GetArrayFromImage(resampled2)", ...
                    "resultArray = np.ascontiguousarray(np.transpose(resultArray, (1, 2, 0)))" ...
                    ];
                full_script = [basic, apply_addon];

                % Switch moving and parameter images
                try
                    [resultImage_py, warns_py] = pyrun(full_script, ["resultArray", "stage_warnings"], ...
                        fixIm = fixed_Image_py, ...
                        movIm = parameter_Image_py, ...
                        applyIm = moving_Image_py, ...
                        fixSpacing    = fixedSpacing_py, ...
                        movSpacing    = paramSpacing_py, ...
                        applySpacing  = movingSpacing_py, ...
                        outInterp     = string(app.OutputInterpolatorDropDown.Value), ...
                        stagesList    = stages_py);
                catch ME
                    handleRegistrationError(app, ME);
                    return
                end
            else
                try
                    [resultImage_py, warns_py] = pyrun(basic, ["resultArray", "stage_warnings"], ...
                        fixIm = fixed_Image_py, ...
                        movIm = moving_Image_py, ...
                        fixSpacing = fixedSpacing_py, ...
                        movSpacing = movingSpacing_py, ...
                        outInterp  = string(app.OutputInterpolatorDropDown.Value), ...
                        stagesList = stages_py);
                catch ME
                    handleRegistrationError(app, ME);
                    return
                end
            end

            % Save the result
            app.RegisteredImageData = double(resultImage_py);

            % Surface non-fatal stage notices (e.g. a BSpline stage dropped for folding)
            stageNotices = cellfun(@char, cell(warns_py), 'UniformOutput', false);
            if ~isempty(stageNotices)
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    strjoin(string(stageNotices), newline), ...
                    'Registration notice', 'Icon', 'warning');
            end
            
            % In case of standard registration, concatenate mask
            if app.ChooseRegistrationTypeDropDown.Value == "Standard"
                app.RegisteredMask = subvolume(fixed_Mask,[nan,nan,nan,nan,dim3_start,dim3_end]);
            end
            

            dims_reg = size(app.RegisteredImageData);
            try
                dim3_size = dims_reg(3);
                app.SliceSlider_Registration.Value = 1;
                app.SliceSlider_Registration.Enable = 'on';
                app.SliceSlider_Registration.Limits = [1, dim3_size];
                app.SliceSpinner_Registration.Value = 1;
                app.SliceSpinner_Registration.Enable = 'on';
                app.SliceSpinner_Registration.Limits = [1, dim3_size];
            catch
                app.SliceSlider_Registration.Enable = 'off';
                app.SliceSlider_Registration.Value = 1;
                app.SliceSpinner_Registration.Enable = 'off';
                app.SliceSpinner_Registration.Value = 1;
            end
            app.TurboButton_Registration.Enable = 'on';
            app.GreyscaleButton_Registration.Enable = 'on';
            app.ShowReferenceFixedCheckBox.Enable = 'on';
            app.ReferenceFixedViewDropDown.Enable = app.ShowReferenceFixedCheckBox.Value;

            RefreshImageRegistration(app); 

            app.SaveRegisteredDataButton.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Registration.Enable = 'on';
            end

        end

        % Value changed function: SelectTimeAlignmentDropDown
        function SelectTimeAlignmentDropDownValueChanged(app, event)
            
            if app.SelectTimeAlignmentDropDown.Value == "None"
                app.Dim4Spinner_TimeAlignmentReference.Enable = 'off';
                app.Dim4Spinner_TimeAlignmentReference.Value = 1;
                app.Dim5Spinner_TimeAlignmentReference.Enable = 'off';
                app.Dim5Spinner_TimeAlignmentReference.Value = 1;
                app.AlignDataButton.Enable = 'off';
                return
            end
            % Get image date
            try
                working_data = cell2mat(app.SavedTable.Image(app.SelectTimeAlignmentDropDown.Value));
            catch
                working_data = cell2mat(app.ExperimentPropertyTable.("Image data")(app.SelectTimeAlignmentDropDown.Value));     
            end
            data_dims = size(working_data);
            switch numel(data_dims)
                case 4
                    dim4_size = data_dims(4);
                    app.Dim4Spinner_TimeAlignmentReference.Enable = 'on';
                    app.Dim4Spinner_TimeAlignmentReference.Limits = [1, dim4_size];
                    app.Dim4Spinner_TimeAlignmentReference.Value = 1;
                    app.Dim5Spinner_TimeAlignmentReference.Enable = 'off';
                    app.AlignDataButton.Enable = 'on';
                case 5
                    dim4_size = data_dims(4);
                    app.Dim4Spinner_TimeAlignmentReference.Enable = 'on';
                    app.Dim4Spinner_TimeAlignmentReference.Limits = [1, dim4_size];
                    app.Dim4Spinner_TimeAlignmentReference.Value = 1;
                    dim5_size = data_dims(5);
                    app.Dim5Spinner_TimeAlignmentReference.Enable = 'on';
                    app.Dim5Spinner_TimeAlignmentReference.Limits = [1, dim5_size];
                    app.Dim5Spinner_TimeAlignmentReference.Value = 1;
                    app.AlignDataButton.Enable = 'on';
                otherwise
                    % Disable alignment
                    app.Dim4Spinner_TimeAlignmentReference.Enable = 'off';
                    app.Dim4Spinner_TimeAlignmentReference.Value = 1;
                    app.Dim5Spinner_TimeAlignmentReference.Enable = 'off';
                    app.Dim5Spinner_TimeAlignmentReference.Value = 1;
                    app.AlignDataButton.Enable = 'off';
                    uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Time alignment not possible, data must have 4 or 5 dimensions.', 'Dimension error')

            end
        end

        % Button pushed function: AlignDataButton
        function AlignDataButtonPushed(app, event)

            % Draw progress bar
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Title', 'Please wait', ...
                'Indeterminate', 'on', 'Message', 'Aligning data');
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            % Get image data
            expName = app.SelectTimeAlignmentDropDown.Value;
            try
                original_data = cell2mat(app.SavedTable.Image(expName));
            catch
                original_data = cell2mat(app.ExperimentPropertyTable.("Image data")(expName));
            end
            data_dims = size(original_data);
            if ~ismember(numel(data_dims), [4 5])
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    'Time-series alignment needs 4D (x, y, slice, time) or 5D data.', ...
                    'Unsupported data');
                return
            end

            % Set before data
            app.PreRegistrationFixedImage = original_data;

            % Flatten dims 4 (and 5) into one frame axis. The reference frame is
            % the single (dim4, dim5) position chosen in the spinners; every
            % other frame is registered onto it.
            frameDims = data_dims(4:end);
            nFrames   = prod(frameDims);
            if isscalar(frameDims)
                refIdx = app.Dim4Spinner_TimeAlignmentReference.Value;
            else
                refIdx = sub2ind(frameDims, app.Dim4Spinner_TimeAlignmentReference.Value, ...
                                            app.Dim5Spinner_TimeAlignmentReference.Value);
            end
            series = reshape(original_data, data_dims(1), data_dims(2), data_dims(3), nFrames);

            % Physical voxel spacing (x, y, slice thickness + gap), shared by all frames
            p = LookupExperimentParams(app, expName);
            spacing_py = py.list(num2cell(suMRak.voxelSpacing(p)));

            % Stage pipeline: the Time-Series preset (single in-plane Euler3D stage)
            if isempty(app.StageSettings)
                app.StageSettings = suMRak.defaultStageOfType("Euler3D");
            end
            stages_py = suMRak.stagesToPy(app.StageSettings);

            regCode = strjoin(suMRak.buildRegistrationScript(), newline);
            series_script = [ ...
                "import numpy as np", ...
                "reg = compile(str(regCode), '<suMRak registration>', 'exec')", ...
                "ref = int(refIdx)", ...
                "fixRef = np.array(seriesIm[..., ref])", ...
                "frames = []", ...
                "stage_warnings_all = []", ...
                "for k in range(seriesIm.shape[-1]):", ...
                "    if k == ref:", ...
                "        frames.append(np.asarray(fixRef, dtype=np.float32))", ...
                "        continue", ...
                "    ns = {'fixIm': fixRef, 'movIm': np.array(seriesIm[..., k]),", ...
                "          'fixSpacing': spacing, 'movSpacing': spacing,", ...
                "          'outInterp': 'Linear', 'stagesList': stagesList}", ...
                "    exec(reg, ns)", ...
                "    frames.append(np.asarray(ns['resultArray'], dtype=np.float32))", ...
                "    stage_warnings_all.extend('Frame %d: %s' % (k + 1, w) for w in ns['stage_warnings'])", ...
                "resultArray = np.ascontiguousarray(np.stack(frames, axis=-1))" ...
                ];

            try
                [resultImage_py, warns_py] = pyrun(series_script, ["resultArray", "stage_warnings_all"], ...
                    regCode    = regCode, ...
                    seriesIm   = py.numpy.array(series), ...
                    refIdx     = int32(refIdx - 1), ...
                    spacing    = spacing_py, ...
                    stagesList = stages_py);
            catch ME
                handleRegistrationError(app, ME);
                return
            end

            % Back to the original 4D/5D layout
            working_data = reshape(double(resultImage_py), data_dims);

            % Surface non-fatal stage notices (e.g. a BSpline stage dropped for folding)
            stageNotices = cellfun(@char, cell(warns_py), 'UniformOutput', false);
            if ~isempty(stageNotices)
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    strjoin(string(stageNotices), newline), ...
                    'Alignment notice', 'Icon', 'warning');
            end

            switch numel(data_dims)
                case 4
                    % Disable dim5 controls
                    app.Dim5Slider_TimeAlignmentControl.Enable = 'off';
                    app.Dim5Slider_TimeAlignmentControl.Value = 1;
                    app.Dim5Spinner_TimeAlignmentControl.Enable = 'off';
                    app.Dim5Spinner_TimeAlignmentControl.Value = 1;
                case 5
                    % Enable dim5 controls
                    app.Dim5Slider_TimeAlignmentControl.Enable = 'on';
                    app.Dim5Slider_TimeAlignmentControl.Limits = [1, data_dims(5)];
                    app.Dim5Slider_TimeAlignmentControl.Value = 1;
                    app.Dim5Spinner_TimeAlignmentControl.Enable = 'on';
                    app.Dim5Spinner_TimeAlignmentControl.Limits = [1, data_dims(5)];
                    app.Dim5Spinner_TimeAlignmentControl.Value = 1;
            end

            % Update slice controls
            try
                app.SliceSlider_Registration.Value = 1;
                app.SliceSlider_Registration.Enable = 'on';
                app.SliceSlider_Registration.Limits = [1, data_dims(3)];
                app.SliceSpinner_Registration.Value = 1;
                app.SliceSpinner_Registration.Enable = 'on';
                app.SliceSpinner_Registration.Limits = [1, data_dims(3)];
            catch
                app.SliceSlider_Registration.Enable = 'off';
                app.SliceSlider_Registration.Value = 1;
                app.SliceSpinner_Registration.Enable = 'off';
                app.SliceSpinner_Registration.Value = 1;
            end

            % Enable dim4 controls
            app.Dim4Slider_TimeAlignmentControl.Enable = 'on';
            app.Dim4Slider_TimeAlignmentControl.Limits = [1, data_dims(4)];
            app.Dim4Slider_TimeAlignmentControl.Value = 1;
            app.Dim4Spinner_TimeAlignmentControl.Enable = 'on';
            app.Dim4Spinner_TimeAlignmentControl.Limits = [1, data_dims(4)];
            app.Dim4Spinner_TimeAlignmentControl.Value = 1;

            % Set working data as registration data
            app.RegisteredImageData = working_data;

            % Enable remaining components, refresh image
            app.TurboButton_Registration.Enable = 'on';
            app.GreyscaleButton_Registration.Enable = 'on';
            app.ShowReferenceFixedCheckBox.Enable = 'on';
            app.ReferenceFixedViewDropDown.Enable = app.ShowReferenceFixedCheckBox.Value;

            RefreshImageRegistration(app);

            app.SaveRegisteredDataButton.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Registration.Enable = 'on';
            end
        end

        % Value changing function: SliceSlider_Registration
        function SliceSlider_RegistrationValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSpinner_Registration.Value = event.Source.Value;

            RefreshImageRegistration(app);
        end

        % Value changed function: SliceSpinner_Registration
        function SliceSpinner_RegistrationValueChanged(app, event)
            app.SliceSlider_Registration.Value = app.SliceSpinner_Registration.Value;

            RefreshImageRegistration(app);
        end

        % Value changing function: Dim4Slider_TimeAlignmentControl
        function Dim4Slider_TimeAlignmentControlValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.Dim4Spinner_TimeAlignmentControl.Value = event.Source.Value;

            RefreshImageRegistration(app);
        end

        % Value changed function: Dim4Spinner_TimeAlignmentControl
        function Dim4Spinner_TimeAlignmentControlValueChanged(app, event)
            app.Dim4Slider_TimeAlignmentControl.Value = app.Dim4Spinner_TimeAlignmentControl.Value;

            RefreshImageRegistration(app);
        end

        % Value changing function: Dim5Slider_TimeAlignmentControl
        function Dim5Slider_TimeAlignmentControlValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.Dim5Spinner_TimeAlignmentControl.Value = event.Source.Value;

            RefreshImageRegistration(app);
        end

        % Value changed function: Dim5Spinner_TimeAlignmentControl
        function Dim5Spinner_TimeAlignmentControlValueChanged(app, event)
            app.Dim5Slider_TimeAlignmentControl.Value = app.Dim5Spinner_TimeAlignmentControl.Value;

            RefreshImageRegistration(app); 
        end

        % Selection changed function: ColormapButtonGroup_Registration
        function ColormapButtonGroup_RegistrationSelectionChanged(app, event)

            RefreshImageRegistration(app);
        end

        % Value changed function: ShowReferenceFixedCheckBox
        function ShowReferenceFixedCheckBoxValueChanged(app, event)
            
            app.ReferenceFixedViewDropDown.Enable = app.ShowReferenceFixedCheckBox.Value;
            RefreshImageRegistration(app);
        end

        % Button pushed function: ExportDataButton_Registration
        function ExportDataButton_RegistrationPushed(app, event)
            
            switch app.ChooseRegistrationTypeDropDown.Value
                case "Standard"
                    ExportImageDataRegistration(app, 'Standard Registration');
                case "Reference Atlas"
                    ExportImageDataRegistration(app, 'Atlas Registration');
                case "Time-Series Alignment"
                    ExportImageDataRegistration(app, 'Time Series Alignment');
            end
 
            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Registered image data exported in NIfTI format.", "","Options",{'OK'},"DefaultOption",1, "Icon","success");
        end

        % Button pushed function: SaveRegisteredDataButton
        function SaveRegisteredDataButtonPushed(app, event)
            SaveData(app, 'Registration');
        end

        % Menu selected function: ResetViewMenu_Registration
        function ResetViewMenu_RegistrationSelected(app, event)
            
            % Reset zoom
            app.UIAxes_Registration.XLim = [-inf inf];
            app.UIAxes_Registration.YLim = [-inf inf];
        end

        % Menu selected function: ResetInstructionsMenu
        function ResetInstructionsMenuSelected(app, event)
            app.RegistrationSliceLimitsTextArea.Value = '';
        end

        % Value changed function: ChooseMapTypeDropDown
        function ChooseMapTypeDropDownValueChanged(app, event)
            switch app.ChooseMapTypeDropDown.Value
                case "DSC"
                    app.DSCMappingOptionsPanel.Visible = 'on';
                    app.T1OptionsPanel.Visible = 'off';
                    app.T2OptionsPanel.Visible = 'off';
                    % app.OptimizationOptionsPanel.Visible = 'off';
                    app.FAIRpASLMappingoptionsPanel.Visible = 'off';
                case "T2"
                    app.DSCMappingOptionsPanel.Visible = 'off';
                    app.T1OptionsPanel.Visible = 'off';
                    app.T2OptionsPanel.Visible = 'on';
                    % app.OptimizationOptionsPanel.Visible = 'on';
                    app.FAIRpASLMappingoptionsPanel.Visible = 'off';
                    % t2Position = app.T2OptionsPanel.Position;
                    % optimizationPosition = app.OptimizationOptionsPanel.Position;
                    % optimizationPosition(2) = t2Position(2)-optimizationPosition(4);
                    % app.OptimizationOptionsPanel.Position = optimizationPosition;
                case "T1"
                    app.DSCMappingOptionsPanel.Visible = 'off';
                    app.T1OptionsPanel.Visible = 'on';
                    app.T2OptionsPanel.Visible = 'off';
                    app.CalculateT1MapButton.Visible = 'on';
                    % app.OptimizationOptionsPanel.Visible = 'on';
                    app.FAIRpASLMappingoptionsPanel.Visible = 'off';
                    % t1Position = app.T1OptionsPanel.Position;
                    % optimizationPosition = app.OptimizationOptionsPanel.Position;
                    % optimizationPosition(2) = t1Position(2)-optimizationPosition(4);
                    % app.OptimizationOptionsPanel.Position = optimizationPosition;
                case "pASL"
                    app.DSCMappingOptionsPanel.Visible = 'off';
                    app.T1OptionsPanel.Visible = 'on';
                    app.T2OptionsPanel.Visible = 'off';
                    app.CalculateT1MapButton.Visible = 'off';
                    % app.OptimizationOptionsPanel.Visible = 'on';
                    t1Position = app.T1OptionsPanel.Position;
                    pASLPosition = app.OptimizationOptionsPanel.Position;
                    pASLPosition(2) = t1Position(2)-pASLPosition(4);
                    app.FAIRpASLMappingoptionsPanel.Position = pASLPosition;
                    app.FAIRpASLMappingoptionsPanel.Visible = 'on';
                    % aslPosition = app.FAIRpASLMappingoptionsPanel.Position;
                    % optimizationPosition = app.OptimizationOptionsPanel.Position;
                    % optimizationPosition(2) = aslPosition(2)-optimizationPosition(4);
                    % app.OptimizationOptionsPanel.Position = optimizationPosition;
            end
        end

        % Value changed function: SelectPreMapDropDown
        function SelectPreMapDropDownValueChanged(app, event)
            value = app.SelectPreMapDropDown.Value;

            % If 'None' is selected, disable UIaxis controls
            if value == "None"
                cla(app.UIAxes_PreMap);
                app.SliceSpinner_PreMap.Enable = 'off';
                app.SliceSlider_PreMap.Enable = 'off';
                app.Dim4Spinner_PreMap.Enable = 'off';
                app.Dim5Spinner_PreMap.Enable = 'off';
                return
            end
            
            % Get selected experiment image data from loaded experiments or saved registration experiments
            try
                app.PreMapImageData = cell2mat(app.SavedTable.Image(value));
            catch
                try
                    app.PreMapImageData = cell2mat(app.ExperimentPropertyTable.("Image data")(value));
                catch
                end
            end
            
            % Initialize default slider values
            app.Dim5Spinner_PreMap.Value = 1;
            app.Dim4Spinner_PreMap.Value = 1;
            app.SliceSlider_PreMap.Value = 1;
            app.SliceSpinner_PreMap.Value = 1;
            app.AIFExtractionSliceSpinner.Value = 1;

            % Get data dimension sizes, set slider limits
            app.ExpDimsPreMap= size(app.PreMapImageData);
            dim3_size = app.ExpDimsPreMap(3);
            app.SliceSlider_PreMap.Limits = [1, dim3_size];
            app.SliceSpinner_PreMap.Limits = [1, dim3_size];
            app.AIFExtractionSliceSpinner.Limits = [1, dim3_size];
            
            switch numel(app.ExpDimsPreMap)
                case 4
                    dim4_size = app.ExpDimsPreMap(4);
                    app.Dim4Spinner_PreMap.Enable = 'on';
                    app.Dim5Spinner_PreMap.Enable = 'off';
                    app.Dim4Spinner_PreMap.Limits = [1, dim4_size];
                    app.PermuteDimensionsMenu_PreMap.Enable = 'on';
                    app.PermuteMenu_3_4_PreMap.Enable ='on';
                    app.PermuteMenu_3_5_PreMap.Enable ='off';
                    app.PermuteMenu_4_5_PreMap.Enable ='off';
                case 5
                    dim4_size = app.ExpDimsPreMap(4);
                    app.Dim4Spinner_PreMap.Enable = 'on';
                    app.Dim4Spinner_PreMap.Limits = [1, dim4_size];
                    dim5_size = app.ExpDimsPreMap(5);
                    app.Dim5Spinner_PreMap.Enable = 'on';
                    app.Dim5Spinner_PreMap.Limits = [1, dim5_size];
                    app.PermuteDimensionsMenu_PreMap.Enable = 'on';
                    app.PermuteMenu_3_4_PreMap.Enable ='on';
                    app.PermuteMenu_3_5_PreMap.Enable ='on';
                    app.PermuteMenu_4_5_PreMap.Enable ='on';
                case 3
                    app.Dim4Spinner_PreMap.Enable = 'off';
                    app.Dim5Spinner_PreMap.Enable = 'off';
                    app.PermuteDimensionsMenu_PreMap.Enable = 'off';
                    app.PermuteMenu_3_4_PreMap.Enable ='off';
                    app.PermuteMenu_3_5_PreMap.Enable ='off';
                    app.PermuteMenu_4_5_PreMap.Enable ='off';
            end
            
            % Lookup TE values from ExperimentTable
            try
                expTEvalues = nonzeros(app.TEvalues(app.SavedTable.OrigIndex(value),:));
            catch
                expTEvalues = nonzeros(app.TEvalues(find(strcmp(app.ExperimentPropertyTable{:,'Experiment ID'}, value)),:));
            end
            if isempty(expTEvalues)
                app.TEvaluesText.Value = "";
            else
                app.TEvaluesText.Value = erase(mat2str(expTEvalues), ["[";"]"]);
            end

            % Lookup TR values from ExperimentTable
            try
                expTRvalues = nonzeros(app.TRvalues(app.SavedTable.OrigIndex(value),:));
            catch
                expTRvalues = nonzeros(app.TRvalues(find(strcmp(app.ExperimentPropertyTable{:,'Experiment ID'}, value)),:));
            end
            if isempty(expTRvalues)
                app.TRvaluesText.Value = "";
            else
                app.TRvaluesText.Value = erase(mat2str(expTRvalues), ["[";"]"]);
            end

            % Lookup TI values from ExperimentTable
            try
                expTIvalues = nonzeros(app.TIvalues(app.SavedTable.OrigIndex(value),:));
            catch
                expTIvalues = nonzeros(app.TIvalues(find(strcmp(app.ExperimentPropertyTable{:,'Experiment ID'}, value)),:));
            end
            if isempty(expTIvalues)
                app.TIvaluesText.Value = "";
            else
                app.TIvaluesText.Value = erase(mat2str(expTIvalues), ["[";"]"]);
            end


            % Enable slice controls
            app.SliceSlider_PreMap.Enable = 'on';
            app.SliceSpinner_PreMap.Enable = 'on';
            
            % Display sequence
            RefreshImagePreMap(app);
            
            % Set interactions of preview uiaxes
            app.UIAxes_PreMap.Interactions = [regionZoomInteraction zoomInteraction];       
        end

        % Value changing function: SliceSlider_PreMap
        function SliceSlider_PreMapValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSpinner_PreMap.Value = event.Source.Value;

            RefreshImagePreMap(app);

            % Reset zoom
            app.UIAxes_PreMap.XLim = [-inf inf];
            app.UIAxes_PreMap.YLim = [-inf inf];         
        end

        % Value changed function: SliceSpinner_PreMap
        function SliceSpinner_PreMapValueChanged(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSlider_PreMap.Value = event.Source.Value;

            RefreshImagePreMap(app);

            % Reset zoom
            app.UIAxes_PreMap.XLim = [-inf inf];
            app.UIAxes_PostMap.YLim = [-inf inf];
        end

        % Value changed function: Dim4Spinner_PreMap
        function Dim4Spinner_PreMapValueChanged(app, event)
            RefreshImagePreMap(app);

            % Reset zoom
            app.UIAxes_PreMap.XLim = [-inf inf];
            app.UIAxes_PreMap.YLim = [-inf inf];
        end

        % Value changed function: Dim5Spinner_PreMap
        function Dim5Spinner_PreMapValueChanged(app, event)
            RefreshImagePreMap(app);

            % Reset zoom
            app.UIAxes_PreMap.XLim = [-inf inf];
            app.UIAxes_PreMap.YLim = [-inf inf];
        end

        % Menu selected function: RotateMenu_PreMap
        function RotateMenu_PreMapSelected(app, event)
            % Rotate image data, show image
            app.PreMapImageData = rot90(app.PreMapImageData, -1);
            
            RefreshImagePreMap(app);
        end

        % Menu selected function: FlipVerticallyMenu_PreMap
        function FlipVerticallyMenu_PreMapSelected(app, event)
            % Flip image data, show image
            app.PreMapImageData = flipud(app.PreMapImageData);
            
            RefreshImagePreMap(app);
        end

        % Menu selected function: FlipHorizontallyMenu_PreMap
        function FlipHorizontallyMenu_PreMapSelected(app, event)
            % Flip image data, show image
            app.PreMapImageData = fliplr(app.PreMapImageData);
            
            RefreshImagePreMap(app);
        end

        % Menu selected function: ResetViewMenu_PreMap
        function ResetViewMenu_PreMapSelected(app, event)
            % Reset zoom
            app.UIAxes_PreMap.XLim = [-inf inf];
            app.UIAxes_PreMap.YLim = [-inf inf];

            RefreshImagePreMap(app);
        end

        % Menu selected function: PermuteMenu_3_4_PreMap
        function PermuteMenu_3_4_PreMapSelected(app, event)
            switch numel(app.ExpDimsPreMap)
                case 4
                    app.PreMapImageData = permute(app.PreMapImageData, [1,2,4,3]);
                case 5
                    app.PreMapImageData = permute(app.PreMapImageData, [1,2,4,3,5]);
            end
            app.ExpDimsPreMap = size(app.PreMapImageData);

            temp_limits = app.SliceSpinner_PreMap.Limits;
            temp_value = app.SliceSpinner_PreMap.Value;

            app.SliceSpinner_PreMap.Limits = app.Dim4Spinner_PreMap.Limits;
            app.SliceSpinner_PreMap.Value = app.Dim4Spinner_PreMap.Value;
            app.SliceSlider_PreMap.Limits = app.Dim4Spinner_PreMap.Limits;
            app.SliceSlider_PreMap.Value = app.Dim4Spinner_PreMap.Value;
            app.Dim4Spinner_PreMap.Limits = temp_limits;
            app.Dim4Spinner_PreMap.Value = temp_value;

            RefreshImagePreMap(app);
        end

        % Menu selected function: PermuteMenu_3_5_PreMap
        function PermuteMenu_3_5_PreMapSelected(app, event)
            app.PreMapImageData = permute(app.PreMapImageData, [1,2,5,4,3]);
            app.ExpDimsPreMap = size(app.PreMapImageData);

            temp_value_slice = app.SliceSpinner_PreMap.Value;
            temp_limits_slice = app.SliceSpinner_PreMap.Limits;

            app.SliceSpinner_PreMap.Limits = app.Dim5Spinner_PreMap.Limits;
            app.SliceSpinner_PreMap.Value = app.Dim5Spinner_PreMap.Value;
            app.SliceSlider_PreMap.Limits = app.Dim5Spinner_PreMap.Limits;
            app.SliceSlider_PreMap.Value = app.Dim5Spinner_PreMap.Value;
            app.Dim5Spinner_PreMap.Limits = temp_limits_slice;
            app.Dim5Spinner_PreMap.Value = temp_value_slice;

            RefreshImagePreMap(app);
        end

        % Menu selected function: PermuteMenu_4_5_PreMap
        function PermuteMenu_4_5_PreMapSelected(app, event)
            app.PreMapImageData = permute(app.PreMapImageData, [1,2,3,5,4]);
            app.PreMapImageData = app.PreMapImageData;
            app.ExpDimsPreMap = size(app.PreMapImageData);

            temp_limits = app.Dim5Spinner_PreMap.Limits;
            temp_value = app.Dim5Spinner_PreMap.Value;

            app.Dim5Spinner_PreMap.Limits = app.Dim4Spinner_PreMap.Limits;
            app.Dim5Spinner_PreMap.Value = app.Dim4Spinner_PreMap.Value;
            app.Dim4Spinner_PreMap.Limits = temp_limits;
            app.Dim4Spinner_PreMap.Value = temp_value;

            RefreshImagePreMap(app);
        end

        % Button pushed function: AdvancedSettingsButton
        function AdvancedSettingsButtonPushed(app, event)
            app.ProgressBar = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Adjusting DSC Mapping Options...", 'Indeterminate','on');
            drawnow
            app.AdvancedSettingsButton.Enable = 'off';
            app.DSCSettingsWindow = DSCSettings(app, app.DSCOptions);
        end

        % Button pushed function: CalculateDSCmapsButton
        function CalculateDSCmapsButtonPushed(app, event)
            
            % Draw progress box
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Retrieving data for DSC mapping");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            % Set options display to 1
            app.DSCOptions.display = 1;
            app.DSCOptions.aif.nSlice = app.AIFExtractionSliceSpinner.Value;
            if app.SVDButton.Value == 1
                app.DSCOptions.deconv.method = {'SVD'};
            elseif app.cSVDButton.Value == 1
                app.DSCOptions.deconv.method = {'cSVD'};
            elseif app.oSVDButton.Value == 1
                app.DSCOptions.deconv.method = {'oSVD'};
            end
            
            % Get volumetric data and sequence parameters for map
            % calculation
            progress.Value = 0.2;

            drop_Value = app.SelectPreMapDropDown.Value;
            p = LookupExperimentParams(app, drop_Value);
            TE = p.TE;
            TR = p.TR;
            
            % Calculate and display DSC maps
            progress.Value = 0.4;
            progress.Message = "Calculating maps...";
            if numel(size(app.PreMapImageData)) == 4   
                [cbv,cbf,mtt,cbv_lc,ttp,mask,aif,conc,s0]=DSC_mri_core(app.PreMapImageData, TE, TR, app.DSCOptions); %#ok<ASGLU> 
                
                app.MTTData = struct2array(mtt);
                app.MTTData(isnan(app.MTTData)) = 0;
                app.MTTData(app.MTTData == inf) = 0;
                app.CBFData = struct2array(cbf);
                app.CBFData.map(app.CBFData.map == inf) = 0;
                app.CBVData = cbv;
                app.CBVData(app.CBVData == inf) = 0;
                app.CBVLCData = cbv_lc;
                app.CBVLCData(app.CBVLCData == inf) = 0;

                switch app.DSCMapDropDown.Value
                    case 'CBF'
                        app.PostMapImageData = app.CBFData.map;
                    case 'CBV'
                        app.PostMapImageData = app.CBVData;
                    case 'MTT'
                        app.PostMapImageData = app.MTTData;
                end
                data_dims = size(app.PostMapImageData);
                app.SliceSpinner_PostMap.Limits = [1, data_dims(3)];              
                app.SliceSpinner_PostMap.Enable = 'on';
                app.SliceSpinner_PostMap.Value = 1;
                app.SliceSlider_PostMap.Limits = [1, data_dims(3)];              
                app.SliceSlider_PostMap.Enable = 'on';
                app.SliceSlider_PostMap.Value = 1;
            else
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'DSC map calculation not possible, data must be 4-dimensional.', 'Dimension error')
            end
            
            % Set interactions on UIAxes
            app.UIAxes_PreMap.Interactions = [regionZoomInteraction zoomInteraction];
            
            app.DSCMapDropDown.Enable = 'on';
            app.TurboButton_PostMap.Enable = 'on';
            app.GreyscaleButton_PostMap.Enable = 'on';
            app.ContrastSlider_PostMap.Enable = 'on';
            app.BrightnessSlider_PostMap.Enable = 'on';

            app.SaveDataButton_Map.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Map.Enable = 'on';
            end
            
            % Display maps
            progress.Value = 0.8;
            progress.Message = "Displaying mapping results";

            RefreshImagePostMap(app);

            % close the dialog box
            progress.Value = 1;
            progress.Message = "Done!";

            
        end

        % Value changed function: DSCMapDropDown
        function DSCMapDropDownValueChanged(app, event)
            switch app.DSCMapDropDown.Value
                case "CBF"
                    app.PostMapImageData = app.CBFData.map;
                case "CBV"
                    app.PostMapImageData = app.CBVData;
                case "MTT"
                    app.PostMapImageData = app.MTTData;
            end
            
            % Display chosen map image based on method selected
            RefreshImagePostMap(app);
            
            % Reset zoom and comparation data
            app.UIAxes_PreMap.XLim = [-inf inf];
            app.UIAxes_PreMap.YLim = [-inf inf];
        end

        % Button pushed function: ResettodefaultsButton
        function ResettodefaultsButtonPushed(app, event)
            app.fxToleranceEditField.Value = 1.0e-3;
            app.OptimalityToleranceEditField.Value = 1.0e-3;
            app.StepToleranceEditField.Value = 1.0e-3;
            app.dfStepSizeEditField.Value = 1.0e-4;
            app.MaxNrofEvaluationsEditField.Value = 1.0e+1;
            app.MaxNrofIterationsEditField.Value = 1.0e+1;
        end

        % Button pushed function: CalculateT1MapButton
        function CalculateT1MapButtonPushed(app, event)
            
            tvalues = str2double(split(app.TRvaluesText.Value, ";")');
            ivalues = str2double(split(app.TIvaluesText.Value, ";")');

            if ~(length(tvalues) == size(app.PreMapImageData, 4) || length(ivalues) == size(app.PreMapImageData, 4))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Number of TR or TI values not equal to number of echoes. Please permute dimensions in following order: x, y, z, nEcho.', ...
                    'Dimension error');
                return
            end
            if ~isnan(ivalues(1)) && length(tvalues) ~= 1
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Please use a single TR value for inversion recovery T1 maps', ...
                    'Function error');
                return
            end

            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Calculating T1 maps...", "Indeterminate", "on");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            T1raw_reordered = permute(app.PreMapImageData,[3 1 2 4]); % reorder 4-D matrix to have echos as the last dimension (slice, x, y, echos)
            [z, x, y, ~] = size(T1raw_reordered);
            T1raw_reordered_reshaped = reshape(T1raw_reordered,[],size(T1raw_reordered,4));
            % tmax = max(tvalues);

            % try
            %     opts = optimoptions(@lsqcurvefit,'FunctionTolerance',app.fxToleranceEditField.Value, ...
            %         'OptimalityTolerance',app.OptimalityToleranceEditField.Value, ...
            %         'StepTolerance',app.StepToleranceEditField.Value, ...
            %         'FiniteDifferenceStepSize',app.dfStepSizeEditField.Value, ...
            %         'MaxFunctionEvaluations',app.MaxNrofEvaluationsEditField.Value, ...
            %         'MaxIterations',app.MaxNrofIterationsEditField.Value,'Display','none');
            % catch
            %     uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'App failed to set desired optimization options. Please check settings.', ...
            %         'Optimization settings error');
            %     return
            % end

            % use parallel processing for faster fitting
            % delete(gcp('nocreate'));
            % parpool("local");
            
            coeffs = CalculateT1Map_mex(T1raw_reordered_reshaped,tvalues,ivalues);             

            % if isnan(ivalues(1))
            %     fun = @(coeffs,tvalues)coeffs(1)*(1-exp(-tvalues/coeffs(2))); 
            %     coeffs0 = [1,1];
            %     coeffs = zeros(length(T1raw_reordered_reshaped),2);
            % 
            %     parfor br = 1:length(T1raw_reordered_reshaped)
            %         if max(T1raw_reordered_reshaped(br,:)) ~= 0
            %             T1raw_reordered_reshaped(br,:) = T1raw_reordered_reshaped(br,:) / max(T1raw_reordered_reshaped(br,:));
            %             coeffs(br,:) = lsqcurvefit(fun,coeffs0,tvalues,T1raw_reordered_reshaped(br,:), [0 0], ...
            %             [1 2*tmax], opts);
            %         end
            %     end
            % else
            %     fun = @(coeffs,ivalues)coeffs(1)*abs((1-2*exp(-ivalues/coeffs(2)))); 
            %     coeffs0 = [1,1];
            %     coeffs = zeros(length(T1raw_reordered_reshaped),2);
            % 
            %     parfor br = 1:length(T1raw_reordered_reshaped)
            %         if max(T1raw_reordered_reshaped(br,:)) ~= 0
            %             T1raw_reordered_reshaped(br,:) = T1raw_reordered_reshaped(br,:) / max(T1raw_reordered_reshaped(br,:));
            %             coeffs(br,:) = lsqcurvefit(fun,coeffs0,ivalues,T1raw_reordered_reshaped(br,:), [0 0], ...
            %             [1 2*tmax], opts);
            %         end
            %     end
            % end

            % delete(gcp('nocreate'));

            T1map_calculated_stack = reshape(squeeze(coeffs(:,2)), z, x, y);
            app.PostMapImageData = permute(T1map_calculated_stack,[2 3 1]);

            data_dims = size(app.PostMapImageData);
            app.SliceSpinner_PostMap.Limits = [1, data_dims(3)];              
            app.SliceSpinner_PostMap.Enable = 'on';
            app.SliceSpinner_PostMap.Value = 1;
            app.SliceSlider_PostMap.Limits = [1, data_dims(3)];              
            app.SliceSlider_PostMap.Enable = 'on';
            app.SliceSlider_PostMap.Value = 1;

            app.TurboButton_PostMap.Enable = 'on';
            app.GreyscaleButton_PostMap.Enable = 'on';
            app.ContrastSlider_PostMap.Enable = 'on';
            app.BrightnessSlider_PostMap.Enable = 'on';

            app.SaveDataButton_Map.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Map.Enable = 'on';
            end

            progress.Message = "Done!";


            RefreshImagePostMap(app);
        end

        % Button pushed function: CalculateT2MapButton
        function CalculateT2MapButtonPushed(app, event)

            tvalues = str2double(split(app.TEvaluesText.Value, ";")');

            if size(tvalues) ~= size(app.PreMapImageData, 4)
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Number of TE values not equal to number of echoes. Please permute dimensions in following order: x, y, z, nEcho.', ...
                    'Dimension error');
                return
            end

            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Calculating T2 maps...", "Indeterminate", "on");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            T2raw_reordered = permute(app.PreMapImageData,[3 1 2 4]); % reorder 4-D matrix to have echos as the last dimension (slice, x, y, echos)
            [z, x, y, ~] = size(T2raw_reordered);
            T2raw_reordered_reshaped = reshape(T2raw_reordered,[],size(T2raw_reordered,4));
            % tmax = max(tvalues);
         
            % fun = @(coeffs,tvalues)coeffs(1)*exp(-tvalues/coeffs(2)); 
            % coeffs0 = [1,0.03];
            % coeffs = zeros(length(T2raw_reordered_reshaped),2);

            % try
            %     opts = optimoptions(@lsqcurvefit,'FunctionTolerance',app.fxToleranceEditField.Value, ...
            %         'OptimalityTolerance',app.OptimalityToleranceEditField.Value, ...
            %         'StepTolerance',app.StepToleranceEditField.Value, ...
            %         'FiniteDifferenceStepSize',app.dfStepSizeEditField.Value, ...
            %         'MaxFunctionEvaluations',app.MaxNrofEvaluationsEditField.Value, ...
            %         'MaxIterations',app.MaxNrofIterationsEditField.Value,'Display','none');
            % catch
            %     uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'App failed to set desired optimization options. Please check settings.', ...
            %         'Optimization settings error');
            %     return
            % end

            coeffs = CalculateT2Map_mex(T2raw_reordered_reshaped,tvalues);
            % use parallel processing for faster fitting
            % delete(gcp('nocreate'));
            % parpool("local");
            % progress.Message = "Calculating T2 maps... This might take awhile.";
            % 
            % parfor br = 1:length(T2raw_reordered_reshaped)
            %     if max(T2raw_reordered_reshaped(br,:)) ~= 0
            %         T2raw_reordered_reshaped(br,:) = T2raw_reordered_reshaped(br,:) / max(T2raw_reordered_reshaped(br,:));
            %         coeffs(br,:) = lsqcurvefit(fun,coeffs0,tvalues,T2raw_reordered_reshaped(br,:), [0 0], ...
            %         [1 2*tmax], opts);
            %     end
            % end
            % 
            % delete(gcp('nocreate'));

            T2map_calculated_stack = reshape(squeeze(coeffs(:,2)), z, x, y);
            app.PostMapImageData = permute(T2map_calculated_stack,[2 3 1]);

            data_dims = size(app.PostMapImageData);
            app.SliceSpinner_PostMap.Limits = [1, data_dims(3)];              
            app.SliceSpinner_PostMap.Enable = 'on';
            app.SliceSpinner_PostMap.Value = 1;
            app.SliceSlider_PostMap.Limits = [1, data_dims(3)];              
            app.SliceSlider_PostMap.Enable = 'on';
            app.SliceSlider_PostMap.Value = 1;

            app.TurboButton_PostMap.Enable = 'on';
            app.GreyscaleButton_PostMap.Enable = 'on';
            app.ContrastSlider_PostMap.Enable = 'on';
            app.BrightnessSlider_PostMap.Enable = 'on';

            app.SaveDataButton_Map.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Map.Enable = 'on';
            end

            progress.Message = "Done!";


            RefreshImagePostMap(app);
        end

        % Button pushed function: CalculatepASLMapButton
        function CalculatepASLMapButtonPushed(app, event)
            
            tvalues = str2double(split(app.TRvaluesText.Value, ";")');
            tvalues = tvalues(1:2:end);
            ivalues = str2double(split(app.TIvaluesText.Value, ";")');

            if ~(length(ivalues) == size(app.PreMapImageData, 4) || length(ivalues) == size(app.PreMapImageData, 5))
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Number of TR or TI values not equal to number of echoes. Please permute dimensions in following order: x, y, z, nEcho.', ...
                    'Dimension error');
                return
            end

            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Calculating pASL maps...", "Indeterminate", "on");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow

            switch numel(size(app.PreMapImageData))
                case 4
                    switch app.SliceSelectiveFirstButton.Value
                        case true
                            T1rawSS = app.PreMapImageData(:,:,1,:);
                            T1rawNS = app.PreMapImageData(:,:,2,:);
                        case false
                            T1rawSS = app.PreMapImageData(:,:,2,:);
                            T1rawNS = app.PreMapImageData(:,:,1,:);
                    end
                case 5
                    switch app.SliceSelectiveFirstButton.Value
                        case true
                            T1rawSS = squeeze(app.PreMapImageData(:,:,:,1,:));
                            T1rawNS = squeeze(app.PreMapImageData(:,:,:,2,:));
                        case false
                            T1rawSS = squeeze(app.PreMapImageData(:,:,:,2,:));
                            T1rawNS = squeeze(app.PreMapImageData(:,:,:,1,:));
                    end
                otherwise
            end
           
            T1rawSS_reordered = permute(T1rawSS,[3 1 2 4]); % reorder 4-D matrix to have echos as the last dimension (slice, x, y, echos)
            T1rawNS_reordered = permute(T1rawNS,[3 1 2 4]);
            [z, x, y, ~] = size(T1rawSS_reordered);
            T1rawSS_reordered_reshaped = reshape(T1rawSS_reordered,[],size(T1rawSS_reordered,4));
            T1rawNS_reordered_reshaped = reshape(T1rawNS_reordered,[],size(T1rawNS_reordered,4));
            T1blood = app.BloodT1sEditField.Value;

            % try
            %     opts = optimoptions(@lsqcurvefit,'FunctionTolerance',app.fxToleranceEditField.Value, ...
            %         'OptimalityTolerance',app.OptimalityToleranceEditField.Value, ...
            %         'StepTolerance',app.StepToleranceEditField.Value, ...
            %         'FiniteDifferenceStepSize',app.dfStepSizeEditField.Value, ...
            %         'MaxFunctionEvaluations',app.MaxNrofEvaluationsEditField.Value, ...
            %         'MaxIterations',app.MaxNrofIterationsEditField.Value,'Display','none');
            % catch
            %     uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'App failed to set desired optimization options. Please check settings.', ...
            %         'Optimization settings error');
            %     return
            % end

            perf = CalculateASLMap_mex(T1rawSS_reordered_reshaped,T1rawNS_reordered_reshaped,tvalues,ivalues,T1blood);
            % tmax = max(tvalues);
            % use parallel processing for faster fitting
            % delete(gcp('nocreate'));
            % parpool("local");
            % progress.Message = "Calculating T1 maps for slice selective experiment... This might take awhile.";
            % 
            % fun = @(coeffs,ivalues)coeffs(1)*abs((1-2*exp(-ivalues/coeffs(2)))); 
            % coeffs0 = [1,1];
            % coeffsSS = zeros(length(T1rawSS_reordered_reshaped),2);
            % coeffsNS = zeros(length(T1rawNS_reordered_reshaped),2);
            % perf = zeros(length(T1rawNS_reordered_reshaped),1);
            % 
            % 
            % parfor br = 1:length(T1rawSS_reordered_reshaped)
            %     if max(T1rawSS_reordered_reshaped(br,:)) ~= 0
            %         T1rawSS_reordered_reshaped(br,:) = T1rawSS_reordered_reshaped(br,:) / max(T1rawSS_reordered_reshaped(br,:));
            %         coeffsSS(br,:) = lsqcurvefit(fun,coeffs0,ivalues,T1rawSS_reordered_reshaped(br,:), [0 0], ...
            %         [1 2*tmax], opts);
            %     end
            % end
            % 
            % progress.Message = "Calculating T1 maps for non-selective experiment... This might take awhile.";
            % 
            % parfor br = 1:length(T1rawNS_reordered_reshaped)
            %     if max(T1rawNS_reordered_reshaped(br,:)) ~= 0
            %         T1rawNS_reordered_reshaped(br,:) = T1rawNS_reordered_reshaped(br,:) / max(T1rawNS_reordered_reshaped(br,:));
            %         coeffsNS(br,:) = lsqcurvefit(fun,coeffs0,ivalues,T1rawNS_reordered_reshaped(br,:), [0 0], ...
            %         [1 2*tmax], opts);
            %     end
            % end
            % 
            % progress.Message = "Calculating CBF maps... Almost there.";
            % 
            % parfor br = 1:length(T1rawSS_reordered_reshaped)
            %     perf(br) = abs(4980 * coeffsNS(br,2) / T1blood * (1 / coeffsSS(br,2) - 1 / coeffsNS(br,2)));
            % end
            % 
            % delete(gcp('nocreate'));

            perf(isnan(perf))=0;
            perf(isinf(perf))=0;
            CBFmap_calculated_stack = reshape(perf(:,1), z, x, y);
            app.PostMapImageData = squeeze(permute(CBFmap_calculated_stack,[2 3 1]));

            data_dims = size(app.PostMapImageData);
            try
                app.SliceSpinner_PostMap.Limits = [1, data_dims(3)];              
                app.SliceSpinner_PostMap.Enable = 'on';
                app.SliceSlider_PostMap.Limits = [1, data_dims(3)];              
                app.SliceSlider_PostMap.Enable = 'on';
            catch
            end

            app.SliceSpinner_PostMap.Value = 1;
            app.SliceSlider_PostMap.Value = 1;
            app.TurboButton_PostMap.Enable = 'on';
            app.GreyscaleButton_PostMap.Enable = 'on';
            app.ContrastSlider_PostMap.Enable = 'on';
            app.BrightnessSlider_PostMap.Enable = 'on';

            app.SaveDataButton_Map.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportDataButton_Map.Enable = 'on';
            end

            progress.Message = "Done!";


            RefreshImagePostMap(app);
        end

        % Menu selected function: RotateMenu_PostMap
        function RotateMenu_PostMapSelected(app, event)
            % Rotate image data, show image
            app.PostMapImageData = rot90(app.PostMapImageData, -1);
            
            RefreshImagePostMap(app);
        end

        % Menu selected function: FlipVerticallyMenu_PostMap
        function FlipVerticallyMenu_PostMapSelected(app, event)
            % Flip image data, show image
            app.PostMapImageData = flipud(app.PostMapImageData);
            
            RefreshImagePostMap(app);
        end

        % Menu selected function: FlipHorizontallyMenu_PostMap
        function FlipHorizontallyMenu_PostMapSelected(app, event)
            % Flip image data, show image
            app.PostMapImageData = fliplr(app.PostMapImageData);
            
            RefreshImagePostMap(app);
        end

        % Menu selected function: ResetViewMenu_PostMap
        function ResetViewMenu_PostMapSelected(app, event)
            % Reset zoom
            app.UIAxes_PostMap.XLim = [-inf inf];
            app.UIAxes_PostMap.YLim = [-inf inf];

            % Reset contrast and brightness then refresh
            app.BrightnessSlider_PostMap.Value = 0;
            app.ContrastSlider_PostMap.Value = 0;
            RefreshImagePostMap(app);
        end

        % Value changed function: SliceSpinner_PostMap
        function SliceSpinner_PostMapValueChanged(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSlider_PostMap.Value = event.Source.Value;
            
            RefreshImagePostMap(app);
            
            % Reset zoom
            app.UIAxes_PostMap.XLim = [-inf inf];
            app.UIAxes_PostMap.YLim = [-inf inf];
        end

        % Value changing function: SliceSlider_PostMap
        function SliceSlider_PostMapValueChanging(app, event)
            event.Source.Value = round(event.Value);
            app.SliceSpinner_PostMap.Value = event.Source.Value;

            RefreshImagePostMap(app);

            % Reset zoom
            app.UIAxes_PostMap.XLim = [-inf inf];
            app.UIAxes_PostMap.YLim = [-inf inf];
        end

        % Selection changed function: ColormapButtonGroup_PostMap
        function ColormapButtonGroup_PostMapSelectionChanged(app, event)
            RefreshImagePostMap(app);
        end

        % Value changing function: ContrastSlider_PostMap
        function ContrastSlider_PostMapValueChanging(app, event)
            app.ContrastSlider_PostMap.Value = event.Value;
            RefreshImagePostMap(app);
        end

        % Value changing function: BrightnessSlider_PostMap
        function BrightnessSlider_PostMapValueChanging(app, event)
            app.BrightnessSlider_PostMap.Value = event.Value;
            RefreshImagePostMap(app);
        end

        % Button pushed function: ExportDataButton_Map
        function ExportDataButton_MapPushed(app, event)

            ExportImageDataGeneral(app, 'Map');

            uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Parameter map image data exported in NIfTI format.", "","Options",{'OK'},"DefaultOption",1, "Icon","success");
        end

        % Button pushed function: SaveDataButton_Map
        function SaveDataButton_MapPushed(app, event)
            SaveData(app, 'Map');
        end

        % Value changed function: Select3DViewerDropDown
        function Select3DViewerDropDownValueChanged(app, event)
            delete(app.ViewerParentObject)
            value = app.Select3DViewerDropDown.Value;
            if value == "None"
                cla(app.UIAxes_AlphaMap);
                app.RenderingStyleDropDown.Enable = 'off';
                app.RenderingStyleDropDown.Value = "Volume Rendering";
                app.PlaneIntersectionSwitch.Enable = 'off';
                app.ColormapDropDown_Viewer.Enable = 'off';
                app.ColormapDropDown_Viewer.Value = "Greyscale";
                app.ColormapImage_Viewer.Visible = 'off';
                app.AlphamapDropDown_Viewer.Enable = 'off';
                app.AlphamapDropDown_Viewer.Value = "Linear";
                app.XEditField_Viewer.Value = 0;
                app.YEditField_Viewer.Value = 0;
                app.ZEditField_Viewer.Value = 0;
                app.SliceRangeLowSpinner_Viewer.Enable = 'off';
                app.SliceRangeLowSpinner_Viewer.Value = 1;
                app.SliceRangeHighSpinner_Viewer.Enable = 'off';
                app.SliceRangeHighSpinner_Viewer.Limits = [1, 2];
                app.SliceRangeHighSpinner_Viewer.Value = 1;
                app.Dim4Spinner_Viewer.Enable = 'off';
                app.Dim4Spinner_Viewer.Value = 1;
                app.Dim5Spinner_Viewer.Enable = 'off';
                app.Dim5Spinner_Viewer.Value = 1;
                app.OverlayButton.Enable = 'off';
                app.ExportSceneButton.Enable = 'off';
                return
            end
            
            % Get image data, check dims, initialize viewer3d parent object
            try
                app.ViewerImageData = cell2mat(app.SavedTable.Image(value));
            catch
                app.ViewerImageData = cell2mat(app.ExperimentPropertyTable.("Image data")(value));     
            end
            app.ViewerImageData = (app.ViewerImageData - min(app.ViewerImageData(:))) / (max(app.ViewerImageData(:)) - min(app.ViewerImageData(:)));
            app.ViewerImageDataDims = size(app.ViewerImageData);
            if numel(app.ViewerImageDataDims)<3
                app.RenderingStyleDropDown.Enable = 'off';
                app.RenderingStyleDropDown.Value = "Volume Rendering";
                app.PlaneIntersectionSwitch.Enable = 'off';
                app.ColormapDropDown_Viewer.Enable = 'off';
                app.ColormapDropDown_Viewer.Value = "Greyscale";
                app.ColormapImage_Viewer.Visible = 'off';
                app.AlphamapDropDown_Viewer.Enable = 'off';
                app.AlphamapDropDown_Viewer.Value = "Linear";
                app.XEditField_Viewer.Value = 0;
                app.YEditField_Viewer.Value = 0;
                app.ZEditField_Viewer.Value = 0;
                app.SliceRangeLowSpinner_Viewer.Enable = 'off';
                app.SliceRangeLowSpinner_Viewer.Value = 1;
                app.SliceRangeHighSpinner_Viewer.Enable = 'off';
                app.SliceRangeHighSpinner_Viewer.Limits = [1, 2];
                app.SliceRangeHighSpinner_Viewer.Value = 1;
                app.Dim4Spinner_Viewer.Enable = 'off';
                app.Dim4Spinner_Viewer.Value = 1;
                app.Dim5Spinner_Viewer.Enable = 'off';
                app.Dim5Spinner_Viewer.Value = 1;
                app.OverlayButton.Enable = 'off';
                app.ExportSceneButton.Enable = 'off';
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, 'Selected data cannot be rendered: number of data dimensions must be between 3 and 5.', '3D Viewer Data Dimension Error')
                return
            end
            app.ViewerParentObject = viewer3d('Parent', app.ViewerPanel);
            app.ViewerParentObject.OrientationAxes = 'off';
            
            % Get dimension scales, create triplet and update edit fields
            try
                app.ViewerDimTriplet = [app.SavedTable.VoxDimX(value) app.SavedTable.VoxDimY(value) app.SavedTable.SliceThickness(value)+app.SavedTable.SliceGap(value)];
            catch 
                app.ViewerDimTriplet = [app.ExperimentPropertyTable.("Voxel dimension X")(value) app.ExperimentPropertyTable.("Voxel dimension Y")(value) app.ExperimentPropertyTable.("Slice Thickness")(value)+app.ExperimentPropertyTable.("Slice Gap")(value)];
            end
            app.XEditField_Viewer.Value = app.ViewerDimTriplet(1);
            app.YEditField_Viewer.Value = app.ViewerDimTriplet(2);
            app.ZEditField_Viewer.Value = app.ViewerDimTriplet(3);
            
            % Enable/Disable Components
            app.RenderingStyleDropDown.Enable = 'on';
            app.RenderingStyleDropDown.Value = "Volume Rendering";
            app.PlaneIntersectionSwitch.Enable = 'on';
            app.ColormapDropDown_Viewer.Enable = 'on';
            app.ColormapDropDown_Viewer.Value = "Greyscale";
            app.ColormapImage_Viewer.Visible = 'on';
            app.ColormapImage_Viewer.ImageSource = getColormapImageArray(25,256, "Greyscale");
            app.AlphamapDropDown_Viewer.Enable = 'on';
            app.AlphamapDropDown_Viewer.Value = "Linear";
            app.OverlayButton.Enable = 'on';
            if isstring(app.ExportFolderPath)
                app.ExportSceneButton.Enable = 'on';
            end
            % Set slice slider limits and values
            app.SliceRangeLowSpinner_Viewer.Enable = 'on';
            app.SliceRangeLowSpinner_Viewer.Limits = [1, app.ViewerImageDataDims(3)-1];
            app.SliceRangeLowSpinner_Viewer.Value = 1;
            app.SliceRangeHighSpinner_Viewer.Enable = 'on';
            app.SliceRangeHighSpinner_Viewer.Limits = [2, app.ViewerImageDataDims(3)];
            app.SliceRangeHighSpinner_Viewer.Value = app.ViewerImageDataDims(3);       

            % Create transformation matrix
            T = [app.ViewerDimTriplet(1) 0 0 0; 0 app.ViewerDimTriplet(2) 0 0; 0 0 app.ViewerDimTriplet(3) 0; 0 0 0 1];
            tform = affinetform3d(T);

            % Enable/disable spinners based on number of dims, refresh 3D Viewer
            switch numel(app.ViewerImageDataDims)
                case 3
                    app.Dim4Spinner_Viewer.Enable = 'off';
                    app.Dim4Spinner_Viewer.Value = 1;
                    app.Dim5Spinner_Viewer.Enable = 'off';
                    app.Dim5Spinner_Viewer.Value = 1;
                    app.CurrentVolume = volshow(app.ViewerImageData, 'Parent', app.ViewerParentObject, 'Transformation', tform, 'RenderingStyle', "VolumeRendering");
                case 4
                    app.Dim4Spinner_Viewer.Enable = 'on';
                    app.Dim4Spinner_Viewer.Value = 1;
                    app.Dim4Spinner_Viewer.Limits = [1, app.ViewerImageDataDims(4)];
                    app.Dim5Spinner_Viewer.Enable = 'off';
                    app.Dim5Spinner_Viewer.Value = 1;
                    app.CurrentVolume = volshow(app.ViewerImageData(:,:,:,1), 'Parent', app.ViewerParentObject, 'Transformation', tform, 'RenderingStyle', "VolumeRendering");
                case 5
                    app.Dim4Spinner_Viewer.Enable = 'on';
                    app.Dim4Spinner_Viewer.Value = 1;
                    app.Dim4Spinner_Viewer.Limits = [1, app.ViewerImageDataDims(4)];
                    app.Dim5Spinner_Viewer.Enable = 'on';
                    app.Dim5Spinner_Viewer.Value = 1;
                    app.Dim5Spinner_Viewer.Limits = [1, app.ViewerImageDataDims(5)];
                    app.CurrentVolume = volshow(app.ViewerImageData(:,:,:,1,1), 'Parent', app.ViewerParentObject, 'Transformation', tform, 'RenderingStyle', "VolumeRendering");
            end     

            AlphamapDropDown_ViewerValueChanged(app);
        end

        % Value changed function: RenderingStyleDropDown
        function RenderingStyleDropDownValueChanged(app, event)

            switch app.RenderingStyleDropDown.Value
                case "Volume Rendering"
                    renderingStyle = 'VolumeRendering';
                    app.PlaneIntersectionSwitch.Enable = 'on';
                case "Maximum Intensity Projection"
                    renderingStyle = 'MaximumIntensityProjection';
                    app.PlaneIntersectionSwitch.Enable = 'on';
                case "Minimum Intensity Projection"
                    renderingStyle = 'MinimumIntensityProjection';
                    app.PlaneIntersectionSwitch.Enable = 'on';
                case "Slice Planes"
                    renderingStyle = 'SlicePlanes';
                    app.PlaneIntersectionSwitch.Enable = 'off';
                case "Gradient Opacity"
                    renderingStyle = 'GradientOpacity';
                    app.PlaneIntersectionSwitch.Enable = 'on';
                case "Isosurface"
                    renderingStyle = 'Isosurface';
                    app.PlaneIntersectionSwitch.Enable = 'on';
            end
            app.ViewerParentObject.Children.RenderingStyle = renderingStyle;
        end

        % Value changed function: PlaneIntersectionSwitch
        function PlaneIntersectionSwitchValueChanged(app, event)
            
            switch app.PlaneIntersectionSwitch.Value
                case 'On'
                    app.ViewerParentObject.ClipIntersection = 'on';
                case 'Off'
                    app.ViewerParentObject.ClipIntersection = 'off';
            end            
        end

        % Value changed function: ColormapDropDown_Viewer
        function ColormapDropDown_ViewerValueChanged(app, event)
            
            switch app.ColormapDropDown_Viewer.Value
                case "Greyscale"
                    colormapChoice = gray;
                case "Turbo"
                    colormapChoice = turbo;
                case "Hot"
                    colormapChoice = hot;
                case "Jet"
                    colormapChoice = jet;
                case "Parula"
                    colormapChoice = parula;
                case "Sky"
                    colormapChoice = sky;
                case "Cool"
                    colormapChoice = cool;
                case "Copper"
                    colormapChoice = copper;
                case "Bone"
                    colormapChoice = bone;
            end
            app.ViewerParentObject.Children.Colormap = colormapChoice;
            app.ColormapImage_Viewer.ImageSource = getColormapImageArray(25,256, app.ColormapDropDown_Viewer.Value);
        end

        % Value changed function: AlphamapDropDown_Viewer
        function AlphamapDropDown_ViewerValueChanged(app, event)
            
            h = histogram(app.UIAxes_AlphaMap,im2uint8(app.CurrentVolume.Data),256,"Normalization","probability","FaceColor",[0 0 0],"EdgeColor","none");
            peaks = findpeaks(h.Values);
            app.UIAxes_AlphaMap.XLim = [0 255];
            app.UIAxes_AlphaMap.YLim = [0 2*max(peaks(2:end))];

            switch app.AlphamapDropDown_Viewer.Value
                case "Linear"
                    app.AlphamapPoly = images.roi.Polyline(app.UIAxes_AlphaMap,"Position",[0 255;0 app.UIAxes_AlphaMap.YLim(2)]');               
                case "MRI"
                    app.AlphamapPoly = images.roi.Polyline(app.UIAxes_AlphaMap,"Position",[5 0.000588235294117645*app.UIAxes_AlphaMap.YLim(2); ...
                        10 0.150294117647059*app.UIAxes_AlphaMap.YLim(2); ...
                        30 0.300376470588235*app.UIAxes_AlphaMap.YLim(2); ...
                        55 0.380128767925081*app.UIAxes_AlphaMap.YLim(2); ...
                        255 0.500000000000000*app.UIAxes_AlphaMap.YLim(2)]); 
                    % app.AlphamapPoly = [0;0;0;0;0;0.000588235294117645;0.0307058823529412;0.0608235294117647;0.0909411764705882;0.121058823529412];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
                case "MRI-mip"
                    app.AlphamapPoly = images.roi.Polyline(app.UIAxes_AlphaMap,"Position",[9 0.000712491613668749*app.UIAxes_AlphaMap.YLim(2); ...
                        38 0.500128998968008*app.UIAxes_AlphaMap.YLim(2); ...
                        255 1*app.UIAxes_AlphaMap.YLim(2)]); 
                    % app.AlphamapPoly = [0;0;0;0;0;0;0;0;0;0.000712491613668749];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
            end
            
            app.AlphamapPoly.Deletable = false;
            ApplyAlphaMap(app);
            addlistener(app.AlphamapPoly,'MovingROI',@(o, e)ApplyAlphaMap(app, o, e));
            app.UIAxes_AlphaMap.Interactions = [];
        end

        % Value changed function: XEditField_Viewer
        function XEditField_ViewerValueChanged(app, event)
            value = app.XEditField_Viewer.Value;
            app.ViewerDimTriplet(1) = value;
            T = [app.ViewerDimTriplet(1) 0 0 0; 0 app.ViewerDimTriplet(2) 0 0; 0 0 app.ViewerDimTriplet(3) 0; 0 0 0 1];
            tform = affinetform3d(T);
            app.ViewerParentObject.Children.Transformation = tform;
        end

        % Value changed function: YEditField_Viewer
        function YEditField_ViewerValueChanged(app, event)
            value = app.YEditField_Viewer.Value;
            app.ViewerDimTriplet(2) = value;
            T = [app.ViewerDimTriplet(1) 0 0 0; 0 app.ViewerDimTriplet(2) 0 0; 0 0 app.ViewerDimTriplet(3) 0; 0 0 0 1];
            tform = affinetform3d(T);
            app.ViewerParentObject.Children.Transformation = tform;
        end

        % Value changed function: ZEditField_Viewer
        function ZEditField_ViewerValueChanged(app, event)
            value = app.ZEditField_Viewer.Value;
            app.ViewerDimTriplet(3) = value;
            T = [app.ViewerDimTriplet(1) 0 0 0; 0 app.ViewerDimTriplet(2) 0 0; 0 0 app.ViewerDimTriplet(3) 0; 0 0 0 1];
            tform = affinetform3d(T);
            app.ViewerParentObject.Children.Transformation = tform;
        end

        % Value changed function: SliceRangeLowSpinner_Viewer
        function SliceRangeLowSpinner_ViewerValueChanged(app, event)
            value = app.SliceRangeLowSpinner_Viewer.Value;
            
            % Update spinner limits
            maxBoundary = app.SliceRangeHighSpinner_Viewer.Limits(2);
            app.SliceRangeHighSpinner_Viewer.Limits = [value+1, maxBoundary];
            % Update 3D Viewer data based on number of dims
            switch numel(size(app.ViewerImageData))
                case 3
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value);
                case 4
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value);
                case 5
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value, app.Dim5Spinner_Viewer.Value);
            end 
        end

        % Value changed function: SliceRangeHighSpinner_Viewer
        function SliceRangeHighSpinner_ViewerValueChanged(app, event)
            value = app.SliceRangeHighSpinner_Viewer.Value;
            
            % Update spinner limits
            minBoundary = app.SliceRangeLowSpinner_Viewer.Limits(1);
            app.SliceRangeLowSpinner_Viewer.Limits = [minBoundary, value-1];
            % Update 3D Viewer data based on number of dims
            switch numel(size(app.ViewerImageData))
                case 3
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value);
                case 4
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value);
                case 5
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value, app.Dim5Spinner_Viewer.Value);
            end
        end

        % Value changed function: Dim4Spinner_Viewer
        function Dim4Spinner_ViewerValueChanged(app, event)
            
            % Update 3D Viewer data based on number of dims
            switch numel(size(app.ViewerImageData))
                case 4
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value);
                case 5
                    app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value, app.Dim5Spinner_Viewer.Value);
            end 
        end

        % Value changed function: Dim5Spinner_Viewer
        function Dim5Spinner_ViewerValueChanged(app, event)
            
            % Update 3D Viewer data
            app.ViewerParentObject.Children.Data = app.ViewerImageData(:,:, app.SliceRangeLowSpinner_Viewer.Value:app.SliceRangeHighSpinner_Viewer.Value, app.Dim4Spinner_Viewer.Value, app.Dim5Spinner_Viewer.Value);
            
        end

        % Button pushed function: ExportSceneButton
        function ExportSceneButtonPushed(app, event)
            image_name = inputdlg('Enter new image file name', 'Export scene to an image', [1 40], {'image.tif'});

            if exist(image_name{1}, 'file')
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Error exporting scene image. Please pick a non-existant file name.", "Error exporting scene: duplicate file.")
                return
            end

            cd(app.ExportFolderPath);
            screencapture(app.ViewerPanel,[],image_name{1});
        end

        % Value changed function: OverlayButton
        function OverlayButtonValueChanged(app, event)
            value = app.OverlayButton.Value;
            
            switch value
                case 1
                    app.ProgressBar = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Please wait",...
                 'Message', "Selecting a volume to overlay in 3D Viewer...", 'Indeterminate','on');
                    drawnow
            
                    app.OverlayButton.Enable = 'off';

                    app.OverlayPickerWindow = OverlayPicker(app, app.DropDownItemsCombined, app.ExperimentPropertyTable, ...
                        app.SavedTable, app.ViewerImageDataDims);
                case 0
                    Select3DViewerDropDownValueChanged(app);
            end           
        end

        % Close request function: suMRakSimpleUtilityMRiAnalysisKitUIFigure
        function suMRakSimpleUtilityMRiAnalysisKitUIFigureCloseRequest(app, event)
            
            progress = uiprogressdlg(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure,'Title',"Shutting down",...
                             'Message', "Deleting temporary data...","Indeterminate","on");
            cleanupDlg = suMRak.guardDialog(progress);  %#ok<NASGU>
            drawnow
    
            % Purge old temporary data
            try
                rmdir(app.WorkingFolder, "s");
            catch
            end


            selection = uiconfirm(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, "Thank you for using suMRak! Please cite us as: Ister R, Sternak M, Škokić S and Gajović S (2024) suMRak: a multi-tool solution for preclinical brain MRI data analysis. Front. Neuroinform. 18:1358917. doi: 10.3389/fninf.2024.1358917", ...
                "","Options",{'Bye!'},"DefaultOption",1,"Icon","info");
    
            switch selection
                case 'Bye!'
                    delete(app.ROIVolumeSegmenterWindow);
                    delete(app.AtlasImporterWindow);
                    delete(app.RegistrationViewerWindow);       
                    delete(app.DSCSettingsWindow);
                    delete(app.OverlayPickerWindow);
                    delete(app);
                otherwise
                    return
            end
            
        end

        % Menu selected function: EditTransformMenu
        function EditTransformMenuSelected(app, event)
            idx = currentStageIndex(app);
            if isempty(idx), return, end
            TransformParameterEditor(app, idx);
        end

        % Menu selected function: AddTranslationMenu
        function AddTranslationMenuSelected(app, event)
            insertStageOfType(app, "Translation");
        end

        % Menu selected function: AddEuler3DMenu
        function AddEuler3DMenuSelected(app, event)
            insertStageOfType(app, "Euler3D");
        end

        % Menu selected function: AddSimilarity3DMenu
        function AddSimilarity3DMenuSelected(app, event)
            insertStageOfType(app, "Similarity3D");
        end

        % Menu selected function: AddAffineMenu
        function AddAffineMenuSelected(app, event)
            insertStageOfType(app, "Affine");
        end

        % Menu selected function: AddBSplineMenu
        function AddBSplineMenuSelected(app, event)
            insertStageOfType(app, "BSpline");
        end

        % Menu selected function: DuplicateTransformMenu
        function DuplicateTransformMenuSelected(app, event)
            idx = currentStageIndex(app);
            if isempty(idx), return, end
            app.StageSettings = [ ...
                app.StageSettings(1:idx), ...
                app.StageSettings(idx), ...
                app.StageSettings(idx+1:end)];
            refreshTransformsListBox(app);
            selectStage(app, idx + 1);
        end

        % Menu selected function: RemoveTransformMenu
        function RemoveTransformMenuSelected(app, event)
            idx = currentStageIndex(app);
            if isempty(idx), return, end
            if numel(app.StageSettings) <= 1
                uialert(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure, ...
                    'Cannot remove the last stage. Add another first, or use Reset.', ...
                    'Cannot remove');
                return
            end
            app.StageSettings(idx) = [];
            refreshTransformsListBox(app);
            selectStage(app, idx);
        end

        % Menu selected function: MoveUpTransformMenu
        function MoveUpTransformMenuSelected(app, event)
            idx = currentStageIndex(app);
            if isempty(idx) || idx == 1, return, end
            app.StageSettings([idx-1, idx]) = app.StageSettings([idx, idx-1]);
            refreshTransformsListBox(app);
            selectStage(app, idx - 1);
        end

        % Menu selected function: MoveDownTransformMenu
        function MoveDownTransformMenuSelected(app, event)
            idx = currentStageIndex(app);
            if isempty(idx) || idx == numel(app.StageSettings), return, end
            app.StageSettings([idx, idx+1]) = app.StageSettings([idx+1, idx]);
            refreshTransformsListBox(app);
            selectStage(app, idx + 1);
        end

        % Menu selected function: ResetTransformMenu
        function ResetTransformMenuSelected(app, event)
            if strcmp(app.ChooseRegistrationTypeDropDown.Value, 'Time-Series Alignment')
                app.StageSettings = suMRak.defaultStageOfType("Euler3D");
            else
                app.StageSettings = suMRak.defaultStageSettings(app.ChooseRegistrationTypeDropDown.Value);
            end
            refreshTransformsListBox(app);
            selectStage(app, 1);
        end

        % Value changed function: TransformsListBox
        function TransformsListBoxValueChanged(app, event)
            updateContextMenuState(app);
        end

        % Value changed function: ReferenceFixedViewDropDown
        function ReferenceFixedViewDropDownValueChanged(app, event)

            refreshReferenceFixedView(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.suMRakSimpleUtilityMRiAnalysisKitUIFigure = uifigure('Visible', 'off');
            app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.Name = 'suMRak - Simple Utility MRi Analysis Kit';
            app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'icon.png');
            app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.CloseRequestFcn = createCallbackFcn(app, @suMRakSimpleUtilityMRiAnalysisKitUIFigureCloseRequest, true);
            app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.KeyPressFcn = createCallbackFcn(app, @suMRakSimpleUtilityMRiAnalysisKitUIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes_Preview = uiaxes(app.MainTab);
            app.UIAxes_Preview.XLimitMethod = 'tight';
            app.UIAxes_Preview.YLimitMethod = 'tight';
            app.UIAxes_Preview.XTick = [];
            app.UIAxes_Preview.XTickLabel = '';
            app.UIAxes_Preview.YTick = [];
            app.UIAxes_Preview.YTickLabel = '';
            app.PreviewLabel = uilabel(app.MainTab);
            app.PreviewLabel.Text = 'Preview';
            app.PreviewDropDown = uidropdown(app.MainTab);
            app.PreviewDropDown.Items = {'None'};
            app.PreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewDropDownValueChanged, true);
            app.PreviewDropDown.Placeholder = 'None';
            app.PreviewDropDown.Value = 'None';
            app.Dim5Slider_PreviewLabel = uilabel(app.MainTab);
            app.Dim5Slider_PreviewLabel.Text = 'Dim - 5';
            app.Dim5Slider_Preview = uislider(app.MainTab);
            app.Dim5Slider_Preview.MajorTicks = [];
            app.Dim5Slider_Preview.MajorTickLabels = {''};
            app.Dim5Slider_Preview.ValueChangingFcn = createCallbackFcn(app, @Dim5Slider_PreviewValueChanging, true);
            app.Dim5Slider_Preview.MinorTicks = [];
            app.Dim5Slider_Preview.Enable = 'off';
            app.Dim4Slider_PreviewLabel = uilabel(app.MainTab);
            app.Dim4Slider_PreviewLabel.Text = 'Dim - 4';
            app.Dim4Slider_Preview = uislider(app.MainTab);
            app.Dim4Slider_Preview.MajorTicks = [];
            app.Dim4Slider_Preview.MajorTickLabels = {''};
            app.Dim4Slider_Preview.ValueChangingFcn = createCallbackFcn(app, @Dim4Slider_PreviewValueChanging, true);
            app.Dim4Slider_Preview.MinorTicks = [];
            app.Dim4Slider_Preview.Enable = 'off';
            app.SliceSpinner_Preview = uispinner(app.MainTab);
            app.SliceSpinner_Preview.Limits = [1 100];
            app.SliceSpinner_Preview.ValueChangedFcn = createCallbackFcn(app, @SliceSpinner_PreviewValueChanged, true);
            app.SliceSpinner_Preview.Enable = 'off';
            app.SliceSpinner_Preview.Value = 1;
            app.SliceLabel_Preview = uilabel(app.MainTab);
            app.SliceLabel_Preview.Text = 'Slice';
            app.SliceSlider_Preview = uislider(app.MainTab);
            app.SliceSlider_Preview.Limits = [1 100];
            app.SliceSlider_Preview.MajorTicks = [];
            app.SliceSlider_Preview.MajorTickLabels = {};
            app.SliceSlider_Preview.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_PreviewValueChanging, true);
            app.SliceSlider_Preview.MinorTicks = [];
            app.SliceSlider_Preview.Enable = 'off';
            app.SliceSlider_Preview.Value = 1;
            app.BrightnessSliderLabel_Preview = uilabel(app.MainTab);
            app.BrightnessSliderLabel_Preview.Text = 'Brightness';
            app.BrightnessSlider_Preview = uislider(app.MainTab);
            app.BrightnessSlider_Preview.Limits = [-1 1];
            app.BrightnessSlider_Preview.MajorTicks = 0;
            app.BrightnessSlider_Preview.ValueChangingFcn = createCallbackFcn(app, @BrightnessSlider_PreviewValueChanging, true);
            app.BrightnessSlider_Preview.MinorTicks = [];
            app.BrightnessSlider_Preview.Enable = 'off';
            app.ContrastSliderLabel_Preview = uilabel(app.MainTab);
            app.ContrastSliderLabel_Preview.Text = 'Contrast';
            app.ContrastSlider_Preview = uislider(app.MainTab);
            app.ContrastSlider_Preview.Limits = [-1 1];
            app.ContrastSlider_Preview.MajorTicks = 0;
            app.ContrastSlider_Preview.ValueChangingFcn = createCallbackFcn(app, @ContrastSlider_PreviewValueChanging, true);
            app.ContrastSlider_Preview.MinorTicks = [];
            app.ContrastSlider_Preview.Enable = 'off';
            app.LoadPvDatasetsFileButton = uibutton(app.MainTab, 'push');
            app.LoadPvDatasetsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPvDatasetsFileButtonPushed, true);
            app.LoadPvDatasetsFileButton.Text = 'Load PvDatasets File';
            app.ArchiveEditField = uieditfield(app.MainTab, 'text');
            app.ArchiveEditField.Editable = 'off';
            app.UITable_Preview = uitable(app.MainTab);
            app.UITable_Preview.ColumnName = {''; ''};
            app.UITable_Preview.RowName = {};
            app.UITable_Preview.ColumnEditable = true;
            app.UITable_Preview.CellEditCallback = createCallbackFcn(app, @UITable_PreviewCellEdit, true);
            app.ResetEnvironmentButton = uibutton(app.MainTab, 'push');
            app.ResetEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEnvironmentButtonButtonPushed, true);
            app.ResetEnvironmentButton.Text = 'Reset Environment';
            app.SubjectIDEditFieldLabel = uilabel(app.MainTab);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.MainTab, 'text');
            app.SubjectIDEditField.Editable = 'off';
            app.StudyIDEditFieldLabel = uilabel(app.MainTab);
            app.StudyIDEditFieldLabel.Text = 'Study ID';
            app.StudyIDEditField = uieditfield(app.MainTab, 'text');
            app.StudyIDEditField.Editable = 'off';
            app.SubjectCommentEditFieldLabel = uilabel(app.MainTab);
            app.SubjectCommentEditFieldLabel.Text = 'Subject Comment';
            app.SubjectCommentEditField = uieditfield(app.MainTab, 'text');
            app.SubjectCommentEditField.Editable = 'off';
            app.StudyCommentEditFieldLabel = uilabel(app.MainTab);
            app.StudyCommentEditFieldLabel.Text = 'Study Comment';
            app.StudyCommentEditField = uieditfield(app.MainTab, 'text');
            app.StudyCommentEditField.Editable = 'off';
            app.SubjectAgeEditFieldLabel = uilabel(app.MainTab);
            app.SubjectAgeEditFieldLabel.Text = 'Subject Age';
            app.SubjectAgeEditField = uieditfield(app.MainTab, 'text');
            app.SubjectAgeEditField.Editable = 'off';
            app.SubjectAgeEditFieldLabel_Days = uilabel(app.MainTab);
            app.SubjectAgeEditFieldLabel_Days.Text = 'days';
            app.StudyStartTimeEditFieldLabel = uilabel(app.MainTab);
            app.StudyStartTimeEditFieldLabel.Text = 'Study Start Time';
            app.StudyStartTimeEditField = uieditfield(app.MainTab, 'text');
            app.StudyStartTimeEditField.Editable = 'off';
            app.StudyStartDateEditFieldLabel = uilabel(app.MainTab);
            app.StudyStartDateEditFieldLabel.Text = 'Study Start Date';
            app.StudyStartDateEditField = uieditfield(app.MainTab, 'text');
            app.StudyStartDateEditField.Editable = 'off';
            app.SubjectTypeEditFieldLabel = uilabel(app.MainTab);
            app.SubjectTypeEditFieldLabel.Text = 'Subject Type';
            app.SubjectTypeEditField = uieditfield(app.MainTab, 'text');
            app.SubjectTypeEditField.Editable = 'off';
            app.SexEditFieldLabel = uilabel(app.MainTab);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.MainTab, 'text');
            app.SexEditField.Editable = 'off';
            app.WeightEditFieldLabel = uilabel(app.MainTab);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.MainTab, 'text');
            app.WeightEditField.Editable = 'off';
            app.WeightEditFieldLabel_kg = uilabel(app.MainTab);
            app.WeightEditFieldLabel_kg.Text = 'kg';
            app.ArchiveLabel = uilabel(app.MainTab);
            app.ArchiveLabel.Text = 'Archive';
            app.ColormapButtonGroup_Preview = uibuttongroup(app.MainTab);
            app.ColormapButtonGroup_Preview.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroup_PreviewSelectionChanged, true);
            app.ColormapButtonGroup_Preview.Title = 'Colormap';
            app.GreyscaleButton_Preview = uiradiobutton(app.ColormapButtonGroup_Preview);
            app.GreyscaleButton_Preview.Enable = 'off';
            app.GreyscaleButton_Preview.Text = 'Greyscale';
            app.GreyscaleButton_Preview.Value = true;
            app.TurboButton_Preview = uiradiobutton(app.ColormapButtonGroup_Preview);
            app.TurboButton_Preview.Enable = 'off';
            app.TurboButton_Preview.Text = 'Turbo';
            app.ExportDataButton_Preview = uibutton(app.MainTab, 'push');
            app.ExportDataButton_Preview.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_PreviewPushed, true);
            app.ExportDataButton_Preview.Enable = 'off';
            app.ExportDataButton_Preview.Text = 'Export Data';
            app.SaveDataButton_Preview = uibutton(app.MainTab, 'push');
            app.SaveDataButton_Preview.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_PreviewPushed, true);
            app.SaveDataButton_Preview.Enable = 'off';
            app.SaveDataButton_Preview.Text = 'Save Data';
            app.CreatesuMRakFolderButton = uibutton(app.MainTab, 'push');
            app.CreatesuMRakFolderButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesuMRakFolderButtonPushed, true);
            app.CreatesuMRakFolderButton.Enable = 'off';
            app.CreatesuMRakFolderButton.Tooltip = {'Creates a suMRak folder used as a directory for all data/environment exports.'};
            app.CreatesuMRakFolderButton.Text = 'Create suMRak Folder';
            app.LoadBrukerStudyButton = uibutton(app.MainTab, 'push');
            app.LoadBrukerStudyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBrukerStudyButtonPushed, true);
            app.LoadBrukerStudyButton.Text = 'Load Bruker Study';
            app.LoadsuMRakFolderButton = uibutton(app.MainTab, 'push');
            app.LoadsuMRakFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsuMRakFolderButtonPushed, true);
            app.LoadsuMRakFolderButton.Text = 'Load suMRak Folder';
            app.ExportEnvironmentButton = uibutton(app.MainTab, 'push');
            app.ExportEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ExportEnvironmentButtonPushed, true);
            app.ExportEnvironmentButton.Enable = 'off';
            app.ExportEnvironmentButton.Text = 'Export Environment';
            app.LoadSingleNIfTIButton = uibutton(app.MainTab, 'push');
            app.LoadSingleNIfTIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSingleNIfTIButtonPushed, true);
            app.LoadSingleNIfTIButton.Text = 'Load Single NIfTI';
            app.SegmenterTab = uitab(app.TabGroup);
            app.SegmenterTab.Title = 'Segmentation';
            app.UIAxes_Segmenter = uiaxes(app.SegmenterTab);
            app.UIAxes_Segmenter.XLimitMethod = 'tight';
            app.UIAxes_Segmenter.YLimitMethod = 'tight';
            app.UIAxes_Segmenter.XTick = [];
            app.UIAxes_Segmenter.YTick = [];
            app.UIAxes_SegmenterHelperUp = uiaxes(app.SegmenterTab);
            app.UIAxes_SegmenterHelperUp.XLimitMethod = 'tight';
            app.UIAxes_SegmenterHelperUp.YLimitMethod = 'tight';
            app.UIAxes_SegmenterHelperUp.XTick = [];
            app.UIAxes_SegmenterHelperUp.YTick = [];
            app.UIAxes_SegmenterHelperUp.Visible = 'off';
            app.UIAxes_SegmenterHelperDown = uiaxes(app.SegmenterTab);
            app.UIAxes_SegmenterHelperDown.XLimitMethod = 'tight';
            app.UIAxes_SegmenterHelperDown.YLimitMethod = 'tight';
            app.UIAxes_SegmenterHelperDown.XTick = [];
            app.UIAxes_SegmenterHelperDown.YTick = [];
            app.UIAxes_SegmenterHelperDown.Visible = 'off';
            app.SelectExperimentToSegmentLabel = uilabel(app.SegmenterTab);
            app.SelectExperimentToSegmentLabel.Text = 'Select Experiment To Segment';
            app.SegmentDropDown = uidropdown(app.SegmenterTab);
            app.SegmentDropDown.Items = {'None'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Placeholder = 'None';
            app.SegmentDropDown.Value = 'None';
            app.SliceSpinner_Segmenter = uispinner(app.SegmenterTab);
            app.SliceSpinner_Segmenter.ValueChangedFcn = createCallbackFcn(app, @SliceSpinner_SegmenterValueChanged, true);
            app.SliceSpinner_Segmenter.Enable = 'off';
            app.SliceSpinner_Segmenter.Value = 1;
            app.SaveSegmentedDataButton = uibutton(app.SegmenterTab, 'push');
            app.SaveSegmentedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSegmentedDataButtonPushed, true);
            app.SaveSegmentedDataButton.Enable = 'off';
            app.SaveSegmentedDataButton.Text = 'Save Segmented Data';
            app.ExportDataButton_Segmenter = uibutton(app.SegmenterTab, 'push');
            app.ExportDataButton_Segmenter.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_SegmenterPushed, true);
            app.ExportDataButton_Segmenter.Enable = 'off';
            app.ExportDataButton_Segmenter.Text = 'Export Segmented Data';
            app.Dim4Spinner_SegmenterLabel = uilabel(app.SegmenterTab);
            app.Dim4Spinner_SegmenterLabel.Text = 'Dim - 4';
            app.Dim4Spinner_Segmenter = uispinner(app.SegmenterTab);
            app.Dim4Spinner_Segmenter.ValueChangedFcn = createCallbackFcn(app, @Dim4Spinner_SegmenterValueChanged, true);
            app.Dim4Spinner_Segmenter.Enable = 'off';
            app.Dim4Spinner_Segmenter.Value = 1;
            app.Dim5Spinner_SegmenterLabel = uilabel(app.SegmenterTab);
            app.Dim5Spinner_SegmenterLabel.Text = 'Dim - 5';
            app.Dim5Spinner_Segmenter = uispinner(app.SegmenterTab);
            app.Dim5Spinner_Segmenter.ValueChangedFcn = createCallbackFcn(app, @Dim5Spinner_SegmenterValueChanged, true);
            app.Dim5Spinner_Segmenter.Enable = 'off';
            app.Dim5Spinner_Segmenter.Value = 1;
            app.SliceSliderLabel = uilabel(app.SegmenterTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider_Segmenter = uislider(app.SegmenterTab);
            app.SliceSlider_Segmenter.Limits = [1 100];
            app.SliceSlider_Segmenter.MajorTicks = [];
            app.SliceSlider_Segmenter.MajorTickLabels = {};
            app.SliceSlider_Segmenter.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_SegmenterValueChanging, true);
            app.SliceSlider_Segmenter.MinorTicks = [];
            app.SliceSlider_Segmenter.Enable = 'off';
            app.SliceSlider_Segmenter.Value = 1;
            app.BrightnessSliderLabel_Segmenter = uilabel(app.SegmenterTab);
            app.BrightnessSliderLabel_Segmenter.Text = 'Brightness';
            app.BrightnessSlider_Segmenter = uislider(app.SegmenterTab);
            app.BrightnessSlider_Segmenter.Limits = [-1 1];
            app.BrightnessSlider_Segmenter.MajorTicks = 0;
            app.BrightnessSlider_Segmenter.ValueChangingFcn = createCallbackFcn(app, @BrightnessSlider_SegmenterValueChanging, true);
            app.BrightnessSlider_Segmenter.MinorTicks = [];
            app.BrightnessSlider_Segmenter.Enable = 'off';
            app.ContrastSliderLabel_Segmenter = uilabel(app.SegmenterTab);
            app.ContrastSliderLabel_Segmenter.Text = 'Contrast';
            app.ColormapButtonGroup_Segmenter = uibuttongroup(app.SegmenterTab);
            app.ColormapButtonGroup_Segmenter.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroup_SegmenterSelectionChanged, true);
            app.ColormapButtonGroup_Segmenter.Title = 'Colormap';
            app.GreyscaleButton_Segmenter = uiradiobutton(app.ColormapButtonGroup_Segmenter);
            app.GreyscaleButton_Segmenter.Enable = 'off';
            app.GreyscaleButton_Segmenter.Text = 'Greyscale';
            app.GreyscaleButton_Segmenter.Value = true;
            app.TurboButton_Segmenter = uiradiobutton(app.ColormapButtonGroup_Segmenter);
            app.TurboButton_Segmenter.Enable = 'off';
            app.TurboButton_Segmenter.Text = 'Turbo';
            app.ContrastSlider_Segmenter = uislider(app.SegmenterTab);
            app.ContrastSlider_Segmenter.Limits = [-1 1];
            app.ContrastSlider_Segmenter.MajorTicks = 0;
            app.ContrastSlider_Segmenter.ValueChangingFcn = createCallbackFcn(app, @ContrastSlider_SegmenterValueChanging, true);
            app.ContrastSlider_Segmenter.MinorTicks = [];
            app.ContrastSlider_Segmenter.Enable = 'off';
            app.CurrentsegmentationLabel = uilabel(app.SegmenterTab);
            app.CurrentsegmentationLabel.Text = 'Current Segmentation';
            app.CurrentSegmentationDropDown = uidropdown(app.SegmenterTab);
            app.CurrentSegmentationDropDown.Items = {'Brain', 'Hemisphere', 'ROI'};
            app.CurrentSegmentationDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentSegmentationDropDownValueChanged, true);
            app.CurrentSegmentationDropDown.Enable = 'off';
            app.CurrentSegmentationDropDown.Placeholder = 'None';
            app.CurrentSegmentationDropDown.Value = 'Brain';
            app.SelectionToolsPanel = uipanel(app.SegmenterTab);
            app.SelectionToolsPanel.Title = 'Selection Tools';
            app.SelectionToolsPanel.Visible = 'off';
            app.FreeButton_Add = uibutton(app.SelectionToolsPanel, 'push');
            app.FreeButton_Add.ButtonPushedFcn = createCallbackFcn(app, @FreeButton_AddPushed, true);
            app.FreeButton_Add.Text = {'+ Free'; ''};
            app.PolyButton_Add = uibutton(app.SelectionToolsPanel, 'push');
            app.PolyButton_Add.ButtonPushedFcn = createCallbackFcn(app, @PolyButton_AddPushed, true);
            app.PolyButton_Add.Text = '+ Poly';
            app.PolyButton_Remove = uibutton(app.SelectionToolsPanel, 'push');
            app.PolyButton_Remove.ButtonPushedFcn = createCallbackFcn(app, @PolyButton_RemovePushed, true);
            app.PolyButton_Remove.Text = {'- Poly'; ''};
            app.FreeButton_Remove = uibutton(app.SelectionToolsPanel, 'push');
            app.FreeButton_Remove.ButtonPushedFcn = createCallbackFcn(app, @FreeButton_RemovePushed, true);
            app.FreeButton_Remove.Text = {'- Free'; ''};
            app.ConfirmButton = uibutton(app.SelectionToolsPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Icon = fullfile(pathToMLAPP, 'resources', 'check icon.png');
            app.ConfirmButton.Text = '';
            app.DeleteButton = uibutton(app.SelectionToolsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = fullfile(pathToMLAPP, 'resources', 'x icon.png');
            app.DeleteButton.Text = '';
            app.ROISegmentationToolsPanel = uipanel(app.SegmenterTab);
            app.ROISegmentationToolsPanel.Title = 'ROI Segmentation Tools';
            app.ROISegmentationToolsPanel.Visible = 'off';
            app.ROIListListBoxLabel = uilabel(app.ROISegmentationToolsPanel);
            app.ROIListListBoxLabel.Text = 'ROI List';
            app.ROIListListBox = uilistbox(app.ROISegmentationToolsPanel);
            app.ROIListListBox.Items = {};
            app.ROIListListBox.ValueChangedFcn = createCallbackFcn(app, @ROIListListBoxValueChanged, true);
            app.ROIListListBox.Value = {};
            app.AddROIButton = uibutton(app.ROISegmentationToolsPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.DeleteROIButton = uibutton(app.ROISegmentationToolsPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.ResetROISliceButton = uibutton(app.ROISegmentationToolsPanel, 'push');
            app.ResetROISliceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROISliceButtonPushed, true);
            app.ResetROISliceButton.Text = 'Reset ROI Slice';
            app.ImageshownSwitchLabel_ROI = uilabel(app.ROISegmentationToolsPanel);
            app.ImageshownSwitchLabel_ROI.Text = {'Image shown'; ''};
            app.ImageshownSwitch_ROI = uiswitch(app.ROISegmentationToolsPanel, 'slider');
            app.ImageshownSwitch_ROI.Items = {'Overlay', 'Masked'};
            app.ImageshownSwitch_ROI.ValueChangedFcn = createCallbackFcn(app, @ImageshownSwitch_ROIValueChanged, true);
            app.ImageshownSwitch_ROI.Value = 'Overlay';
            app.LoadExternalROIPackButton = uibutton(app.ROISegmentationToolsPanel, 'push');
            app.LoadExternalROIPackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExternalROIPackButtonPushed, true);
            app.LoadExternalROIPackButton.Text = 'Load External ROI Pack';
            app.VolROISegmentationToolsButton = uibutton(app.ROISegmentationToolsPanel, 'push');
            app.VolROISegmentationToolsButton.ButtonPushedFcn = createCallbackFcn(app, @VolROISegmentationToolsButtonPushed, true);
            app.VolROISegmentationToolsButton.Text = '3D ROI Segmentation Tools';
            app.HemisphereSegmentationToolsPanel = uipanel(app.SegmenterTab);
            app.HemisphereSegmentationToolsPanel.Title = 'Hemisphere Segmentation Tools';
            app.HemisphereSegmentationToolsPanel.Visible = 'off';
            app.ResetHemispheresButton = uibutton(app.HemisphereSegmentationToolsPanel, 'push');
            app.ResetHemispheresButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHemispheresButtonPushed, true);
            app.ResetHemispheresButton.Text = 'Reset Hemispheres';
            app.HemisphereButtonGroup = uibuttongroup(app.HemisphereSegmentationToolsPanel);
            app.HemisphereButtonGroup.Title = 'Hemisphere ';
            app.LeftblueButton = uiradiobutton(app.HemisphereButtonGroup);
            app.LeftblueButton.Text = 'Left - blue';
            app.LeftblueButton.Value = true;
            app.RightredButton = uiradiobutton(app.HemisphereButtonGroup);
            app.RightredButton.Text = 'Right - red';
            app.LoadExternalHemisphereMaskButton = uibutton(app.HemisphereSegmentationToolsPanel, 'push');
            app.LoadExternalHemisphereMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExternalHemisphereMaskButtonPushed, true);
            app.LoadExternalHemisphereMaskButton.Text = 'Load External Hemisphere Mask';
            app.AutoCompleteHemispheresCheckBox = uicheckbox(app.HemisphereSegmentationToolsPanel);
            app.AutoCompleteHemispheresCheckBox.Text = 'Auto-Complete Hemispheres';
            app.BrainSegmentationToolsPanel = uipanel(app.SegmenterTab);
            app.BrainSegmentationToolsPanel.Title = 'Brain Segmentation Tools';
            app.BrainSegmentationToolsPanel.Visible = 'off';
            app.DiskradiusSpinnerLabel = uilabel(app.BrainSegmentationToolsPanel);
            app.DiskradiusSpinnerLabel.Text = {'Disk radius'; ''};
            app.DiskradiusSpinner = uispinner(app.BrainSegmentationToolsPanel);
            app.DiskradiusSpinner.Limits = [1 100];
            app.DiskradiusSpinner.Value = 3;
            app.OpenMaskButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.OpenMaskButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMaskButtonPushed, true);
            app.OpenMaskButton.Text = {'Open Mask'; ''};
            app.CloseMaskButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.CloseMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CloseMaskButtonPushed, true);
            app.CloseMaskButton.Text = {'Close Mask'; ''};
            app.MorphologyLabel = uilabel(app.BrainSegmentationToolsPanel);
            app.MorphologyLabel.Text = {'Morphology'; ''};
            app.InitialSelectionButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.InitialSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @InitialSelectionButtonPushed, true);
            app.InitialSelectionButton.Text = {'Initial Selection'; ''};
            app.AutoClusterButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.AutoClusterButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClusterButtonPushed, true);
            app.AutoClusterButton.Enable = 'off';
            app.AutoClusterButton.Text = 'Auto Cluster';
            app.LoadExternalBrainMaskButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.LoadExternalBrainMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExternalBrainMaskButtonPushed, true);
            app.LoadExternalBrainMaskButton.Text = 'Load External Brain Mask';
            app.ImageshownSwitchLabel = uilabel(app.BrainSegmentationToolsPanel);
            app.ImageshownSwitchLabel.Text = {'Image shown'; ''};
            app.ImageshownSwitch_Brain = uiswitch(app.BrainSegmentationToolsPanel, 'slider');
            app.ImageshownSwitch_Brain.Items = {'Overlay', 'Masked'};
            app.ImageshownSwitch_Brain.ValueChangedFcn = createCallbackFcn(app, @ImageshownSwitch_BrainValueChanged, true);
            app.ImageshownSwitch_Brain.Value = 'Overlay';
            app.ResetSliceButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.ResetSliceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSliceButtonPushed, true);
            app.ResetSliceButton.Text = 'Reset Slice';
            app.ApplyMaskButton = uibutton(app.BrainSegmentationToolsPanel, 'push');
            app.ApplyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaskButtonPushed, true);
            app.ApplyMaskButton.Text = 'Apply Mask';
            app.VolumeSwitch = uiswitch(app.BrainSegmentationToolsPanel, 'slider');
            app.VolumeSwitch.Items = {'2D', '3D'};
            app.VolumeSwitch.ValueChangedFcn = createCallbackFcn(app, @VolumeSwitchValueChanged, true);
            app.VolumeSwitch.Value = '2D';
            app.PerspectiveViewButton = uibutton(app.SegmenterTab, 'state');
            app.PerspectiveViewButton.ValueChangedFcn = createCallbackFcn(app, @PerspectiveViewButtonValueChanged, true);
            app.PerspectiveViewButton.Enable = 'off';
            app.PerspectiveViewButton.Text = 'Perspective View';
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.UIAxes_Registration = uiaxes(app.RegistrationTab);
            app.UIAxes_Registration.XLimitMethod = 'tight';
            app.UIAxes_Registration.YLimitMethod = 'tight';
            app.UIAxes_Registration.XTick = [];
            app.UIAxes_Registration.YTick = [];
            app.SaveRegisteredDataButton = uibutton(app.RegistrationTab, 'push');
            app.SaveRegisteredDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRegisteredDataButtonPushed, true);
            app.SaveRegisteredDataButton.Enable = 'off';
            app.SaveRegisteredDataButton.Text = 'Save Registered Data';
            app.ExportDataButton_Registration = uibutton(app.RegistrationTab, 'push');
            app.ExportDataButton_Registration.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_RegistrationPushed, true);
            app.ExportDataButton_Registration.Enable = 'off';
            app.ExportDataButton_Registration.Text = 'Export Registered Data';
            app.ColormapButtonGroup_Registration = uibuttongroup(app.RegistrationTab);
            app.ColormapButtonGroup_Registration.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroup_RegistrationSelectionChanged, true);
            app.ColormapButtonGroup_Registration.Title = 'Colormap';
            app.GreyscaleButton_Registration = uiradiobutton(app.ColormapButtonGroup_Registration);
            app.GreyscaleButton_Registration.Enable = 'off';
            app.GreyscaleButton_Registration.Text = 'Greyscale';
            app.GreyscaleButton_Registration.Value = true;
            app.TurboButton_Registration = uiradiobutton(app.ColormapButtonGroup_Registration);
            app.TurboButton_Registration.Enable = 'off';
            app.TurboButton_Registration.Text = 'Turbo';
            app.SliceSpinner_Registration = uispinner(app.RegistrationTab);
            app.SliceSpinner_Registration.ValueChangedFcn = createCallbackFcn(app, @SliceSpinner_RegistrationValueChanged, true);
            app.SliceSpinner_Registration.Enable = 'off';
            app.SliceSpinner_Registration.Value = 1;
            app.SliceSliderLabel_Registration = uilabel(app.RegistrationTab);
            app.SliceSliderLabel_Registration.Text = 'Slice';
            app.SliceSlider_Registration = uislider(app.RegistrationTab);
            app.SliceSlider_Registration.Limits = [1 100];
            app.SliceSlider_Registration.MajorTicks = [];
            app.SliceSlider_Registration.MajorTickLabels = {};
            app.SliceSlider_Registration.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_RegistrationValueChanging, true);
            app.SliceSlider_Registration.MinorTicks = [];
            app.SliceSlider_Registration.Enable = 'off';
            app.SliceSlider_Registration.Value = 1;
            app.ChooseRegistrationTypeDropDownLabel = uilabel(app.RegistrationTab);
            app.ChooseRegistrationTypeDropDownLabel.Text = 'Choose Registration Type';
            app.ChooseRegistrationTypeDropDown = uidropdown(app.RegistrationTab);
            app.ChooseRegistrationTypeDropDown.Items = {'Standard', 'Reference Atlas', 'Time-Series Alignment'};
            app.ChooseRegistrationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseRegistrationTypeDropDownValueChanged, true);
            app.ChooseRegistrationTypeDropDown.Tooltip = {''};
            app.ChooseRegistrationTypeDropDown.Value = 'Standard';
            app.TimeSeriesAlignmentPanel = uipanel(app.RegistrationTab);
            app.TimeSeriesAlignmentPanel.Visible = 'off';
            app.SelectTimeAlignmentLabel = uilabel(app.TimeSeriesAlignmentPanel);
            app.SelectTimeAlignmentLabel.Text = 'Select Image Data For Alignment';
            app.SelectTimeAlignmentDropDown = uidropdown(app.TimeSeriesAlignmentPanel);
            app.SelectTimeAlignmentDropDown.Items = {'None'};
            app.SelectTimeAlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTimeAlignmentDropDownValueChanged, true);
            app.SelectTimeAlignmentDropDown.Tooltip = {''};
            app.SelectTimeAlignmentDropDown.Placeholder = 'None';
            app.SelectTimeAlignmentDropDown.Value = 'None';
            app.AlignDataButton = uibutton(app.TimeSeriesAlignmentPanel, 'push');
            app.AlignDataButton.ButtonPushedFcn = createCallbackFcn(app, @AlignDataButtonPushed, true);
            app.AlignDataButton.Enable = 'off';
            app.AlignDataButton.Text = 'Align Data';
            app.Dim4Spinner_TimeAlignmentReferenceLabel = uilabel(app.TimeSeriesAlignmentPanel);
            app.Dim4Spinner_TimeAlignmentReferenceLabel.Text = 'Dim - 4 ';
            app.Dim4Spinner_TimeAlignmentReference = uispinner(app.TimeSeriesAlignmentPanel);
            app.Dim4Spinner_TimeAlignmentReference.Enable = 'off';
            app.Dim4Spinner_TimeAlignmentReference.Value = 1;
            app.Dim5Spinner_TimeAlignmentReferenceLabel = uilabel(app.TimeSeriesAlignmentPanel);
            app.Dim5Spinner_TimeAlignmentReferenceLabel.Text = 'Dim - 5';
            app.Dim5Spinner_TimeAlignmentReference = uispinner(app.TimeSeriesAlignmentPanel);
            app.Dim5Spinner_TimeAlignmentReference.Enable = 'off';
            app.Dim5Spinner_TimeAlignmentReference.Value = 1;
            app.ReferenceDataPointLabel = uilabel(app.TimeSeriesAlignmentPanel);
            app.ReferenceDataPointLabel.Text = 'Reference Data Point';
            app.AdditionalDimensionControlsPanel = uipanel(app.TimeSeriesAlignmentPanel);
            app.AdditionalDimensionControlsPanel.Title = 'Additional Dimension Controls';
            app.Dim4Spinner_TimeAlignmentControl = uispinner(app.AdditionalDimensionControlsPanel);
            app.Dim4Spinner_TimeAlignmentControl.ValueChangedFcn = createCallbackFcn(app, @Dim4Spinner_TimeAlignmentControlValueChanged, true);
            app.Dim4Spinner_TimeAlignmentControl.Enable = 'off';
            app.Dim4Spinner_TimeAlignmentControl.Value = 1;
            app.Dim4SliderLabel_TimeAlignmentControl = uilabel(app.AdditionalDimensionControlsPanel);
            app.Dim4SliderLabel_TimeAlignmentControl.Text = 'Dim - 4';
            app.Dim4Slider_TimeAlignmentControl = uislider(app.AdditionalDimensionControlsPanel);
            app.Dim4Slider_TimeAlignmentControl.Limits = [1 100];
            app.Dim4Slider_TimeAlignmentControl.MajorTicks = [];
            app.Dim4Slider_TimeAlignmentControl.MajorTickLabels = {};
            app.Dim4Slider_TimeAlignmentControl.ValueChangingFcn = createCallbackFcn(app, @Dim4Slider_TimeAlignmentControlValueChanging, true);
            app.Dim4Slider_TimeAlignmentControl.MinorTicks = [];
            app.Dim4Slider_TimeAlignmentControl.Enable = 'off';
            app.Dim4Slider_TimeAlignmentControl.Value = 1;
            app.Dim5Spinner_TimeAlignmentControl = uispinner(app.AdditionalDimensionControlsPanel);
            app.Dim5Spinner_TimeAlignmentControl.ValueChangedFcn = createCallbackFcn(app, @Dim5Spinner_TimeAlignmentControlValueChanged, true);
            app.Dim5Spinner_TimeAlignmentControl.Enable = 'off';
            app.Dim5Spinner_TimeAlignmentControl.Value = 1;
            app.Dim5SliderLabel_TimeAlignmentControl = uilabel(app.AdditionalDimensionControlsPanel);
            app.Dim5SliderLabel_TimeAlignmentControl.Text = 'Dim - 5';
            app.Dim5Slider_TimeAlignmentControl = uislider(app.AdditionalDimensionControlsPanel);
            app.Dim5Slider_TimeAlignmentControl.Limits = [1 100];
            app.Dim5Slider_TimeAlignmentControl.MajorTicks = [];
            app.Dim5Slider_TimeAlignmentControl.MajorTickLabels = {};
            app.Dim5Slider_TimeAlignmentControl.ValueChangingFcn = createCallbackFcn(app, @Dim5Slider_TimeAlignmentControlValueChanging, true);
            app.Dim5Slider_TimeAlignmentControl.MinorTicks = [];
            app.Dim5Slider_TimeAlignmentControl.Enable = 'off';
            app.Dim5Slider_TimeAlignmentControl.Value = 1;
            app.StandardAtlasRegistrationPanel = uipanel(app.RegistrationTab);
            app.RegisterButton = uibutton(app.StandardAtlasRegistrationPanel, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.ManualInstructionInputCheckBox = uicheckbox(app.StandardAtlasRegistrationPanel);
            app.ManualInstructionInputCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualInstructionInputCheckBoxValueChanged, true);
            app.ManualInstructionInputCheckBox.Text = 'Manual Instruction Input';
            app.RegistrationViewerButton = uibutton(app.StandardAtlasRegistrationPanel, 'push');
            app.RegistrationViewerButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationViewerButtonPushed, true);
            app.RegistrationViewerButton.Text = 'Registration Viewer';
            app.SelectmovingLabel = uilabel(app.StandardAtlasRegistrationPanel);
            app.SelectmovingLabel.Text = 'Select Moving Image Data';
            app.SelectmovingDropDown = uidropdown(app.StandardAtlasRegistrationPanel);
            app.SelectmovingDropDown.Items = {'None'};
            app.SelectmovingDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmovingDropDownValueChanged, true);
            app.SelectmovingDropDown.Tooltip = {''};
            app.SelectmovingDropDown.Placeholder = 'None';
            app.SelectmovingDropDown.Value = 'None';
            app.SelectfixedLabel = uilabel(app.StandardAtlasRegistrationPanel);
            app.SelectfixedLabel.Text = 'Select Fixed Image Data';
            app.SelectfixedDropDown = uidropdown(app.StandardAtlasRegistrationPanel);
            app.SelectfixedDropDown.Items = {'None'};
            app.SelectfixedDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectfixedDropDownValueChanged, true);
            app.SelectfixedDropDown.Placeholder = 'None';
            app.SelectfixedDropDown.Value = 'None';
            app.UseDifferentParameterMapCheckBox = uicheckbox(app.StandardAtlasRegistrationPanel);
            app.UseDifferentParameterMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDifferentParameterMapCheckBoxValueChanged, true);
            app.UseDifferentParameterMapCheckBox.Text = 'Use Different Parameter Map';
            app.SelectparameterLabel = uilabel(app.StandardAtlasRegistrationPanel);
            app.SelectparameterLabel.Text = 'Select Parameter Image Data';
            app.SelectparameterDropDown = uidropdown(app.StandardAtlasRegistrationPanel);
            app.SelectparameterDropDown.Items = {'None'};
            app.SelectparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectparameterDropDownValueChanged, true);
            app.SelectparameterDropDown.Enable = 'off';
            app.SelectparameterDropDown.Placeholder = 'None';
            app.SelectparameterDropDown.Value = 'None';
            app.ImportReferenceAtlasButton = uibutton(app.StandardAtlasRegistrationPanel, 'push');
            app.ImportReferenceAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ImportReferenceAtlasButtonPushed, true);
            app.ImportReferenceAtlasButton.Visible = 'off';
            app.ImportReferenceAtlasButton.Text = 'Import Reference Atlas';
            app.SelectAtlasDropDown = uidropdown(app.StandardAtlasRegistrationPanel);
            app.SelectAtlasDropDown.Items = {'None'};
            app.SelectAtlasDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAtlasDropDownValueChanged, true);
            app.SelectAtlasDropDown.Visible = 'off';
            app.SelectAtlasDropDown.Placeholder = 'None';
            app.SelectAtlasDropDown.Value = 'None';
            app.RegistrationSliceLimitsTextAreaLabel = uilabel(app.StandardAtlasRegistrationPanel);
            app.RegistrationSliceLimitsTextAreaLabel.Text = 'Registration Slice Limits';
            app.RegistrationSliceLimitsTextArea = uitextarea(app.StandardAtlasRegistrationPanel);
            app.RegistrationSliceLimitsTextArea.Editable = 'off';
            app.TransformsLabel = uilabel(app.StandardAtlasRegistrationPanel);
            app.TransformsLabel.Text = 'Transforms';
            app.TransformsListBox = uilistbox(app.StandardAtlasRegistrationPanel);
            app.TransformsListBox.Items = {'Rigid', 'Affine', 'Non-Rigid'};
            app.TransformsListBox.ValueChangedFcn = createCallbackFcn(app, @TransformsListBoxValueChanged, true);
            app.TransformsListBox.Value = 'Rigid';
            app.OutputInterpolatorDropDownLabel = uilabel(app.StandardAtlasRegistrationPanel);
            app.OutputInterpolatorDropDownLabel.Text = 'Output Interpolator';
            app.OutputInterpolatorDropDown = uidropdown(app.StandardAtlasRegistrationPanel);
            app.OutputInterpolatorDropDown.Items = {'Nearest Neighbor', 'Linear', 'BSpline'};
            app.OutputInterpolatorDropDown.ItemsData = {'NearestNeighbor', 'Linear', 'BSpline'};
            app.OutputInterpolatorDropDown.Tooltip = {'Interpolation for the final resample of the moving image onto the fixed grid. Nearest Neighbor preserves quantitative values exactly; Linear and B-Spline give smoother anatomical images but blend/overshoot voxel values.'};
            app.OutputInterpolatorDropDown.Placeholder = 'NearestNeighbor';
            app.OutputInterpolatorDropDown.Value = 'NearestNeighbor';
            app.ShowReferenceFixedCheckBox = uicheckbox(app.RegistrationTab);
            app.ShowReferenceFixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowReferenceFixedCheckBoxValueChanged, true);
            app.ShowReferenceFixedCheckBox.Enable = 'off';
            app.ShowReferenceFixedCheckBox.Text = 'Show Reference Fixed Data';
            app.ReferenceFixedViewDropDown = uidropdown(app.RegistrationTab);
            app.ReferenceFixedViewDropDown.Items = {'Multiply', 'Side-by-side', 'Falsecolor difference'};
            app.ReferenceFixedViewDropDown.ItemsData = {'multiply', 'sidebyside', 'falsecolor'};
            app.ReferenceFixedViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceFixedViewDropDownValueChanged, true);
            app.ReferenceFixedViewDropDown.Enable = 'off';
            app.ReferenceFixedViewDropDown.Value = 'sidebyside';
            app.ParameterMapsTab = uitab(app.TabGroup);
            app.ParameterMapsTab.Title = 'Parameter Maps';
            app.UIAxes_PreMap = uiaxes(app.ParameterMapsTab);
            app.UIAxes_PreMap.XLimitMethod = 'tight';
            app.UIAxes_PreMap.YLimitMethod = 'tight';
            app.UIAxes_PreMap.XTick = [];
            app.UIAxes_PreMap.XTickLabel = '';
            app.UIAxes_PreMap.YTick = [];
            app.UIAxes_PreMap.YTickLabel = '';
            app.UIAxes_PostMap = uiaxes(app.ParameterMapsTab);
            app.UIAxes_PostMap.XLimitMethod = 'tight';
            app.UIAxes_PostMap.YLimitMethod = 'tight';
            app.UIAxes_PostMap.XTick = [];
            app.UIAxes_PostMap.XTickLabel = '';
            app.UIAxes_PostMap.YTick = [];
            app.UIAxes_PostMap.YTickLabel = '';
            app.SliceSpinner_PreMap = uispinner(app.ParameterMapsTab);
            app.SliceSpinner_PreMap.ValueChangedFcn = createCallbackFcn(app, @SliceSpinner_PreMapValueChanged, true);
            app.SliceSpinner_PreMap.Enable = 'off';
            app.SliceSpinner_PreMap.Value = 1;
            app.SelectDSCvolumetricdataformapcalculationLabel = uilabel(app.ParameterMapsTab);
            app.SelectDSCvolumetricdataformapcalculationLabel.Text = 'Select Image Data For Map Calculation';
            app.SelectPreMapDropDown = uidropdown(app.ParameterMapsTab);
            app.SelectPreMapDropDown.Items = {'None'};
            app.SelectPreMapDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPreMapDropDownValueChanged, true);
            app.SelectPreMapDropDown.Placeholder = 'None';
            app.SelectPreMapDropDown.Value = 'None';
            app.SliceLabel_ParameterMaps_PreMap = uilabel(app.ParameterMapsTab);
            app.SliceLabel_ParameterMaps_PreMap.Text = 'Slice';
            app.SliceSlider_PreMap = uislider(app.ParameterMapsTab);
            app.SliceSlider_PreMap.Limits = [1 100];
            app.SliceSlider_PreMap.MajorTicks = [];
            app.SliceSlider_PreMap.MajorTickLabels = {};
            app.SliceSlider_PreMap.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_PreMapValueChanging, true);
            app.SliceSlider_PreMap.MinorTicks = [];
            app.SliceSlider_PreMap.Enable = 'off';
            app.SliceSlider_PreMap.Value = 1;
            app.Dim4Spinner_Label_ParameterMaps = uilabel(app.ParameterMapsTab);
            app.Dim4Spinner_Label_ParameterMaps.Text = 'Dim - 4';
            app.Dim4Spinner_PreMap = uispinner(app.ParameterMapsTab);
            app.Dim4Spinner_PreMap.ValueChangedFcn = createCallbackFcn(app, @Dim4Spinner_PreMapValueChanged, true);
            app.Dim4Spinner_PreMap.Enable = 'off';
            app.Dim4Spinner_PreMap.Value = 1;
            app.Dim5Spinner_Label_ParameterMaps = uilabel(app.ParameterMapsTab);
            app.Dim5Spinner_Label_ParameterMaps.Text = 'Dim - 5';
            app.Dim5Spinner_PreMap = uispinner(app.ParameterMapsTab);
            app.Dim5Spinner_PreMap.ValueChangedFcn = createCallbackFcn(app, @Dim5Spinner_PreMapValueChanged, true);
            app.Dim5Spinner_PreMap.Enable = 'off';
            app.Dim5Spinner_PreMap.Value = 1;
            app.ChooseMapTypeDropDownLabel = uilabel(app.ParameterMapsTab);
            app.ChooseMapTypeDropDownLabel.Text = 'Choose Map Type';
            app.ChooseMapTypeDropDown = uidropdown(app.ParameterMapsTab);
            app.ChooseMapTypeDropDown.Items = {'DSC', 'T1', 'T2', 'pASL'};
            app.ChooseMapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMapTypeDropDownValueChanged, true);
            app.ChooseMapTypeDropDown.Tooltip = {''};
            app.ChooseMapTypeDropDown.Value = 'DSC';
            app.BrightnessSliderLabel_ParameterMaps = uilabel(app.ParameterMapsTab);
            app.BrightnessSliderLabel_ParameterMaps.Text = 'Brightness';
            app.BrightnessSlider_PostMap = uislider(app.ParameterMapsTab);
            app.BrightnessSlider_PostMap.Limits = [-1 1];
            app.BrightnessSlider_PostMap.MajorTicks = 0;
            app.BrightnessSlider_PostMap.ValueChangingFcn = createCallbackFcn(app, @BrightnessSlider_PostMapValueChanging, true);
            app.BrightnessSlider_PostMap.MinorTicks = [];
            app.BrightnessSlider_PostMap.Enable = 'off';
            app.ContrastSliderLabel_ParameterMaps = uilabel(app.ParameterMapsTab);
            app.ContrastSliderLabel_ParameterMaps.Text = 'Contrast';
            app.ContrastSlider_PostMap = uislider(app.ParameterMapsTab);
            app.ContrastSlider_PostMap.Limits = [-1 1];
            app.ContrastSlider_PostMap.MajorTicks = 0;
            app.ContrastSlider_PostMap.ValueChangingFcn = createCallbackFcn(app, @ContrastSlider_PostMapValueChanging, true);
            app.ContrastSlider_PostMap.MinorTicks = [];
            app.ContrastSlider_PostMap.Enable = 'off';
            app.ColormapButtonGroup_PostMap = uibuttongroup(app.ParameterMapsTab);
            app.ColormapButtonGroup_PostMap.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroup_PostMapSelectionChanged, true);
            app.ColormapButtonGroup_PostMap.Title = 'Colormap';
            app.GreyscaleButton_PostMap = uiradiobutton(app.ColormapButtonGroup_PostMap);
            app.GreyscaleButton_PostMap.Enable = 'off';
            app.GreyscaleButton_PostMap.Text = 'Greyscale';
            app.GreyscaleButton_PostMap.Value = true;
            app.TurboButton_PostMap = uiradiobutton(app.ColormapButtonGroup_PostMap);
            app.TurboButton_PostMap.Enable = 'off';
            app.TurboButton_PostMap.Text = 'Turbo';
            app.DSCMappingOptionsPanel = uipanel(app.ParameterMapsTab);
            app.DSCMappingOptionsPanel.Title = 'DSC Mapping Options';
            app.CalculateDSCmapsButton = uibutton(app.DSCMappingOptionsPanel, 'push');
            app.CalculateDSCmapsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDSCmapsButtonPushed, true);
            app.CalculateDSCmapsButton.Text = 'Calculate DSC maps';
            app.MethodButtonGroup = uibuttongroup(app.DSCMappingOptionsPanel);
            app.MethodButtonGroup.Title = 'Method';
            app.SVDButton = uiradiobutton(app.MethodButtonGroup);
            app.SVDButton.Text = 'SVD';
            app.SVDButton.Value = true;
            app.cSVDButton = uiradiobutton(app.MethodButtonGroup);
            app.cSVDButton.Text = 'cSVD';
            app.oSVDButton = uiradiobutton(app.MethodButtonGroup);
            app.oSVDButton.Text = 'oSVD';
            app.AdvancedSettingsButton = uibutton(app.DSCMappingOptionsPanel, 'push');
            app.AdvancedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSettingsButtonPushed, true);
            app.AdvancedSettingsButton.Text = 'Advanced Settings';
            app.DSCMapDropDownLabel = uilabel(app.DSCMappingOptionsPanel);
            app.DSCMapDropDownLabel.Text = 'DSC Map';
            app.DSCMapDropDown = uidropdown(app.DSCMappingOptionsPanel);
            app.DSCMapDropDown.Items = {'CBF', 'CBV', 'MTT'};
            app.DSCMapDropDown.ValueChangedFcn = createCallbackFcn(app, @DSCMapDropDownValueChanged, true);
            app.DSCMapDropDown.Enable = 'off';
            app.DSCMapDropDown.Value = 'CBF';
            app.AIFExtractionSliceSpinnerLabel = uilabel(app.DSCMappingOptionsPanel);
            app.AIFExtractionSliceSpinnerLabel.Text = 'Slice For AIF Extraction';
            app.AIFExtractionSliceSpinner = uispinner(app.DSCMappingOptionsPanel);
            app.AIFExtractionSliceSpinner.Value = 1;
            app.SliceSpinner_PostMap = uispinner(app.ParameterMapsTab);
            app.SliceSpinner_PostMap.ValueChangedFcn = createCallbackFcn(app, @SliceSpinner_PostMapValueChanged, true);
            app.SliceSpinner_PostMap.Enable = 'off';
            app.SliceSpinner_PostMap.Value = 1;
            app.SliceLabel_ParameterMaps_PostMap = uilabel(app.ParameterMapsTab);
            app.SliceLabel_ParameterMaps_PostMap.Text = 'Slice';
            app.SliceSlider_PostMap = uislider(app.ParameterMapsTab);
            app.SliceSlider_PostMap.Limits = [1 100];
            app.SliceSlider_PostMap.MajorTicks = [];
            app.SliceSlider_PostMap.MajorTickLabels = {};
            app.SliceSlider_PostMap.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_PostMapValueChanging, true);
            app.SliceSlider_PostMap.MinorTicks = [];
            app.SliceSlider_PostMap.Enable = 'off';
            app.SliceSlider_PostMap.Value = 1;
            app.SaveDataButton_Map = uibutton(app.ParameterMapsTab, 'push');
            app.SaveDataButton_Map.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_MapPushed, true);
            app.SaveDataButton_Map.Enable = 'off';
            app.SaveDataButton_Map.Text = 'Save Parameter Map';
            app.ExportDataButton_Map = uibutton(app.ParameterMapsTab, 'push');
            app.ExportDataButton_Map.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_MapPushed, true);
            app.ExportDataButton_Map.Enable = 'off';
            app.ExportDataButton_Map.Text = 'Export Parameter Map';
            app.OptimizationOptionsPanel = uipanel(app.ParameterMapsTab);
            app.OptimizationOptionsPanel.Title = 'Optimization Options';
            app.OptimizationOptionsPanel.Visible = 'off';
            app.fxToleranceEditFieldLabel = uilabel(app.OptimizationOptionsPanel);
            app.fxToleranceEditFieldLabel.Text = 'f(x) Tolerance';
            app.fxToleranceEditField = uieditfield(app.OptimizationOptionsPanel, 'numeric');
            app.fxToleranceEditField.ValueDisplayFormat = '%1.1e';
            app.fxToleranceEditField.Value = 0.001;
            app.OptimalityToleranceEditFieldLabel = uilabel(app.OptimizationOptionsPanel);
            app.OptimalityToleranceEditFieldLabel.Text = 'Optimality Tolerance';
            app.OptimalityToleranceEditField = uieditfield(app.OptimizationOptionsPanel, 'numeric');
            app.OptimalityToleranceEditField.ValueDisplayFormat = '%1.1e';
            app.OptimalityToleranceEditField.Value = 0.001;
            app.StepToleranceEditFieldLabel = uilabel(app.OptimizationOptionsPanel);
            app.StepToleranceEditFieldLabel.Text = 'Step Tolerance';
            app.StepToleranceEditField = uieditfield(app.OptimizationOptionsPanel, 'numeric');
            app.StepToleranceEditField.ValueDisplayFormat = '%1.1e';
            app.StepToleranceEditField.Value = 0.001;
            app.dfStepSizeEditFieldLabel = uilabel(app.OptimizationOptionsPanel);
            app.dfStepSizeEditFieldLabel.Text = 'd(f) Step Size';
            app.dfStepSizeEditField = uieditfield(app.OptimizationOptionsPanel, 'numeric');
            app.dfStepSizeEditField.ValueDisplayFormat = '%1.1e';
            app.dfStepSizeEditField.Value = 0.0001;
            app.MaxNrofEvaluationsEditFieldLabel = uilabel(app.OptimizationOptionsPanel);
            app.MaxNrofEvaluationsEditFieldLabel.Text = 'Max Nr. of Evaluations';
            app.MaxNrofEvaluationsEditField = uieditfield(app.OptimizationOptionsPanel, 'numeric');
            app.MaxNrofEvaluationsEditField.ValueDisplayFormat = '%1.1e';
            app.MaxNrofEvaluationsEditField.Value = 10;
            app.MaxNrofIterationsEditFieldLabel = uilabel(app.OptimizationOptionsPanel);
            app.MaxNrofIterationsEditFieldLabel.Text = 'Max Nr. of Iterations';
            app.MaxNrofIterationsEditField = uieditfield(app.OptimizationOptionsPanel, 'numeric');
            app.MaxNrofIterationsEditField.ValueDisplayFormat = '%1.1e';
            app.MaxNrofIterationsEditField.Value = 10;
            app.ResettodefaultsButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.ResettodefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultsButtonPushed, true);
            app.ResettodefaultsButton.Text = 'Reset to defaults';
            app.T2OptionsPanel = uipanel(app.ParameterMapsTab);
            app.T2OptionsPanel.Title = 'T2 Mapping Options';
            app.T2OptionsPanel.Visible = 'off';
            app.TEvaluesLabel = uilabel(app.T2OptionsPanel);
            app.TEvaluesLabel.Text = 'TE Values';
            app.TEvaluesText = uitextarea(app.T2OptionsPanel);
            app.CalculateT2MapButton = uibutton(app.T2OptionsPanel, 'push');
            app.CalculateT2MapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateT2MapButtonPushed, true);
            app.CalculateT2MapButton.Text = 'Calculate T2 Map';
            app.T1OptionsPanel = uipanel(app.ParameterMapsTab);
            app.T1OptionsPanel.Title = 'T1 Mapping Options';
            app.T1OptionsPanel.Visible = 'off';
            app.TRvaluesLabel = uilabel(app.T1OptionsPanel);
            app.TRvaluesLabel.Text = 'TR Values';
            app.TRvaluesText = uitextarea(app.T1OptionsPanel);
            app.CalculateT1MapButton = uibutton(app.T1OptionsPanel, 'push');
            app.CalculateT1MapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateT1MapButtonPushed, true);
            app.CalculateT1MapButton.Text = 'Calculate T1 Map';
            app.TIvaluesLabel = uilabel(app.T1OptionsPanel);
            app.TIvaluesLabel.Text = 'TI Values';
            app.TIvaluesText = uitextarea(app.T1OptionsPanel);
            app.FAIRpASLMappingoptionsPanel = uipanel(app.ParameterMapsTab);
            app.FAIRpASLMappingoptionsPanel.Title = 'FAIR pASL Mapping options';
            app.FAIRpASLMappingoptionsPanel.Visible = 'off';
            app.BloodT1sEditFieldLabel = uilabel(app.FAIRpASLMappingoptionsPanel);
            app.BloodT1sEditFieldLabel.Text = 'Blood T1 (s)';
            app.BloodT1sEditField = uieditfield(app.FAIRpASLMappingoptionsPanel, 'numeric');
            app.BloodT1sEditField.Value = 2.4;
            app.ExperimentorderButtonGroup = uibuttongroup(app.FAIRpASLMappingoptionsPanel);
            app.ExperimentorderButtonGroup.Title = 'Experiment order';
            app.SliceSelectiveFirstButton = uiradiobutton(app.ExperimentorderButtonGroup);
            app.SliceSelectiveFirstButton.Text = 'Slice Selective First';
            app.SliceSelectiveFirstButton.Value = true;
            app.NonSelectiveFirstButton = uiradiobutton(app.ExperimentorderButtonGroup);
            app.NonSelectiveFirstButton.Text = 'Non-Selective First';
            app.CalculatepASLMapButton = uibutton(app.FAIRpASLMappingoptionsPanel, 'push');
            app.CalculatepASLMapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepASLMapButtonPushed, true);
            app.CalculatepASLMapButton.Text = 'Calculate pASL Map';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.ButtonDownFcn = createCallbackFcn(app, @ResultsTabButtonDown, true);
            app.BrainPanel = uipanel(app.ResultsTab);
            app.BrainPanel.Title = 'Brain';
            app.BrainPanel.ButtonDownFcn = createCallbackFcn(app, @BrainPanelButtonDown, true);
            app.UITable_ResultsBrain = uitable(app.BrainPanel);
            app.UITable_ResultsBrain.ColumnName = {'Slice Number'; 'Slice Area'};
            app.UITable_ResultsBrain.RowName = {};
            app.UITable_ResultsBrain.CellSelectionCallback = createCallbackFcn(app, @UITable_ResultsBrainCellSelection, true);
            app.UITable_ResultsBrain.Multiselect = 'off';
            app.VolumeEditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.VolumeEditFieldLabel_Brain.Text = 'Volume';
            app.VolumeEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.SDEditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.SDEditFieldLabel_Brain.Text = 'SD';
            app.SDEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.MeanEditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.MeanEditFieldLabel_Brain.Text = 'Mean';
            app.MeanEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.LabelPlusMinus_Brain = uilabel(app.BrainPanel);
            app.LabelPlusMinus_Brain.Interpreter = 'latex';
            app.LabelPlusMinus_Brain.Text = '±';
            app.IQREditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.IQREditFieldLabel_Brain.Text = 'IQR';
            app.IQRLowerEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.MedianEditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.MedianEditFieldLabel_Brain.Text = 'Median';
            app.MedianEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.MaxEditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.MaxEditFieldLabel_Brain.Text = 'Max';
            app.MaxEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.MinEditFieldLabel_Brain = uilabel(app.BrainPanel);
            app.MinEditFieldLabel_Brain.Text = 'Min';
            app.MinEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.IQRUpperEditField_Brain = uieditfield(app.BrainPanel, 'numeric');
            app.HemispherePanel = uipanel(app.ResultsTab);
            app.HemispherePanel.Title = 'Hemisphere';
            app.HemispherePanel.ButtonDownFcn = createCallbackFcn(app, @HemispherePanelButtonDown, true);
            app.UITable_ResultsHemisphere = uitable(app.HemispherePanel);
            app.UITable_ResultsHemisphere.ColumnName = {'Slice Number'; 'Slice Area'};
            app.UITable_ResultsHemisphere.RowName = {};
            app.UITable_ResultsHemisphere.CellSelectionCallback = createCallbackFcn(app, @UITable_ResultsHemisphereCellSelection, true);
            app.VolumeEditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.VolumeEditFieldLabel_Hemisphere.Text = 'Volume';
            app.VolumeEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.LabelPlusMinus_Hemisphere = uilabel(app.HemispherePanel);
            app.LabelPlusMinus_Hemisphere.Interpreter = 'latex';
            app.LabelPlusMinus_Hemisphere.Text = '±';
            app.SDEditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.SDEditFieldLabel_Hemisphere.Text = 'SD';
            app.SDEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.MeanEditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.MeanEditFieldLabel_Hemisphere.Text = 'Mean';
            app.MeanEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.MaxEditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.MaxEditFieldLabel_Hemisphere.Text = 'Max';
            app.MaxEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.MinEditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.MinEditFieldLabel_Hemisphere.Text = 'Min';
            app.MinEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.SelectHemisphereDropDownLabel = uilabel(app.HemispherePanel);
            app.SelectHemisphereDropDownLabel.Text = 'Select Hemisphere';
            app.SelectHemisphereDropDown = uidropdown(app.HemispherePanel);
            app.SelectHemisphereDropDown.Items = {'Left', 'Right'};
            app.SelectHemisphereDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectHemisphereDropDownValueChanged, true);
            app.SelectHemisphereDropDown.Enable = 'off';
            app.SelectHemisphereDropDown.Placeholder = 'None';
            app.SelectHemisphereDropDown.ClickedFcn = createCallbackFcn(app, @SelectHemisphereDropDownClicked, true);
            app.SelectHemisphereDropDown.Value = 'Left';
            app.MedianEditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.MedianEditFieldLabel_Hemisphere.Text = 'Median';
            app.MedianEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.IQREditFieldLabel_Hemisphere = uilabel(app.HemispherePanel);
            app.IQREditFieldLabel_Hemisphere.Text = 'IQR';
            app.IQRLowerEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.IQRUpperEditField_Hemisphere = uieditfield(app.HemispherePanel, 'numeric');
            app.ROIPanel_Results = uipanel(app.ResultsTab);
            app.ROIPanel_Results.Title = 'ROI';
            app.ROIPanel_Results.ButtonDownFcn = createCallbackFcn(app, @ROIPanel_ResultsButtonDown, true);
            app.UITable_ResultsROI = uitable(app.ROIPanel_Results);
            app.UITable_ResultsROI.ColumnName = {'Slice Number'; 'Slice Area'};
            app.UITable_ResultsROI.RowName = {};
            app.UITable_ResultsROI.CellSelectionCallback = createCallbackFcn(app, @UITable_ResultsROICellSelection, true);
            app.VolumeEditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.VolumeEditFieldLabel_ROI.Text = 'Volume';
            app.VolumeEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.LabelPlusMinus_ROI = uilabel(app.ROIPanel_Results);
            app.LabelPlusMinus_ROI.Interpreter = 'latex';
            app.LabelPlusMinus_ROI.Text = '±';
            app.SDEditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.SDEditFieldLabel_ROI.Text = 'SD';
            app.SDEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.MeanEditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.MeanEditFieldLabel_ROI.Text = 'Mean';
            app.MeanEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.MaxEditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.MaxEditFieldLabel_ROI.Text = 'Max';
            app.MaxEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.MinEditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.MinEditFieldLabel_ROI.Text = 'Min';
            app.MinEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.SelectROIDropDownLabel = uilabel(app.ROIPanel_Results);
            app.SelectROIDropDownLabel.Text = 'Select ROI';
            app.SelectROIDropDown = uidropdown(app.ROIPanel_Results);
            app.SelectROIDropDown.Items = {};
            app.SelectROIDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectROIDropDownValueChanged, true);
            app.SelectROIDropDown.Enable = 'off';
            app.SelectROIDropDown.Placeholder = 'None';
            app.SelectROIDropDown.ClickedFcn = createCallbackFcn(app, @SelectROIDropDownClicked, true);
            app.SelectROIDropDown.Value = {};
            app.MedianEditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.MedianEditFieldLabel_ROI.Text = 'Median';
            app.MedianEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.IQREditFieldLabel_ROI = uilabel(app.ROIPanel_Results);
            app.IQREditFieldLabel_ROI.Text = 'IQR';
            app.IQRLowerEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.IQRUpperEditField_ROI = uieditfield(app.ROIPanel_Results, 'numeric');
            app.ApplyEdemaCorrectionCheckBox = uicheckbox(app.ROIPanel_Results);
            app.ApplyEdemaCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyEdemaCorrectionCheckBoxValueChanged, true);
            app.ApplyEdemaCorrectionCheckBox.Enable = 'off';
            app.ApplyEdemaCorrectionCheckBox.Text = 'Apply Edema Correction';
            app.SelectExperimentForVolumetryLabel = uilabel(app.ResultsTab);
            app.SelectExperimentForVolumetryLabel.Text = 'Select Experiment For Statistic Calculation';
            app.SelectResultsDropDown = uidropdown(app.ResultsTab);
            app.SelectResultsDropDown.Items = {'None'};
            app.SelectResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectResultsDropDownValueChanged, true);
            app.SelectResultsDropDown.Placeholder = 'None';
            app.SelectResultsDropDown.ClickedFcn = createCallbackFcn(app, @SelectResultsDropDownClicked, true);
            app.SelectResultsDropDown.Value = 'None';
            app.UnitsLabel = uilabel(app.ResultsTab);
            app.UnitsLabel.Text = 'Units:';
            app.AreaLabel = uilabel(app.ResultsTab);
            app.AreaLabel.Text = 'Area';
            app.VolumeLabel = uilabel(app.ResultsTab);
            app.VolumeLabel.Text = 'Volume';
            app.AreaUnitLabel = uilabel(app.ResultsTab);
            app.AreaUnitLabel.Text = '';
            app.VolumeUnitLabel = uilabel(app.ResultsTab);
            app.VolumeUnitLabel.Text = '';
            app.ExportDataButton_Results = uibutton(app.ResultsTab, 'push');
            app.ExportDataButton_Results.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_ResultsPushed, true);
            app.ExportDataButton_Results.Enable = 'off';
            app.ExportDataButton_Results.Text = 'Export Results to Excel File';
            app.UIAxes_Results_Container = uipanel(app.ResultsTab);
            app.UIAxes_Results_Container.Visible = 'off';
            app.UIAxes_Results = uiaxes(app.UIAxes_Results_Container);
            app.UIAxes_Results.XLim = [-Inf Inf];
            app.UIAxes_Results.YLim = [-Inf Inf];
            app.UIAxes_Results.XTick = [];
            app.UIAxes_Results.YTick = [];
            app.UIAxes_Results.ZTick = [];
            app.UIAxes_Results.Visible = 'off';
            app.DViewerTab = uitab(app.TabGroup);
            app.DViewerTab.Title = '3D Viewer';
            app.UIAxes_AlphaMap = uiaxes(app.DViewerTab);
            app.UIAxes_AlphaMap.XTick = [];
            app.UIAxes_AlphaMap.YTick = [];
            app.UIAxes_AlphaMap.ZTick = [];
            app.ViewerPanel = uipanel(app.DViewerTab);
            app.Select3DViewerLabel = uilabel(app.DViewerTab);
            app.Select3DViewerLabel.Text = 'Select Experiment To View';
            app.Select3DViewerDropDown = uidropdown(app.DViewerTab);
            app.Select3DViewerDropDown.Items = {'None'};
            app.Select3DViewerDropDown.ValueChangedFcn = createCallbackFcn(app, @Select3DViewerDropDownValueChanged, true);
            app.Select3DViewerDropDown.Placeholder = 'None';
            app.Select3DViewerDropDown.Value = 'None';
            app.RenderingStyleDropDownLabel = uilabel(app.DViewerTab);
            app.RenderingStyleDropDownLabel.Text = 'Rendering Style';
            app.RenderingStyleDropDown = uidropdown(app.DViewerTab);
            app.RenderingStyleDropDown.Items = {'Volume Rendering', 'Maximum Intensity Projection', 'Minimum Intensity Projection', 'Slice Planes', 'Gradient Opacity', 'Isosurface'};
            app.RenderingStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingStyleDropDownValueChanged, true);
            app.RenderingStyleDropDown.Enable = 'off';
            app.RenderingStyleDropDown.Tooltip = {''};
            app.RenderingStyleDropDown.Value = 'Volume Rendering';
            app.ColormapDropDownLabel_Viewer = uilabel(app.DViewerTab);
            app.ColormapDropDownLabel_Viewer.Text = 'Colormap';
            app.ColormapDropDown_Viewer = uidropdown(app.DViewerTab);
            app.ColormapDropDown_Viewer.Items = {'Greyscale', 'Turbo', 'Hot', 'Jet', 'Parula', 'Sky', 'Cool', 'Copper', 'Bone'};
            app.ColormapDropDown_Viewer.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_ViewerValueChanged, true);
            app.ColormapDropDown_Viewer.Enable = 'off';
            app.ColormapDropDown_Viewer.Tooltip = {''};
            app.ColormapDropDown_Viewer.Value = 'Greyscale';
            app.AlphamapDropDownLabel_Viewer = uilabel(app.DViewerTab);
            app.AlphamapDropDownLabel_Viewer.Text = 'Alphamap';
            app.AlphamapDropDown_Viewer = uidropdown(app.DViewerTab);
            app.AlphamapDropDown_Viewer.Items = {'Linear', 'MRI', 'MRI-mip'};
            app.AlphamapDropDown_Viewer.ValueChangedFcn = createCallbackFcn(app, @AlphamapDropDown_ViewerValueChanged, true);
            app.AlphamapDropDown_Viewer.Enable = 'off';
            app.AlphamapDropDown_Viewer.Tooltip = {''};
            app.AlphamapDropDown_Viewer.Value = 'Linear';
            app.ColormapImage_Viewer = uiimage(app.DViewerTab);
            app.ColormapImage_Viewer.Visible = 'off';
            app.Dim5Spinner_ViewerLabel = uilabel(app.DViewerTab);
            app.Dim5Spinner_ViewerLabel.Text = 'Dim - 5';
            app.Dim5Spinner_Viewer = uispinner(app.DViewerTab);
            app.Dim5Spinner_Viewer.ValueChangedFcn = createCallbackFcn(app, @Dim5Spinner_ViewerValueChanged, true);
            app.Dim5Spinner_Viewer.Enable = 'off';
            app.Dim5Spinner_Viewer.Value = 1;
            app.Dim4Spinner_ViewerLabel = uilabel(app.DViewerTab);
            app.Dim4Spinner_ViewerLabel.Text = 'Dim - 4';
            app.Dim4Spinner_Viewer = uispinner(app.DViewerTab);
            app.Dim4Spinner_Viewer.ValueChangedFcn = createCallbackFcn(app, @Dim4Spinner_ViewerValueChanged, true);
            app.Dim4Spinner_Viewer.Enable = 'off';
            app.Dim4Spinner_Viewer.Value = 1;
            app.DisplayedSliceRangeLabel = uilabel(app.DViewerTab);
            app.DisplayedSliceRangeLabel.Text = 'Displayed Slice Range';
            app.SliceRangeLowSpinner_Viewer = uispinner(app.DViewerTab);
            app.SliceRangeLowSpinner_Viewer.ValueChangedFcn = createCallbackFcn(app, @SliceRangeLowSpinner_ViewerValueChanged, true);
            app.SliceRangeLowSpinner_Viewer.Enable = 'off';
            app.SliceRangeLowSpinner_Viewer.Value = 1;
            app.SliceRangeHighSpinner_Viewer = uispinner(app.DViewerTab);
            app.SliceRangeHighSpinner_Viewer.ValueChangedFcn = createCallbackFcn(app, @SliceRangeHighSpinner_ViewerValueChanged, true);
            app.SliceRangeHighSpinner_Viewer.Enable = 'off';
            app.SliceRangeHighSpinner_Viewer.Value = 1;
            app.DashLabel = uilabel(app.DViewerTab);
            app.DashLabel.Text = '-';
            app.OverlayButton = uibutton(app.DViewerTab, 'state');
            app.OverlayButton.ValueChangedFcn = createCallbackFcn(app, @OverlayButtonValueChanged, true);
            app.OverlayButton.Enable = 'off';
            app.OverlayButton.Text = 'Overlay';
            app.DataDimensionsPanel = uipanel(app.DViewerTab);
            app.DataDimensionsPanel.Title = 'Data Dimensions';
            app.YEditFieldLabel_Viewer = uilabel(app.DataDimensionsPanel);
            app.YEditFieldLabel_Viewer.Text = 'Y';
            app.YEditField_Viewer = uieditfield(app.DataDimensionsPanel, 'numeric');
            app.YEditField_Viewer.ValueChangedFcn = createCallbackFcn(app, @YEditField_ViewerValueChanged, true);
            app.XEditFieldLabel_Viewer = uilabel(app.DataDimensionsPanel);
            app.XEditFieldLabel_Viewer.Text = 'X';
            app.XEditField_Viewer = uieditfield(app.DataDimensionsPanel, 'numeric');
            app.XEditField_Viewer.ValueChangedFcn = createCallbackFcn(app, @XEditField_ViewerValueChanged, true);
            app.ZEditFieldLabel_Viewer = uilabel(app.DataDimensionsPanel);
            app.ZEditFieldLabel_Viewer.Text = 'Z';
            app.ZEditField_Viewer = uieditfield(app.DataDimensionsPanel, 'numeric');
            app.ZEditField_Viewer.ValueChangedFcn = createCallbackFcn(app, @ZEditField_ViewerValueChanged, true);
            app.ExportSceneButton = uibutton(app.DViewerTab, 'push');
            app.ExportSceneButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSceneButtonPushed, true);
            app.ExportSceneButton.Enable = 'off';
            app.ExportSceneButton.Text = 'Export Scene';
            app.PlaneIntersectionSwitchLabel = uilabel(app.DViewerTab);
            app.PlaneIntersectionSwitchLabel.Text = 'Plane Intersection';
            app.PlaneIntersectionSwitch = uiswitch(app.DViewerTab, 'slider');
            app.PlaneIntersectionSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaneIntersectionSwitchValueChanged, true);
            app.PlaneIntersectionSwitch.Enable = 'off';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.UNIZG = uiimage(app.AboutTab);
            app.UNIZG.ImageSource = fullfile(pathToMLAPP, 'resources', 'unizg.png');
            app.GlowLab = uiimage(app.AboutTab);
            app.GlowLab.ImageSource = fullfile(pathToMLAPP, 'resources', 'glowlab.png');
            app.HRZZ = uiimage(app.AboutTab);
            app.HRZZ.ImageSource = fullfile(pathToMLAPP, 'resources', 'hrzz.png');
            app.Splash = uiimage(app.AboutTab);
            app.Splash.ImageSource = fullfile(pathToMLAPP, 'resources', 'splash.png');
            app.Bruker = uiimage(app.AboutTab);
            app.Bruker.ImageSource = fullfile(pathToMLAPP, 'resources', 'bruker.svg');
            app.MATLAB = uiimage(app.AboutTab);
            app.MATLAB.ImageSource = fullfile(pathToMLAPP, 'resources', 'MATLAB.png');
            app.TitleLabel = uilabel(app.AboutTab);
            app.TitleLabel.Text = 'suMRak - Simple Utility MRi Analysis Kit';
            app.VersionLabel = uilabel(app.AboutTab);
            app.VersionLabel.Text = 'version 1.1.0';
            app.AuthorsILabel = uilabel(app.AboutTab);
            app.AuthorsILabel.Text = 'Authors: Ister Rok & Sternak Marko';
            app.AboutLabel = uilabel(app.AboutTab);
            app.AboutLabel.Text = {'suMRak is an open-source tool developed in MATLAB for the analysis of MRI data for research purposes. It is provided "AS IS" and is a non-commercial research-only program package not meant to be sold or distributed to a third party. suMRak is a pre-clinical experimental tool and it is not to be used in a clinical setting. The authors of suMRak provide no warranty or support for any alleged or actual damage, arising in any way out of the use of suMRak. suMRak source code, along with the provided manual and test data, are protected under a public license.'; ''; 'suMRak was developed as a part of the doctoral study of Ister Rok, MD, PhD candidate in Neuroscience at University of Zagreb School of Medicine, Šalata 3, 10000 Zagreb, Croatia. The work of doctoral student Ister Rok has been fully supported by the “Young researchers'' career development project – training of doctoral students” project of the Croatian Science Foundation funded by the European Union from the European Social Fund.'; ''; 'BRUKER pvmatlab is integrated and provided, alongside its respective manual, with suMRak under written consent from Bruker BioSpin MRI GmbH, Rudolf-Plank Str. 23, 76275 Ettlingen. suMRak uses BRUKER pvmatlab solely to import reconstructed image data and metadata by initializing objects of its ImageDataObject class. Bruker reserves all rights on the use and distribution of the pvmatlab package. Its source code and test data are protected under copyright. For additional info, refer to the "Legal Matters" section of the pvmatlab manual. Bruker is a registered trademark of BRUKER-PHYSIK AG.'; ''; 'Dynamic Susceptibility Contrast (DSC) MRI toolbox is an open source MATLAB toolbox used to analyze DSC-MRI data authored by Peruzzo Denis and Castellaro Marco. It is used in suMRak solely for DSC map calculation. DSC MRI toolbox source code is available at: https://github.com/marcocastellaro/dsc-mri-toolbox.'; ''; 'Registration of image data is implemented using SimpleElastix. SimpleElastix is an extension of SimpleITK that offers a user-friendly API to the popular image registration algorithms of the elastix C++ library. SimpleElastix source code is available at: https://github.com/SuperElastix/SimpleElastix'; ''; 'The 3D viewer screen export is implemented using the Screen Capture tool by Yair Altman, openly available via MATLAB Central''s File Exchange.'; ''; 'All product names used in this text are trademarks of their respective owners. Product names and their respective logos are used solely to identify their products and the authors of suMRak are in no way associated or affiliated with these companies.'};
            app.ContextMenu_Preview = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.RotateMenu_Preview = uimenu(app.ContextMenu_Preview);
            app.RotateMenu_Preview.MenuSelectedFcn = createCallbackFcn(app, @RotateMenu_PreviewSelected, true);
            app.RotateMenu_Preview.Text = 'Rotate';
            app.FlipVerticallyMenu_Preview = uimenu(app.ContextMenu_Preview);
            app.FlipVerticallyMenu_Preview.MenuSelectedFcn = createCallbackFcn(app, @FlipVerticallyMenu_PreviewSelected, true);
            app.FlipVerticallyMenu_Preview.Text = 'Flip Vertically';
            app.FlipHorizontallyMenu_Preview = uimenu(app.ContextMenu_Preview);
            app.FlipHorizontallyMenu_Preview.MenuSelectedFcn = createCallbackFcn(app, @FlipHorizontallyMenu_PreviewSelected, true);
            app.FlipHorizontallyMenu_Preview.Text = 'Flip Horizontally';
            app.ResetViewMenu_Preview = uimenu(app.ContextMenu_Preview);
            app.ResetViewMenu_Preview.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenu_PreviewSelected, true);
            app.ResetViewMenu_Preview.Text = 'Reset View';
            app.ContextMenu_Segmenter = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.RotateMenu_Segmenter = uimenu(app.ContextMenu_Segmenter);
            app.RotateMenu_Segmenter.MenuSelectedFcn = createCallbackFcn(app, @RotateMenu_SegmenterSelected, true);
            app.RotateMenu_Segmenter.Text = 'Rotate';
            app.FlipVerticallyMenu_Segmenter = uimenu(app.ContextMenu_Segmenter);
            app.FlipVerticallyMenu_Segmenter.MenuSelectedFcn = createCallbackFcn(app, @FlipVerticallyMenu_SegmenterSelected, true);
            app.FlipVerticallyMenu_Segmenter.Text = 'Flip Vertically';
            app.FlipHorizontallyMenu_Segmenter = uimenu(app.ContextMenu_Segmenter);
            app.FlipHorizontallyMenu_Segmenter.MenuSelectedFcn = createCallbackFcn(app, @FlipHorizontallyMenu_SegmenterSelected, true);
            app.FlipHorizontallyMenu_Segmenter.Text = 'Flip Horizontally';
            app.ResetViewMenu_Segmenter = uimenu(app.ContextMenu_Segmenter);
            app.ResetViewMenu_Segmenter.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenu_SegmenterSelected, true);
            app.ResetViewMenu_Segmenter.Text = 'Reset View';
            app.PermuteDimensionsMenu = uimenu(app.ContextMenu_Segmenter);
            app.PermuteDimensionsMenu.Text = 'Permute Dimensions';
            app.PermuteMenu_3_4 = uimenu(app.PermuteDimensionsMenu);
            app.PermuteMenu_3_4.MenuSelectedFcn = createCallbackFcn(app, @PermuteMenu_3_4Selected, true);
            app.PermuteMenu_3_4.Text = '3-4';
            app.PermuteMenu_3_5 = uimenu(app.PermuteDimensionsMenu);
            app.PermuteMenu_3_5.MenuSelectedFcn = createCallbackFcn(app, @PermuteMenu_3_5Selected, true);
            app.PermuteMenu_3_5.Text = '3-5';
            app.PermuteMenu_4_5 = uimenu(app.PermuteDimensionsMenu);
            app.PermuteMenu_4_5.MenuSelectedFcn = createCallbackFcn(app, @PermuteMenu_4_5Selected, true);
            app.PermuteMenu_4_5.Text = '4-5';
            app.ContextMenu_PreMap = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.RotateMenu_PreMap = uimenu(app.ContextMenu_PreMap);
            app.RotateMenu_PreMap.MenuSelectedFcn = createCallbackFcn(app, @RotateMenu_PreMapSelected, true);
            app.RotateMenu_PreMap.Text = 'Rotate';
            app.FlipVerticallyMenu_PreMap = uimenu(app.ContextMenu_PreMap);
            app.FlipVerticallyMenu_PreMap.MenuSelectedFcn = createCallbackFcn(app, @FlipVerticallyMenu_PreMapSelected, true);
            app.FlipVerticallyMenu_PreMap.Text = 'Flip Vertically';
            app.FlipHorizontallyMenu_PreMap = uimenu(app.ContextMenu_PreMap);
            app.FlipHorizontallyMenu_PreMap.MenuSelectedFcn = createCallbackFcn(app, @FlipHorizontallyMenu_PreMapSelected, true);
            app.FlipHorizontallyMenu_PreMap.Text = 'Flip Horizontally';
            app.ResetViewMenu_PreMap = uimenu(app.ContextMenu_PreMap);
            app.ResetViewMenu_PreMap.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenu_PreMapSelected, true);
            app.ResetViewMenu_PreMap.Text = 'Reset View';
            app.PermuteDimensionsMenu_PreMap = uimenu(app.ContextMenu_PreMap);
            app.PermuteDimensionsMenu_PreMap.Text = 'Permute Dimensions';
            app.PermuteMenu_3_4_PreMap = uimenu(app.PermuteDimensionsMenu_PreMap);
            app.PermuteMenu_3_4_PreMap.MenuSelectedFcn = createCallbackFcn(app, @PermuteMenu_3_4_PreMapSelected, true);
            app.PermuteMenu_3_4_PreMap.Text = '3-4';
            app.PermuteMenu_3_5_PreMap = uimenu(app.PermuteDimensionsMenu_PreMap);
            app.PermuteMenu_3_5_PreMap.MenuSelectedFcn = createCallbackFcn(app, @PermuteMenu_3_5_PreMapSelected, true);
            app.PermuteMenu_3_5_PreMap.Text = '3-5';
            app.PermuteMenu_4_5_PreMap = uimenu(app.PermuteDimensionsMenu_PreMap);
            app.PermuteMenu_4_5_PreMap.MenuSelectedFcn = createCallbackFcn(app, @PermuteMenu_4_5_PreMapSelected, true);
            app.PermuteMenu_4_5_PreMap.Text = '4-5';
            app.ContextMenuEdema = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.HemisphereScalingFactorMenu = uimenu(app.ContextMenuEdema);
            app.HemisphereScalingFactorMenu.MenuSelectedFcn = createCallbackFcn(app, @HemisphereScalingFactorMenuSelected, true);
            app.HemisphereScalingFactorMenu.Tooltip = {'ROI = ROI*(Contralateral/Ipsilateral)'};
            app.HemisphereScalingFactorMenu.Checked = 'on';
            app.HemisphereScalingFactorMenu.Text = 'Hemisphere Scaling Factor';
            app.BelayevScalingFactorMenu = uimenu(app.ContextMenuEdema);
            app.BelayevScalingFactorMenu.MenuSelectedFcn = createCallbackFcn(app, @BelayevScalingFactorMenuSelected, true);
            app.BelayevScalingFactorMenu.Tooltip = {'ROI = ROI*[1-(Ipsilateral-Contralateral)/Contralateral]'};
            app.BelayevScalingFactorMenu.Text = 'Belayev Scaling Factor';
            app.GerrietsCompressionFactorMenu = uimenu(app.ContextMenuEdema);
            app.GerrietsCompressionFactorMenu.MenuSelectedFcn = createCallbackFcn(app, @GerrietsCompressionFactorMenuSelected, true);
            app.GerrietsCompressionFactorMenu.Tooltip = {'Check documentation for detailed formula.'};
            app.GerrietsCompressionFactorMenu.Text = 'Gerriets Compression Factor';
            % Assign app.ContextMenuEdema
            app.ApplyEdemaCorrectionCheckBox.ContextMenu = app.ContextMenuEdema;
            app.ContextMenu_PostMap = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.RotateMenu_PostMap = uimenu(app.ContextMenu_PostMap);
            app.RotateMenu_PostMap.MenuSelectedFcn = createCallbackFcn(app, @RotateMenu_PostMapSelected, true);
            app.RotateMenu_PostMap.Text = 'Rotate';
            app.FlipVerticallyMenu_PostMap = uimenu(app.ContextMenu_PostMap);
            app.FlipVerticallyMenu_PostMap.MenuSelectedFcn = createCallbackFcn(app, @FlipVerticallyMenu_PostMapSelected, true);
            app.FlipVerticallyMenu_PostMap.Text = 'Flip Vertically';
            app.FlipHorizontallyMenu_PostMap = uimenu(app.ContextMenu_PostMap);
            app.FlipHorizontallyMenu_PostMap.MenuSelectedFcn = createCallbackFcn(app, @FlipHorizontallyMenu_PostMapSelected, true);
            app.FlipHorizontallyMenu_PostMap.Text = 'Flip Horizontally';
            app.ResetViewMenu_PostMap = uimenu(app.ContextMenu_PostMap);
            app.ResetViewMenu_PostMap.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenu_PostMapSelected, true);
            app.ResetViewMenu_PostMap.Text = 'Reset View';
            app.ContextMenu_Registration = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.ResetViewMenu_Registration = uimenu(app.ContextMenu_Registration);
            app.ResetViewMenu_Registration.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenu_RegistrationSelected, true);
            app.ResetViewMenu_Registration.Text = 'Reset View';
            app.ContextMenu_RegistrationInstructions = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.ResetInstructionsMenu = uimenu(app.ContextMenu_RegistrationInstructions);
            app.ResetInstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetInstructionsMenuSelected, true);
            app.ResetInstructionsMenu.Text = 'Reset Instructions';
            % Assign app.ContextMenu_RegistrationInstructions
            app.RegistrationSliceLimitsTextArea.ContextMenu = app.ContextMenu_RegistrationInstructions;
            app.TransformsContextMenu = uicontextmenu(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure);
            app.EditTransformMenu = uimenu(app.TransformsContextMenu);
            app.EditTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @EditTransformMenuSelected, true);
            app.EditTransformMenu.Text = 'Edit...';
            app.AddTransformMenu = uimenu(app.TransformsContextMenu);
            app.AddTransformMenu.Text = 'Add stage';
            app.AddTranslationMenu = uimenu(app.AddTransformMenu);
            app.AddTranslationMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTranslationMenuSelected, true);
            app.AddTranslationMenu.Text = 'Translation';
            app.AddEuler3DMenu = uimenu(app.AddTransformMenu);
            app.AddEuler3DMenu.MenuSelectedFcn = createCallbackFcn(app, @AddEuler3DMenuSelected, true);
            app.AddEuler3DMenu.Text = 'Euler3D';
            app.AddSimilarity3DMenu = uimenu(app.AddTransformMenu);
            app.AddSimilarity3DMenu.MenuSelectedFcn = createCallbackFcn(app, @AddSimilarity3DMenuSelected, true);
            app.AddSimilarity3DMenu.Text = 'Similarity3D';
            app.AddAffineMenu = uimenu(app.AddTransformMenu);
            app.AddAffineMenu.MenuSelectedFcn = createCallbackFcn(app, @AddAffineMenuSelected, true);
            app.AddAffineMenu.Text = 'Affine';
            app.AddBSplineMenu = uimenu(app.AddTransformMenu);
            app.AddBSplineMenu.MenuSelectedFcn = createCallbackFcn(app, @AddBSplineMenuSelected, true);
            app.AddBSplineMenu.Text = 'BSpline';
            app.DuplicateTransformMenu = uimenu(app.TransformsContextMenu);
            app.DuplicateTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicateTransformMenuSelected, true);
            app.DuplicateTransformMenu.Text = 'Duplicate';
            app.RemoveTransformMenu = uimenu(app.TransformsContextMenu);
            app.RemoveTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveTransformMenuSelected, true);
            app.RemoveTransformMenu.Text = 'Remove';
            app.MoveUpTransformMenu = uimenu(app.TransformsContextMenu);
            app.MoveUpTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveUpTransformMenuSelected, true);
            app.MoveUpTransformMenu.Text = 'Move up';
            app.MoveDownTransformMenu = uimenu(app.TransformsContextMenu);
            app.MoveDownTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveDownTransformMenuSelected, true);
            app.MoveDownTransformMenu.Text = 'Move down';
            app.ResetTransformMenu = uimenu(app.TransformsContextMenu);
            app.ResetTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetTransformMenuSelected, true);
            app.ResetTransformMenu.Separator = 'on';
            app.ResetTransformMenu.Text = 'Reset to default';
            % Assign app.TransformsContextMenu
            app.TransformsListBox.ContextMenu = app.TransformsContextMenu;
            % Show the figure after all components are created
            app.suMRakSimpleUtilityMRiAnalysisKitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = suMRak

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.suMRakSimpleUtilityMRiAnalysisKitUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartUpFcn)
            else

                % Focus the running singleton app
                figure(runningApp.suMRakSimpleUtilityMRiAnalysisKitUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.suMRakSimpleUtilityMRiAnalysisKitUIFigure)
        end
    end
end